classdef A1_DigitizeFaults_MCQsimV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ViewAngle_EditField            matlab.ui.control.EditField
        viewangleLabel                 matlab.ui.control.Label
        ShowMesh_CheckBox              matlab.ui.control.CheckBox
        SingleElementEQsize_EditField  matlab.ui.control.NumericEditField
        ActualtrigfaultsLabel          matlab.ui.control.Label
        PlotCompBound_DropDown         matlab.ui.control.DropDown
        PlotCompFault_DropDown         matlab.ui.control.DropDown
        faultelementlengthkmEditField  matlab.ui.control.NumericEditField
        faultelementlengthkmEditFieldLabel  matlab.ui.control.Label
        boundaryelementlengthkmEditField  matlab.ui.control.NumericEditField
        boundaryelementlengthkmEditFieldLabel  matlab.ui.control.Label
        BoundaryelementEditField       matlab.ui.control.NumericEditField
        BoundaryelementEditFieldLabel  matlab.ui.control.Label
        Panel_3                        matlab.ui.container.Panel
        BoundRotateAllBy_EditField     matlab.ui.control.NumericEditField
        DipdegEditField_2Label_2       matlab.ui.control.Label
        BoundNormalratemmyrEditField   matlab.ui.control.EditField
        normalratemmyrEditFieldLabel   matlab.ui.control.Label
        BoundDipratemmyrEditField      matlab.ui.control.EditField
        dipratemmyrEditFieldLabel      matlab.ui.control.Label
        BoundStrikeratemmyrEditField   matlab.ui.control.EditField
        strikeratemmyrEditFieldLabel   matlab.ui.control.Label
        BoundApplyRateAt_DropDown      matlab.ui.control.DropDown
        applyrateDropDownLabel         matlab.ui.control.Label
        DeleteBound_Button             matlab.ui.control.Button
        AddBound_Button                matlab.ui.control.Button
        BoundDip_EditField             matlab.ui.control.NumericEditField
        DipdegEditField_2Label         matlab.ui.control.Label
        BoundStrike_EditField          matlab.ui.control.NumericEditField
        StrikedegEditField_2Label      matlab.ui.control.Label
        BoundWidth_EditField           matlab.ui.control.NumericEditField
        WidthkmEditField_2Label        matlab.ui.control.Label
        BoundLength_EditField          matlab.ui.control.NumericEditField
        LengthkmEditField_2Label       matlab.ui.control.Label
        BoundDepth_EditField           matlab.ui.control.NumericEditField
        DepthtopcenterkmEditField_2Label  matlab.ui.control.Label
        BoundNorthing_EditField        matlab.ui.control.NumericEditField
        NorthingtopcenterkmEditField_2Label  matlab.ui.control.Label
        BoundEasting_EditField         matlab.ui.control.NumericEditField
        EastingtopcenterkmEditField_2Label  matlab.ui.control.Label
        BoundName_EditField            matlab.ui.control.EditField
        NameEditField_2Label           matlab.ui.control.Label
        Bound_ListBox                  matlab.ui.control.ListBox
        FaultelementEditField          matlab.ui.control.NumericEditField
        FaultelementEditFieldLabel     matlab.ui.control.Label
        SaveFaultModel_Button          matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        RemoveSelSection_Button        matlab.ui.control.Button
        UsefaultsliprateCheckBox       matlab.ui.control.CheckBox
        StressDirection_EditField      matlab.ui.control.NumericEditField
        RakedegLabel_3                 matlab.ui.control.Label
        StressRate_EditField           matlab.ui.control.NumericEditField
        SlipratemmyrLabel_2            matlab.ui.control.Label
        UseregionalstressCheckBox      matlab.ui.control.CheckBox
        toapplychangetoselfaultCheckBox  matlab.ui.control.CheckBox
        TextArea_2                     matlab.ui.control.TextArea
        TextArea_4                     matlab.ui.control.TextArea
        TextArea_3                     matlab.ui.control.TextArea
        FaultIDnum_EditField           matlab.ui.control.NumericEditField
        RakedegLabel_2                 matlab.ui.control.Label
        TracedFaults_ListBox           matlab.ui.control.ListBox
        Trace_FlipIt_Button            matlab.ui.control.Button
        selbottomtraceCheckBox         matlab.ui.control.CheckBox
        seltoptraceCheckBox            matlab.ui.control.CheckBox
        continueselectedfaultCheckBox  matlab.ui.control.CheckBox
        SlipRate_EditField             matlab.ui.control.NumericEditField
        SlipratemmyrLabel              matlab.ui.control.Label
        SlipRake_EditField             matlab.ui.control.NumericEditField
        RakedegLabel                   matlab.ui.control.Label
        Name_EditField                 matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        FaultDip_3_EditField           matlab.ui.control.NumericEditField
        DipdegLabel_3                  matlab.ui.control.Label
        FaultOrSeisWidth_3a_DropDown   matlab.ui.control.DropDown
        Width_3a_EditField             matlab.ui.control.NumericEditField
        kmLabel_2                      matlab.ui.control.Label
        TraceFaults_3a_Button          matlab.ui.control.Button
        FaultOrSeisWidth_3_DropDown    matlab.ui.control.DropDown
        Width_3_EditField              matlab.ui.control.NumericEditField
        kmLabel                        matlab.ui.control.Label
        TraceFaults_Button             matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        LoadFaultModel_Button          matlab.ui.control.Button
        CutMeshedBoundary_Button       matlab.ui.control.Button
        FigNumMap_EditField            matlab.ui.control.NumericEditField
        FigEditFieldLabel              matlab.ui.control.Label
        MaxEasting_EditField           matlab.ui.control.NumericEditField
        kmEditField_4Label             matlab.ui.control.Label
        MinEasting_EditField           matlab.ui.control.NumericEditField
        kmEditField_3Label             matlab.ui.control.Label
        MinNorthing_EditField          matlab.ui.control.NumericEditField
        kmEditField_2Label             matlab.ui.control.Label
        MaxNorthing_EditField          matlab.ui.control.NumericEditField
        kmEditFieldLabel               matlab.ui.control.Label
        maxEastingButton               matlab.ui.control.Button
        minEastingButton               matlab.ui.control.Button
        minNorthingButton              matlab.ui.control.Button
        maxNorthingButton              matlab.ui.control.Button
        PlotFaultMap_Button            matlab.ui.control.Button
        LoadFaultMap_Button            matlab.ui.control.Button
        MeshPlot_Button                matlab.ui.control.Button
        AxisEqual_CheckBox             matlab.ui.control.CheckBox
        FigNumFaults_EditField         matlab.ui.control.NumericEditField
        FigEditFieldLabel_3            matlab.ui.control.Label
    end

    
    properties (Access = private)
        GreenButton     = [0.7569    0.8667    0.7765];
        RedButton       = [0.9255    0.8392    0.8392];
        GrayButton      = [0.9059    0.9059    0.9059];
        MaxSectionID    = 0;
        AllFltNames    = {'no fault traced'};

        TheBaseMap      = [];
        EastExtend      = [];
        NorthExtend     = [];
        DataLoaded      = [];
        
        MinNorthingMouse= [];
        MaxNorthingMouse= [];
        MinEastingMouse = [];
        MaxEastingMouse = [];
        dE              = [];
        dN              = [];
        Evect           = [];
        Nvect           = [];                                                   %rate: left9-11           right12-14  
        BoundaryNames   = {'Boundary_Bottom'};                                   %rate: top9-11          bottom12-14  
        BoundaryValues  = [ -100,         0,      -20,    200,   200,  0,  0,   1,  NaN, NaN, NaN,      NaN, NaN, NaN];
        Faults      = struct; 
        TrigList_Flt    = [];
        VertList_Flt    = [];
        TrigList_Bnd    = [];
        VertList_Bnd    = [];
    end
    
    methods (Access = private)
        %-----------------------------------------------------------------
        %-----------------------------------------------------------------
        function Compute_dE_dN(app)
            app.dE       = (app.MaxEasting_EditField.Value -app.MinEasting_EditField.Value) /(app.MaxEastingMouse-app.MinEastingMouse);
            app.dN       = (app.MaxNorthing_EditField.Value-app.MinNorthing_EditField.Value)/(app.MaxNorthingMouse-app.MinNorthingMouse);
            
            StartEvalue  = app.MinEasting_EditField.Value - app.dE*(app.MinEastingMouse-1);
            EndEvalue    = StartEvalue  + app.dE*size(app.TheBaseMap,2) ;
            app.Evect    = linspace(StartEvalue, EndEvalue, size(app.TheBaseMap,2));
            
            StartNvalue  = app.MinNorthing_EditField.Value - app.dN*(app.MinNorthingMouse-1);
            EndNvalue    = StartNvalue  + app.dN*size(app.TheBaseMap,1) ;
            app.Nvect    = linspace(StartNvalue, EndNvalue, size(app.TheBaseMap,1));
        end
        %-----------------------------------------------------------------
        %-----------------------------------------------------------------
        function MeshFault(app)
            %--------------------------
            app.TrigList_Flt   = [];
            app.VertList_Flt   = [];
            UseFaultWidth  = str2double(app.FaultOrSeisWidth_3a_DropDown.Value);
            DesiredLeg     = app.faultelementlengthkmEditField.Value;
            %--------------------------
            for SelSection = 1 : app.MaxSectionID
                %--------------------------
                FaultIDnum = app.Faults(SelSection).FaultIDnum;
                UseSlip    = app.Faults(SelSection).UseSlip;
                SlipRate   = app.Faults(SelSection).SlipRate;
                Rake       = app.Faults(SelSection).Rake;
                StressRate = app.Faults(SelSection).StressRate;
                StressDir  = app.Faults(SelSection).StressDir;
                if (UseSlip == 1)
                    TheRate = SlipRate;             TheDirect = Rake;
                else
                    TheRate = StressRate;           TheDirect = StressDir;
                end
                %--------------------------
                TopPoints  = [app.Faults(SelSection).TopEasting, app.Faults(SelSection).TopNrthing, app.Faults(SelSection).TopDepth];
                BotPoints  = [app.Faults(SelSection).BotEasting, app.Faults(SelSection).BotNrthing, app.Faults(SelSection).BotDepth];
                TopLength  = sum(sqrt(sum(diff(TopPoints(:,1:2)).^2,2)));
                BotLength  = sum(sqrt(sum(diff(BotPoints(:,1:2)).^2,2)));
                ElemNumS   = floor(((TopLength+BotLength)/2)/DesiredLeg);
                %---------------------------------------------------------
                UsedSdeLgh = TopLength/ElemNumS;
                PntListTop = zeros(ElemNumS+1,3);
                PntListTop(1,:)   = TopPoints(1,:);
                PntListTop(end,:) = TopPoints(end,:);
                CurrLocn   = TopPoints(1,:);
                Counter    = 1;
                TrgtPos    = 2;
                UsedAmount = 0;
                %--------------------------
                while (Counter < ElemNumS)
                    TrgtLocn  = TopPoints(TrgtPos,:);
                    Vect2Next = TrgtLocn - CurrLocn;
                    VectLgth  = sqrt(sum(Vect2Next.^2));
                    if ((UsedSdeLgh-UsedAmount) <= (VectLgth))
                        CurrLocn = CurrLocn + ((UsedSdeLgh-UsedAmount)/VectLgth).*Vect2Next;
                        Counter  = Counter  +1;
                        PntListTop(Counter,:) = CurrLocn;
                        UsedAmount = 0;
                    else
                        CurrLocn   = TopPoints(TrgtPos,:);
                        TrgtPos    = TrgtPos +1;
                        UsedAmount = UsedAmount + VectLgth;
                    end
                end
                %--------------------------
                UsedSdeLgh = BotLength/ElemNumS;
                PntListBot = zeros(ElemNumS+1,3);
                PntListBot(1,:)   = BotPoints(1,:);
                PntListBot(end,:) = BotPoints(end,:);
                CurrLocn   = BotPoints(1,:);
                Counter    = 1;
                TrgtPos    = 2;
                UsedAmount = 0;
                %--------------------------
                while (Counter < ElemNumS)
                    TrgtLocn  = BotPoints(TrgtPos,:);
                    Vect2Next = TrgtLocn - CurrLocn;
                    VectLgth  = sqrt(sum(Vect2Next.^2));
                    if ((UsedSdeLgh-UsedAmount) <= (VectLgth))
                        CurrLocn = CurrLocn + ((UsedSdeLgh-UsedAmount)/VectLgth).*Vect2Next;
                        Counter  = Counter  +1;
                        PntListBot(Counter,:) = CurrLocn;
                        UsedAmount = 0;
                    else
                        CurrLocn   = BotPoints(TrgtPos,:);
                        TrgtPos    = TrgtPos +1;
                        UsedAmount = UsedAmount + VectLgth;
                    end
                end
                %---------------------------------------------------------
                if (UseFaultWidth == 1)
                    ProjVect  = PntListBot(:,1:2)-PntListTop(:,1:2);
                    ProjLgths = sqrt(sum(ProjVect.^2,2));
                    SelDepth  = mean(abs(PntListBot(:,3)));
                    PntListBot(ProjLgths>SelDepth,1:2) = PntListTop(ProjLgths>SelDepth,1:2) +(SelDepth./ProjLgths(ProjLgths>SelDepth)).*ProjVect(ProjLgths>SelDepth,1:2);
                    
                    ProjVect  = PntListBot(:,1:2)-PntListTop(:,1:2);
                    ProjLgths = sqrt(sum(ProjVect.^2,2));
                    VectLgths = ones(size(PntListTop,1),1).*SelDepth;
                    VectLgths = max(VectLgths, ProjLgths);
                    
                    PntListBot(:,3) = -sqrt(VectLgths.^2 - ProjLgths.^2);
                end
                %---------------------------------------------------------
                VectLgths   = sqrt( sum((PntListBot - PntListTop).^2,2));
                ElemNumD    = floor(mean(VectLgths)/DesiredLeg);
                
                IniPointMat = zeros(ElemNumS,ElemNumD,4);
                IDCount     = 0;
                %----------------------------------------------
                for i = 1 : (ElemNumS+1)
                    IDCount             = IDCount+1;
                    IniPointMat(i,1,1:3)= PntListTop(i,1:3);
                    IniPointMat(i,1,4)  = IDCount;
                    CurrLocn            = PntListTop(i,1:3);
                    EndLocn             = PntListBot(i,1:3);
    
                    NextVect   = EndLocn- CurrLocn;
                    VectLgth   = sqrt(sum(NextVect.^2));
                    UsedSdeLgh = VectLgth/ElemNumD;
                    Counter    = 1;
                    %--------------------------
                    while (Counter < ElemNumD)
                        IDCount   = IDCount+1;
                        Counter   = Counter+1;
                        Vect2Next = EndLocn - CurrLocn;
                        VectLgth  = sqrt(sum(Vect2Next.^2));
                        CurrLocn  = CurrLocn + (UsedSdeLgh/VectLgth).*Vect2Next;
                       
                        IniPointMat(i,Counter,1:3) = CurrLocn;
                        IniPointMat(i,Counter,4)   = IDCount;
                    end
                    %--------------------------
                    IDCount   = IDCount+1;
                    Counter   = Counter+1;
                    IniPointMat(i,Counter,1:3) = EndLocn;
                    IniPointMat(i,Counter,4)   = IDCount;
                end
                %----------------------------------------------
                VertexList = zeros(((ElemNumS+1)*(ElemNumD+1) + (ElemNumS*ElemNumD)), 3);
                TrigListe  = zeros(ElemNumS*ElemNumD*4, 11);
                VertCount  = 0;
                TrigCount  = 0;
                %----------------------------------------------
                for i = 1 : (ElemNumS+1)
                    for j = 1 : (ElemNumD+1)
                        VertCount = VertCount +1;
                        VertexList(VertCount,:) = squeeze(IniPointMat(i,j,1:3));
                    end
                end
                %----------------------------------------------
                for i = 1 : ElemNumS
                    for j = 1 : ElemNumD
                        VertCount = VertCount +1;
                        Vert_A = squeeze(IniPointMat(i,j,:));
                        Vert_B = squeeze(IniPointMat(i+1,j,:));
                        Vert_C = squeeze(IniPointMat(i,j+1,:));
                        Vert_D = squeeze(IniPointMat(i+1,j+1,:));
                        Vert_E = [(Vert_A(1:3,1)+Vert_B(1:3,1)+Vert_C(1:3,1)+Vert_D(1:3,1))./4; VertCount]; %the "0" at end is vertex "height"
                        
                        VertexList(VertCount,:) = Vert_E(1:3,1);

                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_A(1:3,1), Vert_B(1:3,1), Vert_E(1:3,1));
                        TrigListe(TrigCount,:) = [Vert_A(4,1), Vert_B(4,1), Vert_E(4,1), SelSection, FaultIDnum, UseSlip, TheRate, TheDirect, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1)];
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_B(1:3,1), Vert_D(1:3,1), Vert_E(1:3,1));
                        TrigListe(TrigCount,:) = [Vert_B(4,1), Vert_D(4,1), Vert_E(4,1), SelSection, FaultIDnum, UseSlip, TheRate, TheDirect, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1)];
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_D(1:3,1), Vert_C(1:3,1), Vert_E(1:3,1));
                        TrigListe(TrigCount,:) = [Vert_D(4,1), Vert_C(4,1), Vert_E(4,1), SelSection, FaultIDnum, UseSlip, TheRate, TheDirect, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1)];
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_C(1:3,1), Vert_A(1:3,1), Vert_E(1:3,1));
                        TrigListe(TrigCount,:) = [Vert_C(4,1), Vert_A(4,1), Vert_E(4,1), SelSection, FaultIDnum, UseSlip, TheRate, TheDirect, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1)];
                    end
                end
                %----------------------------------------------
                TrigListe(:,1:3) = TrigListe(:,1:3) + size(app.VertList_Flt,1);
                app.TrigList_Flt = [app.TrigList_Flt; TrigListe];
                app.VertList_Flt = [app.VertList_Flt; VertexList];
            end
            %--------------------------
            RandListOrder    = [rand(size(app.TrigList_Flt,1),1), cumsum(ones(size(app.TrigList_Flt,1),1))];
            RandListOrder    = sortrows(RandListOrder);
            app.TrigList_Flt = app.TrigList_Flt(RandListOrder(:,2),:);
            %--------------------------
            app.VertList_Flt = [app.VertList_Flt(:,1:3),zeros(size(app.VertList_Flt,1),1)];
            app.FaultelementEditField.Value = size(app.TrigList_Flt,1);
        end
        %-----------------------------------------------------------------
        %-----------------------------------------------------------------
        function MeshBoundary(app)
             %--------------------------
            app.TrigList_Bnd = [];
            app.VertList_Bnd = [];
            DesiredLeg       = app.boundaryelementlengthkmEditField.Value;
            %--------------------------
            if (size(app.BoundaryValues,1) < 1),    return;     end
            for SelBound = 1 : size(app.BoundaryValues,1)
                BoundCent_E = app.BoundaryValues(SelBound,1);
                BoundCent_N = app.BoundaryValues(SelBound,2);
                BoundCent_Z = app.BoundaryValues(SelBound,3);
                Bound_Lgth  = app.BoundaryValues(SelBound,4);
                Bound_Wdth  = app.BoundaryValues(SelBound,5);
                Bound_Stk   = app.BoundaryValues(SelBound,6)*pi/180;
                Bound_Dip   = app.BoundaryValues(SelBound,7)*pi/180;
              
                UL_stk = -Bound_Lgth/2;     UR_stk =  Bound_Lgth/2;     LL_stk = -Bound_Lgth/2;     LR_stk = Bound_Lgth/2;
                UL_dip = 0;                 UR_dip =  0;                LL_dip = -Bound_Wdth;       LR_dip =-Bound_Wdth;
                UL_nrm = 0;                 UR_nrm =  0;                LL_nrm = 0;                 LR_nrm =  0;   
                stkA   = [sin(Bound_Stk), cos(Bound_Stk), 0];
                nrmA   = [sin(Bound_Dip)*cos(Bound_Stk), -sin(Bound_Dip)*sin(Bound_Stk), cos(Bound_Dip)];
                dipA   = cross(nrmA,stkA);
                RotMat = [stkA;dipA;nrmA];
                RotMatT= RotMat';
                %-------------------- 
                l_left  = [LL_stk;LL_dip;LL_nrm];           l_right = [LR_stk;LR_dip;LR_nrm];
                u_right = [UR_stk;UR_dip;UR_nrm];           u_left  = [UL_stk;UL_dip;UL_nrm];
                %-------------------- 
                TopPoints  = [u_left'; u_right'];           BotPoints  = [l_left'; l_right'];
                %-------------------- 
                TopLength  = sum(sqrt(sum(diff(TopPoints(:,1:3)).^2,2)));
                BotLength  = sum(sqrt(sum(diff(BotPoints(:,1:3)).^2,2)));
                ElemNumS   = floor(((TopLength+BotLength)/2)/DesiredLeg);
                %---------------------------------------------------------
                UsedSdeLgh = TopLength/ElemNumS;
                PntListTop = zeros(ElemNumS+1,3);
                PntListTop(1,:)   = TopPoints(1,:);
                PntListTop(end,:) = TopPoints(end,:);
                CurrLocn   = TopPoints(1,:);
                Counter    = 1;
                TrgtPos    = 2;
                UsedAmount = 0;
                %--------------------------
                while (Counter < ElemNumS)
                    TrgtLocn  = TopPoints(TrgtPos,:);
                    Vect2Next = TrgtLocn - CurrLocn;
                    VectLgth  = sqrt(sum(Vect2Next.^2));
                    if ((UsedSdeLgh-UsedAmount) <= (VectLgth))
                        CurrLocn = CurrLocn + ((UsedSdeLgh-UsedAmount)/VectLgth).*Vect2Next;
                        Counter  = Counter  +1;
                        PntListTop(Counter,:) = CurrLocn;
                        UsedAmount = 0;
                    else
                        CurrLocn   = TopPoints(TrgtPos,:);
                        TrgtPos    = TrgtPos +1;
                        UsedAmount = UsedAmount + VectLgth;
                    end
                end
                %--------------------------
                UsedSdeLgh = BotLength/ElemNumS;
                PntListBot = zeros(ElemNumS+1,3);
                PntListBot(1,:)   = BotPoints(1,:);
                PntListBot(end,:) = BotPoints(end,:);
                CurrLocn   = BotPoints(1,:);
                Counter    = 1;
                TrgtPos    = 2;
                UsedAmount = 0;
                %--------------------------
                while (Counter < ElemNumS)
                    TrgtLocn  = BotPoints(TrgtPos,:);
                    Vect2Next = TrgtLocn - CurrLocn;
                    VectLgth  = sqrt(sum(Vect2Next.^2));
                    if ((UsedSdeLgh-UsedAmount) <= (VectLgth))
                        CurrLocn = CurrLocn + ((UsedSdeLgh-UsedAmount)/VectLgth).*Vect2Next;
                        Counter  = Counter  +1;
                        PntListBot(Counter,:) = CurrLocn;
                        UsedAmount = 0;
                    else
                        CurrLocn   = BotPoints(TrgtPos,:);
                        TrgtPos    = TrgtPos +1;
                        UsedAmount = UsedAmount + VectLgth;
                    end
                end
                %---------------------------------------------------------
                VectLgths   = sqrt( sum((PntListBot - PntListTop).^2,2));
                ElemNumD    = floor(mean(VectLgths)/DesiredLeg);
                
                IniPointMat = zeros(ElemNumS,ElemNumD,4);
                IDCount     = 0;
                %----------------------------------------------
                for i = 1 : (ElemNumS+1)
                    IDCount             = IDCount+1;
                    IniPointMat(i,1,1:3)= PntListTop(i,1:3);
                    IniPointMat(i,1,4)  = IDCount;
                    CurrLocn            = PntListTop(i,1:3);
                    EndLocn             = PntListBot(i,1:3);
    
                    NextVect   = EndLocn- CurrLocn;
                    VectLgth   = sqrt(sum(NextVect.^2));
                    UsedSdeLgh = VectLgth/ElemNumD;
                    Counter    = 1;
                    %--------------------------
                    while (Counter < ElemNumD)
                        IDCount   = IDCount+1;
                        Counter   = Counter+1;
                        Vect2Next = EndLocn - CurrLocn;
                        VectLgth  = sqrt(sum(Vect2Next.^2));
                        CurrLocn  = CurrLocn + (UsedSdeLgh/VectLgth).*Vect2Next;
                       
                        IniPointMat(i,Counter,1:3) = CurrLocn;
                        IniPointMat(i,Counter,4)   = IDCount;
                    end
                    %--------------------------
                    IDCount   = IDCount+1;
                    Counter   = Counter+1;
                    IniPointMat(i,Counter,1:3) = EndLocn;
                    IniPointMat(i,Counter,4)   = IDCount;
                end
                %----------------------------------------------
                VertexList = zeros(((ElemNumS+1)*(ElemNumD+1) + (ElemNumS*ElemNumD)), 3);
                TrigListe  = zeros(ElemNumS*ElemNumD*4, 10);
                VertCount  = 0;
                TrigCount  = 0;
                %----------------------------------------------
                for i = 1 : (ElemNumS+1)
                    for j = 1 : (ElemNumD+1)
                        VertCount = VertCount +1;
                        VertexList(VertCount,:) = squeeze(IniPointMat(i,j,1:3));
                    end
                end
                %----------------------------------------------
                for i = 1 : ElemNumS
                    for j = 1 : ElemNumD
                        %----------------------------------------------
                        StkRate = NaN;      DipRate = NaN;      NrmRate = NaN;
                        %----------------------------------------------
                        if (app.BoundaryValues(SelBound,8) == 1) %top/bottom 
                            if     (j == 1)          
                                if (~isnan(app.BoundaryValues(SelBound,9))),     StkRate = app.BoundaryValues(SelBound,9);      end     
                                if (~isnan(app.BoundaryValues(SelBound,10))),    DipRate = app.BoundaryValues(SelBound,10);     end 
                                if (~isnan(app.BoundaryValues(SelBound,11))),    NrmRate = app.BoundaryValues(SelBound,11);     end 
                            elseif (j == ElemNumD)  
                                if (~isnan(app.BoundaryValues(SelBound,12))),    StkRate = app.BoundaryValues(SelBound,12);     end     
                                if (~isnan(app.BoundaryValues(SelBound,13))),    DipRate = app.BoundaryValues(SelBound,13);     end 
                                if (~isnan(app.BoundaryValues(SelBound,14))),    NrmRate = app.BoundaryValues(SelBound,14);     end  
                            end
                        else
                            if     (i == 1)        
                                if (~isnan(app.BoundaryValues(SelBound,9))),     StkRate = app.BoundaryValues(SelBound,9);      end     
                                if (~isnan(app.BoundaryValues(SelBound,10))),    DipRate = app.BoundaryValues(SelBound,10);     end 
                                if (~isnan(app.BoundaryValues(SelBound,11))),    NrmRate = app.BoundaryValues(SelBound,11);     end 
                            elseif (i == ElemNumS)  
                                if (~isnan(app.BoundaryValues(SelBound,12))),    StkRate = app.BoundaryValues(SelBound,12);     end     
                                if (~isnan(app.BoundaryValues(SelBound,13))),    DipRate = app.BoundaryValues(SelBound,13);     end 
                                if (~isnan(app.BoundaryValues(SelBound,14))),    NrmRate = app.BoundaryValues(SelBound,14);     end              
                            end
                        end
                        %----------------------------------------------
                        VertCount = VertCount +1;
                        Vert_A = squeeze(IniPointMat(i,j,:));
                        Vert_B = squeeze(IniPointMat(i+1,j,:));
                        Vert_C = squeeze(IniPointMat(i,j+1,:));
                        Vert_D = squeeze(IniPointMat(i+1,j+1,:));
                        Vert_E = [(Vert_A(1:3,1)+Vert_B(1:3,1)+Vert_C(1:3,1)+Vert_D(1:3,1))./4; VertCount];
                        %-------------------------------
                        VertexList(VertCount,:) = Vert_E(1:3,1);
                        %-------------------------------
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_A(1:3,1), Vert_B(1:3,1), Vert_E(1:3,1));
                        TrigListe(TrigCount,:) = [Vert_A(4,1), Vert_B(4,1), Vert_E(4,1), SelBound, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1), StkRate, DipRate, NrmRate];
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_B(1:3,1), Vert_D(1:3,1), Vert_E(1:3,1));
                        TrigListe(TrigCount,:) = [Vert_B(4,1), Vert_D(4,1), Vert_E(4,1), SelBound, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1), StkRate, DipRate, NrmRate];
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_D(1:3,1), Vert_C(1:3,1), Vert_E(1:3,1));
                        TrigListe(TrigCount,:) = [Vert_D(4,1), Vert_C(4,1), Vert_E(4,1), SelBound, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1), StkRate, DipRate, NrmRate];
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_C(1:3,1), Vert_A(1:3,1), Vert_E(1:3,1));
                        TrigListe(TrigCount,:) = [Vert_C(4,1), Vert_A(4,1), Vert_E(4,1), SelBound, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1), StkRate, DipRate, NrmRate];
                    end
                end
                %----------------------------------------------
                %----------------------------------------------
                SelBounds = ~isnan(TrigListe(:,8));
                if (sum(SelBounds(:) > 0))
                    SelCentE       = TrigListe(SelBounds,5);         SelCentN  = TrigListe(SelBounds,6);         SelCentC  = TrigListe(SelBounds,8);
                    AllCentE       = TrigListe(:,5);                 AllCentN  = TrigListe(:,6);
                    F_stk          = scatteredInterpolant(SelCentE,SelCentN,SelCentC,'natural');
                    TrigListe(:,8) = F_stk(AllCentE,AllCentN);
                else
                    TrigListe(:,8) = 0;
                end
                %----------------------------------------------
                SelBounds = ~isnan(TrigListe(:,9));
                if (sum(SelBounds(:) > 0))
                    SelCentE       = TrigListe(SelBounds,5);         SelCentN  = TrigListe(SelBounds,6);         SelCentC  = TrigListe(SelBounds,9);
                    AllCentE       = TrigListe(:,5);                 AllCentN  = TrigListe(:,6);
                    F_stk          = scatteredInterpolant(SelCentE,SelCentN,SelCentC,'natural');
                    TrigListe(:,9) = F_stk(AllCentE,AllCentN);
                else
                    TrigListe(:,9) = 0;
                end
                %----------------------------------------------
                SelBounds = ~isnan(TrigListe(:,10));
                if (sum(SelBounds(:) > 0))
                    SelCentE       = TrigListe(SelBounds,5);         SelCentN  = TrigListe(SelBounds,6);         SelCentC  = TrigListe(SelBounds,10);
                    AllCentE       = TrigListe(:,5);                 AllCentN  = TrigListe(:,6);
                    F_stk          = scatteredInterpolant(SelCentE,SelCentN,SelCentC,'natural');
                    TrigListe(:,10)= F_stk(AllCentE,AllCentN);
                else
                    TrigListe(:,10) = 0;
                end
                %----------------------------------------------
                %----------------------------------------------
                VertexListNew = RotMatT*VertexList' +[BoundCent_E;BoundCent_N;BoundCent_Z];
                %----------------------------------------------
                for i = 1 : size(TrigListe,1)
                    Vert_A = VertexListNew(:,TrigListe(i,1));
                    Vert_B = VertexListNew(:,TrigListe(i,2));
                    Vert_C = VertexListNew(:,TrigListe(i,3));
                    InCentPos  = GetInCenter(app,Vert_A, Vert_B, Vert_C);
                    TrigListe(i,5) = InCentPos(1,1);
                    TrigListe(i,6) = InCentPos(2,1);
                    TrigListe(i,7) = InCentPos(3,1);
                end
                %----------------------------------------------
                %----------------------------------------------
                TrigListe(:,1:3) = TrigListe(:,1:3) + size(app.VertList_Bnd,1);
                app.TrigList_Bnd = [app.TrigList_Bnd; TrigListe];
                app.VertList_Bnd = [app.VertList_Bnd; VertexListNew'];
                %----------------------------------------------
                %----------------------------------------------
            end            
            %--------------------------
            RandListOrder    = [rand(size(app.TrigList_Bnd,1),1), cumsum(ones(size(app.TrigList_Bnd,1),1))];
            RandListOrder    = sortrows(RandListOrder);
            app.TrigList_Bnd = app.TrigList_Bnd(RandListOrder(:,2),:);
            %--------------------------
            app.BoundaryelementEditField.Value = size(app.TrigList_Bnd,1);
            %----------------------------------------------
            RotAngle         = app.BoundRotateAllBy_EditField.Value;
            MeanENofBoundary = mean(app.VertList_Bnd(:,1:2));
            TempEN           = app.VertList_Bnd(:,1:2) - MeanENofBoundary;
            %----------------------------------------------
            app.VertList_Bnd(:,1) =  TempEN(:,1)*cosd(RotAngle) + TempEN(:,2)*sind(RotAngle) + MeanENofBoundary(1,1);
            app.VertList_Bnd(:,2) = -TempEN(:,1)*sind(RotAngle) + TempEN(:,2)*cosd(RotAngle) + MeanENofBoundary(1,2);
            %----------------------------------------------
            for i = 1 : size(app.TrigList_Bnd,1)
                Vert_A = app.VertList_Bnd(app.TrigList_Bnd (i,1),:)';
                Vert_B = app.VertList_Bnd(app.TrigList_Bnd (i,2),:)';
                Vert_C = app.VertList_Bnd(app.TrigList_Bnd (i,3),:)';
                InCentPos  = GetInCenter(app,Vert_A, Vert_B, Vert_C);
                app.TrigList_Bnd(i,5) = InCentPos(1,1);
                app.TrigList_Bnd(i,6) = InCentPos(2,1);
                app.TrigList_Bnd(i,7) = InCentPos(3,1);

                if (app.BoundaryValues(app.TrigList_Bnd(i,4),7) == 0)
                    CombStk     = (app.BoundaryValues(app.TrigList_Bnd(i,4),6) + RotAngle);
                    TempStkRate = app.TrigList_Bnd(i,8)*cosd(CombStk) + app.TrigList_Bnd(i,9)*sind(CombStk); 
                    TempDipRate =-app.TrigList_Bnd(i,8)*sind(CombStk) + app.TrigList_Bnd(i,9)*cosd(CombStk); 
                    app.TrigList_Bnd(i,8) = TempStkRate;
                    app.TrigList_Bnd(i,9) = TempDipRate;
                end
            end
            %----------------------------------------------         
        end
        %-----------------------------------------------------------------
        %-----------------------------------------------------------------
        function InCentPos  = GetInCenter(~, Vert_A,Vert_B, Vert_C)
            %https://www.geeksforgeeks.org/program-to-find-the-incenter-of-a-triangle/
            a = sqrt(sum((Vert_C-Vert_B).^2));
            b = sqrt(sum((Vert_A-Vert_C).^2));
            c = sqrt(sum((Vert_B-Vert_A).^2));

            InCentPos(1,1) = (a*Vert_A(1,1) +b*Vert_B(1,1) +c*Vert_C(1,1))/(a +b +c);
            InCentPos(2,1) = (a*Vert_A(2,1) +b*Vert_B(2,1) +c*Vert_C(2,1))/(a +b +c);
            InCentPos(3,1) = (a*Vert_A(3,1) +b*Vert_B(3,1) +c*Vert_C(3,1))/(a +b +c);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFaultMap_Button
        function LoadFaultMap_ButtonPushed(app, event)
            [DataName,DataPath]  = uigetfile({'*.jpg;*.tif;*.png;*.gif'},'Load fault base map');
            FullPath             = strcat(DataPath,DataName);
            %-----------------------------------------------
            if (DataName ==0),          return;                     end
            %-----------------------------------------------
            app.DataLoaded   = zeros(1,6);
            app.TheBaseMap   = flipud(imread(FullPath));
            app.EastExtend   = 1:size(app.TheBaseMap,2);
            app.NorthExtend  = 1:size(app.TheBaseMap,1);
            %-----------------------------------------------
            app.LoadFaultMap_Button.BackgroundColor = app.GrayButton;
            app.PlotFaultMap_Button.BackgroundColor = app.GreenButton;
            app.DataLoaded(1,1) = 1;
            %-----------------------------------------------           
        end

        % Button pushed function: PlotFaultMap_Button
        function PlotFaultMap_ButtonPushed(app, event)
            if (app.DataLoaded(1,1) == 0),      return;             end
            %-----------------------------------------------
            figure(app.FigNumMap_EditField.Value); 
            clf; hold on; xlabel('Easting'); ylabel('Northing'); axis equal;
            imagesc(app.EastExtend,app.NorthExtend,app.TheBaseMap);
            %-----------------------------------------------
            app.minNorthingButton.BackgroundColor   = app.GreenButton;
            app.maxNorthingButton.BackgroundColor   = app.GreenButton;
            app.minEastingButton.BackgroundColor    = app.GreenButton;
            app.maxEastingButton.BackgroundColor    = app.GreenButton;
            %-----------------------------------------------
            app.DataLoaded(1,2) = 1;
        end

        % Button pushed function: minEastingButton
        function minEastingButtonPushed(app, event)
            if (app.DataLoaded(1,2) == 0),      return;             end
            %-----------------------------------------------
            figure(app.FigNumMap_EditField.Value);
            %-----------------------------------------------
            [x,~,but] = ginput(1);
            if (but == 1)
                app.MinEastingMouse  = x;
                app.minEastingButton.BackgroundColor = app.GrayButton;
            end
            %-----------------------------------------------
            MinEButt = sum(abs(app.minEastingButton.BackgroundColor  - app.GrayButton));
            MaxEButt = sum(abs(app.maxEastingButton.BackgroundColor  - app.GrayButton));
            MinNButt = sum(abs(app.minNorthingButton.BackgroundColor - app.GrayButton));
            MaxNButt = sum(abs(app.maxNorthingButton.BackgroundColor - app.GrayButton));
            if ((MinEButt+MaxEButt+MinNButt+MaxNButt) < 1E-3)
                app.TraceFaults_Button.BackgroundColor    = app.GreenButton;
                Compute_dE_dN(app);
            end
            %-----------------------------------------------   
            app.DataLoaded(1,3) = 1;
        end

        % Button pushed function: maxEastingButton
        function maxEastingButtonPushed(app, event)
            if (app.DataLoaded(1,2) == 0),      return;             end
            %-----------------------------------------------
            figure(app.FigNumMap_EditField.Value);
            %-----------------------------------------------
            [x,~,but] = ginput(1);
            if (but == 1)
                app.MaxEastingMouse  = x;
                app.maxEastingButton.BackgroundColor = app.GrayButton;
            end
            %-----------------------------------------------
            MinEButt = sum(abs(app.minEastingButton.BackgroundColor  - app.GrayButton));
            MaxEButt = sum(abs(app.maxEastingButton.BackgroundColor  - app.GrayButton));
            MinNButt = sum(abs(app.minNorthingButton.BackgroundColor - app.GrayButton));
            MaxNButt = sum(abs(app.maxNorthingButton.BackgroundColor - app.GrayButton));
            if ((MinEButt+MaxEButt+MinNButt+MaxNButt) < 1E-3)
                app.TraceFaults_Button.BackgroundColor    = app.GreenButton;
                Compute_dE_dN(app);
            end
            %-----------------------------------------------   
            app.DataLoaded(1,4) = 1;
        end

        % Button pushed function: minNorthingButton
        function minNorthingButtonPushed(app, event)
            if (app.DataLoaded(1,2) == 0),      return;             end
            %-----------------------------------------------
            figure(app.FigNumMap_EditField.Value);
            %-----------------------------------------------
            [~,y,but] = ginput(1);
            if (but == 1)
                app.MinNorthingMouse  = y;
                app.minNorthingButton.BackgroundColor = app.GrayButton;
            end
            %-----------------------------------------------
            MinEButt = sum(abs(app.minEastingButton.BackgroundColor  - app.GrayButton));
            MaxEButt = sum(abs(app.maxEastingButton.BackgroundColor  - app.GrayButton));
            MinNButt = sum(abs(app.minNorthingButton.BackgroundColor - app.GrayButton));
            MaxNButt = sum(abs(app.maxNorthingButton.BackgroundColor - app.GrayButton));
            if ((MinEButt+MaxEButt+MinNButt+MaxNButt) < 1E-3)
                app.TraceFaults_Button.BackgroundColor    = app.GreenButton;
                Compute_dE_dN(app);
            end
            %-----------------------------------------------
            app.DataLoaded(1,5) = 1;
        end

        % Button pushed function: maxNorthingButton
        function maxNorthingButtonPushed(app, event)
            if (app.DataLoaded(1,2) == 0),      return;             end
            %-----------------------------------------------
            figure(app.FigNumMap_EditField.Value);
            %-----------------------------------------------
            [~,y,but] = ginput(1);
            if (but == 1)
                app.MaxNorthingMouse  = y;
                app.maxNorthingButton.BackgroundColor = app.GrayButton;
            end
            %-----------------------------------------------
            MinEButt = sum(abs(app.minEastingButton.BackgroundColor  - app.GrayButton));
            MaxEButt = sum(abs(app.maxEastingButton.BackgroundColor  - app.GrayButton));
            MinNButt = sum(abs(app.minNorthingButton.BackgroundColor - app.GrayButton));
            MaxNButt = sum(abs(app.maxNorthingButton.BackgroundColor - app.GrayButton));
            if ((MinEButt+MaxEButt+MinNButt+MaxNButt) < 1E-3)
                app.TraceFaults_Button.BackgroundColor    = app.GreenButton;
                Compute_dE_dN(app);
            end
            %-----------------------------------------------       
            app.DataLoaded(1,6) = 1;
        end

        % Value changed function: TracedFaults_ListBox
        function TracedFaults_ListBoxValueChanged(app, event)
            SelSection = app.TracedFaults_ListBox.Value;
            app.FaultIDnum_EditField.Value     = app.Faults(SelSection).FaultIDnum; 
            app.SlipRate_EditField.Value       = app.Faults(SelSection).SlipRate;
            app.SlipRake_EditField.Value       = app.Faults(SelSection).Rake; 
            
            app.StressRate_EditField.Value     = app.Faults(SelSection).StressRate;
            app.StressDirection_EditField.Value= app.Faults(SelSection).StressDir;

            if (app.Faults(SelSection).UseSlip == 1) 
                app.UsefaultsliprateCheckBox.Value = 1;         app.UseregionalstressCheckBox.Value = 0;
            else
                app.UsefaultsliprateCheckBox.Value = 0;         app.UseregionalstressCheckBox.Value = 1;
            end
            app.Width_3_EditField.Value        = app.Faults(SelSection).Width; 
            app.FaultDip_3_EditField.Value     = app.Faults(SelSection).Dip;        
            app.Name_EditField.Value           = app.Faults(SelSection).SegmName;


        end

        % Button pushed function: TraceFaults_Button
        function TraceTopFaults_ButtonPushed(app, event)
            SegmName  = app.Name_EditField.Value;
            FaultIDnum = app.FaultIDnum_EditField.Value;
            SlipRate   = app.SlipRate_EditField.Value;
            Rake       = app.SlipRake_EditField.Value;
            UseSlip    = app.UsefaultsliprateCheckBox.Value;
            StressRate = app.StressRate_EditField.Value;
            StressDir  = app.StressDirection_EditField.Value;
            Width      = app.Width_3_EditField.Value;
            FaultDip   = app.FaultDip_3_EditField.Value*pi/180;
            ContTrace  = app.continueselectedfaultCheckBox.Value;
            SelSection = app.TracedFaults_ListBox.Value;
            %----------------------------------------------
            if (str2double(app.FaultOrSeisWidth_3_DropDown.Value) == 1),    FaultWidth = Width;    
            else,                                                           FaultWidth = Width/sin(FaultDip);
            end
            %----------------------------------------------
            if ((ContTrace == 1) && (app.MaxSectionID > 0))
                TempVals       = app.Faults(SelSection).TopPoints;
                TempHandleLine = app.Faults(SelSection).TopLineM;
                TempHandlePt   = app.Faults(SelSection).TopPntsM;
                counter        = size(TempVals,1);

                delete(TempHandlePt(end,1));        
                TempHandlePt   = app.Faults(SelSection).TopPntsM(1:end-1,1);
            else
                SelSection        = app.MaxSectionID  +1;
                app.MaxSectionID  = app.MaxSectionID  +1;
                TempVals      = [];        counter        = 0;   
                TempHandlePt  = [];        TempHandleLine = [];     
            end
            %----------------------------------------------
            figure(app.FigNumMap_EditField.Value); drawnow; hold on;
            %----------------------------------------------
            but            = 1;
            while but ~= 32
                  [xi,yi,but] = ginput(1);
                if     but == 3 %remove a point
                    but = 1;
                    if (size(TempVals,1) == 1),           TempVals = [];                    counter = 0;            delete(TempHandlePt(1,1));      TempHandlePt = [];                                          end
                    if (size(TempVals,1) >=2),            TempVals = TempVals(1:end-1,:);   counter = counter -1;   delete(TempHandlePt(end,1));    TempHandlePt = TempHandlePt(1:end-1,1);                     end
                    if (size(TempHandleLine,1) == 1),     delete(TempHandleLine(1,1));      TempHandleLine = [];                          end 
                    if (size(TempHandleLine,1) >= 2),     delete(TempHandleLine(end,1));    TempHandleLine = TempHandleLine(1:end-1,1);   end 
                elseif but == 32 %this is the space bar
                    Picked = size(TempVals,1);
                    if (Picked <=1), errordlg('define at least two points for feature trace'); uiwait; but = 1; end
                    TempHandle   = plot(TempVals(end,1),TempVals(end,2),'square','MarkerEdgeColor','k','MarkerFaceColor','r','MarkerSize',10);
                    TempHandlePt = [TempHandlePt; TempHandle];
                else
                    TempVals     = [TempVals;xi, yi]; 
                    if (counter == 0)
                        TempHandle   = plot(xi,yi,'o','MarkerEdgeColor','k','MarkerFaceColor','r','MarkerSize',10);
                    else
                        TempHandle   = plot(xi,yi,'o','MarkerEdgeColor','k','MarkerFaceColor','r','MarkerSize',6);
                    end
                    TempHandlePt = [TempHandlePt; TempHandle];
                    counter      = counter  +1;
                    if counter >=2
                        TempHandle    = plot([TempVals(counter-1,1), TempVals(counter,1)],[TempVals(counter-1,2), TempVals(counter,2)],'-r','linewidth',2);
                        TempHandleLine= [TempHandleLine; TempHandle];
                    end 
                    but = 1;
                end
            end
            %----------------------------------------------
            app.Faults(SelSection).TopLineM   = TempHandleLine;
            app.Faults(SelSection).TopPntsM   = TempHandlePt;
            app.Faults(SelSection).TopPoints  = TempVals;
       
            app.Faults(SelSection).UseSlip    = UseSlip;
            app.Faults(SelSection).SlipRate   = SlipRate;
            app.Faults(SelSection).Rake       = Rake;
            app.Faults(SelSection).StressRate = StressRate;
            app.Faults(SelSection).StressDir  = StressDir;
            app.Faults(SelSection).FaultIDnum = FaultIDnum;

            app.Faults(SelSection).SegmName   = strcat(SegmName,'_ID_',num2str(SelSection));
            %----------------------------------------------
            app.Faults(SelSection).TopEasting = zeros(size(TempVals,1), 1);
            app.Faults(SelSection).TopNrthing = zeros(size(TempVals,1), 1);
            app.Faults(SelSection).TopDepth   = zeros(size(TempVals,1), 1);

            for j = 1 : size(TempVals,1)
                app.Faults(SelSection).TopEasting(j,1) = app.Evect(1,round(app.Faults(SelSection).TopPoints(j, 1)));
                app.Faults(SelSection).TopNrthing(j,1) = app.Nvect(1,round(app.Faults(SelSection).TopPoints(j, 2)));
                app.Faults(SelSection).TopDepth(j,1)   = 0;
            end

            FltVect        = [(app.Faults(SelSection).TopEasting(end,1) -app.Faults(SelSection).TopEasting(1,1)) , (app.Faults(SelSection).TopNrthing(end,1) -app.Faults(SelSection).TopNrthing(1,1))];
            FaultLength    = sqrt(sum(FltVect.^2));
            FltVect        = FltVect./FaultLength; %normalized the length
            FaultStrike    = atan2(FltVect(1,1),FltVect(1,2));
            if (FaultStrike < 0),   FaultStrike = 2*pi + FaultStrike;           end
            LocalY         = [sin(FaultStrike), cos(FaultStrike), 0]; %is strike dir
            LocalX         = [sin(FaultDip)*cos(FaultStrike), -sin(FaultDip)*sin(FaultStrike), cos(FaultDip)]; %is fault normal
            LocalZ         = cross(LocalX,LocalY); %is up-dip dir
            RotMatT        = [LocalY;LocalZ;LocalX]';
            %-----------------------
            app.Faults(SelSection).FaultCent =[(app.Faults(SelSection).TopEasting(1,1) +app.Faults(SelSection).TopEasting(end,1))/2; (app.Faults(SelSection).TopNrthing(1,1) +app.Faults(SelSection).TopNrthing(end,1))/2; 0];
            app.Faults(SelSection).Length    = FaultLength;
            app.Faults(SelSection).Width     = FaultWidth;

            app.Faults(SelSection).Strike    = FaultStrike*180/pi;
            app.Faults(SelSection).Dip       = FaultDip*180/pi;
            %-----------------------
            for j = 1 : size(TempVals,1)
                RelBottPos = [0 -FaultWidth 0];
                AbsBottPos = RotMatT*RelBottPos' +[app.Faults(SelSection).TopEasting(j,1); app.Faults(SelSection).TopNrthing(j,1); app.Faults(SelSection).TopDepth(j,1)];
                app.Faults(SelSection).BotEasting(j,1) = AbsBottPos(1,1); 
                app.Faults(SelSection).BotNrthing(j,1) = AbsBottPos(2,1);
                app.Faults(SelSection).BotDepth(j,1)   = AbsBottPos(3,1); 
            end 
            %-------------------------------------------------- 
            SegmentCounter       = 0;
            for i = 1 : app.MaxSectionID
                SegmentCounter = SegmentCounter +1;
                app.AllFltNames{SegmentCounter}   = app.Faults(i).SegmName;
            end
            app.TracedFaults_ListBox.Items     = app.AllFltNames;
            app.TracedFaults_ListBox.ItemsData = 1:size(app.AllFltNames,2);
            app.TracedFaults_ListBox.Value     = 1;
            %----------------------------------------------
            app.MeshPlot_Button.BackgroundColor = app.GreenButton;
            app.SaveFaultModel_Button.BackgroundColor      = app.GreenButton;  
        end

        % Button pushed function: TraceFaults_3a_Button
        function TraceFaults_3a_ButtonPushed(app, event)
            if (app.MaxSectionID == 0),         return;             end
            %--------------------------------------------------
            ContTrace  = app.continueselectedfaultCheckBox.Value;
            SelSection = app.TracedFaults_ListBox.Value;
            SelDepth   = app.Width_3a_EditField.Value;
            %--------------------------------------------------    
            figure(app.FigNumMap_EditField.Value); hold on;
            %--------- now the figure is plotted --- now I can draw the feature...
            if ((ContTrace == 1) && (app.MaxSectionID > 0) && (isfield(app.Faults(SelSection),'BotPntsM')))
                TempVals       = app.Faults(SelSection).BotPoints;
                TempHandleLine = app.Faults(SelSection).BotLineM;
                TempHandlePt   = app.Faults(SelSection).BotPntsM;
                counter        = size(TempVals,1);

                delete(TempHandlePt(end,1));        
                TempHandlePt   = app.Faults(SelSection).BotPntsM(1:end-1,1);
            else
                TempVals      = [];        counter        = 0;   
                TempHandlePt  = [];        TempHandleLine = [];  
                
                if isfield(app.Faults(SelSection),'BotLineM'),        delete(app.Faults(SelSection).BotLineM);           end  
                if isfield(app.Faults(SelSection),'BotPntsM'),        delete(app.Faults(SelSection).BotPntsM);           end 
            end  
            %--------------------------------------------------  
            but            = 1;
            while but ~= 32
                  [xi,yi,but] = ginput(1);
                if     but == 3 %remove a point
                    but = 1;
                    if (size(TempVals,1) == 1),           TempVals = [];                    counter = 0;            delete(TempHandlePt(1,1));      TempHandlePt = [];                                          end
                    if (size(TempVals,1) >=2),            TempVals = TempVals(1:end-1,:);   counter = counter -1;   delete(TempHandlePt(end,1));    TempHandlePt = TempHandlePt(1:end-1,1);                     end
                    if (size(TempHandleLine,1) == 1),     delete(TempHandleLine(1,1));      TempHandleLine = [];                          end 
                    if (size(TempHandleLine,1) >= 2),     delete(TempHandleLine(end,1));    TempHandleLine = TempHandleLine(1:end-1,1);   end 
                elseif but == 32 %this is the space bar
                    Picked = size(TempVals,1);
                    if (Picked <=1), errordlg('define at least two points for feature trace'); uiwait; but = 1; end
                    TempHandle   = plot(TempVals(end,1),TempVals(end,2),'square','MarkerEdgeColor','k','MarkerFaceColor','b','MarkerSize',10);
                    TempHandlePt = [TempHandlePt; TempHandle];
                else
                    TempVals     = [TempVals;xi, yi];                                                  
                    if (counter == 0)
                        TempHandle   = plot(xi,yi,'o','MarkerEdgeColor','k','MarkerFaceColor','b','MarkerSize',10);
                    else
                        TempHandle   = plot(xi,yi,'o','MarkerEdgeColor','k','MarkerFaceColor','b','MarkerSize',6);
                    end
                    TempHandlePt = [TempHandlePt; TempHandle];
                    counter      = counter  +1;
                    if counter >=2
                        TempHandle    = plot([TempVals(counter-1,1), TempVals(counter,1)],[TempVals(counter-1,2), TempVals(counter,2)],'-b','linewidth',2);
                        TempHandleLine= [TempHandleLine; TempHandle];
                    end 
                    but = 1;
                end
            end
            %----------------------------------------------
            app.Faults(SelSection).BotLineM   = TempHandleLine;
            app.Faults(SelSection).BotPntsM   = TempHandlePt;
            app.Faults(SelSection).BotPoints  = TempVals;
            app.Faults(SelSection).BotEasting = zeros(size(TempVals,1), 1);
            app.Faults(SelSection).BotNrthing = zeros(size(TempVals,1), 1);
            app.Faults(SelSection).BotDepth   = zeros(size(TempVals,1), 1);
            %----------------------------------------------
            for j = 1 : size(TempVals,1)
                app.Faults(SelSection).BotEasting(j,1) = app.Evect(1,round(TempVals(j, 1)));
                app.Faults(SelSection).BotNrthing(j,1) = app.Nvect(1,round(TempVals(j, 2)));
                app.Faults(SelSection).BotDepth(j,1)   = -SelDepth;
            end

        end

        % Button pushed function: Trace_FlipIt_Button
        function Trace_FlipIt_ButtonPushed(app, event)
            if (app.MaxSectionID == 0),             return;     end
            FlipTop    = app.seltoptraceCheckBox.Value;
            FlipBot    = app.selbottomtraceCheckBox.Value;
            SelSection = app.TracedFaults_ListBox.Value;
            if ((FlipTop == 0) &&(FlipBot == 0)),   return;     end
            %-----------------------------------
            if (FlipTop == 1)
                app.Faults(SelSection).TopPoints  = flipud(app.Faults(SelSection).TopPoints);
                app.Faults(SelSection).TopLineM   = flipud(app.Faults(SelSection).TopLineM);
                app.Faults(SelSection).TopEasting = flipud(app.Faults(SelSection).TopEasting);
                app.Faults(SelSection).TopNrthing = flipud(app.Faults(SelSection).TopNrthing);
                app.Faults(SelSection).TopDepth   = flipud(app.Faults(SelSection).TopDepth);

                TempHandlePt = flipud(app.Faults(SelSection).TopPntsM);
                delete(TempHandlePt(1,1));
                delete(TempHandlePt(end,1));

                TempHandle1  = plot(app.Faults(SelSection).TopPoints(1,1),  app.Faults(SelSection).TopPoints(1,2),   'o',    'MarkerEdgeColor','k','MarkerFaceColor','r','MarkerSize',10);
                TempHandle2  = plot(app.Faults(SelSection).TopPoints(end,1),app.Faults(SelSection).TopPoints(end,2),'square','MarkerEdgeColor','k','MarkerFaceColor','r','MarkerSize',10);
                
                app.Faults(SelSection).TopPntsM = [TempHandle1; TempHandlePt; TempHandle2];
            end
            %-----------------------------------
            if (FlipBot == 1) 
                if (isfield(app.Faults(SelSection),'BotPntsM'))
                    app.Faults(SelSection).BotPoints  = flipud(app.Faults(SelSection).BotPoints);
                    app.Faults(SelSection).BotLineM   = flipud(app.Faults(SelSection).BotLineM);
                    app.Faults(SelSection).BotEasting = flipud(app.Faults(SelSection).BotEasting);
                    app.Faults(SelSection).BotNrthing = flipud(app.Faults(SelSection).BotNrthing);
                    app.Faults(SelSection).BotDepth   = flipud(app.Faults(SelSection).BotDepth);
    
                    TempHandlePt = flipud(app.Faults(SelSection).BotPntsM);
                    delete(TempHandlePt(1,1));
                    delete(TempHandlePt(end,1));
    
                    TempHandle1  = plot(app.Faults(SelSection).BotPoints(1,1),  app.Faults(SelSection).BotPoints(1,2),    'o',   'MarkerEdgeColor','k','MarkerFaceColor','b','MarkerSize',10);
                    TempHandle2  = plot(app.Faults(SelSection).BotPoints(end,1),app.Faults(SelSection).BotPoints(end,2),'square','MarkerEdgeColor','k','MarkerFaceColor','b','MarkerSize',10);
    
                    app.Faults(SelSection).BotPntsM = [TempHandle1; TempHandlePt; TempHandle2];
                else
                    app.Faults(SelSection).BotEasting = flipud(app.Faults(SelSection).BotEasting);
                    app.Faults(SelSection).BotNrthing = flipud(app.Faults(SelSection).BotNrthing);
                    app.Faults(SelSection).BotDepth   = flipud(app.Faults(SelSection).BotDepth);
                end
            end
            %-----------------------------------
        end

        % Button pushed function: RemoveSelSection_Button
        function RemoveSelSection_ButtonPushed(app, event)
            if (app.MaxSectionID == 0),         return;             end

            button = questdlg('Really delete fault section?','Delete Fault','Yes','No','No');
            if (strcmp(button,'Yes') == 0),         return;             end
            %-------------------------------------------------- 
            value  = app.TracedFaults_ListBox.Value;
            if (value == 0),    return;     end
            %--------------------------------------------------           
            if isfield(app.Faults(value),'TopLineM'),        delete(app.Faults(value).TopLineM);           end  
            if isfield(app.Faults(value),'TopPntsM'),        delete(app.Faults(value).TopPntsM);           end 
            if isfield(app.Faults(value),'BotLineM'),        delete(app.Faults(value).BotLineM);           end  
            if isfield(app.Faults(value),'BotPntsM'),        delete(app.Faults(value).BotPntsM);           end 
            %-------------------------------------------------- 
            app.Faults(value) = [];
            NewSectionNum     = app.MaxSectionID -1;     
            %-------------------------------------------------- 
            if (NewSectionNum == 0)
                %-------------------------------------------------- 
                app.AllFltNames  = {'No segment traced'};
                %-------------------------------------------------- 
            else
                NewSegNames = {};
                for i = 1 : NewSectionNum
                    NewSegNames{i} = strcat(app.Faults(i).SegmName,'_ID_',num2str(i));
                end
                app.AllFltNames = NewSegNames;
            end 
            %-------------------------------------------------- 
            app.TracedFaults_ListBox.Items     = app.AllFltNames;
            app.TracedFaults_ListBox.ItemsData = 1:size(app.AllFltNames,2);
            app.TracedFaults_ListBox.Value     = 1;
            app.MaxSectionID                   = NewSectionNum;

        end

        % Value changed function: Bound_ListBox
        function Bound_ListBoxValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            %-------------------------------------------------- 
            SelBoundName = app.BoundaryNames{SelBoundPos};
            %-------------------------------------------------- 
            app.BoundName_EditField.Value       = SelBoundName;
            app.BoundEasting_EditField.Value    = app.BoundaryValues(SelBoundPos,1);
            app.BoundNorthing_EditField.Value   = app.BoundaryValues(SelBoundPos,2);
            app.BoundDepth_EditField.Value      = app.BoundaryValues(SelBoundPos,3);
            app.BoundLength_EditField.Value     = app.BoundaryValues(SelBoundPos,4);
            app.BoundWidth_EditField.Value      = app.BoundaryValues(SelBoundPos,5);
            app.BoundStrike_EditField.Value     = app.BoundaryValues(SelBoundPos,6);
            app.BoundDip_EditField.Value        = app.BoundaryValues(SelBoundPos,7);   
            app.BoundApplyRateAt_DropDown.Value = app.BoundApplyRateAt_DropDown.ItemsData(app.BoundaryValues(SelBoundPos,8));
            %-------------------------------------------------- 
            app.BoundStrikeratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(SelBoundPos, 9)),"  ",num2str(app.BoundaryValues(SelBoundPos,12)));
            app.BoundDipratemmyrEditField.Value    = strcat(num2str(app.BoundaryValues(SelBoundPos,10)),"  ",num2str(app.BoundaryValues(SelBoundPos,13)));
            app.BoundNormalratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(SelBoundPos,11)),"  ",num2str(app.BoundaryValues(SelBoundPos,14)));
        end

        % Button pushed function: AddBound_Button
        function AddBound_ButtonPushed(app, event)
            s = 'ABCDEFGHIJKLMNOPQRSTUVWXYZabcdefghijklmnopqrstuvwxyz0123456789';
            sLength      = 3;
            RandomName   = s(randi([1, 62],1,sLength));
            NewBndName   = strcat('Boundary_',RandomName);
            BoundaryInList                      = size(app.BoundaryValues,1);
            app.BoundaryNames(BoundaryInList+1) = {NewBndName};
            %---------------------------------------
            NewBoundary                         = [  rand(1), rand(1), -rand(1), (rand(1)*10+5), (rand(1)*10+5), (rand(1)*360), (rand(1)*90),    1, NaN, NaN, NaN,      NaN, NaN, NaN];
            app.BoundaryValues                  = [app.BoundaryValues; NewBoundary];
            %---------------------------------------
            app.BoundName_EditField.Value       = NewBndName;
            app.BoundEasting_EditField.Value    = app.BoundaryValues(end,1);
            app.BoundNorthing_EditField.Value   = app.BoundaryValues(end,2);
            app.BoundDepth_EditField.Value      = app.BoundaryValues(end,3);
            app.BoundLength_EditField.Value     = app.BoundaryValues(end,4);
            app.BoundWidth_EditField.Value      = app.BoundaryValues(end,5);
            app.BoundStrike_EditField.Value     = app.BoundaryValues(end,6);
            app.BoundDip_EditField.Value        = app.BoundaryValues(end,7);
            app.BoundApplyRateAt_DropDown.Value = app.BoundApplyRateAt_DropDown.ItemsData(app.BoundaryValues(end,8));
            app.BoundStrikeratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(end, 9)),"  ",num2str(app.BoundaryValues(end,12)));
            app.BoundDipratemmyrEditField.Value    = strcat(num2str(app.BoundaryValues(end,10)),"  ",num2str(app.BoundaryValues(end,13)));
            app.BoundNormalratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(end,11)),"  ",num2str(app.BoundaryValues(end,14)));
            %---------------------------------------
            app.Bound_ListBox.Items     = app.BoundaryNames;
            app.Bound_ListBox.ItemsData = 1:size(app.BoundaryNames,2);
            app.Bound_ListBox.Value     = size(app.BoundaryNames,2);
            %---------------------------------------           
        end

        % Button pushed function: DeleteBound_Button
        function DeleteBound_ButtonPushed(app, event)
            if (size(app.BoundaryValues,1) < 1),           return;                                     end
            SelBoundPos  = app.Bound_ListBox.Value;   
            if (~isa(SelBoundPos,'double')),        SelBoundPos = str2double(SelBoundPos);             end
       
            app.BoundaryNames(SelBoundPos)         = [];
            app.BoundaryValues(SelBoundPos,:)      = [];
            
            if (size(app.BoundaryValues,1) < 1)   
                app.BoundaryNames                   = {'No Boundary used'};              
                app.BoundName_EditField.Value       = 'No Boundary used';
                app.BoundNorthing_EditField.Value   = 0;
                app.BoundEasting_EditField.Value    = 0;
                app.BoundDepth_EditField.Value      = 0;
                app.BoundLength_EditField.Value     = 0;
                app.BoundWidth_EditField.Value      = 0;
                app.BoundStrike_EditField.Value     = 0;
                app.BoundDip_EditField.Value        = 0;  
                app.BoundStrikeratemmyrEditField.Value = 'NaN NaN';
                app.BoundDipratemmyrEditField.Value    = 'NaN NaN';
                app.BoundNormalratemmyrEditField.Value = 'NaN NaN';
            else
                app.BoundName_EditField.Value       = app.BoundaryNames{1};
                app.BoundNorthing_EditField.Value   = app.BoundaryValues(1,1);
                app.BoundEasting_EditField.Value    = app.BoundaryValues(1,2);
                app.BoundDepth_EditField.Value      = app.BoundaryValues(1,3);
                app.BoundLength_EditField.Value     = app.BoundaryValues(1,4);
                app.BoundWidth_EditField.Value      = app.BoundaryValues(1,5);
                app.BoundStrike_EditField.Value     = app.BoundaryValues(1,6);
                app.BoundDip_EditField.Value        = app.BoundaryValues(1,7);  
                app.BoundApplyRateAt_DropDown.Value = app.BoundApplyRateAt_DropDown.ItemsData(app.BoundaryValues(1,8));
                app.BoundStrikeratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(end, 9)),"  ",num2str(app.BoundaryValues(end,12)));
                app.BoundDipratemmyrEditField.Value    = strcat(num2str(app.BoundaryValues(end,10)),"  ",num2str(app.BoundaryValues(end,13)));
                app.BoundNormalratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(end,11)),"  ",num2str(app.BoundaryValues(end,14)));
            end
             %---------------------------------------
            app.Bound_ListBox.Items     = app.BoundaryNames;
            app.Bound_ListBox.ItemsData = 1:size(app.BoundaryNames,2);
            app.Bound_ListBox.Value     = size(app.BoundaryNames,2);
            %---------------------------------------
        end

        % Value changed function: BoundName_EditField
        function BoundName_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            app.BoundaryNames(SelBoundPos) = {app.BoundName_EditField.Value};
            app.Bound_ListBox.Items        = app.BoundaryNames;
            app.Bound_ListBox.ItemsData    = 1:size(app.BoundaryNames,2);
            app.Bound_ListBox.Value        = 1;     
        end

        % Value changed function: BoundEasting_EditField
        function BoundEasting_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end          
            app.BoundaryValues(SelBoundPos,1) = app.BoundEasting_EditField.Value;
        end

        % Value changed function: BoundNorthing_EditField
        function BoundNorthing_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end           
            app.BoundaryValues(SelBoundPos,2) = app.BoundNorthing_EditField.Value;
        end

        % Value changed function: BoundDepth_EditField
        function BoundDepth_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end           
            app.BoundaryValues(SelBoundPos,3) = app.BoundDepth_EditField.Value;
        end

        % Value changed function: BoundLength_EditField
        function BoundLength_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end         
            app.BoundaryValues(SelBoundPos,4) = app.BoundLength_EditField.Value; 
        end

        % Value changed function: BoundWidth_EditField
        function BoundWidth_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end          
            app.BoundaryValues(SelBoundPos,5) = app.BoundWidth_EditField.Value;   
        end

        % Value changed function: BoundStrike_EditField
        function BoundStrike_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end          
            app.BoundaryValues(SelBoundPos,6) = app.BoundStrike_EditField.Value; 
        end

        % Value changed function: BoundDip_EditField
        function BoundDip_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end         
            app.BoundaryValues(SelBoundPos,7) = app.BoundDip_EditField.Value;
        end

        % Value changed function: BoundApplyRateAt_DropDown
        function BoundApplyRateAt_DropDownValueChanged(app, event)
            value = str2double(app.BoundApplyRateAt_DropDown.Value);
            %------------------------------------        
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            %------------------------------------ 
            app.BoundaryValues(SelBoundPos,8) = value;
        end

        % Value changed function: BoundStrikeratemmyrEditField
        function BoundStrikeratemmyrEditFieldValueChanged(app, event)
            value = str2num(app.BoundStrikeratemmyrEditField.Value);
            if (isempty(value)),            return;                         end
            if (size(value,2) == 1),        value = [value, value];         end
            if (isnan(sum(value))),         value = [NaN, NaN];             end
            %------------------------------------        
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            %------------------------------------  
            app.BoundaryValues(SelBoundPos,9) = value(1,1);
            app.BoundaryValues(SelBoundPos,12)= value(1,2);
        end

        % Value changed function: BoundDipratemmyrEditField
        function BoundDipratemmyrEditFieldValueChanged(app, event)
            value = str2num(app.BoundDipratemmyrEditField.Value);
            if (isempty(value)),            return;                         end
            if (size(value,2) == 1),        value = [value, value];         end
            if (isnan(sum(value))),         value = [NaN, NaN];             end
            %------------------------------------        
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            %------------------------------------ 
            app.BoundaryValues(SelBoundPos,10)= value(1,1);
            app.BoundaryValues(SelBoundPos,13)= value(1,2);
        end

        % Value changed function: BoundNormalratemmyrEditField
        function BoundNormalratemmyrEditFieldValueChanged(app, event)
            value = str2num(app.BoundNormalratemmyrEditField.Value);
            if (isempty(value)),            return;                         end
            if (size(value,2) == 1),        value = [value, value];         end
            if (isnan(sum(value))),         value = [NaN, NaN];             end
            %------------------------------------        
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            %------------------------------------ 
            app.BoundaryValues(SelBoundPos,11)= value(1,1);
            app.BoundaryValues(SelBoundPos,14)= value(1,2);    
        end

        % Callback function
        function BoundStkRate_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            SelEdge = str2double(app.BoundApplyRateAt_DropDown.Value);

            if (SelEdge == 1),      app.BoundaryValues(SelBoundPos,8) = app.BoundStkRate_EditField.Value;
                aa = app.BoundaryValues(SelBoundPos,8) 
            elseif (SelEdge == 2),  app.BoundaryValues(SelBoundPos,11)= app.BoundStkRate_EditField.Value;
            elseif (SelEdge == 3),  app.BoundaryValues(SelBoundPos,14)= app.BoundStkRate_EditField.Value;
            elseif (SelEdge == 4),  app.BoundaryValues(SelBoundPos,17)= app.BoundStkRate_EditField.Value;
            elseif (SelEdge == 5),  app.BoundaryValues(SelBoundPos,8) = app.BoundStkRate_EditField.Value;   app.BoundaryValues(SelBoundPos,11) = app.BoundStkRate_EditField.Value;  app.BoundaryValues(SelBoundPos,14) = app.BoundStkRate_EditField.Value;  app.BoundaryValues(SelBoundPos,17) = app.BoundStkRate_EditField.Value;
            end            
        end

        % Callback function
        function BoundDipRate_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end   
            SelEdge = str2double(app.BoundApplyRateAt_DropDown.Value);
            if (SelEdge == 1),      app.BoundaryValues(SelBoundPos,9) = app.BoundDipRate_EditField.Value;
            elseif (SelEdge == 2),  app.BoundaryValues(SelBoundPos,12)= app.BoundDipRate_EditField.Value;
            elseif (SelEdge == 3),  app.BoundaryValues(SelBoundPos,15)= app.BoundDipRate_EditField.Value;
            elseif (SelEdge == 4),  app.BoundaryValues(SelBoundPos,18)= app.BoundDipRate_EditField.Value;
            elseif (SelEdge == 5),  app.BoundaryValues(SelBoundPos,9) = app.BoundDipRate_EditField.Value;   app.BoundaryValues(SelBoundPos,12) = app.BoundDipRate_EditField.Value;  app.BoundaryValues(SelBoundPos,15) = app.BoundDipRate_EditField.Value;  app.BoundaryValues(SelBoundPos,18) = app.BoundDipRate_EditField.Value;
            end                  
        end

        % Callback function
        function BoundNrmRate_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end  
            SelEdge = str2double(app.BoundApplyRateAt_DropDown.Value);
            if (SelEdge == 1),      app.BoundaryValues(SelBoundPos,10)= app.BoundNrmRate_EditField.Value;
            elseif (SelEdge == 2),  app.BoundaryValues(SelBoundPos,13)= app.BoundNrmRate_EditField.Value;
            elseif (SelEdge == 3),  app.BoundaryValues(SelBoundPos,16)= app.BoundNrmRate_EditField.Value;
            elseif (SelEdge == 4),  app.BoundaryValues(SelBoundPos,19)= app.BoundNrmRate_EditField.Value;
            elseif (SelEdge == 5),  app.BoundaryValues(SelBoundPos,10)= app.BoundNrmRate_EditField.Value;   app.BoundaryValues(SelBoundPos,13) = app.BoundNrmRate_EditField.Value;  app.BoundaryValues(SelBoundPos,16) = app.BoundNrmRate_EditField.Value;  app.BoundaryValues(SelBoundPos,19) = app.BoundNrmRate_EditField.Value;
            end           
        end

        % Button pushed function: MeshPlot_Button
        function MeshPlot_ButtonPushed(app, event)
            if (app.MaxSectionID == 0),                                 return;                 end
            %----------------------------------
            MeshFault(app);

            MeshBoundary(app);
            %----------------------------------
            SelFlt = app.TracedFaults_ListBox.Value;
            FltComp = str2double(app.PlotCompFault_DropDown.Value);
            BndComp = str2double(app.PlotCompBound_DropDown.Value);
            %----------------------------------
            AxEqual  = app.AxisEqual_CheckBox.Value;
            ShowMesh = app.ShowMesh_CheckBox.Value;
            ViewAngle= app.ViewAngle_EditField.Value;        ViewAngle = str2num(ViewAngle);
            FigNum   = app.FigNumFaults_EditField.Value;
            if (ShowMesh == 1), UsedEdge = 'black';
            else,               UsedEdge = 'none';
            end
            %----------------------------------
            figure(FigNum); clf; hold on; 
            if (AxEqual == 1),              axis equal;                     end
            %----------------------------------
            TrigCount = size(app.TrigList_Flt,1);
            AllV1s    = zeros(TrigCount,3);
            AreasFlt  = zeros(TrigCount,1);
            AllV2s    = zeros(TrigCount,3);
            AllV3s    = zeros(TrigCount,3);
            FltCol    = zeros(TrigCount,1);
            Cntr      = 1;
            %-----------------------------------------------
            for j = 1 : TrigCount
                v1   = app.TrigList_Flt(j,1);
                v2   = app.TrigList_Flt(j,2);
                v3   = app.TrigList_Flt(j,3);
                v1v2 = app.VertList_Flt(v2,1:3)- app.VertList_Flt(v1,1:3);
                v2v3 = app.VertList_Flt(v3,1:3)- app.VertList_Flt(v2,1:3);

                AreasFlt(j,1) = 0.5*sqrt(sum(cross(v1v2,v2v3).^2));
          
                AllV1s(Cntr,1) = app.VertList_Flt(v1,1);
                AllV1s(Cntr,2) = app.VertList_Flt(v2,1);
                AllV1s(Cntr,3) = app.VertList_Flt(v3,1);
            
                AllV2s(Cntr,1) = app.VertList_Flt(v1,2);
                AllV2s(Cntr,2) = app.VertList_Flt(v2,2);
                AllV2s(Cntr,3) = app.VertList_Flt(v3,2);
            
                AllV3s(Cntr,1) = app.VertList_Flt(v1,3);
                AllV3s(Cntr,2) = app.VertList_Flt(v2,3);
                AllV3s(Cntr,3) = app.VertList_Flt(v3,3);
                if     (FltComp == 1),     FltCol(Cntr,1) = app.TrigList_Flt(j,4);
                elseif (FltComp == 2),     FltCol(Cntr,1) = app.TrigList_Flt(j,6);
                elseif (FltComp == 3),     FltCol(Cntr,1) = app.TrigList_Flt(j,7);
                elseif (FltComp == 4),     FltCol(Cntr,1) = app.TrigList_Flt(j,8);
                end
                Cntr           = Cntr +1;
            end
            patch(AllV1s',AllV2s',AllV3s',FltCol','facealpha',0.7,'edgecolor',UsedEdge);
            %-----------------------------------------------
            app.SingleElementEQsize_EditField.Value = log10(mean(AreasFlt)) +3.98; %HanksAndBakun2008
            %-----------------------------------------------
            TrigCount = size(app.TrigList_Bnd,1);
            AllV1s    = zeros(TrigCount,3);
            AllV2s    = zeros(TrigCount,3);
            AllV3s    = zeros(TrigCount,3);
            BndCol    = zeros(TrigCount,1);
            Cntr      = 1;
            %-----------------------------------------------
            for j = 1 : TrigCount
                AllV1s(Cntr,1) = app.VertList_Bnd(app.TrigList_Bnd(j,1),1);
                AllV1s(Cntr,2) = app.VertList_Bnd(app.TrigList_Bnd(j,2),1);
                AllV1s(Cntr,3) = app.VertList_Bnd(app.TrigList_Bnd(j,3),1);
            
                AllV2s(Cntr,1) = app.VertList_Bnd(app.TrigList_Bnd(j,1),2);
                AllV2s(Cntr,2) = app.VertList_Bnd(app.TrigList_Bnd(j,2),2);
                AllV2s(Cntr,3) = app.VertList_Bnd(app.TrigList_Bnd(j,3),2);
            
                AllV3s(Cntr,1) = app.VertList_Bnd(app.TrigList_Bnd(j,1),3);
                AllV3s(Cntr,2) = app.VertList_Bnd(app.TrigList_Bnd(j,2),3);
                AllV3s(Cntr,3) = app.VertList_Bnd(app.TrigList_Bnd(j,3),3);
                if     (BndComp == 1),     BndCol(Cntr,1) = app.TrigList_Bnd(j,4);
                elseif (BndComp == 2),     BndCol(Cntr,1) = app.TrigList_Bnd(j,8);
                elseif (BndComp == 3),     BndCol(Cntr,1) = app.TrigList_Bnd(j,9);
                elseif (BndComp == 4),     BndCol(Cntr,1) = app.TrigList_Bnd(j,10);
                end
                Cntr           = Cntr +1;
            end
            patch(AllV1s',AllV2s',AllV3s',BndCol,'facealpha',0.7,'edgecolor',UsedEdge);
            %plot3(app.TrigList_Bnd(:,5), app.TrigList_Bnd(:,6), app.TrigList_Bnd(:,7), 'o');
            %-----------------------------------------------
            EastVals = [app.Faults(SelFlt).TopEasting; flipud(app.Faults(SelFlt).BotEasting); app.Faults(SelFlt).TopEasting(1,1)];
            NrthVals = [app.Faults(SelFlt).TopNrthing; flipud(app.Faults(SelFlt).BotNrthing); app.Faults(SelFlt).TopNrthing(1,1)];
            DpthVals = [app.Faults(SelFlt).TopDepth;   flipud(app.Faults(SelFlt).BotDepth);   app.Faults(SelFlt).TopDepth(1,1)];
            %--------------------------
            plot3(EastVals,NrthVals,DpthVals,'-r','LineWidth',2);
            %-----------------------------------------------
            if (min([BndCol;FltCol]) ~= max([BndCol;FltCol]))
                caxis([min([BndCol;FltCol])  max([BndCol;FltCol])]);
                colorbar;
            end
            hold off;
            view(ViewAngle);
            %-----------------------------------------------
        end

        % Button pushed function: SaveFaultModel_Button
        function SaveFaultModel_ButtonPushed(app, event)
            if (app.MaxSectionID == 0),                                 return;                 end
            %----------------------------------
            FltSectNum = app.MaxSectionID;
            BoundNum   = size(app.BoundaryValues,1);   
            %-----------------------   
            [DataName,DataPath]  = uiputfile('*.*','Save Fault Model');
            if     (DataName ~=0),      DataName = strtok(DataName,'.');        CompletePath = strcat(DataPath,DataName); 
            elseif (DataName ==0),      return;         
            end
            %-----------------------
            Name4ascii  = strcat(CompletePath,'_FLT.txt');
            Name4binary = strcat(CompletePath,'_FLTtrig.dat');
            %----------------------------------------------------------------
            fidascii    = fopen(Name4ascii ,'w');
            if (fidascii == -1), errordlg('The entered filename has not been found.');    return;   end
            
            fprintf(fidascii,'Number_of_fault_sections:  %d\n',FltSectNum);            
            fprintf(fidascii,'Combined_number_of_trig_elements_and_vertices:  %d   %d\n', size(app.TrigList_Flt,1), size(app.VertList_Flt,1));
            fprintf(fidascii,'\n');   
            %--------------------------------------
            for i = 1 : FltSectNum
                fprintf(fidascii,'Fault_ID_number:   %d\n',app.Faults(i).FaultIDnum);
                fprintf(fidascii,'Fault_section_name: %s\n',app.Faults(i).SegmName);
                fprintf(fidascii,'Easting_of_fault_top_center(km):     %10.4e\n',app.Faults(i).FaultCent(1,1));
                fprintf(fidascii,'Northing_of_fault_top_center(km):    %10.4e\n',app.Faults(i).FaultCent(2,1));
                fprintf(fidascii,'Depth_of_fault_top_center(km):       %10.4e\n',app.Faults(i).FaultCent(3,1));
                fprintf(fidascii,'Length_of_fault_section(km):         %10.4e\n',app.Faults(i).Length);
                fprintf(fidascii,'Width_of_fault_section(km):          %10.4e\n',app.Faults(i).Width);
                fprintf(fidascii,'Strike_of_fault_section(deg):        %10.4e\n',app.Faults(i).Strike);
                fprintf(fidascii,'Dip_of_fault_section(deg):           %10.4e\n',app.Faults(i).Dip);
                fprintf(fidascii,'UseSlipRate_or_RegStress_(1/0):      %d    \n',app.Faults(i).UseSlip);
                fprintf(fidascii,'SlipRate_of_fault_section(mm/yr):    %10.4e\n',app.Faults(i).SlipRate);
                fprintf(fidascii,'Rake_of_fault_section(deg):          %10.4e\n',app.Faults(i).Rake);
                fprintf(fidascii,'StressRate_of_fault_section(MPa/yr): %10.4e\n',app.Faults(i).StressRate);
                fprintf(fidascii,'StressDir_of_fault_section(deg):     %10.4e\n',app.Faults(i).StressDir);

                fprintf(fidascii,'Shift_of_lowerleft_vertex_(E/N/Z)(km):    0.0   0.0   0.0\n');
                fprintf(fidascii,'Shift_of_lowerright_vertex_(E/N/Z)(km):   0.0   0.0   0.0\n');
                fprintf(fidascii,'Shift_of_upperright_vertex_(E/N/Z)(km):   0.0   0.0   0.0\n');
                fprintf(fidascii,'Shift_of_upperleft_vertex_(E/N/Z)(km):    0.0   0.0   0.0\n');

                for j = 1 : size(app.Faults(i).TopEasting,1),   fprintf(fidascii,'%10.4e  ',app.Faults(i).TopEasting(j,1));       end,    fprintf(fidascii,'\n');
                for j = 1 : size(app.Faults(i).TopNrthing,1),   fprintf(fidascii,'%10.4e  ',app.Faults(i).TopNrthing(j,1));       end,    fprintf(fidascii,'\n');
                for j = 1 : size(app.Faults(i).TopDepth,1),     fprintf(fidascii,'%10.4e  ',app.Faults(i).TopDepth(j,1));         end,    fprintf(fidascii,'\n');
                for j = 1 : size(app.Faults(i).BotEasting,1),   fprintf(fidascii,'%10.4e  ',app.Faults(i).BotEasting(j,1));       end,    fprintf(fidascii,'\n');
                for j = 1 : size(app.Faults(i).BotNrthing,1),   fprintf(fidascii,'%10.4e  ',app.Faults(i).BotNrthing(j,1));       end,    fprintf(fidascii,'\n');
                for j = 1 : size(app.Faults(i).BotDepth,1),     fprintf(fidascii,'%10.4e  ',app.Faults(i).BotDepth(j,1));         end,    fprintf(fidascii,'\n');

                FltTrigs        = app.TrigList_Flt(app.TrigList_Flt(:,4) == i,1:3);
                PatchesPerFault = size(FltTrigs,1);             VertsOfFaults   = unique(FltTrigs(:));              VertsPerFault   = size(VertsOfFaults,1);
                fprintf(fidascii,'Triangle_patch_number_of_section:   %d\n', PatchesPerFault);
                fprintf(fidascii,'Triangle_vertex_number_of_section:  %d\n', VertsPerFault);
                fprintf(fidascii,'\n');      
            end
            fclose(fidascii);
            %------------------------------------------
            fidbin    = fopen(Name4binary ,'wb'); 
            if (fidbin == -1), errordlg('The entered filename has not been found.');    return;   end
      
            fwrite(fidbin,size(app.TrigList_Flt,1),'int32');
            fwrite(fidbin,size(app.VertList_Flt,1),'int32');

            fwrite(fidbin,app.TrigList_Flt(:,1),  'int32');
            fwrite(fidbin,app.TrigList_Flt(:,2),  'int32');
            fwrite(fidbin,app.TrigList_Flt(:,3),  'int32');
            fwrite(fidbin,app.TrigList_Flt(:,4),  'int32'); %fault (section) ID number
            fwrite(fidbin,app.TrigList_Flt(:,5),  'int32'); %fault (section) ID number
            fwrite(fidbin,app.TrigList_Flt(:,6),  'int32');%fault stressing rate
            fwrite(fidbin,app.TrigList_Flt(:,7),  'single');%fault rate
            fwrite(fidbin,app.TrigList_Flt(:,8),  'single');%fault rake 
            fwrite(fidbin,app.TrigList_Flt(:,9),  'single');%center_E
            fwrite(fidbin,app.TrigList_Flt(:,10), 'single');%center_N
            fwrite(fidbin,app.TrigList_Flt(:,11), 'single');%center_Z 

            fwrite(fidbin,app.VertList_Flt(:,1),  'single');%Coord_E
            fwrite(fidbin,app.VertList_Flt(:,2),  'single');%Coord_N    
            fwrite(fidbin,app.VertList_Flt(:,3),  'single');%Coord_Z
            fwrite(fidbin,app.VertList_Flt(:,4),  'single');%"height"
            
            fclose(fidbin);    
            %------------------------------------------%------------------------------------------
            if (BoundNum > 0)
                Name4ascii  = strcat(CompletePath,'_BND.txt');
                Name4binary = strcat(CompletePath,'_BNDtrig.dat');
                %----------------------------------------------------------------
                fidascii    = fopen(Name4ascii ,'w');
                if (fidascii == -1), errordlg('The entered filename has not been found.');    return;   end
                
                fprintf(fidascii,'Number_of_boundary_segments:  %d\n',BoundNum);
                fprintf(fidascii,'Combined_number_of_trig_patches_and_vertices:  %d   %d\n', size(app.TrigList_Bnd,1), size(app.VertList_Bnd,1));
                fprintf(fidascii,'\n');   
                %--------------------------------------
                for i = 1 : BoundNum
                    fprintf(fidascii,'Fault_section_name: %s\n',                  app.BoundaryNames{1,i});
                    fprintf(fidascii,'Easting_of_fault_top_center(km):  %10.4e\n',app.BoundaryValues(i,1));
                    fprintf(fidascii,'Northing_of_fault_top_center(km): %10.4e\n',app.BoundaryValues(i,2));
                    fprintf(fidascii,'Depth_of_fault_top_center(km):    %10.4e\n',app.BoundaryValues(i,3));
                    fprintf(fidascii,'Length_of_fault_section(km):      %10.4e\n',app.BoundaryValues(i,4));
                    fprintf(fidascii,'Width_of_fault_section(km):       %10.4e\n',app.BoundaryValues(i,5));
                    fprintf(fidascii,'Strike_of_fault_section(deg):     %10.4e\n',app.BoundaryValues(i,6));
                    fprintf(fidascii,'Dip_of_fault_section(deg):        %10.4e\n',app.BoundaryValues(i,7));
                    fprintf(fidascii,'ApplyTo_TopBottom_or_LeftRight:   %d\n',    app.BoundaryValues(i,8));
                    fprintf(fidascii,'Slip-rate_in_strike(mm/yr):       %10.4e   %10.4e\n',app.BoundaryValues(i,9), app.BoundaryValues(i,12));
                    fprintf(fidascii,'Slip-rate_in_dip(mm/yr):          %10.4e   %10.4e\n',app.BoundaryValues(i,10),app.BoundaryValues(i,13));
                    fprintf(fidascii,'Slip-rate_in_normal(mm/yr):       %10.4e   %10.4e\n',app.BoundaryValues(i,11),app.BoundaryValues(i,14));  

                    BndTrigs        = app.TrigList_Bnd(app.TrigList_Bnd(:,4) == i,1:3);
                    PatchesPerBound = size(BndTrigs,1);         VertsOfBound    = unique(BndTrigs(:));         VertsPerBound   = size(VertsOfBound,1);
                    fprintf(fidascii,'Triangle_patch_number_of_boundary:   %d\n',PatchesPerBound);
                    fprintf(fidascii,'Triangle_vertex_number_of_boundary:  %d\n',VertsPerBound);
                    fprintf(fidascii,'\n'); 
                end
                fclose(fidascii);
                %--------------------------------------
                fidbin    = fopen(Name4binary ,'wb'); 
                if (fidbin == -1), errordlg('The entered filename has not been found.');    return;         end

                fwrite(fidbin,size(app.TrigList_Bnd,1),'int32');
                fwrite(fidbin,size(app.VertList_Bnd,1),'int32');

                fwrite(fidbin,app.TrigList_Bnd(:,1),  'int32');
                fwrite(fidbin,app.TrigList_Bnd(:,2),  'int32');
                fwrite(fidbin,app.TrigList_Bnd(:,3),  'int32');
                fwrite(fidbin,app.TrigList_Bnd(:,4),  'int32');
                fwrite(fidbin,app.TrigList_Bnd(:,5),  'single');
                fwrite(fidbin,app.TrigList_Bnd(:,6),  'single');    
                fwrite(fidbin,app.TrigList_Bnd(:,7),  'single');  

                fwrite(fidbin,app.VertList_Bnd(:,1),  'single');
                fwrite(fidbin,app.VertList_Bnd(:,2),  'single');    
                fwrite(fidbin,app.VertList_Bnd(:,3),  'single'); 

                fwrite(fidbin,app.TrigList_Bnd(:,8),  'single');
                fwrite(fidbin,app.TrigList_Bnd(:,9),  'single');    
                fwrite(fidbin,app.TrigList_Bnd(:,10), 'single'); 


                fclose(fidbin);    
            end
        end

        % Button pushed function: LoadFaultModel_Button
        function LoadFaultModel_ButtonPushed(app, event)
            %-----------------------   
            [DataName,DataPath]  = uigetfile('*_FLT.txt','Load Fault Model');
            if     (DataName ~=0),      DataName = extractBefore(DataName,'_FLT.txt');      CompletePath = strcat(DataPath,DataName); 
            elseif (DataName ==0),      return;         
            end
            Name4ascii  = strcat(CompletePath,'_FLT.txt');
            Name4binary = strcat(CompletePath,'_FLTtrig.dat');
            %----------------------------------------------------------------
            fidascii    = fopen(Name4ascii ,'r');
            if (fidascii == -1), errordlg('The entered filename has not been found.');    return;   end
            
            nextline = fgetl(fidascii);             app.MaxSectionID = sscanf(nextline,'%*s %d');
            nextline = fgetl(fidascii);             nextvals         = sscanf(nextline,'%*s %d %d');
            TrigNumFault = nextvals(1,1);           VertNumFault     = nextvals(2,1);
            nextline = fgetl(fidascii);

            for i = 1 : app.MaxSectionID
                nextline = fgetl(fidascii);         app.Faults(i).FaultIDnum     = sscanf(nextline,'%*s %d');
                nextline = fgetl(fidascii);         app.Faults(i).SegmName       = sscanf(nextline,'%*s %s'); 
                nextline = fgetl(fidascii);         app.Faults(i).FaultCent(1,1) = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         app.Faults(i).FaultCent(2,1) = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         app.Faults(i).FaultCent(3,1) = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         app.Faults(i).Length         = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         app.Faults(i).Width          = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         app.Faults(i).Strike         = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         app.Faults(i).Dip            = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         app.Faults(i).UseSlip        = sscanf(nextline,'%*s %d');
                nextline = fgetl(fidascii);         app.Faults(i).SlipRate       = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         app.Faults(i).Rake           = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         app.Faults(i).StressRate     = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         app.Faults(i).StressDir      = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         nextline = fgetl(fidascii);
                nextline = fgetl(fidascii);         nextline = fgetl(fidascii);
     
                nextline = fgetl(fidascii);         app.Faults(i).TopEasting     = sscanf(nextline,'%e');
                nextline = fgetl(fidascii);         app.Faults(i).TopNrthing     = sscanf(nextline,'%e');
                nextline = fgetl(fidascii);         app.Faults(i).TopDepth       = sscanf(nextline,'%e');
                nextline = fgetl(fidascii);         app.Faults(i).BotEasting     = sscanf(nextline,'%e');
                nextline = fgetl(fidascii);         app.Faults(i).BotNrthing     = sscanf(nextline,'%e');
                nextline = fgetl(fidascii);         app.Faults(i).BotDepth       = sscanf(nextline,'%e');

                nextline = fgetl(fidascii);         nextline = fgetl(fidascii);
                nextline = fgetl(fidascii);
            end
            fclose(fidascii);
            %------------------------------------------
            fidbin    = fopen(Name4binary ,'rb'); 
            if (fidbin == -1), errordlg('The entered filename has not been found.');    return;     end
            TrigNumFault2 = fread(fidbin,1,'int32');
            VertNumFault2 = fread(fidbin,1,'int32');
            if ((TrigNumFault ~= TrigNumFault2) ||(VertNumFault ~= VertNumFault2)),     return;     end

            app.TrigList_Flt = zeros(TrigNumFault,11);
            app.VertList_Flt = zeros(VertNumFault,4);

            app.TrigList_Flt(:,1) = fread(fidbin,TrigNumFault, 'int32');
            app.TrigList_Flt(:,2) = fread(fidbin,TrigNumFault, 'int32');
            app.TrigList_Flt(:,3) = fread(fidbin,TrigNumFault, 'int32');
            app.TrigList_Flt(:,4) = fread(fidbin,TrigNumFault, 'int32');
            app.TrigList_Flt(:,5) = fread(fidbin,TrigNumFault, 'int32');
            app.TrigList_Flt(:,6) = fread(fidbin,TrigNumFault, 'int32');
            app.TrigList_Flt(:,7) = fread(fidbin,TrigNumFault, 'single');
            app.TrigList_Flt(:,8) = fread(fidbin,TrigNumFault, 'single');
            app.TrigList_Flt(:,9) = fread(fidbin,TrigNumFault, 'single');
            app.TrigList_Flt(:,10)= fread(fidbin,TrigNumFault, 'single');
            app.TrigList_Flt(:,11)= fread(fidbin,TrigNumFault, 'single');

            app.VertList_Flt(:,1)= fread(fidbin,VertNumFault,  'single');
            app.VertList_Flt(:,2)= fread(fidbin,VertNumFault,  'single');
            app.VertList_Flt(:,3)= fread(fidbin,VertNumFault,  'single');
            app.VertList_Flt(:,4)= fread(fidbin,VertNumFault,  'single');

            fclose(fidbin);    
            SegmentCounter       = 0;
            %----------------------------------------------
            for i = 1 : app.MaxSectionID
                SegmentCounter = SegmentCounter +1;
                app.AllFltNames{SegmentCounter}   = app.Faults(i).SegmName;
            end
            app.TracedFaults_ListBox.Items     = app.AllFltNames;
            app.TracedFaults_ListBox.ItemsData = 1:size(app.AllFltNames,2);
            app.TracedFaults_ListBox.Value     = 1;
            drawnow;
            %----------------------------------------------
            Name4ascii  = strcat(CompletePath,'_BND.txt');
            Name4binary = strcat(CompletePath,'_BNDtrig.dat');
            %----------------------------------------------------------------
            fidascii    = fopen(Name4ascii ,'r');
            if (fidascii == -1)
                DeleteBound_ButtonPushed(app);
                return;         
            end

            nextline = fgetl(fidascii);             BoundNum         = sscanf(nextline,'%*s %d');
            nextline = fgetl(fidascii);             nextvals         = sscanf(nextline,'%*s %d %d');
            TrigNumBound = nextvals(1,1);           VertNumBound     = nextvals(2,1);
            nextline = fgetl(fidascii);
            %--------------------------------------
            for i = 1 : BoundNum
                nextline = fgetl(fidascii);         app.BoundaryNames{1,i}  = sscanf(nextline,'%*s %s'); 
                nextline = fgetl(fidascii);         app.BoundaryValues(i,1) = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         app.BoundaryValues(i,2) = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         app.BoundaryValues(i,3) = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         app.BoundaryValues(i,4) = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         app.BoundaryValues(i,5) = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         app.BoundaryValues(i,6) = sscanf(nextline,'%*s %e');                
                nextline = fgetl(fidascii);         app.BoundaryValues(i,7) = sscanf(nextline,'%*s %e');
                nextline = fgetl(fidascii);         app.BoundaryValues(i,8) = sscanf(nextline,'%*s %d');
                nextline = fgetl(fidascii);         app.BoundaryValues(i,9) = sscanf(nextline,'%*s %e %*e');           app.BoundaryValues(i,12)     = sscanf(nextline,'%*s %*e %e');
                nextline = fgetl(fidascii);         app.BoundaryValues(i,10)= sscanf(nextline,'%*s %e %*e');           app.BoundaryValues(i,13)     = sscanf(nextline,'%*s %*e %e');
                nextline = fgetl(fidascii);         app.BoundaryValues(i,11)= sscanf(nextline,'%*s %e %*e');           app.BoundaryValues(i,14)     = sscanf(nextline,'%*s %*e %e');
                nextline = fgetl(fidascii);         nextline = fgetl(fidascii);         nextline = fgetl(fidascii);
            end
            fclose(fidascii);
            %--------------------------------------
            fidbin    = fopen(Name4binary ,'rb'); 
            if (fidbin == -1), errordlg('The entered filename has not been found.');    return;         end
            
            TrigNumBound2 = fread(fidbin,1,'int32');
            VertNumBound2 = fread(fidbin,1,'int32');            
            if ((TrigNumBound ~= TrigNumBound2) ||(VertNumBound ~= VertNumBound2)),     return;     end
            
            app.TrigList_Bnd = zeros(TrigNumBound,7);
            app.VertList_Bnd = zeros(VertNumBound,3);

            app.TrigList_Bnd(:,1) = fread(fidbin,TrigNumBound, 'int32');
            app.TrigList_Bnd(:,2) = fread(fidbin,TrigNumBound, 'int32');
            app.TrigList_Bnd(:,3) = fread(fidbin,TrigNumBound, 'int32');
            app.TrigList_Bnd(:,4) = fread(fidbin,TrigNumBound, 'int32');
            app.TrigList_Bnd(:,5) = fread(fidbin,TrigNumBound, 'single');
            app.TrigList_Bnd(:,6) = fread(fidbin,TrigNumBound, 'single');
            app.TrigList_Bnd(:,7) = fread(fidbin,TrigNumBound, 'single');

            app.VertList_Bnd(:,1) = fread(fidbin,VertNumBound, 'single');
            app.VertList_Bnd(:,2) = fread(fidbin,VertNumBound, 'single');
            app.VertList_Bnd(:,3) = fread(fidbin,VertNumBound, 'single');

            fclose(fidbin);    
            %-------------------------------------------------- 
            app.BoundName_EditField.Value         = app.BoundaryNames{1};
            app.BoundNorthing_EditField.Value     = app.BoundaryValues(1,1);
            app.BoundEasting_EditField.Value      = app.BoundaryValues(1,2);
            app.BoundDepth_EditField.Value        = app.BoundaryValues(1,3);
            app.BoundLength_EditField.Value       = app.BoundaryValues(1,4);
            app.BoundWidth_EditField.Value        = app.BoundaryValues(1,5);
            app.BoundStrike_EditField.Value       = app.BoundaryValues(1,6);
            app.BoundDip_EditField.Value          = app.BoundaryValues(1,7);    
            app.BoundApplyRateAt_DropDown.Value = app.BoundApplyRateAt_DropDown.ItemsData(app.BoundaryValues(1,8));
            %-------------------------------------------------- 
            app.BoundStrikeratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(1, 9)),"  ",num2str(app.BoundaryValues(1,12)));
            app.BoundDipratemmyrEditField.Value    = strcat(num2str(app.BoundaryValues(1,10)),"  ",num2str(app.BoundaryValues(1,13)));
            app.BoundNormalratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(1,11)),"  ",num2str(app.BoundaryValues(1,14)));
            %-----------------------%-----------------------%-----------------------
            app.Bound_ListBox.Items     = app.BoundaryNames;
            app.Bound_ListBox.ItemsData = 1:size(app.BoundaryNames,2);
            app.Bound_ListBox.Value     = size(app.BoundaryNames,2);
            %-------------------------------------------------- 
            app.Bound_ListBox.Items = app.BoundaryNames;
            app.Bound_ListBox.ItemsData = 1:size(app.BoundaryNames,2);
            app.Bound_ListBox.Value     = 1;
        end

        % Value changed function: FaultIDnum_EditField
        function FaultIDnum_EditFieldValueChanged(app, event)
            SelSection = app.TracedFaults_ListBox.Value;
            if ((SelSection ~= 0) && (app.toapplychangetoselfaultCheckBox.Value == 1))
                app.Faults(SelSection).FaultIDnum = app.FaultIDnum_EditField.Value;
            end
        end

        % Value changed function: UsefaultsliprateCheckBox
        function UsefaultsliprateCheckBoxValueChanged(app, event)
            SelSection = app.TracedFaults_ListBox.Value;
            if ((SelSection ~= 0) && (app.toapplychangetoselfaultCheckBox.Value == 1))
                app.UsefaultsliprateCheckBox.Value  = 1;
                app.UseregionalstressCheckBox.Value = 0;
                app.Faults(SelSection).UseSlip      = 1;
            end
        end

        % Value changed function: UseregionalstressCheckBox
        function UseregionalstressCheckBoxValueChanged(app, event)
            SelSection = app.TracedFaults_ListBox.Value;
            if ((SelSection ~= 0) && (app.toapplychangetoselfaultCheckBox.Value == 1))
                app.UsefaultsliprateCheckBox.Value  = 0;
                app.UseregionalstressCheckBox.Value = 1;
                app.Faults(SelSection).UseSlip      = 0;
            end
        end

        % Value changed function: SlipRake_EditField
        function SlipRake_EditFieldValueChanged(app, event)
            SelSection = app.TracedFaults_ListBox.Value;
            if ((SelSection ~= 0) && (app.toapplychangetoselfaultCheckBox.Value == 1))
                app.Faults(SelSection).Rake = app.SlipRake_EditField.Value;
            end
        end

        % Value changed function: SlipRate_EditField
        function SlipRate_EditFieldValueChanged(app, event)
            SelSection = app.TracedFaults_ListBox.Value;
            if ((SelSection ~= 0) && (app.toapplychangetoselfaultCheckBox.Value == 1))
                app.Faults(SelSection).SlipRate = app.SlipRate_EditField.Value;
            end   
        end

        % Callback function
        function StressRate_EditFieldValueChanged(app, event)
            SelSection = app.TracedFaults_ListBox.Value;
            if ((SelSection ~= 0) && (app.toapplychangetoselfaultCheckBox.Value == 1))
                app.Faults(SelSection).StressRate = app.StressRate_EditField.Value;
            end          
        end

        % Value changed function: StressDirection_EditField
        function StressDirection_EditFieldValueChanged(app, event)
            SelSection = app.TracedFaults_ListBox.Value;
            if ((SelSection ~= 0) && (app.toapplychangetoselfaultCheckBox.Value == 1))
                app.Faults(SelSection).StressDir = app.StressDirection_EditField.Value;
            end
        end

        % Value changed function: Name_EditField
        function Name_EditFieldValueChanged(app, event)
            SelSection = app.TracedFaults_ListBox.Value;
            if ((SelSection ~= 0) && (app.toapplychangetoselfaultCheckBox.Value == 1))
                SegmName = app.Name_EditField.Value;
                app.Faults(SelSection).SegmName   = strcat(SegmName,'_ID_',num2str(SelSection));
                SegmentCounter       = 0;
                for i = 1 : app.MaxSectionID
                    SegmentCounter = SegmentCounter +1;
                    app.AllFltNames{SegmentCounter}   = app.Faults(i).SegmName;
                end
                app.TracedFaults_ListBox.Items     = app.AllFltNames;
                app.TracedFaults_ListBox.ItemsData = 1:size(app.AllFltNames,2);
                app.TracedFaults_ListBox.Value     = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FigEditFieldLabel_3 = uilabel(app.UIFigure);
            app.FigEditFieldLabel_3.Text = 'Fig #';
            app.FigNumFaults_EditField = uieditfield(app.UIFigure, 'numeric');
            app.FigNumFaults_EditField.Limits = [0 Inf];
            app.FigNumFaults_EditField.RoundFractionalValues = 'on';
            app.FigNumFaults_EditField.ValueDisplayFormat = '%d';
            app.FigNumFaults_EditField.Value = 3;
            app.AxisEqual_CheckBox = uicheckbox(app.UIFigure);
            app.AxisEqual_CheckBox.Text = 'axis equal';
            app.AxisEqual_CheckBox.Value = true;
            app.MeshPlot_Button = uibutton(app.UIFigure, 'push');
            app.MeshPlot_Button.ButtonPushedFcn = createCallbackFcn(app, @MeshPlot_ButtonPushed, true);
            app.MeshPlot_Button.Text = '4) Plot/Mesh';
            app.Panel = uipanel(app.UIFigure);
            app.LoadFaultMap_Button = uibutton(app.Panel, 'push');
            app.LoadFaultMap_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadFaultMap_ButtonPushed, true);
            app.LoadFaultMap_Button.Text = '0) Load fault map';
            app.PlotFaultMap_Button = uibutton(app.Panel, 'push');
            app.PlotFaultMap_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotFaultMap_ButtonPushed, true);
            app.PlotFaultMap_Button.Text = '1) Plot fault map';
            app.maxNorthingButton = uibutton(app.Panel, 'push');
            app.maxNorthingButton.ButtonPushedFcn = createCallbackFcn(app, @maxNorthingButtonPushed, true);
            app.maxNorthingButton.Text = '2) max. Northing';
            app.minNorthingButton = uibutton(app.Panel, 'push');
            app.minNorthingButton.ButtonPushedFcn = createCallbackFcn(app, @minNorthingButtonPushed, true);
            app.minNorthingButton.Text = '2) min. Northing';
            app.minEastingButton = uibutton(app.Panel, 'push');
            app.minEastingButton.ButtonPushedFcn = createCallbackFcn(app, @minEastingButtonPushed, true);
            app.minEastingButton.Text = '2) min. Easting';
            app.maxEastingButton = uibutton(app.Panel, 'push');
            app.maxEastingButton.ButtonPushedFcn = createCallbackFcn(app, @maxEastingButtonPushed, true);
            app.maxEastingButton.Text = '2) max. Easting';
            app.kmEditFieldLabel = uilabel(app.Panel);
            app.kmEditFieldLabel.Text = 'km';
            app.MaxNorthing_EditField = uieditfield(app.Panel, 'numeric');
            app.MaxNorthing_EditField.ValueDisplayFormat = '%6.3f';
            app.MaxNorthing_EditField.Value = 100;
            app.kmEditField_2Label = uilabel(app.Panel);
            app.kmEditField_2Label.Text = 'km';
            app.MinNorthing_EditField = uieditfield(app.Panel, 'numeric');
            app.MinNorthing_EditField.ValueDisplayFormat = '%6.3f';
            app.kmEditField_3Label = uilabel(app.Panel);
            app.kmEditField_3Label.Text = 'km';
            app.MinEasting_EditField = uieditfield(app.Panel, 'numeric');
            app.MinEasting_EditField.ValueDisplayFormat = '%6.3f';
            app.kmEditField_4Label = uilabel(app.Panel);
            app.kmEditField_4Label.Text = 'km';
            app.MaxEasting_EditField = uieditfield(app.Panel, 'numeric');
            app.MaxEasting_EditField.ValueDisplayFormat = '%6.3f';
            app.MaxEasting_EditField.Value = 100;
            app.FigEditFieldLabel = uilabel(app.Panel);
            app.FigEditFieldLabel.Text = 'Fig #';
            app.FigNumMap_EditField = uieditfield(app.Panel, 'numeric');
            app.FigNumMap_EditField.Limits = [0 Inf];
            app.FigNumMap_EditField.RoundFractionalValues = 'on';
            app.FigNumMap_EditField.ValueDisplayFormat = '%d';
            app.FigNumMap_EditField.Value = 2;
            app.CutMeshedBoundary_Button = uibutton(app.Panel, 'push');
            app.CutMeshedBoundary_Button.Enable = 'off';
            app.CutMeshedBoundary_Button.Visible = 'off';
            app.CutMeshedBoundary_Button.Text = 'Cut selected boundary (after meshing)';
            app.LoadFaultModel_Button = uibutton(app.Panel, 'push');
            app.LoadFaultModel_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadFaultModel_ButtonPushed, true);
            app.LoadFaultModel_Button.Text = 'Load fault model';
            app.Panel_2 = uipanel(app.UIFigure);
            app.TraceFaults_Button = uibutton(app.Panel_2, 'push');
            app.TraceFaults_Button.ButtonPushedFcn = createCallbackFcn(app, @TraceTopFaults_ButtonPushed, true);
            app.TraceFaults_Button.Text = '3) Trace TOP fault trace';
            app.kmLabel = uilabel(app.Panel_2);
            app.kmLabel.Text = 'km';
            app.Width_3_EditField = uieditfield(app.Panel_2, 'numeric');
            app.Width_3_EditField.Limits = [0 Inf];
            app.Width_3_EditField.ValueDisplayFormat = '%5.2f';
            app.Width_3_EditField.Value = 15;
            app.FaultOrSeisWidth_3_DropDown = uidropdown(app.Panel_2);
            app.FaultOrSeisWidth_3_DropDown.Items = {'Fault width', 'Seis. width'};
            app.FaultOrSeisWidth_3_DropDown.ItemsData = {'1', '2'};
            app.FaultOrSeisWidth_3_DropDown.Value = '2';
            app.TraceFaults_3a_Button = uibutton(app.Panel_2, 'push');
            app.TraceFaults_3a_Button.ButtonPushedFcn = createCallbackFcn(app, @TraceFaults_3a_ButtonPushed, true);
            app.TraceFaults_3a_Button.Text = '3a) OR trace fault BOTTOM';
            app.kmLabel_2 = uilabel(app.Panel_2);
            app.kmLabel_2.Text = 'km';
            app.Width_3a_EditField = uieditfield(app.Panel_2, 'numeric');
            app.Width_3a_EditField.Limits = [0 Inf];
            app.Width_3a_EditField.ValueDisplayFormat = '%5.2f';
            app.Width_3a_EditField.Value = 15;
            app.FaultOrSeisWidth_3a_DropDown = uidropdown(app.Panel_2);
            app.FaultOrSeisWidth_3a_DropDown.Items = {'Fault width', 'Seis. width'};
            app.FaultOrSeisWidth_3a_DropDown.ItemsData = {'1', '2'};
            app.FaultOrSeisWidth_3a_DropDown.Value = '2';
            app.DipdegLabel_3 = uilabel(app.Panel_2);
            app.DipdegLabel_3.Text = 'dip (deg.)';
            app.FaultDip_3_EditField = uieditfield(app.Panel_2, 'numeric');
            app.FaultDip_3_EditField.Limits = [0 90];
            app.FaultDip_3_EditField.ValueDisplayFormat = '%4.2f';
            app.FaultDip_3_EditField.Value = 90;
            app.NameEditFieldLabel = uilabel(app.Panel_2);
            app.NameEditFieldLabel.Text = 'Name';
            app.Name_EditField = uieditfield(app.Panel_2, 'text');
            app.Name_EditField.ValueChangedFcn = createCallbackFcn(app, @Name_EditFieldValueChanged, true);
            app.Name_EditField.Value = 'Fault_1';
            app.RakedegLabel = uilabel(app.Panel_2);
            app.RakedegLabel.Text = 'Rake (deg.)';
            app.SlipRake_EditField = uieditfield(app.Panel_2, 'numeric');
            app.SlipRake_EditField.Limits = [-180 180];
            app.SlipRake_EditField.ValueDisplayFormat = '%5.2f';
            app.SlipRake_EditField.ValueChangedFcn = createCallbackFcn(app, @SlipRake_EditFieldValueChanged, true);
            app.SlipRake_EditField.Tooltip = {'0 = LEFT; 90 = REVERSE; -90 = NORMAL; 180 = RIGHT'};
            app.SlipratemmyrLabel = uilabel(app.Panel_2);
            app.SlipratemmyrLabel.Text = 'Slip-rate (mm/yr)';
            app.SlipRate_EditField = uieditfield(app.Panel_2, 'numeric');
            app.SlipRate_EditField.Limits = [0 Inf];
            app.SlipRate_EditField.ValueDisplayFormat = '%5.2f';
            app.SlipRate_EditField.ValueChangedFcn = createCallbackFcn(app, @SlipRate_EditFieldValueChanged, true);
            app.SlipRate_EditField.Value = 10;
            app.continueselectedfaultCheckBox = uicheckbox(app.Panel_2);
            app.continueselectedfaultCheckBox.Text = 'continue selected fault';
            app.seltoptraceCheckBox = uicheckbox(app.Panel_2);
            app.seltoptraceCheckBox.Text = 'sel. top trace';
            app.selbottomtraceCheckBox = uicheckbox(app.Panel_2);
            app.selbottomtraceCheckBox.Text = 'sel. bottom trace';
            app.Trace_FlipIt_Button = uibutton(app.Panel_2, 'push');
            app.Trace_FlipIt_Button.ButtonPushedFcn = createCallbackFcn(app, @Trace_FlipIt_ButtonPushed, true);
            app.Trace_FlipIt_Button.Text = 'flip start/end';
            app.TracedFaults_ListBox = uilistbox(app.Panel_2);
            app.TracedFaults_ListBox.Items = {'no fault traced'};
            app.TracedFaults_ListBox.ItemsData = 0;
            app.TracedFaults_ListBox.ValueChangedFcn = createCallbackFcn(app, @TracedFaults_ListBoxValueChanged, true);
            app.TracedFaults_ListBox.Value = 0;
            app.RakedegLabel_2 = uilabel(app.Panel_2);
            app.RakedegLabel_2.Text = 'Fault ID';
            app.FaultIDnum_EditField = uieditfield(app.Panel_2, 'numeric');
            app.FaultIDnum_EditField.Limits = [1 Inf];
            app.FaultIDnum_EditField.RoundFractionalValues = 'on';
            app.FaultIDnum_EditField.ValueDisplayFormat = '%d';
            app.FaultIDnum_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultIDnum_EditFieldValueChanged, true);
            app.FaultIDnum_EditField.Value = 1;
            app.TextArea_3 = uitextarea(app.Panel_2);
            app.TextArea_3.Value = {'and copy trace to BOTTOM using WIDTH and DIP'};
            app.TextArea_4 = uitextarea(app.Panel_2);
            app.TextArea_4.Value = {'and use WIDTH'};
            app.TextArea_2 = uitextarea(app.Panel_2);
            app.TextArea_2.Value = {'For tracing: '; '"left-mouse" => add point "right-mouse" => del. point "space" => stop tracing'};
            app.toapplychangetoselfaultCheckBox = uicheckbox(app.Panel_2);
            app.toapplychangetoselfaultCheckBox.Text = 'to apply change to sel. fault';
            app.UseregionalstressCheckBox = uicheckbox(app.Panel_2);
            app.UseregionalstressCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseregionalstressCheckBoxValueChanged, true);
            app.UseregionalstressCheckBox.Text = 'or use regional stress';
            app.SlipratemmyrLabel_2 = uilabel(app.Panel_2);
            app.SlipratemmyrLabel_2.Text = 'Stress-rate (MPa/yr)';
            app.StressRate_EditField = uieditfield(app.Panel_2, 'numeric');
            app.StressRate_EditField.ValueDisplayFormat = '%5.2f';
            app.StressRate_EditField.Tooltip = {'COMPRESSION = NEGATIVE'};
            app.RakedegLabel_3 = uilabel(app.Panel_2);
            app.RakedegLabel_3.Text = 'Stress direction (deg.)';
            app.StressDirection_EditField = uieditfield(app.Panel_2, 'numeric');
            app.StressDirection_EditField.Limits = [0 180];
            app.StressDirection_EditField.ValueDisplayFormat = '%5.2f';
            app.StressDirection_EditField.ValueChangedFcn = createCallbackFcn(app, @StressDirection_EditFieldValueChanged, true);
            app.UsefaultsliprateCheckBox = uicheckbox(app.Panel_2);
            app.UsefaultsliprateCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsefaultsliprateCheckBoxValueChanged, true);
            app.UsefaultsliprateCheckBox.Text = 'use fault slip rate';
            app.UsefaultsliprateCheckBox.Value = true;
            app.RemoveSelSection_Button = uibutton(app.Panel_2, 'push');
            app.RemoveSelSection_Button.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelSection_ButtonPushed, true);
            app.RemoveSelSection_Button.Text = {'Delete sel. fault'; ''};
            app.SaveFaultModel_Button = uibutton(app.UIFigure, 'push');
            app.SaveFaultModel_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveFaultModel_ButtonPushed, true);
            app.SaveFaultModel_Button.Text = '5) Save triangulation';
            app.FaultelementEditFieldLabel = uilabel(app.UIFigure);
            app.FaultelementEditFieldLabel.Text = 'Fault element #';
            app.FaultelementEditField = uieditfield(app.UIFigure, 'numeric');
            app.FaultelementEditField.Limits = [0 Inf];
            app.FaultelementEditField.RoundFractionalValues = 'on';
            app.FaultelementEditField.ValueDisplayFormat = '%d';
            app.FaultelementEditField.Editable = 'off';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Bound_ListBox = uilistbox(app.Panel_3);
            app.Bound_ListBox.Items = {'Boundary_Bottom'};
            app.Bound_ListBox.ItemsData = 1;
            app.Bound_ListBox.ValueChangedFcn = createCallbackFcn(app, @Bound_ListBoxValueChanged, true);
            app.Bound_ListBox.Value = 1;
            app.NameEditField_2Label = uilabel(app.Panel_3);
            app.NameEditField_2Label.Text = 'Name';
            app.BoundName_EditField = uieditfield(app.Panel_3, 'text');
            app.BoundName_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundName_EditFieldValueChanged, true);
            app.BoundName_EditField.Value = 'Boundary_Fault';
            app.EastingtopcenterkmEditField_2Label = uilabel(app.Panel_3);
            app.EastingtopcenterkmEditField_2Label.Text = 'Easting (top center, km)';
            app.BoundEasting_EditField = uieditfield(app.Panel_3, 'numeric');
            app.BoundEasting_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundEasting_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundEasting_EditFieldValueChanged, true);
            app.BoundEasting_EditField.Value = -100;
            app.NorthingtopcenterkmEditField_2Label = uilabel(app.Panel_3);
            app.NorthingtopcenterkmEditField_2Label.Text = 'Northing(top center, km)';
            app.BoundNorthing_EditField = uieditfield(app.Panel_3, 'numeric');
            app.BoundNorthing_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundNorthing_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundNorthing_EditFieldValueChanged, true);
            app.DepthtopcenterkmEditField_2Label = uilabel(app.Panel_3);
            app.DepthtopcenterkmEditField_2Label.Text = 'Depth (top center, km)';
            app.BoundDepth_EditField = uieditfield(app.Panel_3, 'numeric');
            app.BoundDepth_EditField.Limits = [-Inf 0];
            app.BoundDepth_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundDepth_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundDepth_EditFieldValueChanged, true);
            app.BoundDepth_EditField.Value = -20;
            app.LengthkmEditField_2Label = uilabel(app.Panel_3);
            app.LengthkmEditField_2Label.Text = 'Length (km)';
            app.BoundLength_EditField = uieditfield(app.Panel_3, 'numeric');
            app.BoundLength_EditField.Limits = [0 Inf];
            app.BoundLength_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundLength_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundLength_EditFieldValueChanged, true);
            app.BoundLength_EditField.Value = 200;
            app.WidthkmEditField_2Label = uilabel(app.Panel_3);
            app.WidthkmEditField_2Label.Text = 'Width (km)';
            app.BoundWidth_EditField = uieditfield(app.Panel_3, 'numeric');
            app.BoundWidth_EditField.Limits = [0 Inf];
            app.BoundWidth_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundWidth_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundWidth_EditFieldValueChanged, true);
            app.BoundWidth_EditField.Value = 200;
            app.StrikedegEditField_2Label = uilabel(app.Panel_3);
            app.StrikedegEditField_2Label.Text = 'Strike (deg.)';
            app.BoundStrike_EditField = uieditfield(app.Panel_3, 'numeric');
            app.BoundStrike_EditField.Limits = [0 360];
            app.BoundStrike_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundStrike_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundStrike_EditFieldValueChanged, true);
            app.DipdegEditField_2Label = uilabel(app.Panel_3);
            app.DipdegEditField_2Label.Text = 'Dip (deg.)';
            app.BoundDip_EditField = uieditfield(app.Panel_3, 'numeric');
            app.BoundDip_EditField.Limits = [0 90];
            app.BoundDip_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundDip_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundDip_EditFieldValueChanged, true);
            app.AddBound_Button = uibutton(app.Panel_3, 'push');
            app.AddBound_Button.ButtonPushedFcn = createCallbackFcn(app, @AddBound_ButtonPushed, true);
            app.AddBound_Button.Text = 'Add';
            app.DeleteBound_Button = uibutton(app.Panel_3, 'push');
            app.DeleteBound_Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteBound_ButtonPushed, true);
            app.DeleteBound_Button.Text = 'Delete';
            app.applyrateDropDownLabel = uilabel(app.Panel_3);
            app.applyrateDropDownLabel.Text = 'apply rate';
            app.BoundApplyRateAt_DropDown = uidropdown(app.Panel_3);
            app.BoundApplyRateAt_DropDown.Items = {'top/bottom edge', 'left/right edge'};
            app.BoundApplyRateAt_DropDown.ItemsData = {'1', '2'};
            app.BoundApplyRateAt_DropDown.ValueChangedFcn = createCallbackFcn(app, @BoundApplyRateAt_DropDownValueChanged, true);
            app.BoundApplyRateAt_DropDown.Value = '1';
            app.strikeratemmyrEditFieldLabel = uilabel(app.Panel_3);
            app.strikeratemmyrEditFieldLabel.Text = 'strike-rate (mm/yr)';
            app.BoundStrikeratemmyrEditField = uieditfield(app.Panel_3, 'text');
            app.BoundStrikeratemmyrEditField.ValueChangedFcn = createCallbackFcn(app, @BoundStrikeratemmyrEditFieldValueChanged, true);
            app.BoundStrikeratemmyrEditField.Value = 'NaN NaN';
            app.dipratemmyrEditFieldLabel = uilabel(app.Panel_3);
            app.dipratemmyrEditFieldLabel.Text = 'dip-rate (mm/yr)';
            app.BoundDipratemmyrEditField = uieditfield(app.Panel_3, 'text');
            app.BoundDipratemmyrEditField.ValueChangedFcn = createCallbackFcn(app, @BoundDipratemmyrEditFieldValueChanged, true);
            app.BoundDipratemmyrEditField.Value = 'NaN NaN';
            app.normalratemmyrEditFieldLabel = uilabel(app.Panel_3);
            app.normalratemmyrEditFieldLabel.Text = 'normal-rate (mm/yr)';
            app.BoundNormalratemmyrEditField = uieditfield(app.Panel_3, 'text');
            app.BoundNormalratemmyrEditField.ValueChangedFcn = createCallbackFcn(app, @BoundNormalratemmyrEditFieldValueChanged, true);
            app.BoundNormalratemmyrEditField.Value = 'NaN NaN';
            app.DipdegEditField_2Label_2 = uilabel(app.Panel_3);
            app.DipdegEditField_2Label_2.Text = 'rotate all boundaries by (deg)';
            app.BoundRotateAllBy_EditField = uieditfield(app.Panel_3, 'numeric');
            app.BoundRotateAllBy_EditField.Limits = [-180 180];
            app.BoundRotateAllBy_EditField.ValueDisplayFormat = '%3.1f';
            app.BoundaryelementEditFieldLabel = uilabel(app.UIFigure);
            app.BoundaryelementEditFieldLabel.Text = 'Boundary element #';
            app.BoundaryelementEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoundaryelementEditField.Limits = [0 Inf];
            app.BoundaryelementEditField.RoundFractionalValues = 'on';
            app.BoundaryelementEditField.ValueDisplayFormat = '%d';
            app.BoundaryelementEditField.Editable = 'off';
            app.boundaryelementlengthkmEditFieldLabel = uilabel(app.UIFigure);
            app.boundaryelementlengthkmEditFieldLabel.Text = 'boundary element length (km)';
            app.boundaryelementlengthkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.boundaryelementlengthkmEditField.Value = 5;
            app.faultelementlengthkmEditFieldLabel = uilabel(app.UIFigure);
            app.faultelementlengthkmEditFieldLabel.Text = 'fault element length (km)';
            app.faultelementlengthkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.faultelementlengthkmEditField.Value = 2;
            app.PlotCompFault_DropDown = uidropdown(app.UIFigure);
            app.PlotCompFault_DropDown.Items = {'Fault ID', 'useSlip', 'Rate', 'Direction'};
            app.PlotCompFault_DropDown.ItemsData = {'1', '2', '3', '4'};
            app.PlotCompFault_DropDown.Value = '1';
            app.PlotCompBound_DropDown = uidropdown(app.UIFigure);
            app.PlotCompBound_DropDown.Items = {'Boundary ID', 'strike slip-rate', 'dip slip-rate', 'normal slip-rate'};
            app.PlotCompBound_DropDown.ItemsData = {'1', '2', '3', '4'};
            app.PlotCompBound_DropDown.Value = '1';
            app.ActualtrigfaultsLabel = uilabel(app.UIFigure);
            app.ActualtrigfaultsLabel.Text = 'approx. single-element EQ size';
            app.SingleElementEQsize_EditField = uieditfield(app.UIFigure, 'numeric');
            app.SingleElementEQsize_EditField.ValueDisplayFormat = '%4.2f';
            app.SingleElementEQsize_EditField.Editable = 'off';
            app.ShowMesh_CheckBox = uicheckbox(app.UIFigure);
            app.ShowMesh_CheckBox.Text = 'show mesh';
            app.ShowMesh_CheckBox.Value = true;
            app.viewangleLabel = uilabel(app.UIFigure);
            app.viewangleLabel.Text = 'view angle';
            app.ViewAngle_EditField = uieditfield(app.UIFigure, 'text');
            app.ViewAngle_EditField.Value = '40 20';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A1_DigitizeFaults_MCQsimV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end