classdef A2_EnterFault_MCQsimV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ViewAngle_EditField             matlab.ui.control.EditField
        viewangleLabel                  matlab.ui.control.Label
        AxisEqual_CheckBox              matlab.ui.control.CheckBox
        ShowMesh_CheckBox               matlab.ui.control.CheckBox
        PlotCompBound_DropDown          matlab.ui.control.DropDown
        PlotCompFault_DropDown          matlab.ui.control.DropDown
        SingleElementEQsize_EditField   matlab.ui.control.NumericEditField
        ActualtrigfaultsLabel_3         matlab.ui.control.Label
        ElemNumFault_EditField          matlab.ui.control.NumericEditField
        ActualtrigfaultsLabel           matlab.ui.control.Label
        ElemSizeFault_EditField         matlab.ui.control.NumericEditField
        IntendedtrigfaultsLabel         matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        ElemNumBound_EditField          matlab.ui.control.NumericEditField
        ActualtrigboundaryLabel         matlab.ui.control.Label
        ElemSizeBound_EditField         matlab.ui.control.NumericEditField
        IntendedtrigboundaryLabel       matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        ShowTrigStats_CheckBox          matlab.ui.control.CheckBox
        Move_KeepCenterFixed_CheckBox   matlab.ui.control.CheckBox
        FaultVertexPos_Norm_EditField   matlab.ui.control.NumericEditField
        outofplaneEditFieldLabel        matlab.ui.control.Label
        FaultVertexPos_Dip_EditField    matlab.ui.control.NumericEditField
        indipEditFieldLabel             matlab.ui.control.Label
        FaultVertexPos_Strike_EditField  matlab.ui.control.NumericEditField
        instrikeEditFieldLabel          matlab.ui.control.Label
        FaultVertexPos_DropDown         matlab.ui.control.DropDown
        shiftvertexkmLabel              matlab.ui.control.Label
        ShiftingthisoneLabel            matlab.ui.control.Label
        Move2_1stFault_DropDown         matlab.ui.control.DropDown
        ofDropDownLabel                 matlab.ui.control.Label
        Move2_1stCorner_DropDown        matlab.ui.control.DropDown
        toLabel                         matlab.ui.control.Label
        Move2_2ndCorner_DropDown        matlab.ui.control.DropDown
        MoveLabel                       matlab.ui.control.Label
        Move2_2ndFault_DropDown         matlab.ui.control.DropDown
        ofDropDown_2Label               matlab.ui.control.Label
        ShifVertex_tButton              matlab.ui.control.Button
        SaveMesh_Button                 matlab.ui.control.Button
        FigNumFaultMesh_EditField       matlab.ui.control.NumericEditField
        FigEditField_2Label             matlab.ui.control.Label
        PlotMesh_Button                 matlab.ui.control.Button
        Triangulate_Button              matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        BoundRotateAllBy_EditField      matlab.ui.control.NumericEditField
        DipdegEditField_2Label_2        matlab.ui.control.Label
        BoundNormalratemmyrEditField    matlab.ui.control.EditField
        normalratemmyrEditFieldLabel    matlab.ui.control.Label
        BoundDipratemmyrEditField       matlab.ui.control.EditField
        dipratemmyrEditFieldLabel       matlab.ui.control.Label
        BoundStrikeratemmyrEditField    matlab.ui.control.EditField
        strikeratemmyrEditFieldLabel    matlab.ui.control.Label
        BoundApplyRateAt_DropDown       matlab.ui.control.DropDown
        applyrateDropDownLabel          matlab.ui.control.Label
        AddBound_Button                 matlab.ui.control.Button
        DeleteBound_Button              matlab.ui.control.Button
        BoundDip_EditField              matlab.ui.control.NumericEditField
        DipdegEditField_2Label          matlab.ui.control.Label
        BoundStrike_EditField           matlab.ui.control.NumericEditField
        StrikedegEditField_2Label       matlab.ui.control.Label
        BoundWidth_EditField            matlab.ui.control.NumericEditField
        WidthkmEditField_2Label         matlab.ui.control.Label
        BoundLength_EditField           matlab.ui.control.NumericEditField
        LengthkmEditField_2Label        matlab.ui.control.Label
        BoundDepth_EditField            matlab.ui.control.NumericEditField
        DepthtopcenterkmEditField_2Label  matlab.ui.control.Label
        BoundNorthing_EditField         matlab.ui.control.NumericEditField
        NorthingtopcenterkmEditField_2Label  matlab.ui.control.Label
        BoundEasting_EditField          matlab.ui.control.NumericEditField
        EastingtopcenterkmEditField_2Label  matlab.ui.control.Label
        BoundName_EditField             matlab.ui.control.EditField
        NameEditField_2Label            matlab.ui.control.Label
        Bound_ListBox                   matlab.ui.control.ListBox
        Panel                           matlab.ui.container.Panel
        LoadMeshAndModel_Button         matlab.ui.control.Button
        FigNumFaultModel_EditField      matlab.ui.control.NumericEditField
        FigEditFieldLabel               matlab.ui.control.Label
        PlotFaultSetup_Button           matlab.ui.control.Button
        FaultID_EditField               matlab.ui.control.NumericEditField
        FaultIDEditFieldLabel           matlab.ui.control.Label
        FaultStressDirection_EditField  matlab.ui.control.NumericEditField
        RakedegLabel_3                  matlab.ui.control.Label
        FaultStressRate_EditField       matlab.ui.control.NumericEditField
        SlipratemmyrLabel_3             matlab.ui.control.Label
        UseregionalstressCheckBox       matlab.ui.control.CheckBox
        UsefaultsliprateCheckBox        matlab.ui.control.CheckBox
        FaultSlipRake_EditField         matlab.ui.control.NumericEditField
        RakedegLabel_2                  matlab.ui.control.Label
        FaultSlipRate_EditField         matlab.ui.control.NumericEditField
        SlipratemmyrLabel_2             matlab.ui.control.Label
        FaultDip_EditField              matlab.ui.control.NumericEditField
        DipdegEditFieldLabel            matlab.ui.control.Label
        FaultStrike_EditField           matlab.ui.control.NumericEditField
        StrikedegEditFieldLabel         matlab.ui.control.Label
        FaultWidth_EditField            matlab.ui.control.NumericEditField
        WidthkmEditFieldLabel           matlab.ui.control.Label
        FaultLength_EditField           matlab.ui.control.NumericEditField
        LengthkmEditFieldLabel          matlab.ui.control.Label
        FaultDepth_EditField            matlab.ui.control.NumericEditField
        DepthtopcenterkmEditFieldLabel  matlab.ui.control.Label
        FaultNorthing_EditField         matlab.ui.control.NumericEditField
        NorthingtopcenterkmEditFieldLabel  matlab.ui.control.Label
        FaultEasting_EditField          matlab.ui.control.NumericEditField
        EastingtopcenterkmEditFieldLabel  matlab.ui.control.Label
        FaultName_EditField             matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        DeleteFault_Button              matlab.ui.control.Button
        AddFault_Button                 matlab.ui.control.Button
        Fault_ListBox                   matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        FaultNames      = {'Fault_1'};
        %              is Ecentpos, Ncentpos, Zcentpos, length, width, strike, dip; will be extended by offset of vertices out of fault plane (3 values for eachof 
        FaultValues     = [  0,         0,        0,      100,     18,     0,    90, zeros(1,12), 1, 1, 10, 0, 0, 0]; %the last five are UseSlip, slip-rate, rake, stress-rate, direction
                                                                                %rate: left9-11           right12-14  
        BoundaryNames   = {'Boundary_Bottom'};                                   %rate: top9-11          bottom12-14  
        BoundaryValues  = [ -80,         0,      -22,    300,   160,  0,  0,   1,  NaN, NaN, NaN,      NaN, NaN, NaN];


        FaultWasGridded = 0;
        Fault           = struct;
        Bound           = struct;      
    end
    
    methods (Access = private)
        %------------------------------------------------------------------
        function ShiftedVerts = ShiftVertices(app, VertexList, PolygonVerts)
            %I'm skipping the actual "derivation" of the equation used below -only
            %takes 30min and piece of paper plus the website mentioned above...
            %bring die beschreibung der ebene von normalenform in koordinaten form
            %http://www.mathematik-oberstufe.de/vektoren/a/abstand-punkt-ebene-lot.html
            %only need to get the dot product of normal and "start punkt"
            
            VertexNum    = size(VertexList,1);
            ShiftedVerts = nan(VertexNum,1);
            %----------------------------------
            Trig1_x   = [PolygonVerts(1,1), PolygonVerts(2,1), PolygonVerts(3,1),PolygonVerts(1,1)];
            Trig1_y   = [PolygonVerts(1,2), PolygonVerts(2,2), PolygonVerts(3,2),PolygonVerts(1,2)];
            Trig1_z   = [PolygonVerts(1,3), PolygonVerts(2,3), PolygonVerts(3,3),PolygonVerts(1,3)];
            Vect1A    = [(Trig1_x(1,2)-Trig1_x(1,1)); (Trig1_y(1,2)-Trig1_y(1,1)); (Trig1_z(1,2)-Trig1_z(1,1))];    Vect1A= Vect1A./norm(Vect1A); 
            Vect1B    = [(Trig1_x(1,3)-Trig1_x(1,1)); (Trig1_y(1,3)-Trig1_y(1,1)); (Trig1_z(1,3)-Trig1_z(1,1))];    Vect1B= Vect1B./norm(Vect1B);
            Trig1Norm = cross(Vect1A,Vect1B);
            Trig1Dot  = dot(Trig1Norm,[Trig1_x(1,1);Trig1_y(1,1);Trig1_z(1,1)]);
            %----------------------------------
            Trig2_x   = [PolygonVerts(2,1), PolygonVerts(3,1), PolygonVerts(4,1),PolygonVerts(2,1)];
            Trig2_y   = [PolygonVerts(2,2), PolygonVerts(3,2), PolygonVerts(4,2),PolygonVerts(2,2)];
            Trig2_z   = [PolygonVerts(2,3), PolygonVerts(3,3), PolygonVerts(4,3),PolygonVerts(2,3)];
            Vect2A    = [(Trig2_x(1,2)-Trig2_x(1,1)); (Trig2_y(1,2)-Trig2_y(1,1)); (Trig2_z(1,2)-Trig2_z(1,1))];    Vect2A= Vect2A./norm(Vect2A); 
            Vect2B    = [(Trig2_x(1,3)-Trig2_x(1,1)); (Trig2_y(1,3)-Trig2_y(1,1)); (Trig2_z(1,3)-Trig2_z(1,1))];    Vect2B= Vect2B./norm(Vect2B);
            Trig2Norm = cross(Vect2A,Vect2B);
            Trig2Dot  = dot(Trig2Norm,[Trig2_x(1,1);Trig2_y(1,1);Trig2_z(1,1)]);
            %----------------------------------
            Trig3_x   = [PolygonVerts(3,1), PolygonVerts(4,1), PolygonVerts(1,1),PolygonVerts(3,1)];
            Trig3_y   = [PolygonVerts(3,2), PolygonVerts(4,2), PolygonVerts(1,2),PolygonVerts(3,2)];
            Trig3_z   = [PolygonVerts(3,3), PolygonVerts(4,3), PolygonVerts(1,3),PolygonVerts(3,3)];
            Vect3A    = [(Trig3_x(1,2)-Trig3_x(1,1)); (Trig3_y(1,2)-Trig3_y(1,1)); (Trig3_z(1,2)-Trig3_z(1,1))];    Vect3A= Vect3A./norm(Vect3A); 
            Vect3B    = [(Trig3_x(1,3)-Trig3_x(1,1)); (Trig3_y(1,3)-Trig3_y(1,1)); (Trig3_z(1,3)-Trig3_z(1,1))];    Vect3B= Vect3B./norm(Vect3B);
            Trig3Norm = cross(Vect3A,Vect3B);
            Trig3Dot  = dot(Trig3Norm,[Trig3_x(1,1);Trig3_y(1,1);Trig3_z(1,1)]);
            %----------------------------------
            Trig4_x   = [PolygonVerts(4,1), PolygonVerts(1,1), PolygonVerts(2,1),PolygonVerts(4,1)];
            Trig4_y   = [PolygonVerts(4,2), PolygonVerts(1,2), PolygonVerts(2,2),PolygonVerts(4,2)];
            Trig4_z   = [PolygonVerts(4,3), PolygonVerts(1,3), PolygonVerts(2,3),PolygonVerts(4,3)];
            Vect4A    = [(Trig4_x(1,2)-Trig4_x(1,1)); (Trig4_y(1,2)-Trig4_y(1,1)); (Trig4_z(1,2)-Trig4_z(1,1))];    Vect4A= Vect4A./norm(Vect4A); 
            Vect4B    = [(Trig4_x(1,3)-Trig4_x(1,1)); (Trig4_y(1,3)-Trig4_y(1,1)); (Trig4_z(1,3)-Trig4_z(1,1))];    Vect4B= Vect4B./norm(Vect4B);
            Trig4Norm = cross(Vect4A,Vect4B);
            Trig4Dot  = dot(Trig4Norm,[Trig4_x(1,1);Trig4_y(1,1);Trig4_z(1,1)]);
            %-----------------------------------
            IN_1      = inpolygon(VertexList(:,1),VertexList(:,2),Trig1_x,Trig1_y);
            IN_2      = inpolygon(VertexList(:,1),VertexList(:,2),Trig2_x,Trig2_y);
            IN_3      = inpolygon(VertexList(:,1),VertexList(:,2),Trig3_x,Trig3_y);
            IN_4      = inpolygon(VertexList(:,1),VertexList(:,2),Trig4_x,Trig4_y);
            IN_Matrix = [IN_1, IN_2, IN_3, IN_4];
            
            %-----------------------------------
            for i = 1 : VertexNum
                NextLocation   = [VertexList(i,1), VertexList(i,2), 0];
                LocationHeight = 0;
            
                if IN_Matrix(i,1) == 1
                    NextHeight     = (Trig1Dot -Trig1Norm(1,1)*NextLocation(1,1) -Trig1Norm(2,1)*NextLocation(1,2))/Trig1Norm(3,1);
                    LocationHeight = LocationHeight +NextHeight;
                end
                if IN_Matrix(i,2) == 1
                    NextHeight     = (Trig2Dot -Trig2Norm(1,1)*NextLocation(1,1) -Trig2Norm(2,1)*NextLocation(1,2))/Trig2Norm(3,1);
                    LocationHeight = LocationHeight +NextHeight;
                end
                if IN_Matrix(i,3) == 1
                    NextHeight     = (Trig3Dot -Trig3Norm(1,1)*NextLocation(1,1) -Trig3Norm(2,1)*NextLocation(1,2))/Trig3Norm(3,1);
                    LocationHeight = LocationHeight +NextHeight;
                end
                if IN_Matrix(i,4) == 1
                    NextHeight     = (Trig4Dot -Trig4Norm(1,1)*NextLocation(1,1) -Trig4Norm(2,1)*NextLocation(1,2))/Trig4Norm(3,1);
                    LocationHeight = LocationHeight +NextHeight;
                end
                %-----------------------------------
            
                if (sum(IN_Matrix(i,:)) == 0)
                    dist_1 = Point2Line(app,NextLocation(1,1:2), [Trig1_x(1,1), Trig1_y(1,1)], [Trig1_x(1,2), Trig1_y(1,2)]);
                    dist_2 = Point2Line(app,NextLocation(1,1:2), [Trig2_x(1,1), Trig2_y(1,1)], [Trig2_x(1,2), Trig2_y(1,2)]);
                    dist_3 = Point2Line(app,NextLocation(1,1:2), [Trig3_x(1,1), Trig3_y(1,1)], [Trig3_x(1,2), Trig3_y(1,2)]);   
                    dist_4 = Point2Line(app,NextLocation(1,1:2), [Trig4_x(1,1), Trig4_y(1,1)], [Trig4_x(1,2), Trig4_y(1,2)]);
                   
                    dist_1 = [dist_1, Trig1_x(1,1), Trig1_x(1,2), Trig1_y(1,1), Trig1_y(1,2), Trig1_z(1,1), Trig1_z(1,2)];
                    dist_2 = [dist_2, Trig2_x(1,1), Trig2_x(1,2), Trig2_y(1,1), Trig2_y(1,2), Trig2_z(1,1), Trig2_z(1,2)];
                    dist_3 = [dist_3, Trig3_x(1,1), Trig3_x(1,2), Trig3_y(1,1), Trig3_y(1,2), Trig3_z(1,1), Trig3_z(1,2)];
                    dist_4 = [dist_4, Trig4_x(1,1), Trig4_x(1,2), Trig4_y(1,1), Trig4_y(1,2), Trig4_z(1,1), Trig4_z(1,2)];
                     
                    Dists  = [dist_1;dist_2;dist_3;dist_4];
                    MinDisP= find(Dists(:,1) == min(Dists(:,1)));
                    MinDisP= MinDisP(1,1);
                    
                    dist_a = sqrt((Dists(MinDisP,2)-NextLocation(1,1)).^2 +(Dists(MinDisP,4)-NextLocation(1,2)).^2);
                    dist_b = sqrt((Dists(MinDisP,2)-Dists(MinDisP,3)).^2  +(Dists(MinDisP,4)-Dists(MinDisP,5)).^2);
                    ratio  = dist_a/dist_b;
                   ShiftedVerts(i,1) = Dists(MinDisP,6) +ratio* (Dists(MinDisP,7)-Dists(MinDisP,6)); 
                else
                    ShiftedVerts(i,1) = LocationHeight./sum(IN_Matrix(i,:));
                end
            end
            clear Trig1_x Trig1_y Trig1_z Vect1A Vect1B IN_1;
            clear Trig2_x Trig2_y Trig2_z Vect2A Vect2B IN_2;
            clear Trig3_x Trig3_y Trig3_z Vect3A Vect3B IN_3;
            clear Trig4_x Trig4_y Trig4_z Vect4A Vect4B IN_4;
            
        end
        %------------------------------------------------------------------
        function [HistFaultArea_N,HistFaultArea_X,HistBoundArea_N,HistBoundArea_X,HistFaultLgth_N,HistFaultLgth_X,HistBoundLgth_N,HistBoundLgth_X] = ComputeTrigStats(app)
            %-----------------------------------------
            HistFaultArea_N = 0;                        HistFaultArea_X = 0;
            HistBoundArea_N = 0;                        HistBoundArea_X = 0;
            HistFaultLgth_N = 0;                        HistFaultLgth_X = 0;
            HistBoundLgth_N = 0;                        HistBoundLgth_X = 0;
            %-----------------------------------------
            if (app.FaultWasGridded == 0),              return;                                 end
            %-----------------------------------------
            SideLengthFault = zeros(size(app.Fault.TriangleList,1),1);
            PatchAreasFault = zeros(size(app.Fault.TriangleList,1),1);
            %-----------------------------------------
            for i = 1 : size(app.Fault.TriangleList,1)
                v1   = app.Fault.TriangleList(i,1);
                v2   = app.Fault.TriangleList(i,2);
                v3   = app.Fault.TriangleList(i,3);
                v1v2 = app.Fault.VertexList(v2,1:3)- app.Fault.VertexList(v1,1:3);
                v2v3 = app.Fault.VertexList(v3,1:3)- app.Fault.VertexList(v2,1:3);
                v3v1 = app.Fault.VertexList(v1,1:3)- app.Fault.VertexList(v3,1:3);

                PatchAreasFault(i,1) = 0.5*sqrt(sum(cross(v1v2,v2v3).^2));
                SideLengthFault(i,1) = (sqrt(sum(v1v2.^2)) + sqrt(sum(v2v3.^2)) + sqrt(sum(v3v1.^2)))/3; 
                %---------------------------
            end
            %-----------------------------------------%-----------------------------------------
            [HistFaultArea_N,HistFaultArea_X] = histcounts(PatchAreasFault(:),50);
            [HistFaultLgth_N,HistFaultLgth_X] = histcounts(SideLengthFault(:),50);
            %-----------------------------------------%-----------------------------------------
            if (size(app.BoundaryValues,1) > 0)
                SideLengthBound = zeros(size(app.Bound.TriangleList,1),1);
                PatchAreasBound = zeros(size(app.Bound.TriangleList,1),1);
                %-----------------------------------------
                for i = 1 : size(app.Bound.TriangleList,1)
                    v1   = app.Bound.TriangleList(i,1);
                    v2   = app.Bound.TriangleList(i,2);
                    v3   = app.Bound.TriangleList(i,3);
                    v1v2 = app.Bound.VertexList(v2,:)- app.Bound.VertexList(v1,:);
                    v2v3 = app.Bound.VertexList(v3,:)- app.Bound.VertexList(v2,:);
                    v3v1 = app.Bound.VertexList(v1,:)- app.Bound.VertexList(v3,:);

                    PatchAreasBound(i,1) = 0.5*sqrt(sum(cross(v1v2,v2v3).^2));
                    SideLengthBound(i,1) = (sqrt(sum(v1v2.^2)) + sqrt(sum(v2v3.^2)) + sqrt(sum(v3v1.^2)))/3; 
                    %---------------------------                
                end
                %-----------------------------------------%-----------------------------------------
                [HistBoundArea_N,HistBoundArea_X] = histcounts(PatchAreasBound(:),50);
                [HistBoundLgth_N,HistBoundLgth_X] = histcounts(SideLengthBound(:),50);
                %-----------------------------------------%-----------------------------------------
            end
        end
        %------------------------------------------------------------------
        function dist = Point2Line(~, x, a, b)
            d_ab = norm(a-b);
            d_ax = norm(a-x);
            d_bx = norm(b-x);
            
            if dot(a-b,x-b)*dot(b-a,x-a)>=0
                A = [a,1;b,1;x,1];
                dist = abs(det(A))/d_ab;     
            else
                dist = min(d_ax, d_bx);
            end
            
        end
        %------------------------------------------------------------------
        %------------------------------------------------------------------
        function MeshFault(app)
            %---------------------------------------------- 
            app.Fault              = struct;
            app.Fault.TriangleList = [];
            app.Fault.VertexList   = [];
            %---------------------------------------------- 
            for SegID = 1 : size(app.FaultValues,1)
                Ecent  = app.FaultValues(SegID,1);
                Ncent  = app.FaultValues(SegID,2);
                Zcent  = app.FaultValues(SegID,3);
                Length = app.FaultValues(SegID,4);
                Width  = app.FaultValues(SegID,5);
                Strike = app.FaultValues(SegID,6).*pi./180;
                Dip    = app.FaultValues(SegID,7).*pi./180;
                FltID  = app.FaultValues(SegID,20);
                UseSlip= app.FaultValues(SegID,21); 
                %--------------------   
                if (UseSlip == 1)
                    TheRate = app.FaultValues(SegID,22);        TheRake = app.FaultValues(SegID,23);
                else
                    TheRate = app.FaultValues(SegID,24);        TheRake = app.FaultValues(SegID,25);
                end
                %---------------------------------------------- 
                UL_stk = -Length/2 +app.FaultValues(SegID,17);      UR_stk =  Length/2 +app.FaultValues(SegID,14);
                LL_stk = -Length/2 +app.FaultValues(SegID,8);       LR_stk =  Length/2 +app.FaultValues(SegID,11);
                UL_dip = 0         +app.FaultValues(SegID,18);      UR_dip =  0        +app.FaultValues(SegID,15);
                LL_dip = -Width    +app.FaultValues(SegID,9);       LR_dip =  -Width   +app.FaultValues(SegID,12);
                UL_nrm = 0         +app.FaultValues(SegID,19);      UR_nrm =  0        +app.FaultValues(SegID,16);
                LL_nrm = 0         +app.FaultValues(SegID,10);      LR_nrm =  0        +app.FaultValues(SegID,13);
                %--------------------   
                stkA   = [sin(Strike), cos(Strike), 0];
                nrmA   = [sin(Dip)*cos(Strike), -sin(Dip)*sin(Strike), cos(Dip)];
                dipA   = cross(nrmA,stkA);
                %-------------------- 
                RotMat = [stkA;dipA;nrmA];          RotMatT= RotMat';
                %-------------------- 
                UpStart = RotMatT*[UR_stk;UR_dip;UR_nrm] +[Ecent;Ncent;Zcent];
                UpEnd   = RotMatT*[UL_stk;UL_dip;UL_nrm] +[Ecent;Ncent;Zcent];
                DnStart = RotMatT*[LR_stk;LR_dip;LR_nrm] +[Ecent;Ncent;Zcent];
                DnEnd   = RotMatT*[LL_stk;LL_dip;LL_nrm] +[Ecent;Ncent;Zcent];
                %---------------------------------------------- 
                TopPoints    = [UpStart'; UpEnd'];
                BotPoints    = [DnStart'; DnEnd'];
                TopLength    = sum(sqrt(sum(diff(TopPoints(:,1:3)).^2,2)));
                BotLength    = sum(sqrt(sum(diff(BotPoints(:,1:3)).^2,2)));
                ElemNumS     = floor(((TopLength+BotLength)/2)/app.ElemSizeFault_EditField.Value);
                %---------------------------------------------------------
                UsedSdeLgh = TopLength/ElemNumS;
                PntListTop = zeros(ElemNumS+1,3);
                PntListTop(1,:)   = TopPoints(1,:);
                PntListTop(end,:) = TopPoints(end,:);
                CurrLocn   = TopPoints(1,:);
                Counter    = 1;
                TrgtPos    = 2;
                UsedAmount = 0;
                %--------------------------
                while (Counter < ElemNumS)
                    TrgtLocn  = TopPoints(TrgtPos,:);
                    Vect2Next = TrgtLocn - CurrLocn;
                    VectLgth  = sqrt(sum(Vect2Next.^2));
                    if ((UsedSdeLgh-UsedAmount) <= (VectLgth))
                        CurrLocn = CurrLocn + ((UsedSdeLgh-UsedAmount)/VectLgth).*Vect2Next;
                        Counter  = Counter  +1;
                        PntListTop(Counter,:) = CurrLocn;
                        UsedAmount = 0;
                    else
                        CurrLocn   = TopPoints(TrgtPos,:);
                        TrgtPos    = TrgtPos +1;
                        UsedAmount = UsedAmount + VectLgth;
                    end
                end
                %--------------------------
                UsedSdeLgh = BotLength/ElemNumS;
                PntListBot = zeros(ElemNumS+1,3);
                PntListBot(1,:)   = BotPoints(1,:);
                PntListBot(end,:) = BotPoints(end,:);
                CurrLocn   = BotPoints(1,:);
                Counter    = 1;
                TrgtPos    = 2;
                UsedAmount = 0;
                %--------------------------
                while (Counter < ElemNumS)
                    TrgtLocn  = BotPoints(TrgtPos,:);
                    Vect2Next = TrgtLocn - CurrLocn;
                    VectLgth  = sqrt(sum(Vect2Next.^2));
                    if ((UsedSdeLgh-UsedAmount) <= (VectLgth))
                        CurrLocn = CurrLocn + ((UsedSdeLgh-UsedAmount)/VectLgth).*Vect2Next;
                        Counter  = Counter  +1;
                        PntListBot(Counter,:) = CurrLocn;
                        UsedAmount = 0;
                    else
                        CurrLocn   = BotPoints(TrgtPos,:);
                        TrgtPos    = TrgtPos +1;
                        UsedAmount = UsedAmount + VectLgth;
                    end
                end
                %---------------------------------------------------------
                VectLgths   = sqrt( sum((PntListBot - PntListTop).^2,2));
                ElemNumD    = floor(mean(VectLgths)/app.ElemSizeFault_EditField.Value);
                
                IniPointMat = zeros(ElemNumS,ElemNumD,4);
                IDCount     = 0;
                %----------------------------------------------
                for i = 1 : (ElemNumS+1)
                    IDCount             = IDCount+1;
                    IniPointMat(i,1,1:3)= PntListTop(i,1:3);
                    IniPointMat(i,1,4)  = IDCount;
                    CurrLocn            = PntListTop(i,1:3);
                    EndLocn             = PntListBot(i,1:3);
    
                    NextVect   = EndLocn- CurrLocn;
                    VectLgth   = sqrt(sum(NextVect.^2));
                    UsedSdeLgh = VectLgth/ElemNumD;
                    Counter    = 1;
                    %--------------------------
                    while (Counter < ElemNumD)
                        IDCount   = IDCount+1;
                        Counter   = Counter+1;
                        Vect2Next = EndLocn - CurrLocn;
                        VectLgth  = sqrt(sum(Vect2Next.^2));
                        CurrLocn  = CurrLocn + (UsedSdeLgh/VectLgth).*Vect2Next;
                       
                        IniPointMat(i,Counter,1:3) = CurrLocn;
                        IniPointMat(i,Counter,4)   = IDCount;
                    end
                    %--------------------------
                    IDCount   = IDCount+1;
                    Counter   = Counter+1;
                    IniPointMat(i,Counter,1:3) = EndLocn;
                    IniPointMat(i,Counter,4)   = IDCount;
                end
                %----------------------------------------------
                VertexList = zeros(((ElemNumS+1)*(ElemNumD+1) + (ElemNumS*ElemNumD)), 3);
                TrigList  = zeros(ElemNumS*ElemNumD*4,11);
                VertCount  = 0;
                TrigCount  = 0;
                %----------------------------------------------
                for i = 1 : (ElemNumS+1)
                    for j = 1 : (ElemNumD+1)
                        VertCount = VertCount +1;
                        VertexList(VertCount,:) = squeeze(IniPointMat(i,j,1:3));
                    end
                end
                %----------------------------------------------
                for i = 1 : ElemNumS
                    for j = 1 : ElemNumD
                        VertCount = VertCount +1;
                        Vert_A = squeeze(IniPointMat(i,j,:));
                        Vert_B = squeeze(IniPointMat(i+1,j,:));
                        Vert_C = squeeze(IniPointMat(i,j+1,:));
                        Vert_D = squeeze(IniPointMat(i+1,j+1,:));
                        Vert_E = [(Vert_A(1:3,1)+Vert_B(1:3,1)+Vert_C(1:3,1)+Vert_D(1:3,1))./4; VertCount];
                        %----------------------------------------------
                        VertexList(VertCount,:) = Vert_E(1:3,1);
                        %----------------------------------------------
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_A(1:3,1), Vert_B(1:3,1), Vert_E(1:3,1));
                        TrigList(TrigCount,:) = [Vert_A(4,1), Vert_B(4,1), Vert_E(4,1), SegID, FltID, UseSlip, TheRate, TheRake, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1)];
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_B(1:3,1), Vert_D(1:3,1), Vert_E(1:3,1));
                        TrigList(TrigCount,:) = [Vert_B(4,1), Vert_D(4,1), Vert_E(4,1), SegID, FltID, UseSlip, TheRate, TheRake, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1)];
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_D(1:3,1), Vert_C(1:3,1), Vert_E(1:3,1));
                        TrigList(TrigCount,:) = [Vert_D(4,1), Vert_C(4,1), Vert_E(4,1), SegID, FltID, UseSlip, TheRate, TheRake, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1)]; 
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_C(1:3,1), Vert_A(1:3,1), Vert_E(1:3,1));
                        TrigList(TrigCount,:) = [Vert_C(4,1), Vert_A(4,1), Vert_E(4,1), SegID, FltID, UseSlip, TheRate, TheRake, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1)];
                        %----------------------------------------------
                    end
                end
                %----------------------------------------------
                TrigList(:,1:3)        = TrigList(:,1:3) + size(app.Fault.VertexList,1);
                app.Fault.TriangleList = [app.Fault.TriangleList; TrigList];
                app.Fault.VertexList   = [app.Fault.VertexList; [VertexList, zeros(size(VertexList,1),1)]];
                %----------------------------------------------
            end
            %--------------------------
            RandListOrder    = [rand(size(app.Fault.TriangleList,1),1), cumsum(ones(size(app.Fault.TriangleList,1),1))];
            RandListOrder    = sortrows(RandListOrder);
            app.Fault.TriangleList = app.Fault.TriangleList(RandListOrder(:,2),:);
            app.Fault.VertexList   = [app.Fault.VertexList(:,1:3),zeros(size(app.Fault.VertexList,1),1)];
            app.ElemNumFault_EditField.Value = size(app.Fault.TriangleList,1);
        end
        %------------------------------------------------------------------
        %------------------------------------------------------------------
        function MeshBound(app)
            %---------------------------------------------- 
            app.Bound              = struct;
            app.Bound.TriangleList = [];
            app.Bound.VertexList   = [];
            %--------------------------
            for SelBound = 1 : size(app.BoundaryValues,1)
                BoundCent_E = app.BoundaryValues(SelBound,1);
                BoundCent_N = app.BoundaryValues(SelBound,2);
                BoundCent_Z = app.BoundaryValues(SelBound,3);
                Bound_Lgth  = app.BoundaryValues(SelBound,4);
                Bound_Wdth  = app.BoundaryValues(SelBound,5);
                Bound_Stk   = app.BoundaryValues(SelBound,6)*pi/180;
                Bound_Dip   = app.BoundaryValues(SelBound,7)*pi/180;
                %--------------------------
                UL_stk = -Bound_Lgth/2;     UR_stk =  Bound_Lgth/2;     LL_stk = -Bound_Lgth/2;     LR_stk = Bound_Lgth/2;
                UL_dip = 0;                 UR_dip =  0;                LL_dip = -Bound_Wdth;       LR_dip =-Bound_Wdth;
                UL_nrm = 0;                 UR_nrm =  0;                LL_nrm = 0;                 LR_nrm =  0;   
                stkA   = [sin(Bound_Stk), cos(Bound_Stk), 0];
                nrmA   = [sin(Bound_Dip)*cos(Bound_Stk), -sin(Bound_Dip)*sin(Bound_Stk), cos(Bound_Dip)];
                dipA   = cross(nrmA,stkA);
                RotMat = [stkA;dipA;nrmA];
                RotMatT= RotMat';
                %-------------------- 
                l_left  = [LL_stk;LL_dip;LL_nrm];           l_right = [LR_stk;LR_dip;LR_nrm];
                u_right = [UR_stk;UR_dip;UR_nrm];           u_left  = [UL_stk;UL_dip;UL_nrm];
                %-------------------- 
                TopPoints  = [u_left'; u_right'];           BotPoints  = [l_left'; l_right'];
                %-------------------- 
                TopLength  = sum(sqrt(sum(diff(TopPoints(:,1:3)).^2,2)));
                BotLength  = sum(sqrt(sum(diff(BotPoints(:,1:3)).^2,2)));
                ElemNumS   = floor(((TopLength+BotLength)/2)/app.ElemSizeBound_EditField.Value);
                %---------------------------------------------------------
                UsedSdeLgh = TopLength/ElemNumS;
                PntListTop = zeros(ElemNumS+1,3);
                PntListTop(1,:)   = TopPoints(1,:);
                PntListTop(end,:) = TopPoints(end,:);
                CurrLocn   = TopPoints(1,:);
                Counter    = 1;
                TrgtPos    = 2;
                UsedAmount = 0;
                %--------------------------
                while (Counter < ElemNumS)
                    TrgtLocn  = TopPoints(TrgtPos,:);
                    Vect2Next = TrgtLocn - CurrLocn;
                    VectLgth  = sqrt(sum(Vect2Next.^2));
                    if ((UsedSdeLgh-UsedAmount) <= (VectLgth))
                        CurrLocn = CurrLocn + ((UsedSdeLgh-UsedAmount)/VectLgth).*Vect2Next;
                        Counter  = Counter  +1;
                        PntListTop(Counter,:) = CurrLocn;
                        UsedAmount = 0;
                    else
                        CurrLocn   = TopPoints(TrgtPos,:);
                        TrgtPos    = TrgtPos +1;
                        UsedAmount = UsedAmount + VectLgth;
                    end
                end
                %--------------------------
                UsedSdeLgh = BotLength/ElemNumS;
                PntListBot = zeros(ElemNumS+1,3);
                PntListBot(1,:)   = BotPoints(1,:);
                PntListBot(end,:) = BotPoints(end,:);
                CurrLocn   = BotPoints(1,:);
                Counter    = 1;
                TrgtPos    = 2;
                UsedAmount = 0;
                %--------------------------
                while (Counter < ElemNumS)
                    TrgtLocn  = BotPoints(TrgtPos,:);
                    Vect2Next = TrgtLocn - CurrLocn;
                    VectLgth  = sqrt(sum(Vect2Next.^2));
                    if ((UsedSdeLgh-UsedAmount) <= (VectLgth))
                        CurrLocn = CurrLocn + ((UsedSdeLgh-UsedAmount)/VectLgth).*Vect2Next;
                        Counter  = Counter  +1;
                        PntListBot(Counter,:) = CurrLocn;
                        UsedAmount = 0;
                    else
                        CurrLocn   = BotPoints(TrgtPos,:);
                        TrgtPos    = TrgtPos +1;
                        UsedAmount = UsedAmount + VectLgth;
                    end
                end
                %---------------------------------------------------------
                VectLgths   = sqrt( sum((PntListBot - PntListTop).^2,2));
                ElemNumD    = floor(mean(VectLgths)/app.ElemSizeBound_EditField.Value);
                
                IniPointMat = zeros(ElemNumS,ElemNumD,4);
                IDCount     = 0;
                %----------------------------------------------
                for i = 1 : (ElemNumS+1)
                    IDCount             = IDCount+1;
                    IniPointMat(i,1,1:3)= PntListTop(i,1:3);
                    IniPointMat(i,1,4)  = IDCount;
                    CurrLocn            = PntListTop(i,1:3);
                    EndLocn             = PntListBot(i,1:3);
    
                    NextVect   = EndLocn- CurrLocn;
                    VectLgth   = sqrt(sum(NextVect.^2));
                    UsedSdeLgh = VectLgth/ElemNumD;
                    Counter    = 1;
                    %--------------------------
                    while (Counter < ElemNumD)
                        IDCount   = IDCount+1;
                        Counter   = Counter+1;
                        Vect2Next = EndLocn - CurrLocn;
                        VectLgth  = sqrt(sum(Vect2Next.^2));
                        CurrLocn  = CurrLocn + (UsedSdeLgh/VectLgth).*Vect2Next;
                       
                        IniPointMat(i,Counter,1:3) = CurrLocn;
                        IniPointMat(i,Counter,4)   = IDCount;
                    end
                    %--------------------------
                    IDCount   = IDCount+1;
                    Counter   = Counter+1;
                    IniPointMat(i,Counter,1:3) = EndLocn;
                    IniPointMat(i,Counter,4)   = IDCount;
                end
                %----------------------------------------------
                VertexList = zeros(((ElemNumS+1)*(ElemNumD+1) + (ElemNumS*ElemNumD)), 3);
                TrigListe  = zeros(ElemNumS*ElemNumD*4, 10);
                VertCount  = 0;
                TrigCount  = 0;
                %----------------------------------------------
                for i = 1 : (ElemNumS+1)
                    for j = 1 : (ElemNumD+1)
                        VertCount = VertCount +1;
                        VertexList(VertCount,:) = squeeze(IniPointMat(i,j,1:3));
                    end
                end
                %----------------------------------------------
                for i = 1 : ElemNumS
                    for j = 1 : ElemNumD
                        %----------------------------------------------
                        StkRate = NaN;      DipRate = NaN;      NrmRate = NaN;
                        %----------------------------------------------
                        if (app.BoundaryValues(SelBound,8) == 1) %top/bottom 
                            if     (j == 1)          
                                if (~isnan(app.BoundaryValues(SelBound,9))),     StkRate = app.BoundaryValues(SelBound,9);      end     
                                if (~isnan(app.BoundaryValues(SelBound,10))),    DipRate = app.BoundaryValues(SelBound,10);     end 
                                if (~isnan(app.BoundaryValues(SelBound,11))),    NrmRate = app.BoundaryValues(SelBound,11);     end 
                            elseif (j == ElemNumD)  
                                if (~isnan(app.BoundaryValues(SelBound,12))),    StkRate = app.BoundaryValues(SelBound,12);     end     
                                if (~isnan(app.BoundaryValues(SelBound,13))),    DipRate = app.BoundaryValues(SelBound,13);     end 
                                if (~isnan(app.BoundaryValues(SelBound,14))),    NrmRate = app.BoundaryValues(SelBound,14);     end  
                            end
                        else
                            if     (i == 1)        
                                if (~isnan(app.BoundaryValues(SelBound,9))),     StkRate = app.BoundaryValues(SelBound,9);      end     
                                if (~isnan(app.BoundaryValues(SelBound,10))),    DipRate = app.BoundaryValues(SelBound,10);     end 
                                if (~isnan(app.BoundaryValues(SelBound,11))),    NrmRate = app.BoundaryValues(SelBound,11);     end 
                            elseif (i == ElemNumS)  
                                if (~isnan(app.BoundaryValues(SelBound,12))),    StkRate = app.BoundaryValues(SelBound,12);     end     
                                if (~isnan(app.BoundaryValues(SelBound,13))),    DipRate = app.BoundaryValues(SelBound,13);     end 
                                if (~isnan(app.BoundaryValues(SelBound,14))),    NrmRate = app.BoundaryValues(SelBound,14);     end              
                            end
                        end
                        
                        %----------------------------------------------
                        VertCount = VertCount +1;
                        Vert_A = squeeze(IniPointMat(i,j,:));
                        Vert_B = squeeze(IniPointMat(i+1,j,:));
                        Vert_C = squeeze(IniPointMat(i,j+1,:));
                        Vert_D = squeeze(IniPointMat(i+1,j+1,:));
                        Vert_E = [(Vert_A(1:3,1)+Vert_B(1:3,1)+Vert_C(1:3,1)+Vert_D(1:3,1))./4; VertCount];
                        %-------------------------------
                        VertexList(VertCount,:) = Vert_E(1:3,1);
                        %-------------------------------
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_A(1:3,1), Vert_B(1:3,1), Vert_E(1:3,1));
                        TrigListe(TrigCount,:) = [Vert_A(4,1), Vert_B(4,1), Vert_E(4,1), SelBound, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1), StkRate, DipRate, NrmRate];
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_B(1:3,1), Vert_D(1:3,1), Vert_E(1:3,1));
                        TrigListe(TrigCount,:) = [Vert_B(4,1), Vert_D(4,1), Vert_E(4,1), SelBound, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1), StkRate, DipRate, NrmRate];
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_D(1:3,1), Vert_C(1:3,1), Vert_E(1:3,1));
                        TrigListe(TrigCount,:) = [Vert_D(4,1), Vert_C(4,1), Vert_E(4,1), SelBound, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1), StkRate, DipRate, NrmRate];
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_C(1:3,1), Vert_A(1:3,1), Vert_E(1:3,1));
                        TrigListe(TrigCount,:) = [Vert_C(4,1), Vert_A(4,1), Vert_E(4,1), SelBound, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1), StkRate, DipRate, NrmRate];
                    end
                end
                %----------------------------------------------
                %----------------------------------------------
                SelBounds = ~isnan(TrigListe(:,8));
                if (sum(SelBounds(:) > 0))
                    SelCentE       = TrigListe(SelBounds,5);         SelCentN  = TrigListe(SelBounds,6);         SelCentC  = TrigListe(SelBounds,8);
                    AllCentE       = TrigListe(:,5);                 AllCentN  = TrigListe(:,6);
                    F_stk          = scatteredInterpolant(SelCentE,SelCentN,SelCentC,'natural');
                    TrigListe(:,8) = F_stk(AllCentE,AllCentN);
                else
                    TrigListe(:,8) = 0;
                end
                %----------------------------------------------
                SelBounds = ~isnan(TrigListe(:,9));
                if (sum(SelBounds(:) > 0))
                    SelCentE       = TrigListe(SelBounds,5);         SelCentN  = TrigListe(SelBounds,6);         SelCentC  = TrigListe(SelBounds,9);
                    AllCentE       = TrigListe(:,5);                 AllCentN  = TrigListe(:,6);
                    F_stk          = scatteredInterpolant(SelCentE,SelCentN,SelCentC,'natural');
                    TrigListe(:,9) = F_stk(AllCentE,AllCentN);
                else
                    TrigListe(:,9) = 0;
                end
                %----------------------------------------------
                SelBounds = ~isnan(TrigListe(:,10));
                if (sum(SelBounds(:) > 0))
                    SelCentE       = TrigListe(SelBounds,5);         SelCentN  = TrigListe(SelBounds,6);         SelCentC  = TrigListe(SelBounds,10);
                    AllCentE       = TrigListe(:,5);                 AllCentN  = TrigListe(:,6);
                    F_stk          = scatteredInterpolant(SelCentE,SelCentN,SelCentC,'natural');
                    TrigListe(:,10)= F_stk(AllCentE,AllCentN);
                else
                    TrigListe(:,10) = 0;
                end
                %----------------------------------------------
                %----------------------------------------------
                VertexListNew = RotMatT*VertexList' +[BoundCent_E;BoundCent_N;BoundCent_Z];
                %----------------------------------------------
                for i = 1 : size(TrigListe,1)
                    Vert_A = VertexListNew(:,TrigListe(i,1));
                    Vert_B = VertexListNew(:,TrigListe(i,2));
                    Vert_C = VertexListNew(:,TrigListe(i,3));
                    InCentPos  = GetInCenter(app,Vert_A, Vert_B, Vert_C);
                    TrigListe(i,5) = InCentPos(1,1);
                    TrigListe(i,6) = InCentPos(2,1);
                    TrigListe(i,7) = InCentPos(3,1);
                end
                %----------------------------------------------
                %----------------------------------------------
                TrigListe(:,1:3)       = TrigListe(:,1:3) + size(app.Bound.VertexList,1);
                app.Bound.TriangleList = [app.Bound.TriangleList ; TrigListe];
                app.Bound.VertexList   = [app.Bound.VertexList; VertexListNew'];
                %----------------------------------------------
                %----------------------------------------------
            end
            %--------------------------
            RandListOrder    = [rand(size(app.Bound.TriangleList,1),1), cumsum(ones(size(app.Bound.TriangleList,1),1))];
            RandListOrder    = sortrows(RandListOrder);
            app.Bound.TriangleList = app.Bound.TriangleList(RandListOrder(:,2),:);
            %--------------------------
            app.ElemNumBound_EditField.Value = size(app.Bound.TriangleList,1);
            %---------------------------------------------
            if (size(app.Bound.TriangleList,1) > 0)
                RotAngle         = app.BoundRotateAllBy_EditField.Value;
                MeanENofBoundary = mean(app.Bound.VertexList(:,1:2));
                TempEN           = app.Bound.VertexList(:,1:2) - MeanENofBoundary;
                %----------------------------------------------
                app.Bound.VertexList(:,1) =  TempEN(:,1)*cosd(RotAngle) + TempEN(:,2)*sind(RotAngle) + MeanENofBoundary(1,1);
                app.Bound.VertexList(:,2) = -TempEN(:,1)*sind(RotAngle) + TempEN(:,2)*cosd(RotAngle) + MeanENofBoundary(1,2);
                %----------------------------------------------
                for i = 1 : size(app.Bound.TriangleList,1)
                    Vert_A = app.Bound.VertexList(app.Bound.TriangleList(i,1),:)';
                    Vert_B = app.Bound.VertexList(app.Bound.TriangleList(i,2),:)';
                    Vert_C = app.Bound.VertexList(app.Bound.TriangleList(i,3),:)';
                    InCentPos  = GetInCenter(app,Vert_A, Vert_B, Vert_C);
                    app.Bound.TriangleList(i,5) = InCentPos(1,1);
                    app.Bound.TriangleList(i,6) = InCentPos(2,1);
                    app.Bound.TriangleList(i,7) = InCentPos(3,1);
    
                    if (app.BoundaryValues(app.Bound.TriangleList(i,4),7) == 0)
                        CombStk     = (app.BoundaryValues(app.Bound.TriangleList(i,4),6) + RotAngle);
                        TempStkRate = app.Bound.TriangleList(i,8)*cosd(CombStk) + app.Bound.TriangleList(i,9)*sind(CombStk); 
                        TempDipRate =-app.Bound.TriangleList(i,8)*sind(CombStk) + app.Bound.TriangleList(i,9)*cosd(CombStk); 
                        app.Bound.TriangleList(i,8) = TempStkRate;
                        app.Bound.TriangleList(i,9) = TempDipRate;
                    end
                end
            end
            %----------------------------------------------    
        end
        %------------------------------------------------------------------
        %------------------------------------------------------------------
        function InCentPos  = GetInCenter(~, Vert_A,Vert_B, Vert_C)
            %https://www.geeksforgeeks.org/program-to-find-the-incenter-of-a-triangle/
            a = sqrt(sum((Vert_C-Vert_B).^2));
            b = sqrt(sum((Vert_A-Vert_C).^2));
            c = sqrt(sum((Vert_B-Vert_A).^2));

            InCentPos(1,1) = (a*Vert_A(1,1) +b*Vert_B(1,1) +c*Vert_C(1,1))/(a +b +c);
            InCentPos(2,1) = (a*Vert_A(2,1) +b*Vert_B(2,1) +c*Vert_C(2,1))/(a +b +c);
            InCentPos(3,1) = (a*Vert_A(3,1) +b*Vert_B(3,1) +c*Vert_C(3,1))/(a +b +c);
        end
        %------------------------------------------------------------------
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FaultName_EditField
        function FaultName_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end
            app.FaultNames{SelFaultPos} = app.FaultName_EditField.Value;
            app.Fault_ListBox.Items     = app.FaultNames;
            app.Fault_ListBox.ItemsData = 1:size(app.FaultNames,2);
            app.Fault_ListBox.Value     = 1;
            drawnow;
            %---------------------------------------
            if (size(app.BoundaryValues,1) >0)
                app.Move2_1stFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_1stFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_2ndFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_2ndFault_DropDown.Value     = 1;
            else
                app.Move2_1stFault_DropDown.Items     = app.FaultNames;
                app.Move2_1stFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = app.FaultNames;
                app.Move2_2ndFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_2ndFault_DropDown.Value     = 1;
            end
            %---------------------------------------  
        end

        % Value changed function: FaultEasting_EditField
        function FaultEasting_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end         
            app.FaultValues(SelFaultPos,1) = app.FaultEasting_EditField.Value;
        end

        % Value changed function: FaultNorthing_EditField
        function FaultNorthing_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end           
            app.FaultValues(SelFaultPos,2) = app.FaultNorthing_EditField.Value;          
        end

        % Value changed function: FaultDepth_EditField
        function FaultDepth_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end         
            app.FaultValues(SelFaultPos,3) = app.FaultDepth_EditField.Value;   
        end

        % Value changed function: FaultLength_EditField
        function FaultLength_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end           
            app.FaultValues(SelFaultPos,4) = app.FaultLength_EditField.Value;               
        end

        % Value changed function: FaultWidth_EditField
        function FaultWidth_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end           
            app.FaultValues(SelFaultPos,5) = app.FaultWidth_EditField.Value;              
        end

        % Value changed function: FaultStrike_EditField
        function FaultStrike_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end           
            app.FaultValues(SelFaultPos,6) = app.FaultStrike_EditField.Value;              
        end

        % Value changed function: FaultDip_EditField
        function FaultDip_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end         
            app.FaultValues(SelFaultPos,7) = app.FaultDip_EditField.Value;             
        end

        % Value changed function: FaultID_EditField
        function FaultID_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end         
            app.FaultValues(SelFaultPos,20) = app.FaultID_EditField.Value; 
        end

        % Value changed function: UsefaultsliprateCheckBox
        function UsefaultsliprateCheckBoxValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end         
            app.UsefaultsliprateCheckBox.Value  = 1;
            app.UseregionalstressCheckBox.Value = 0;
            app.FaultValues(SelFaultPos,21)     = 1;  
        end

        % Value changed function: UseregionalstressCheckBox
        function UseregionalstressCheckBoxValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end         
            app.UsefaultsliprateCheckBox.Value  = 0;
            app.UseregionalstressCheckBox.Value = 1;
            app.FaultValues(SelFaultPos,21)     = 0;  
        end

        % Value changed function: FaultSlipRate_EditField
        function FaultSlipRate_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end          
            app.FaultValues(SelFaultPos,22) = app.FaultSlipRate_EditField.Value;             
        end

        % Value changed function: FaultSlipRake_EditField
        function FaultSlipRake_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end       
            app.FaultValues(SelFaultPos,23) = app.FaultSlipRake_EditField.Value;            
        end

        % Value changed function: FaultStressRate_EditField
        function FaultStressRate_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end        
            app.FaultValues(SelFaultPos,24) = app.FaultStressRate_EditField.Value;       
        end

        % Value changed function: FaultStressDirection_EditField
        function FaultStressDirection_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end        
            app.FaultValues(SelFaultPos,25) = app.FaultStressDirection_EditField.Value;       
        end

        % Value changed function: FaultVertexPos_DropDown
        function FaultVertexPos_DropDownValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end
            value        = str2double(app.FaultVertexPos_DropDown.Value);
            app.FaultVertexPos_Strike_EditField.Value = app.FaultValues(SelFaultPos,(8 +(value-1)*3));
            app.FaultVertexPos_Dip_EditField.Value    = app.FaultValues(SelFaultPos,(9 +(value-1)*3));
            app.FaultVertexPos_Norm_EditField.Value   = app.FaultValues(SelFaultPos,(10+(value-1)*3));
        end

        % Value changed function: FaultVertexPos_Strike_EditField
        function FaultVertexPos_Strike_EditFieldValueChanged(app, event)
            value        = str2double(app.FaultVertexPos_DropDown.Value);
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end         
            app.FaultValues(SelFaultPos,(8 +(value-1)*3)) = app.FaultVertexPos_Strike_EditField.Value;
        end

        % Value changed function: FaultVertexPos_Dip_EditField
        function FaultVertexPos_Dip_EditFieldValueChanged(app, event)
            value        = str2double(app.FaultVertexPos_DropDown.Value);
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end
            app.FaultValues(SelFaultPos,(9 +(value-1)*3)) = app.FaultVertexPos_Dip_EditField.Value;            
        end

        % Value changed function: FaultVertexPos_Norm_EditField
        function FaultVertexPos_Norm_EditFieldValueChanged(app, event)
            value        = str2double(app.FaultVertexPos_DropDown.Value);
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end
            app.FaultValues(SelFaultPos,(10 +(value-1)*3)) = app.FaultVertexPos_Norm_EditField.Value;     
        end

        % Value changed function: BoundName_EditField
        function BoundName_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            app.BoundaryNames(SelBoundPos) = {app.BoundName_EditField.Value};
            app.Bound_ListBox.Items        = app.BoundaryNames;
            app.Bound_ListBox.ItemsData    = 1:size(app.BoundaryNames,2);
            app.Bound_ListBox.Value        = 1;     
            drawnow;
            %---------------------------------------
            if (size(app.BoundaryValues,1) >0)
                app.Move2_1stFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_1stFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_2ndFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_2ndFault_DropDown.Value     = 1;
            else
                app.Move2_1stFault_DropDown.Items     = app.FaultNames;
                app.Move2_1stFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = app.FaultNames;
                app.Move2_2ndFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_2ndFault_DropDown.Value     = 1;
            end
            %---------------------------------------  
        end

        % Value changed function: BoundEasting_EditField
        function BoundEasting_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end          
            app.BoundaryValues(SelBoundPos,1) = app.BoundEasting_EditField.Value;
        end

        % Value changed function: BoundNorthing_EditField
        function BoundNorthing_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end           
            app.BoundaryValues(SelBoundPos,2) = app.BoundNorthing_EditField.Value;
        end

        % Value changed function: BoundDepth_EditField
        function BoundDepth_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end           
            app.BoundaryValues(SelBoundPos,3) = app.BoundDepth_EditField.Value;   
        end

        % Value changed function: BoundLength_EditField
        function BoundLength_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end         
            app.BoundaryValues(SelBoundPos,4) = app.BoundLength_EditField.Value;  
        end

        % Value changed function: BoundWidth_EditField
        function BoundWidth_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end          
            app.BoundaryValues(SelBoundPos,5) = app.BoundWidth_EditField.Value;   
        end

        % Value changed function: BoundStrike_EditField
        function BoundStrike_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end          
            app.BoundaryValues(SelBoundPos,6) = app.BoundStrike_EditField.Value;  
        end

        % Value changed function: BoundDip_EditField
        function BoundDip_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end         
            app.BoundaryValues(SelBoundPos,7) = app.BoundDip_EditField.Value;  
        end

        % Value changed function: BoundApplyRateAt_DropDown
        function BoundApplyRateAt_DropDownValueChanged(app, event)
            value = str2double(app.BoundApplyRateAt_DropDown.Value);
            %------------------------------------        
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            %------------------------------------ 
            app.BoundaryValues(SelBoundPos,8) = value;
        end

        % Value changed function: BoundStrikeratemmyrEditField
        function BoundStrikeratemmyrEditFieldValueChanged(app, event)
            value = str2num(app.BoundStrikeratemmyrEditField.Value);
            if (isempty(value)),            return;                         end
            if (size(value,2) == 1),        value = [value, value];         end
            if (isnan(sum(value))),         value = [NaN, NaN];             end
            %------------------------------------        
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            %------------------------------------  
            app.BoundaryValues(SelBoundPos,9) = value(1,1);
            app.BoundaryValues(SelBoundPos,12)= value(1,2);
        end

        % Value changed function: BoundDipratemmyrEditField
        function BoundDipratemmyrEditFieldValueChanged(app, event)
            value = str2num(app.BoundDipratemmyrEditField.Value);
            if (isempty(value)),            return;                         end
            if (size(value,2) == 1),        value = [value, value];         end
            if (isnan(sum(value))),         value = [NaN, NaN];             end
            %------------------------------------        
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            %------------------------------------ 
            app.BoundaryValues(SelBoundPos,10)= value(1,1);
            app.BoundaryValues(SelBoundPos,13)= value(1,2);
        end

        % Value changed function: Fault_ListBox
        function Fault_ListBoxValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end
            SelFaultName = app.FaultNames{SelFaultPos};
            SelVertex    = str2double(app.FaultVertexPos_DropDown.Value);
            
            app.FaultName_EditField.Value       = SelFaultName;
            app.FaultEasting_EditField.Value    = app.FaultValues(SelFaultPos,1);
            app.FaultNorthing_EditField.Value   = app.FaultValues(SelFaultPos,2);
            app.FaultDepth_EditField.Value      = app.FaultValues(SelFaultPos,3);
            app.FaultLength_EditField.Value     = app.FaultValues(SelFaultPos,4);
            app.FaultWidth_EditField.Value      = app.FaultValues(SelFaultPos,5);
            app.FaultStrike_EditField.Value     = app.FaultValues(SelFaultPos,6);
            app.FaultDip_EditField.Value        = app.FaultValues(SelFaultPos,7);            
            
            app.FaultVertexPos_Strike_EditField.Value = app.FaultValues(SelFaultPos,8+(SelVertex-1)*3);
            app.FaultVertexPos_Dip_EditField.Value    = app.FaultValues(SelFaultPos,9+(SelVertex-1)*3);
            app.FaultVertexPos_Norm_EditField.Value   = app.FaultValues(SelFaultPos,10+(SelVertex-1)*3);
            
            app.FaultID_EditField.Value         = app.FaultValues(SelFaultPos,20);
            if (app.FaultValues(SelFaultPos,21) == 1)
                app.UsefaultsliprateCheckBox.Value  = 1;
                app.UseregionalstressCheckBox.Value = 0;
            else
                app.UsefaultsliprateCheckBox.Value  = 0;
                app.UseregionalstressCheckBox.Value = 1;
            end
            app.FaultSlipRate_EditField.Value        = app.FaultValues(SelFaultPos,22);
            app.FaultSlipRake_EditField.Value        = app.FaultValues(SelFaultPos,23);  
            app.FaultStressRate_EditField.Value      = app.FaultValues(SelFaultPos,24);  
            app.FaultStressDirection_EditField.Value = app.FaultValues(SelFaultPos,25);  
        end

        % Value changed function: Bound_ListBox
        function Bound_ListBoxValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
    
            SelBoundName = app.BoundaryNames{SelBoundPos};

            app.BoundName_EditField.Value       = SelBoundName;
            app.BoundEasting_EditField.Value    = app.BoundaryValues(SelBoundPos,1);
            app.BoundNorthing_EditField.Value   = app.BoundaryValues(SelBoundPos,2);
            app.BoundDepth_EditField.Value      = app.BoundaryValues(SelBoundPos,3);
            app.BoundLength_EditField.Value     = app.BoundaryValues(SelBoundPos,4);
            app.BoundWidth_EditField.Value      = app.BoundaryValues(SelBoundPos,5);
            app.BoundStrike_EditField.Value     = app.BoundaryValues(SelBoundPos,6);
            app.BoundDip_EditField.Value        = app.BoundaryValues(SelBoundPos,7);   
            app.BoundApplyRateAt_DropDown.Value = app.BoundApplyRateAt_DropDown.ItemsData(app.BoundaryValues(SelBoundPos,8));
            %-------------------------------------------------- 
            app.BoundStrikeratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(SelBoundPos, 9)),"  ",num2str(app.BoundaryValues(SelBoundPos,12)));
            app.BoundDipratemmyrEditField.Value    = strcat(num2str(app.BoundaryValues(SelBoundPos,10)),"  ",num2str(app.BoundaryValues(SelBoundPos,13)));
            app.BoundNormalratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(SelBoundPos,11)),"  ",num2str(app.BoundaryValues(SelBoundPos,14)));

        end

        % Button pushed function: PlotFaultSetup_Button
        function PlotFaultSetup_ButtonPushed(app, event)
            
            FigNum      = app.FigNumFaultModel_EditField.Value;
            ViewAngle   = app.ViewAngle_EditField.Value;        ViewAngle = str2num(ViewAngle);
            Faults2Plot = size(app.FaultValues,1);
            Bounds2Plot = size(app.BoundaryValues,1);

            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);         end
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);         end

            SelVertex1 = app.Move2_1stCorner_DropDown.Value;
            if (~isa(SelVertex1,'double')),      SelVertex1 = str2double(SelVertex1);           end
            SelVertex2 = app.Move2_2ndCorner_DropDown.Value;
            if (~isa(SelVertex2,'double')),      SelVertex2 = str2double(SelVertex2);           end
            SelFault1  = app.Move2_1stFault_DropDown.Value;
            if (~isa(SelFault1,'double')),       SelFault1   = str2double(SelFault1);           end
            SelFault2  = app.Move2_2ndFault_DropDown.Value;
            if (~isa(SelFault2,'double')),       SelFault2 = str2double(SelFault2);             end
            
            if     (SelVertex1 == 1), UseCol1 = 'Red';
            elseif (SelVertex1 == 2), UseCol1 = 'Green';
            elseif (SelVertex1 == 3), UseCol1 = 'Blue';
            elseif (SelVertex1 == 4), UseCol1 = 'Yellow';
            end
            if     (SelVertex2 == 1), UseCol2 = 'Red';
            elseif (SelVertex2 == 2), UseCol2 = 'Green';
            elseif (SelVertex2 == 3), UseCol2 = 'Blue';
            elseif (SelVertex2 == 4), UseCol2 = 'Yellow';
            end
            %  8        9       10       11      12      13     14     15       16      17     18      19 => this is the shift value!.
            %LL_stk, LL_dip, LL_nrm, LR_stk, LR_dip, LR_nrm,UR_stk, UR_dip, UR_nrm, UL_stk, UL_dip, UL_nrm
            figure(FigNum); clf; hold on; axis equal;
            xlabel('easting');ylabel('northing'); zlabel('depth');
            view(ViewAngle(1,1),ViewAngle(1,2));
            %-------------------- %-------------------- %-------------------- 
            for i = 1 : Faults2Plot
                Ecent  = app.FaultValues(i,1);
                Ncent  = app.FaultValues(i,2);
                Zcent  = app.FaultValues(i,3);
                Length = app.FaultValues(i,4);
                Width  = app.FaultValues(i,5);
                Strike = app.FaultValues(i,6).*pi./180;
                Dip    = app.FaultValues(i,7).*pi./180;
                
                UL_stk = -Length/2 +app.FaultValues(i,17);      UR_stk =  Length/2 +app.FaultValues(i,14);
                LL_stk = -Length/2 +app.FaultValues(i,8);       LR_stk =  Length/2 +app.FaultValues(i,11);
                UL_dip = 0         +app.FaultValues(i,18);      UR_dip =  0        +app.FaultValues(i,15);
                LL_dip = -Width    +app.FaultValues(i,9);       LR_dip =  -Width   +app.FaultValues(i,12);
                UL_nrm = 0         +app.FaultValues(i,19);      UR_nrm =  0        +app.FaultValues(i,16);
                LL_nrm = 0         +app.FaultValues(i,10);      LR_nrm =  0        +app.FaultValues(i,13);
                     
                stkA   = [sin(Strike), cos(Strike), 0];
                nrmA   = [sin(Dip)*cos(Strike), -sin(Dip)*sin(Strike), cos(Dip)];
                dipA   = cross(nrmA,stkA);
                
                RotMat = [stkA;dipA;nrmA];
                RotMatT= RotMat';
                %-------------------- 
                l_left  = RotMatT*[LL_stk;LL_dip;LL_nrm] +[Ecent;Ncent;Zcent];
                l_right = RotMatT*[LR_stk;LR_dip;LR_nrm] +[Ecent;Ncent;Zcent];
                u_right = RotMatT*[UR_stk;UR_dip;UR_nrm] +[Ecent;Ncent;Zcent];
                u_left  = RotMatT*[UL_stk;UL_dip;UL_nrm] +[Ecent;Ncent;Zcent];
                
                the_corner = [l_left'; l_right'; u_right'; u_left'];   
                %-------------------- 
                R       = rand(1);
                G       = rand(1);
                B       = rand(1);
                transp  = 0.5;   
                
                fill3(the_corner(:,1),the_corner(:,2),the_corner(:,3),[R G B],'FaceAlpha',transp,'EdgeColor','k');
                
                if (i == SelFault1)
                    fill3(the_corner(:,1),the_corner(:,2),the_corner(:,3),[R G B],'FaceAlpha',transp,'EdgeColor','b','LineWidth',2);
                    plot3(the_corner(SelVertex1,1),the_corner(SelVertex1,2),the_corner(SelVertex1,3),'o','MarkerFaceColor',UseCol1,'MarkerSize',10,'MarkerEdgeColor','black');
                end
                if (i == SelFault2)
                    fill3(the_corner(:,1),the_corner(:,2),the_corner(:,3),[R G B],'FaceAlpha',transp,'EdgeColor','g','LineWidth',2);
                    plot3(the_corner(SelVertex2,1),the_corner(SelVertex2,2),the_corner(SelVertex2,3),'square','MarkerFaceColor',UseCol2,'MarkerSize',10,'MarkerEdgeColor','black');
                end
                
                if (i == SelFaultPos)
                    fill3(the_corner(:,1),the_corner(:,2),the_corner(:,3),[R G B],'FaceAlpha',transp,'EdgeColor','r','LineWidth',2);
                end
            end
            %-------------------- %-------------------- %--------------------      
            for i = 1 : Bounds2Plot
                Ecent  = app.BoundaryValues(i,1);
                Ncent  = app.BoundaryValues(i,2);
                Zcent  = app.BoundaryValues(i,3);
                Length = app.BoundaryValues(i,4);
                Width  = app.BoundaryValues(i,5);
                Strike = app.BoundaryValues(i,6).*pi./180;
                Dip    = app.BoundaryValues(i,7).*pi./180;
                
                UL_stk = -Length/2 ;        UR_stk =  Length/2;
                LL_stk = -Length/2 ;        LR_stk =  Length/2;
                UL_dip = 0;                 UR_dip =  0;
                LL_dip = -Width;            LR_dip =  -Width;
                UL_nrm = 0;                 UR_nrm =  0;
                LL_nrm = 0;                 LR_nrm =  0;
                     
                stkA   = [sin(Strike), cos(Strike), 0];
                nrmA   = [sin(Dip)*cos(Strike), -sin(Dip)*sin(Strike), cos(Dip)];
                dipA   = cross(nrmA,stkA);
                
                RotMat = [stkA;dipA;nrmA];
                RotMatT= RotMat';
                %-------------------- 
                l_left  = RotMatT*[LL_stk;LL_dip;LL_nrm] +[Ecent;Ncent;Zcent];
                l_right = RotMatT*[LR_stk;LR_dip;LR_nrm] +[Ecent;Ncent;Zcent];
                u_right = RotMatT*[UR_stk;UR_dip;UR_nrm] +[Ecent;Ncent;Zcent];
                u_left  = RotMatT*[UL_stk;UL_dip;UL_nrm] +[Ecent;Ncent;Zcent];
                
                the_corner = [l_left'; l_right'; u_right'; u_left'];   
                %-------------------- 
                R       = 0.5;              G       = 0.5;
                B       = 0.5;              transp  = 0.5;   
                
                fill3(the_corner(:,1),the_corner(:,2),the_corner(:,3),[R G B],'FaceAlpha',transp,'EdgeColor','k');

                if ((SelFault1>Faults2Plot) && (i+Faults2Plot == SelFault1))
                    fill3(the_corner(:,1),the_corner(:,2),the_corner(:,3),[R G B],'FaceAlpha',transp,'EdgeColor','b','LineWidth',2);
                    plot3(the_corner(SelVertex1,1),the_corner(SelVertex1,2),the_corner(SelVertex1,3),'o','MarkerFaceColor','blue','MarkerSize',10,'MarkerEdgeColor','black');
                end
                if ((SelFault2>Faults2Plot) && (i+Faults2Plot == SelFault2))
                    fill3(the_corner(:,1),the_corner(:,2),the_corner(:,3),[R G B],'FaceAlpha',transp,'EdgeColor','g','LineWidth',2);
                    plot3(the_corner(SelVertex2,1),the_corner(SelVertex2,2),the_corner(SelVertex2,3),'square','MarkerFaceColor','green','MarkerSize',10,'MarkerEdgeColor','black');
                end
                
                if (i == SelBoundPos)
                    fill3(the_corner(:,1),the_corner(:,2),the_corner(:,3),[R G B],'FaceAlpha',transp,'EdgeColor','r','LineWidth',2);
                end
            end
            %-------------------- %-------------------- %-------------------- 
        end

        % Button pushed function: AddFault_Button
        function AddFault_ButtonPushed(app, event)

            s = 'ABCDEFGHIJKLMNOPQRSTUVWXYZabcdefghijklmnopqrstuvwxyz0123456789';
            sLength      = 3;
            RandomName   = s(randi([1, 62],1,sLength));
            NewFltName   = strcat('Fault_',RandomName);
            FaultsInList                        = size(app.FaultNames,2);
            app.FaultNames(FaultsInList+1)      = {NewFltName};
            %---------------------------------------
            NewFault                            = [  rand(1), rand(1), -rand(1), (rand(1)*10+5), (rand(1)*10+5), (rand(1)*360), (rand(1)*90),zeros(1,12),(FaultsInList+1),1, 0, 0, 0, 0];
            app.FaultValues                     = [app.FaultValues; NewFault];
            %---------------------------------------
            app.FaultName_EditField.Value       = NewFltName;
            app.FaultEasting_EditField.Value    = app.FaultValues(end,1);
            app.FaultNorthing_EditField.Value   = app.FaultValues(end,2);
            app.FaultDepth_EditField.Value      = app.FaultValues(end,3);
            app.FaultLength_EditField.Value     = app.FaultValues(end,4);
            app.FaultWidth_EditField.Value      = app.FaultValues(end,5);
            app.FaultStrike_EditField.Value     = app.FaultValues(end,6);
            app.FaultDip_EditField.Value        = app.FaultValues(end,7);
            app.FaultID_EditField.Value         = app.FaultValues(end,20);
            %---------------------------------------
            if (app.FaultValues(end,21) == 1)
                app.UsefaultsliprateCheckBox.Value  = 1;
                app.UseregionalstressCheckBox.Value = 0;
            else
                app.UsefaultsliprateCheckBox.Value  = 0;
                app.UseregionalstressCheckBox.Value = 1;
            end
            app.FaultSlipRate_EditField.Value        = app.FaultValues(end,22);
            app.FaultSlipRake_EditField.Value        = app.FaultValues(end,23);
            app.FaultStressRate_EditField.Value      = app.FaultValues(end,24);  
            app.FaultStressDirection_EditField.Value = app.FaultValues(end,25);  
            %---------------------------------------
            app.Fault_ListBox.Items     = app.FaultNames;
            app.Fault_ListBox.ItemsData = 1:size(app.FaultNames,2);
            app.Fault_ListBox.Value     = size(app.FaultNames,2);
            %---------------------------------------
            if (size(app.BoundaryValues,1) >0)
                app.Move2_1stFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_1stFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_2ndFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_2ndFault_DropDown.Value     = 1;
            else
                app.Move2_1stFault_DropDown.Items     = app.FaultNames;
                app.Move2_1stFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = app.FaultNames;
                app.Move2_2ndFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_2ndFault_DropDown.Value     = 1;
            end
            %---------------------------------------        
        end

        % Button pushed function: DeleteFault_Button
        function DeleteFault_ButtonPushed(app, event)

            if (size(app.FaultNames,2) <= 1),           return;                                     end
            SelFaultPos  = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);             end
            SelVertex    = str2double(app.FaultVertexPos_DropDown.Value);
            
            app.FaultNames(SelFaultPos)         = [];
            app.FaultValues(SelFaultPos,:)      = [];
    
            app.FaultName_EditField.Value       = app.FaultNames{1};
            app.FaultNorthing_EditField.Value   = app.FaultValues(1,1);
            app.FaultEasting_EditField.Value    = app.FaultValues(1,2);
            app.FaultDepth_EditField.Value      = app.FaultValues(1,3);
            app.FaultLength_EditField.Value     = app.FaultValues(1,4);
            app.FaultWidth_EditField.Value      = app.FaultValues(1,5);
            app.FaultStrike_EditField.Value     = app.FaultValues(1,6);
            app.FaultDip_EditField.Value        = app.FaultValues(1,7);            
            
            app.FaultVertexPos_Strike_EditField.Value = app.FaultValues(1,8+(SelVertex-1)*3);
            app.FaultVertexPos_Dip_EditField.Value    = app.FaultValues(1,9+(SelVertex-1)*3);
            app.FaultVertexPos_Norm_EditField.Value   = app.FaultValues(1,10+(SelVertex-1)*3);
            app.FaultID_EditField.Value               = app.FaultValues(1,20);

            if (app.FaultValues(1,21) == 1)
                app.UsefaultsliprateCheckBox.Value  = 1;
                app.UseregionalstressCheckBox.Value = 0;
            else
                app.UsefaultsliprateCheckBox.Value  = 0;
                app.UseregionalstressCheckBox.Value = 1;
            end
            app.FaultSlipRate_EditField.Value        = app.FaultValues(1,22);
            app.FaultSlipRake_EditField.Value        = app.FaultValues(1,23);
            app.FaultStressRate_EditField.Value      = app.FaultValues(1,24);  
            app.FaultStressDirection_EditField.Value = app.FaultValues(1,25);  
            %---------------------------------------
            app.Fault_ListBox.Items     = app.FaultNames;
            app.Fault_ListBox.ItemsData = 1:size(app.FaultNames,2);
            app.Fault_ListBox.Value     = size(app.FaultNames,2);
            %---------------------------------------
            if (size(app.BoundaryValues,1) >0)
                app.Move2_1stFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_1stFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_2ndFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_2ndFault_DropDown.Value     = 1;
            else
                app.Move2_1stFault_DropDown.Items     = app.FaultNames;
                app.Move2_1stFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = app.FaultNames;
                app.Move2_2ndFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_2ndFault_DropDown.Value     = 1;
            end
            %--------------------------------------- 
        end

        % Button pushed function: AddBound_Button
        function AddBound_ButtonPushed(app, event)
            
            s = 'ABCDEFGHIJKLMNOPQRSTUVWXYZabcdefghijklmnopqrstuvwxyz0123456789';
            sLength      = 3;
            RandomName   = s(randi([1, 62],1,sLength));
            NewBndName   = strcat('Boundary_',RandomName);
            BoundaryInList                      = size(app.BoundaryValues,1);
            app.BoundaryNames(BoundaryInList+1) = {NewBndName};
            %---------------------------------------
            NewBoundary                         = [  rand(1), rand(1), -rand(1), (rand(1)*10+5), (rand(1)*10+5), (rand(1)*360), (rand(1)*90),   1, NaN, NaN, NaN,      NaN, NaN, NaN];
            app.BoundaryValues                  = [app.BoundaryValues; NewBoundary];
            %---------------------------------------
            app.BoundName_EditField.Value       = NewBndName;
            app.BoundEasting_EditField.Value    = app.BoundaryValues(end,1);
            app.BoundNorthing_EditField.Value   = app.BoundaryValues(end,2);
            app.BoundDepth_EditField.Value      = app.BoundaryValues(end,3);
            app.BoundLength_EditField.Value     = app.BoundaryValues(end,4);
            app.BoundWidth_EditField.Value      = app.BoundaryValues(end,5);
            app.BoundStrike_EditField.Value     = app.BoundaryValues(end,6);
            app.BoundDip_EditField.Value        = app.BoundaryValues(end,7);
            app.BoundApplyRateAt_DropDown.Value = app.BoundApplyRateAt_DropDown.ItemsData(app.BoundaryValues(end,8));
            app.BoundStrikeratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(end, 9)),"  ",num2str(app.BoundaryValues(end,12)));
            app.BoundDipratemmyrEditField.Value    = strcat(num2str(app.BoundaryValues(end,10)),"  ",num2str(app.BoundaryValues(end,13)));
            app.BoundNormalratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(end,11)),"  ",num2str(app.BoundaryValues(end,14)));
            %---------------------------------------
            app.Bound_ListBox.Items     = app.BoundaryNames;
            app.Bound_ListBox.ItemsData = 1:size(app.BoundaryNames,2);
            app.Bound_ListBox.Value     = size(app.BoundaryNames,2);
            %---------------------------------------
            if (size(app.BoundaryValues,1) >0)
                app.Move2_1stFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_1stFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_2ndFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_2ndFault_DropDown.Value     = 1;
            else
                app.Move2_1stFault_DropDown.Items     = app.FaultNames;
                app.Move2_1stFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = app.FaultNames;
                app.Move2_2ndFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_2ndFault_DropDown.Value     = 1;
            end
            %---------------------------------------     
        end

        % Button pushed function: DeleteBound_Button
        function DeleteBound_ButtonPushed(app, event)
            
            if (size(app.BoundaryValues,1) < 1),           return;                                     end
            SelBoundPos  = app.Bound_ListBox.Value;   
            if (~isa(SelBoundPos,'double')),        SelBoundPos = str2double(SelBoundPos);             end
       
            app.BoundaryNames(SelBoundPos)         = [];
            app.BoundaryValues(SelBoundPos,:)      = [];
            
            if (size(app.BoundaryValues,1) < 1)   
                app.BoundaryNames                   = {'No Boundary used'};              
                app.BoundName_EditField.Value       = 'No Boundary used';
                app.BoundNorthing_EditField.Value   = 0;
                app.BoundEasting_EditField.Value    = 0;
                app.BoundDepth_EditField.Value      = 0;
                app.BoundLength_EditField.Value     = 0;
                app.BoundWidth_EditField.Value      = 0;
                app.BoundStrike_EditField.Value     = 0;
                app.BoundDip_EditField.Value        = 0;
                app.BoundStrikeratemmyrEditField.Value = 'NaN NaN';
                app.BoundDipratemmyrEditField.Value    = 'NaN NaN';
                app.BoundNormalratemmyrEditField.Value = 'NaN NaN';
            else
                app.BoundName_EditField.Value       = app.BoundaryNames{1};
                app.BoundNorthing_EditField.Value   = app.BoundaryValues(1,1);
                app.BoundEasting_EditField.Value    = app.BoundaryValues(1,2);
                app.BoundDepth_EditField.Value      = app.BoundaryValues(1,3);
                app.BoundLength_EditField.Value     = app.BoundaryValues(1,4);
                app.BoundWidth_EditField.Value      = app.BoundaryValues(1,5);
                app.BoundStrike_EditField.Value     = app.BoundaryValues(1,6);
                app.BoundDip_EditField.Value        = app.BoundaryValues(1,7);     
                app.BoundApplyRateAt_DropDown.Value = app.BoundApplyRateAt_DropDown.ItemsData(app.BoundaryValues(1,8));
                app.BoundStrikeratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(end, 9)),"  ",num2str(app.BoundaryValues(end,12)));
                app.BoundDipratemmyrEditField.Value    = strcat(num2str(app.BoundaryValues(end,10)),"  ",num2str(app.BoundaryValues(end,13)));
                app.BoundNormalratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(end,11)),"  ",num2str(app.BoundaryValues(end,14)));
            end
             %---------------------------------------
            app.Bound_ListBox.Items     = app.BoundaryNames;
            app.Bound_ListBox.ItemsData = 1:size(app.BoundaryNames,2);
            app.Bound_ListBox.Value     = size(app.BoundaryNames,2);
            %---------------------------------------
            if (size(app.BoundaryValues,1) >0)
                app.Move2_1stFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_1stFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_2ndFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_2ndFault_DropDown.Value     = 1;
            else
                app.Move2_1stFault_DropDown.Items     = app.FaultNames;
                app.Move2_1stFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = app.FaultNames;
                app.Move2_2ndFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_2ndFault_DropDown.Value     = 1;
            end
            %--------------------------------------- 
        end

        % Button pushed function: ShifVertex_tButton
        function ShifVertex_tButtonPushed(app, event)
            
            
            PrimaryFault    = app.Move2_1stFault_DropDown.Value;
            if (~isa(PrimaryFault,'double')),     PrimaryFault    = str2double(PrimaryFault);          end  
            SecondaryFault  = app.Move2_2ndFault_DropDown.Value;
            if (~isa(SecondaryFault,'double')),   SecondaryFault  = str2double(SecondaryFault);        end  
            PrimaryCorner   = app.Move2_1stCorner_DropDown.Value;
            if (~isa(PrimaryCorner,'double')),    PrimaryCorner   = str2double(PrimaryCorner);         end  
            SecondaryCorner = app.Move2_2ndCorner_DropDown.Value;
            if (~isa(SecondaryCorner,'double')),  SecondaryCorner = str2double(SecondaryCorner);       end  
            
            KeepCenterFix = app.Move_KeepCenterFixed_CheckBox.Value;

            if (SecondaryFault > size(app.FaultValues,1))
                KeepCenterFix = 0;
                app.Move_KeepCenterFixed_CheckBox.Value = 0;
                drawnow;
            end
            %---------------------------------------------
         %   if (PrimaryFault == SecondaryFault), errordlg('Dont want to "fold" the fault :) => make sure that faults to connect are not the same.');         return;             end
            %---------------------------------------------
            %  8        9       10       11      12      13     14     15       16      17     18      19.
            %LL_stk, LL_dip, LL_nrm, LR_stk, LR_dip, LR_nrm,UR_stk, UR_dip, UR_nrm, UL_stk, UL_dip, UL_nrm
            %oooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooo
            
            if (PrimaryFault <= size(app.FaultValues,1))
                Ecent_primary  = app.FaultValues(PrimaryFault,1);
                Ncent_primary  = app.FaultValues(PrimaryFault,2);
                Zcent_primary  = app.FaultValues(PrimaryFault,3);
                Length_primary = app.FaultValues(PrimaryFault,4);
                Width_primary  = app.FaultValues(PrimaryFault,5);
                Strike_primary = app.FaultValues(PrimaryFault,6).*pi./180;
                Dip_primary    = app.FaultValues(PrimaryFault,7).*pi./180;
                
                UL_stk = -Length_primary/2 +app.FaultValues(PrimaryFault,17);       UR_stk =  Length_primary/2 +app.FaultValues(PrimaryFault,14);
                LL_stk = -Length_primary/2 +app.FaultValues(PrimaryFault,8);        LR_stk =  Length_primary/2 +app.FaultValues(PrimaryFault,11);
                UL_dip = 0                 +app.FaultValues(PrimaryFault,18);       UR_dip =  0                +app.FaultValues(PrimaryFault,15);
                LL_dip = -Width_primary    +app.FaultValues(PrimaryFault,9);        LR_dip =  -Width_primary   +app.FaultValues(PrimaryFault,12);
                UL_nrm = 0                 +app.FaultValues(PrimaryFault,19);       UR_nrm =  0                +app.FaultValues(PrimaryFault,16);
                LL_nrm = 0                 +app.FaultValues(PrimaryFault,10);       LR_nrm =  0                +app.FaultValues(PrimaryFault,13);
            else
                Ecent_primary  = app.BoundaryValues((PrimaryFault-size(app.FaultValues,1)),1);
                Ncent_primary  = app.BoundaryValues((PrimaryFault-size(app.FaultValues,1)),2);
                Zcent_primary  = app.BoundaryValues((PrimaryFault-size(app.FaultValues,1)),3);
                Length_primary = app.BoundaryValues((PrimaryFault-size(app.FaultValues,1)),4);
                Width_primary  = app.BoundaryValues((PrimaryFault-size(app.FaultValues,1)),5);
                Strike_primary = app.BoundaryValues((PrimaryFault-size(app.FaultValues,1)),6).*pi./180;
                Dip_primary    = app.BoundaryValues((PrimaryFault-size(app.FaultValues,1)),7).*pi./180;
                
                UL_stk = -Length_primary/2;                 UR_stk =  Length_primary/2;
                LL_stk = -Length_primary/2;                 LR_stk =  Length_primary/2;
                UL_dip = 0;                                 UR_dip =  0;
                LL_dip = -Width_primary;                    LR_dip =  -Width_primary;
                UL_nrm = 0;                                 UR_nrm =  0;
                LL_nrm = 0;                                 LR_nrm =  0;
            end
            stkA   = [sin(Strike_primary), cos(Strike_primary), 0];
            nrmA   = [sin(Dip_primary)*cos(Strike_primary), -sin(Dip_primary)*sin(Strike_primary), cos(Dip_primary)];
            dipA   = cross(nrmA,stkA);
            
            RotMat = [stkA;dipA;nrmA];
            RotMatT= RotMat';
            %-------------------- 
            l_left          = RotMatT*[LL_stk;LL_dip;LL_nrm] +[Ecent_primary;Ncent_primary;Zcent_primary];
            l_right         = RotMatT*[LR_stk;LR_dip;LR_nrm] +[Ecent_primary;Ncent_primary;Zcent_primary];
            u_right         = RotMatT*[UR_stk;UR_dip;UR_nrm] +[Ecent_primary;Ncent_primary;Zcent_primary];
            u_left          = RotMatT*[UL_stk;UL_dip;UL_nrm] +[Ecent_primary;Ncent_primary;Zcent_primary];
            Corners_primary = [l_left'; l_right'; u_right'; u_left'];
            %oooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooo
            if (SecondaryFault <= size(app.FaultValues,1))
                Ecent_secondary  = app.FaultValues(SecondaryFault,1);
                Ncent_secondary  = app.FaultValues(SecondaryFault,2);
                Zcent_secondary  = app.FaultValues(SecondaryFault,3);
                Length_secondary = app.FaultValues(SecondaryFault,4);
                Width_secondary  = app.FaultValues(SecondaryFault,5);
                Strike_secondary = app.FaultValues(SecondaryFault,6).*pi./180;
                Dip_secondary    = app.FaultValues(SecondaryFault,7).*pi./180;
                
                if (KeepCenterFix == 1)
                    UL_stk = -Length_secondary/2 ;          UR_stk =  Length_secondary/2;
                    LL_stk = -Length_secondary/2 ;          LR_stk =  Length_secondary/2;
                    UL_dip = 0;                             UR_dip =  0;
                    LL_dip = -Width_secondary;              LR_dip =  -Width_secondary;
                    UL_nrm = 0;                             UR_nrm =  0;
                    LL_nrm = 0;                             LR_nrm =  0;
                else
                    UL_stk = -Length_secondary/2 +app.FaultValues(SecondaryFault,17);       UR_stk =  Length_secondary/2 +app.FaultValues(SecondaryFault,14);
                    LL_stk = -Length_secondary/2 +app.FaultValues(SecondaryFault,8);        LR_stk =  Length_secondary/2 +app.FaultValues(SecondaryFault,11);
                    UL_dip = 0                   +app.FaultValues(SecondaryFault,18);       UR_dip =  0                  +app.FaultValues(SecondaryFault,15);
                    LL_dip = -Width_secondary    +app.FaultValues(SecondaryFault,9);        LR_dip =  -Width_secondary   +app.FaultValues(SecondaryFault,12);
                    UL_nrm = 0                   +app.FaultValues(SecondaryFault,19);       UR_nrm =  0                  +app.FaultValues(SecondaryFault,16);
                    LL_nrm = 0                   +app.FaultValues(SecondaryFault,10);       LR_nrm =  0                  +app.FaultValues(SecondaryFault,13);
                end
            else
                Ecent_secondary  = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),1);
                Ncent_secondary  = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),2);
                Zcent_secondary  = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),3);
                Length_secondary = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),4);
                Width_secondary  = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),5);
                Strike_secondary = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),6).*pi./180;
                Dip_secondary    = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),7).*pi./180;
                
                UL_stk = -Length_secondary/2;                 UR_stk =  Length_secondary/2;
                LL_stk = -Length_secondary/2;                 LR_stk =  Length_secondary/2;
                UL_dip = 0;                                 UR_dip =  0;
                LL_dip = -Width_secondary;                    LR_dip =  -Width_secondary;
                UL_nrm = 0;                                 UR_nrm =  0;
                LL_nrm = 0;                                 LR_nrm =  0;
                
            end
            stkA   = [sin(Strike_secondary), cos(Strike_secondary), 0];
            nrmA   = [sin(Dip_secondary)*cos(Strike_secondary), -sin(Dip_secondary)*sin(Strike_secondary), cos(Dip_secondary)];
            dipA   = cross(nrmA,stkA);
            
            RotMat = [stkA;dipA;nrmA];
            RotMatT= RotMat';
            %------------------- 
            l_left            = RotMatT*[LL_stk;LL_dip;LL_nrm] +[Ecent_secondary;Ncent_secondary;Zcent_secondary];
            l_right           = RotMatT*[LR_stk;LR_dip;LR_nrm] +[Ecent_secondary;Ncent_secondary;Zcent_secondary];
            u_right           = RotMatT*[UR_stk;UR_dip;UR_nrm] +[Ecent_secondary;Ncent_secondary;Zcent_secondary];
            u_left            = RotMatT*[UL_stk;UL_dip;UL_nrm] +[Ecent_secondary;Ncent_secondary;Zcent_secondary];
            Corners_secondary = [l_left'; l_right'; u_right'; u_left']; 
            %oooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooo
            CornerShift       =  Corners_primary(PrimaryCorner,:) - Corners_secondary(SecondaryCorner,:);

            if (KeepCenterFix == 1) %need to bring CornerShift in local coordinate system
             
                LocalPos  = RotMat*CornerShift';
            
                if (SecondaryFault <= size(app.FaultValues,1))
                    app.FaultValues(SecondaryFault,(8+(SecondaryCorner-1)*3))     = LocalPos(1,1);
                    app.FaultValues(SecondaryFault,(9+(SecondaryCorner-1)*3))     = LocalPos(2,1);
                    app.FaultValues(SecondaryFault,(10+(SecondaryCorner-1)*3))    = LocalPos(3,1);
                else
                    app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),1) = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),1) +CornerShift(1,1);
                    app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),2) = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),2) +CornerShift(1,2);
                    app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),3) = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),3) +CornerShift(1,3);
                end 
            else 
                if (SecondaryFault <= size(app.FaultValues,1))
                    app.FaultValues(SecondaryFault,1) = app.FaultValues(SecondaryFault,1) +CornerShift(1,1);
                    app.FaultValues(SecondaryFault,2) = app.FaultValues(SecondaryFault,2) +CornerShift(1,2);
                    app.FaultValues(SecondaryFault,3) = app.FaultValues(SecondaryFault,3) +CornerShift(1,3);
                else
                    app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),1) = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),1) +CornerShift(1,1);
                    app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),2) = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),2) +CornerShift(1,2);
                    app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),3) = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),3) +CornerShift(1,3);
                end
            end
            %oooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooo
        end

        % Button pushed function: Triangulate_Button
        function Triangulate_ButtonPushed(app, event)
            %---------------------------------------------- 
            app.Lamp.Color = 'Yellow'; drawnow;
            %---------------------------------------------- 
            MeshFault(app);
            MeshBound(app);
            %---------------------------------------------- 
            PatchAreasFault = zeros(size(app.Fault.TriangleList,1),1);
            %-----------------------------------------
            for i = 1 : size(app.Fault.TriangleList,1)
                v1   = app.Fault.TriangleList(i,1);
                v2   = app.Fault.TriangleList(i,2);
                v3   = app.Fault.TriangleList(i,3);
                v1v2 = app.Fault.VertexList(v2,1:3)- app.Fault.VertexList(v1,1:3);
                v2v3 = app.Fault.VertexList(v3,1:3)- app.Fault.VertexList(v2,1:3);

                PatchAreasFault(i,1) = 0.5*sqrt(sum(cross(v1v2,v2v3).^2));
            end
            app.SingleElementEQsize_EditField.Value = log10(mean(PatchAreasFault)) +3.98; %HanksAndBakun2008
            %---------------------------------------------- 
            app.FaultWasGridded = 1;
            app.Lamp.Color      = 'Green';
            %-----------------------------%-----------------------------
        end

        % Button pushed function: PlotMesh_Button
        function PlotMesh_ButtonPushed(app, event)
            
            FigNum          = app.FigNumFaultMesh_EditField.Value;
            ViewAngle       = app.ViewAngle_EditField.Value;        ViewAngle = str2num(ViewAngle);
            PlotStats       = app.ShowTrigStats_CheckBox.Value;    
            FltComp  = str2double(app.PlotCompFault_DropDown.Value);
            BndComp  = str2double(app.PlotCompBound_DropDown.Value);
            AxEqual  = app.AxisEqual_CheckBox.Value;
            ShowMesh = app.ShowMesh_CheckBox.Value;
            %----------------------------------
            if (ShowMesh == 1), UsedEdge = 'black';
            else,               UsedEdge = 'none';
            end
            %-----------------------------------------------
            figure(FigNum); clf; hold on;   if (AxEqual),       axis equal;         end
            xlabel('easting');ylabel('northing'); zlabel('depth');
            view(ViewAngle(1,1),ViewAngle(1,2));
            %-----------------------------------------------
            AllV1s          = zeros(size(app.Fault.TriangleList,1),3);
            AllV2s          = zeros(size(app.Fault.TriangleList,1),3);
            AllV3s          = zeros(size(app.Fault.TriangleList,1),3);
            FltCol          = zeros(size(app.Fault.TriangleList,1),1);
            Cntr            = 1;
            %-----------------------------------------------
            for j = 1 : size(app.Fault.TriangleList,1)
                AllV1s(Cntr,1) = app.Fault.VertexList(app.Fault.TriangleList(j,1),1);
                AllV1s(Cntr,2) = app.Fault.VertexList(app.Fault.TriangleList(j,2),1);
                AllV1s(Cntr,3) = app.Fault.VertexList(app.Fault.TriangleList(j,3),1);
            
                AllV2s(Cntr,1) = app.Fault.VertexList(app.Fault.TriangleList(j,1),2);
                AllV2s(Cntr,2) = app.Fault.VertexList(app.Fault.TriangleList(j,2),2);
                AllV2s(Cntr,3) = app.Fault.VertexList(app.Fault.TriangleList(j,3),2);
            
                AllV3s(Cntr,1) = app.Fault.VertexList(app.Fault.TriangleList(j,1),3);
                AllV3s(Cntr,2) = app.Fault.VertexList(app.Fault.TriangleList(j,2),3);
                AllV3s(Cntr,3) = app.Fault.VertexList(app.Fault.TriangleList(j,3),3);

                if     (FltComp == 1),     FltCol(Cntr,1) = app.Fault.TriangleList(j,4);
                elseif (FltComp == 2),     FltCol(Cntr,1) = app.Fault.TriangleList(j,6);
                elseif (FltComp == 3),     FltCol(Cntr,1) = app.Fault.TriangleList(j,7);
                elseif (FltComp == 4),     FltCol(Cntr,1) = app.Fault.TriangleList(j,8);
                end
                Cntr           = Cntr +1;
            end
            patch(AllV1s',AllV2s',AllV3s',FltCol','facealpha',0.7,'edgecolor',UsedEdge);
            %FltCol = nan(size(AllV3s))';
            %patch(AllV1s',AllV2s',AllV3s',FltCol,'EdgeColor','black','LineWidth',1);
            %-----------------------------------------------%-----------------------------------------------
            if (size(app.BoundaryValues,1) > 0)
                AllV1s          = zeros(size(app.Bound.TriangleList,1),3);
                AllV2s          = zeros(size(app.Bound.TriangleList,1),3);
                AllV3s          = zeros(size(app.Bound.TriangleList,1),3);
                BndCol          = zeros(size(app.Bound.TriangleList,1),1);
                Cntr            = 1;
                %-----------------------------------------------
                for j = 1 : size(app.Bound.TriangleList,1)
                    AllV1s(Cntr,1) = app.Bound.VertexList(app.Bound.TriangleList(j,1),1);
                    AllV1s(Cntr,2) = app.Bound.VertexList(app.Bound.TriangleList(j,2),1);
                    AllV1s(Cntr,3) = app.Bound.VertexList(app.Bound.TriangleList(j,3),1);
                
                    AllV2s(Cntr,1) = app.Bound.VertexList(app.Bound.TriangleList(j,1),2);
                    AllV2s(Cntr,2) = app.Bound.VertexList(app.Bound.TriangleList(j,2),2);
                    AllV2s(Cntr,3) = app.Bound.VertexList(app.Bound.TriangleList(j,3),2);
                
                    AllV3s(Cntr,1) = app.Bound.VertexList(app.Bound.TriangleList(j,1),3);
                    AllV3s(Cntr,2) = app.Bound.VertexList(app.Bound.TriangleList(j,2),3);
                    AllV3s(Cntr,3) = app.Bound.VertexList(app.Bound.TriangleList(j,3),3);

                    if     (BndComp == 1),     BndCol(Cntr,1) = app.Bound.TriangleList(j,4);
                    elseif (BndComp == 2),     BndCol(Cntr,1) = app.Bound.TriangleList(j,8);
                    elseif (BndComp == 3),     BndCol(Cntr,1) = app.Bound.TriangleList(j,9);
                    elseif (BndComp == 4),     BndCol(Cntr,1) = app.Bound.TriangleList(j,10);
                    end
                    Cntr           = Cntr +1;
                end
                patch(AllV1s',AllV2s',AllV3s',BndCol','facealpha',0.7,'edgecolor',UsedEdge);
                %-----------------------------------------------
                if (min([BndCol;FltCol]) ~= max([BndCol;FltCol]))
                    caxis([min([BndCol;FltCol])  max([BndCol;FltCol])]);
                    colorbar;
                end
                %----------------------------------------------
            end
            %-----------------------------------------------
            
            if PlotStats == 1
                [HistFaultArea_N,HistFaultArea_X,HistBoundArea_N,HistBoundArea_X,HistFaultLgth_N,HistFaultLgth_X,HistBoundLgth_N,HistBoundLgth_X] = ComputeTrigStats(app);
                
                figure(FigNum*100+1); clf; hold on;
                subplot(2,2,1);    
                histogram('BinEdges',HistFaultLgth_X,'BinCounts',HistFaultLgth_N);
                xlabel('FAULT: leg length, normalized by mean'); ylabel('Frequency');
                subplot(2,2,2);    
                histogram('BinEdges',HistFaultArea_X,'BinCounts',HistFaultArea_N);
                xlabel('FAULT: patch area, normalized by mean'); ylabel('Frequency');
                if (size(app.BoundaryValues,1) > 0)
                    subplot(2,2,3);   
                    histogram('BinEdges',HistBoundLgth_X,'BinCounts',HistBoundLgth_N);
                     xlabel('BOUND: leg length, normalized by mean'); ylabel('Frequency');
                    subplot(2,2,4);    
                    histogram('BinEdges',HistBoundArea_X,'BinCounts',HistBoundArea_N);
                    xlabel('BOUND:patch area, normalized by mean'); ylabel('Frequency');
                end
            end
            %-----------------------------------------------     
        end

        % Button pushed function: SaveMesh_Button
        function SaveMesh_ButtonPushed(app, event)
            SectionNum = size(app.FaultValues,1);   
             %-----------------------   
            [DataName,DataPath]  = uiputfile('*.*','Save Fault Model (with triangulation if done already)');
            if     (DataName ~=0),      DataName = strtok(DataName,'.');        CompletePath = strcat(DataPath,DataName); 
            elseif (DataName ==0),      return;         
            end
            %-----------------------
            Name4ascii  = strcat(CompletePath,'_FLT.txt');
            Name4binary = strcat(CompletePath,'_FLTtrig.dat');
            %----------------------------------------------------------------
            fidascii    = fopen(Name4ascii ,'w');
            if (fidascii == -1), errordlg('The entered filename has not been found.');    return;   end
            
            fprintf(fidascii,'Number_of_fault_sections:  %d\n',SectionNum);
            fprintf(fidascii,'Combined_number_of_trig_elements_and_vertices:  %d   %d\n', size(app.Fault.TriangleList,1), size(app.Fault.VertexList,1));
            fprintf(fidascii,'\n');   
            %--------------------------------------
            for i = 1 : SectionNum
                fprintf(fidascii,'Fault_ID_number:   %d\n',app.FaultValues(i,20));
                fprintf(fidascii,'Fault_section_name: %s\n',app.FaultNames{1,i});
                fprintf(fidascii,'Easting_of_fault_top_center(km):     %10.4e\n',app.FaultValues(i,1));
                fprintf(fidascii,'Northing_of_fault_top_center(km):    %10.4e\n',app.FaultValues(i,2));
                fprintf(fidascii,'Depth_of_fault_top_center(km):       %10.4e\n',app.FaultValues(i,3));
                fprintf(fidascii,'Length_of_fault_section(km):         %10.4e\n',app.FaultValues(i,4));
                fprintf(fidascii,'Width_of_fault_section(km):          %10.4e\n',app.FaultValues(i,5));
                fprintf(fidascii,'Strike_of_fault_section(deg):        %10.4e\n',app.FaultValues(i,6));
                fprintf(fidascii,'Dip_of_fault_section(deg):           %10.4e\n',app.FaultValues(i,7));
                fprintf(fidascii,'UseSlipRate_or_RegStress_(1/0):      %d\n',    app.FaultValues(i,21));
                fprintf(fidascii,'SlipRate_of_fault_section(mm/yr):    %10.4e\n',app.FaultValues(i,22));
                fprintf(fidascii,'Rake_of_fault_section(deg):          %10.4e\n',app.FaultValues(i,23));
                fprintf(fidascii,'StressRate_of_fault_section(MPa/yr): %10.4e\n',app.FaultValues(i,24));
                fprintf(fidascii,'StressDir_of_fault_section(deg):     %10.4e\n',app.FaultValues(i,25));
                fprintf(fidascii,'Shift_of_lowerleft_vertex_(E/N/Z)(km):    %10.4e   %10.4e   %10.4e\n',app.FaultValues(i,8),app.FaultValues(i,9),app.FaultValues(i,10));
                fprintf(fidascii,'Shift_of_lowerright_vertex_(E/N/Z)(km):   %10.4e   %10.4e   %10.4e\n',app.FaultValues(i,11),app.FaultValues(i,12),app.FaultValues(i,13));
                fprintf(fidascii,'Shift_of_upperright_vertex_(E/N/Z)(km):   %10.4e   %10.4e   %10.4e\n',app.FaultValues(i,14),app.FaultValues(i,15),app.FaultValues(i,16));
                fprintf(fidascii,'Shift_of_upperleft_vertex_(E/N/Z)(km):    %10.4e   %10.4e   %10.4e\n',app.FaultValues(i,17),app.FaultValues(i,18),app.FaultValues(i,19));
                
                FltTrigs        = app.Fault.TriangleList(app.Fault.TriangleList(:,4) == i,1:3);
                PatchesPerFault = size(FltTrigs,1);             VertsOfFaults   = unique(FltTrigs(:));              VertsPerFault   = size(VertsOfFaults,1);
                fprintf(fidascii,'Triangle_patch_number_of_section:   %d\n', PatchesPerFault);
                fprintf(fidascii,'Triangle_vertex_number_of_section:  %d\n',VertsPerFault);
                fprintf(fidascii,'\n');      
            end
            fclose(fidascii);
            %------------------------------------------
            fidbin    = fopen(Name4binary ,'wb'); 
            if (fidbin == -1), errordlg('The entered filename has not been found.');    return;   end
  
            fwrite(fidbin,size(app.Fault.TriangleList,1),'int32');
            fwrite(fidbin,size(app.Fault.VertexList,1),  'int32');

            fwrite(fidbin,app.Fault.TriangleList(:,1),   'int32');
            fwrite(fidbin,app.Fault.TriangleList(:,2),   'int32');
            fwrite(fidbin,app.Fault.TriangleList(:,3),   'int32');
            fwrite(fidbin,app.Fault.TriangleList(:,4),   'int32'); %fault section ID number
            fwrite(fidbin,app.Fault.TriangleList(:,5),   'int32'); %assigned fault ID number
            fwrite(fidbin,app.Fault.TriangleList(:,6),   'int32'); %UseSlip (== 1)
            fwrite(fidbin,app.Fault.TriangleList(:,7),   'single'); %fault slip rate
            fwrite(fidbin,app.Fault.TriangleList(:,8),   'single'); %fault rake
            fwrite(fidbin,app.Fault.TriangleList(:,9),   'single');
            fwrite(fidbin,app.Fault.TriangleList(:,10),  'single');    
            fwrite(fidbin,app.Fault.TriangleList(:,11),  'single');  

            fwrite(fidbin,app.Fault.VertexList(:,1), 'single');
            fwrite(fidbin,app.Fault.VertexList(:,2), 'single');    
            fwrite(fidbin,app.Fault.VertexList(:,3), 'single'); 
            fwrite(fidbin,app.Fault.VertexList(:,4), 'single'); 

            fclose(fidbin);    
            %------------------------------------------%------------------------------------------
            if (size(app.BoundaryValues,1) > 0)
                Name4ascii  = strcat(CompletePath,'_BND.txt');
                Name4binary = strcat(CompletePath,'_BNDtrig.dat');
                %----------------------------------------------------------------
                fidascii    = fopen(Name4ascii ,'w');
                if (fidascii == -1), errordlg('The entered filename has not been found.');    return;   end
                
                fprintf(fidascii,'Number_of_boundary_segments:  %d\n',size(app.BoundaryValues,1));
                fprintf(fidascii,'Combined_number_of_trig_patches_and_vertices:  %d   %d\n', size(app.Bound.TriangleList,1), size(app.Bound.VertexList,1));
                fprintf(fidascii,'\n');   
                %--------------------------------------
                for i = 1 : size(app.BoundaryValues,1)
                    fprintf(fidascii,'Fault_section_name: %s\n',                  app.BoundaryNames{1,i});
                    fprintf(fidascii,'Easting_of_fault_top_center(km):  %10.4e\n',app.BoundaryValues(i,1));
                    fprintf(fidascii,'Northing_of_fault_top_center(km): %10.4e\n',app.BoundaryValues(i,2));
                    fprintf(fidascii,'Depth_of_fault_top_center(km):    %10.4e\n',app.BoundaryValues(i,3));
                    fprintf(fidascii,'Length_of_fault_section(km):      %10.4e\n',app.BoundaryValues(i,4));
                    fprintf(fidascii,'Width_of_fault_section(km):       %10.4e\n',app.BoundaryValues(i,5));
                    fprintf(fidascii,'Strike_of_fault_section(deg):     %10.4e\n',app.BoundaryValues(i,6));
                    fprintf(fidascii,'Dip_of_fault_section(deg):        %10.4e\n',app.BoundaryValues(i,7));
                    fprintf(fidascii,'ApplyTo_TopBottom_or_LeftRight:   %d\n',    app.BoundaryValues(i,8));
                    fprintf(fidascii,'Slip-rate_in_strike(mm/yr):       %10.4e   %10.4e\n',app.BoundaryValues(i,9), app.BoundaryValues(i,12));
                    fprintf(fidascii,'Slip-rate_in_dip(mm/yr):          %10.4e   %10.4e\n',app.BoundaryValues(i,10),app.BoundaryValues(i,13));
                    fprintf(fidascii,'Slip-rate_in_normal(mm/yr):       %10.4e   %10.4e\n',app.BoundaryValues(i,11),app.BoundaryValues(i,14));                    

                    BndTrigs        = app.Bound.TriangleList(app.Bound.TriangleList(:,4) == i,1:3);
                    PatchesPerBound = size(BndTrigs,1);         VertsOfBound    = unique(BndTrigs(:));         VertsPerBound   = size(VertsOfBound,1);
                    fprintf(fidascii,'Triangle_patch_number_of_boundary:   %d\n',PatchesPerBound);
                    fprintf(fidascii,'Triangle_vertex_number_of_boundary:  %d\n',VertsPerBound);
                    fprintf(fidascii,'\n'); 
                end
                fclose(fidascii);
                %--------------------------------------
                fidbin    = fopen(Name4binary ,'wb'); 
                if (fidbin == -1), errordlg('The entered filename has not been found.');    return;         end

                fwrite(fidbin,size(app.Bound.TriangleList,1),'int32');
                fwrite(fidbin,size(app.Bound.VertexList,1),  'int32');

                fwrite(fidbin,app.Bound.TriangleList(:,1),   'int32');
                fwrite(fidbin,app.Bound.TriangleList(:,2),   'int32');
                fwrite(fidbin,app.Bound.TriangleList(:,3),   'int32');
                fwrite(fidbin,app.Bound.TriangleList(:,4),   'int32');    
                fwrite(fidbin,app.Bound.TriangleList(:,5),   'single');
                fwrite(fidbin,app.Bound.TriangleList(:,6),   'single');    
                fwrite(fidbin,app.Bound.TriangleList(:,7),   'single'); 

                fwrite(fidbin,app.Bound.VertexList(:,1),     'single');
                fwrite(fidbin,app.Bound.VertexList(:,2),     'single');    
                fwrite(fidbin,app.Bound.VertexList(:,3),     'single');  

                fwrite(fidbin,app.Bound.TriangleList(:,8),   'single');
                fwrite(fidbin,app.Bound.TriangleList(:,9),   'single');    
                fwrite(fidbin,app.Bound.TriangleList(:,10),  'single'); 
                %--------------------------------------
                fclose(fidbin);    
            end
        end

        % Button pushed function: LoadMeshAndModel_Button
        function LoadMeshAndModel_ButtonPushed(app, event)
            [DataName,DataPath]  = uigetfile('*_FLT.txt','Load Fault Model (with triangulation if done already)');
            if     (DataName ~=0),      DataName = extractBefore(DataName,'_FLT.txt');        CompletePath = strcat(DataPath,DataName); 
            elseif (DataName ==0),      return;         
            end
            Name4ascii  = strcat(CompletePath,'_FLT.txt');
            Name4binary = strcat(CompletePath,'_FLTtrig.dat');
            %----------------------------------------------------------------
            fidascii    = fopen(Name4ascii ,'r');
            if (fidascii == -1), errordlg('The entered filename has not been found.');    return;   end
            %----------------------------------
            nextline               = fgetl(fidascii);               FltSectionNum          = sscanf(nextline,'%*s %d');               
            app.FaultValues        = nan(FltSectionNum,25);         app.FaultNames         = cell(1,FltSectionNum);
            %-----------------------%-----------------------%-----------------------
            nextline        = fgetl(fidascii);             nextvals       = sscanf(nextline,'%*s %d %d');
            SizeTrigList    = nextvals(1,1);               SizeVertList   = nextvals(2,1);
            nextline                         = fgetl(fidascii);                     
            %----------------------
            app.Fault.TriangleList    = [];
            app.Fault.VertexList      = [];
            %-----------------------%-----------------------%-----------------------
            for i = 1 : FltSectionNum
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,20)    = sscanf(nextline,'%*s %d');
                FltSecName          = extractAfter(fgetl(fidascii)," ");                     app.FaultNames{1,i}      = FltSecName;
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,1)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,2)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,3)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,4)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,5)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,6)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,7)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,21)    = sscanf(nextline,'%*s %d');   
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,22)    = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,23)    = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,24)    = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,25)    = sscanf(nextline,'%*s %e');                
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,8:10)  = sscanf(nextline,'%*s %e %e %e')';
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,11:13) = sscanf(nextline,'%*s %e %e %e')';
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,14:16) = sscanf(nextline,'%*s %e %e %e')';
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,17:19) = sscanf(nextline,'%*s %e %e %e')';
                
                nextline   = fgetl(fidascii);     nextline   = fgetl(fidascii);     nextline   = fgetl(fidascii);%not needed 
            end
            fclose(fidascii);
            %-----------------------%-----------------------%-----------------------
            fidbin    = fopen(Name4binary ,'rb');
            if (fidbin == -1)
            else
                app.FaultWasGridded = 1;
                %-----------------------
                app.Fault.TriangleList    = zeros(SizeTrigList,11);
                app.Fault.VertexList      = zeros(SizeVertList,4);
                %-----------------------          
                PatchNum = fread(fidbin,1,'int');
                VertNum  = fread(fidbin,1,'int');
                
                if ((PatchNum ~= SizeTrigList) || (VertNum ~= SizeVertList))
                   disp('patch and/or vertex numbers are not consistent');
                   return;
                end
                app.Fault.TriangleList(:,1)   = fread(fidbin,PatchNum,'int32');
                app.Fault.TriangleList(:,2)   = fread(fidbin,PatchNum,'int32');
                app.Fault.TriangleList(:,3)   = fread(fidbin,PatchNum,'int32');
                app.Fault.TriangleList(:,4)   = fread(fidbin,PatchNum,'int32');
                app.Fault.TriangleList(:,5)   = fread(fidbin,PatchNum,'int32');
             
                app.Fault.TriangleList(:,6)   = fread(fidbin,PatchNum,'int32');
                app.Fault.TriangleList(:,7)   = fread(fidbin,PatchNum,'single');
                app.Fault.TriangleList(:,8)   = fread(fidbin,PatchNum,'single');
                app.Fault.TriangleList(:,9)   = fread(fidbin,PatchNum,'single');
                app.Fault.TriangleList(:,10)  = fread(fidbin,PatchNum,'single');
                app.Fault.TriangleList(:,11)  = fread(fidbin,PatchNum,'single');
                
                app.Fault.VertexList(:,1) = fread(fidbin,VertNum,'single');
                app.Fault.VertexList(:,2) = fread(fidbin,VertNum,'single');
                app.Fault.VertexList(:,3) = fread(fidbin,VertNum,'single');
                app.Fault.VertexList(:,4) = fread(fidbin,VertNum,'single');
                %-----------------------
                app.ElemNumFault_EditField.Value = SizeTrigList;
                %-----------------------
                fclose(fidbin);
            end
            %-----------------------%-----------------------%-----------------------
            SelVertex                                 = str2double(app.FaultVertexPos_DropDown.Value);
            app.FaultName_EditField.Value             = app.FaultNames{1};
            app.FaultNorthing_EditField.Value         = app.FaultValues(1,1);
            app.FaultEasting_EditField.Value          = app.FaultValues(1,2);
            app.FaultDepth_EditField.Value            = app.FaultValues(1,3);
            app.FaultLength_EditField.Value           = app.FaultValues(1,4);
            app.FaultWidth_EditField.Value            = app.FaultValues(1,5);
            app.FaultStrike_EditField.Value           = app.FaultValues(1,6);
            app.FaultDip_EditField.Value              = app.FaultValues(1,7);            
            
            app.FaultVertexPos_Strike_EditField.Value = app.FaultValues(1,8+(SelVertex-1)*3);
            app.FaultVertexPos_Dip_EditField.Value    = app.FaultValues(1,9+(SelVertex-1)*3);
            app.FaultVertexPos_Norm_EditField.Value   = app.FaultValues(1,10+(SelVertex-1)*3);
            app.FaultID_EditField.Value               = app.FaultValues(1,20);
            if (app.FaultValues(1,21) == 1)
                app.UsefaultsliprateCheckBox.Value    = 1;
                app.UseregionalstressCheckBox.Value   = 0;
            else
                app.UsefaultsliprateCheckBox.Value    = 0;
                app.UseregionalstressCheckBox.Value   = 1;
            end
            app.FaultSlipRake_EditField.Value         = app.FaultValues(1,22);
            app.FaultSlipRake_EditField.Value         = app.FaultValues(1,23);
            app.FaultStressRate_EditField.Value       = app.FaultValues(1,23);  
            app.FaultStressDirection_EditField.Value  = app.FaultValues(1,25);  
            %-----------------------%-----------------------%-----------------------
            app.Fault_ListBox.Items     = app.FaultNames;
            app.Fault_ListBox.ItemsData = 1:size(app.FaultNames,2);
            app.Fault_ListBox.Value     = size(app.FaultNames,2);
            %-----------------------%-----------------------%-----------------------
            %-----------------------%-----------------------%-----------------------
            Name4ascii  = strcat(CompletePath,'_BND.txt');
            Name4binary = strcat(CompletePath,'_BNDtrig.dat');
            fidascii    = fopen(Name4ascii ,'r');
            if (fidascii == -1)       
                DeleteBound_ButtonPushed(app);
                return;                     
            else
                %----------------------------------
                nextline               = fgetl(fidascii);               BndSectionNum          = sscanf(nextline,'%*s %d');  
                app.BoundaryValues     = nan(BndSectionNum,7);          app.BoundaryNames      = cell(1,BndSectionNum);
                %-----------------------%-----------------------%-----------------------
                nextline         = fgetl(fidascii);             nextvals       = sscanf(nextline,'%*s %d %d');
                SizeTrigList     = nextvals(1,1);               SizeVertList   = nextvals(2,1);
                %----------------------
                app.Bound.TriangleList    = [];
                app.Bound.VertexList      = [];
                %----------------------
            end
            nextline                = fgetl(fidascii);
            %-----------------------%-----------------------%-----------------------
            for i = 1 : BndSectionNum
                BndSecName          = extractAfter(fgetl(fidascii)," ");                     app.BoundaryNames{1,i}      = BndSecName;
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,1)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,2)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,3)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,4)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,5)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,6)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,7)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,8)     = sscanf(nextline,'%*s %d');
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,9)     = sscanf(nextline,'%*s %e %*e');           app.BoundaryValues(i,12)     = sscanf(nextline,'%*s %*e %e');
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,10)    = sscanf(nextline,'%*s %e %*e');           app.BoundaryValues(i,13)     = sscanf(nextline,'%*s %*e %e');
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,11)    = sscanf(nextline,'%*s %e %*e');           app.BoundaryValues(i,14)     = sscanf(nextline,'%*s %*e %e');
                nextline   = fgetl(fidascii);      nextline   = fgetl(fidascii);      nextline   = fgetl(fidascii);
            end
            fclose(fidascii);
            %-----------------------%-----------------------%-----------------------
            if (app.FaultWasGridded == 1)
                %-----------------------
                app.Bound.TriangleList    = zeros(SizeTrigList,10);
                app.Bound.VertexList      = zeros(SizeVertList,3);
                %-----------------------
                fidbin    = fopen(Name4binary ,'rb');
                if (fidbin == -1), errordlg('The entered filename has not been found.');    return;   end
                %-----------------------%-----------------------
                PatchNum = fread(fidbin,1,'int32');
                VertNum  = fread(fidbin,1,'int32');
                
                if ((PatchNum ~= SizeTrigList) || (VertNum ~= SizeVertList))
                   disp('patch and/or vertex numbers are not consistent');
                   return;
                end
                %-----------------------%-----------------------
                app.Bound.TriangleList(:,1)   = fread(fidbin,PatchNum,'int32');
                app.Bound.TriangleList(:,2)   = fread(fidbin,PatchNum,'int32');
                app.Bound.TriangleList(:,3)   = fread(fidbin,PatchNum,'int32');
                app.Bound.TriangleList(:,4)   = fread(fidbin,PatchNum,'int32');
                app.Bound.TriangleList(:,5)   = fread(fidbin,PatchNum,'single');
                app.Bound.TriangleList(:,6)   = fread(fidbin,PatchNum,'single');
                app.Bound.TriangleList(:,7)   = fread(fidbin,PatchNum,'single'); 

                app.Bound.VertexList(:,1)     = fread(fidbin,VertNum,'single');
                app.Bound.VertexList(:,2)     = fread(fidbin,VertNum,'single');
                app.Bound.VertexList(:,3)     = fread(fidbin,VertNum,'single');

                app.Bound.TriangleList(:,8)   = fread(fidbin,PatchNum,'single');
                app.Bound.TriangleList(:,9)   = fread(fidbin,PatchNum,'single');
                app.Bound.TriangleList(:,10)  = fread(fidbin,PatchNum,'single'); 
                %-----------------------%-----------------------
                app.ElemNumBound_EditField.Value   = SizeTrigList;
                %-----------------------%-----------------------
                fclose(fidbin);
            end
            %-----------------------%-----------------------%-----------------------
            app.BoundName_EditField.Value         = app.BoundaryNames{1};
            app.BoundNorthing_EditField.Value     = app.BoundaryValues(1,1);
            app.BoundEasting_EditField.Value      = app.BoundaryValues(1,2);
            app.BoundDepth_EditField.Value        = app.BoundaryValues(1,3);
            app.BoundLength_EditField.Value       = app.BoundaryValues(1,4);
            app.BoundWidth_EditField.Value        = app.BoundaryValues(1,5);
            app.BoundStrike_EditField.Value       = app.BoundaryValues(1,6);
            app.BoundDip_EditField.Value          = app.BoundaryValues(1,7);    
            app.BoundApplyRateAt_DropDown.Value = app.BoundApplyRateAt_DropDown.ItemsData(app.BoundaryValues(1,8));
            %-------------------------------------------------- 
            app.BoundStrikeratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(1, 9)),"  ",num2str(app.BoundaryValues(1,12)));
            app.BoundDipratemmyrEditField.Value    = strcat(num2str(app.BoundaryValues(1,10)),"  ",num2str(app.BoundaryValues(1,13)));
            app.BoundNormalratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(1,11)),"  ",num2str(app.BoundaryValues(1,14)));
            %-----------------------%-----------------------%-----------------------
            app.Bound_ListBox.Items     = app.BoundaryNames;
            app.Bound_ListBox.ItemsData = 1:size(app.BoundaryNames,2);
            app.Bound_ListBox.Value     = size(app.BoundaryNames,2);
            %-----------------------%-----------------------%-----------------------
            %-----------------------%-----------------------%-----------------------
            if (size(app.BoundaryValues,1) >0)
                app.Move2_1stFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_1stFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_2ndFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_2ndFault_DropDown.Value     = 1;
            else
                app.Move2_1stFault_DropDown.Items     = app.FaultNames;
                app.Move2_1stFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = app.FaultNames;
                app.Move2_2ndFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_2ndFault_DropDown.Value     = 1;
            end
            %-----------------------%-----------------------%-----------------------    
            app.FaultWasGridded = 1;
        end

        % Value changed function: BoundNormalratemmyrEditField
        function BoundNormalratemmyrEditFieldValueChanged(app, event)
            value = str2num(app.BoundNormalratemmyrEditField.Value);
            if (isempty(value)),            return;                         end
            if (size(value,2) == 1),        value = [value, value];         end
            if (isnan(sum(value))),         value = [NaN, NaN];             end
            %------------------------------------        
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            %------------------------------------ 
            app.BoundaryValues(SelBoundPos,11)= value(1,1);
            app.BoundaryValues(SelBoundPos,14)= value(1,2);    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Fault_ListBox = uilistbox(app.Panel);
            app.Fault_ListBox.Items = {'Fault_1'};
            app.Fault_ListBox.ItemsData = {'1'};
            app.Fault_ListBox.ValueChangedFcn = createCallbackFcn(app, @Fault_ListBoxValueChanged, true);
            app.Fault_ListBox.Value = '1';
            app.AddFault_Button = uibutton(app.Panel, 'push');
            app.AddFault_Button.ButtonPushedFcn = createCallbackFcn(app, @AddFault_ButtonPushed, true);
            app.AddFault_Button.Text = 'Add';
            app.DeleteFault_Button = uibutton(app.Panel, 'push');
            app.DeleteFault_Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteFault_ButtonPushed, true);
            app.DeleteFault_Button.Text = 'Delete';
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.FaultName_EditField = uieditfield(app.Panel, 'text');
            app.FaultName_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultName_EditFieldValueChanged, true);
            app.FaultName_EditField.Value = 'Fault_1';
            app.EastingtopcenterkmEditFieldLabel = uilabel(app.Panel);
            app.EastingtopcenterkmEditFieldLabel.Text = 'Easting (top center, km)';
            app.FaultEasting_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultEasting_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultEasting_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultEasting_EditFieldValueChanged, true);
            app.NorthingtopcenterkmEditFieldLabel = uilabel(app.Panel);
            app.NorthingtopcenterkmEditFieldLabel.Text = 'Northing(top center, km)';
            app.FaultNorthing_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultNorthing_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultNorthing_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultNorthing_EditFieldValueChanged, true);
            app.DepthtopcenterkmEditFieldLabel = uilabel(app.Panel);
            app.DepthtopcenterkmEditFieldLabel.Text = 'Depth (top center, km)';
            app.FaultDepth_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultDepth_EditField.Limits = [-Inf 0];
            app.FaultDepth_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultDepth_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultDepth_EditFieldValueChanged, true);
            app.FaultDepth_EditField.Value = -0.0001;
            app.LengthkmEditFieldLabel = uilabel(app.Panel);
            app.LengthkmEditFieldLabel.Text = 'Length (km)';
            app.FaultLength_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultLength_EditField.Limits = [0 Inf];
            app.FaultLength_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultLength_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultLength_EditFieldValueChanged, true);
            app.FaultLength_EditField.Value = 100;
            app.WidthkmEditFieldLabel = uilabel(app.Panel);
            app.WidthkmEditFieldLabel.Text = 'Width (km)';
            app.FaultWidth_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultWidth_EditField.Limits = [0 Inf];
            app.FaultWidth_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultWidth_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultWidth_EditFieldValueChanged, true);
            app.FaultWidth_EditField.Value = 18;
            app.StrikedegEditFieldLabel = uilabel(app.Panel);
            app.StrikedegEditFieldLabel.Text = 'Strike (deg.)';
            app.FaultStrike_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultStrike_EditField.Limits = [0 360];
            app.FaultStrike_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultStrike_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultStrike_EditFieldValueChanged, true);
            app.DipdegEditFieldLabel = uilabel(app.Panel);
            app.DipdegEditFieldLabel.Text = 'Dip (deg.)';
            app.FaultDip_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultDip_EditField.Limits = [0 90];
            app.FaultDip_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultDip_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultDip_EditFieldValueChanged, true);
            app.FaultDip_EditField.Value = 90;
            app.SlipratemmyrLabel_2 = uilabel(app.Panel);
            app.SlipratemmyrLabel_2.Text = 'Slip-rate (mm/yr)';
            app.FaultSlipRate_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultSlipRate_EditField.ValueDisplayFormat = '%5.2f';
            app.FaultSlipRate_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultSlipRate_EditFieldValueChanged, true);
            app.FaultSlipRate_EditField.Value = 10;
            app.RakedegLabel_2 = uilabel(app.Panel);
            app.RakedegLabel_2.Text = 'Rake (deg.)';
            app.FaultSlipRake_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultSlipRake_EditField.Limits = [-180 180];
            app.FaultSlipRake_EditField.ValueDisplayFormat = '%5.2f';
            app.FaultSlipRake_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultSlipRake_EditFieldValueChanged, true);
            app.UsefaultsliprateCheckBox = uicheckbox(app.Panel);
            app.UsefaultsliprateCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsefaultsliprateCheckBoxValueChanged, true);
            app.UsefaultsliprateCheckBox.Text = 'use fault slip rate';
            app.UsefaultsliprateCheckBox.Value = true;
            app.UseregionalstressCheckBox = uicheckbox(app.Panel);
            app.UseregionalstressCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseregionalstressCheckBoxValueChanged, true);
            app.UseregionalstressCheckBox.Text = 'or use regional stress';
            app.SlipratemmyrLabel_3 = uilabel(app.Panel);
            app.SlipratemmyrLabel_3.Text = 'Stress-rate (MPa/yr)';
            app.FaultStressRate_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultStressRate_EditField.ValueDisplayFormat = '%5.2f';
            app.FaultStressRate_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultStressRate_EditFieldValueChanged, true);
            app.FaultStressRate_EditField.Tooltip = {'COMPRESSION = NEGATIVE'};
            app.RakedegLabel_3 = uilabel(app.Panel);
            app.RakedegLabel_3.Text = 'Stress direction (deg.)';
            app.FaultStressDirection_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultStressDirection_EditField.Limits = [0 180];
            app.FaultStressDirection_EditField.ValueDisplayFormat = '%5.2f';
            app.FaultStressDirection_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultStressDirection_EditFieldValueChanged, true);
            app.FaultIDEditFieldLabel = uilabel(app.Panel);
            app.FaultIDEditFieldLabel.Text = 'Fault ID';
            app.FaultID_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultID_EditField.Limits = [0 Inf];
            app.FaultID_EditField.RoundFractionalValues = 'on';
            app.FaultID_EditField.ValueDisplayFormat = '%d';
            app.FaultID_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultID_EditFieldValueChanged, true);
            app.FaultID_EditField.Value = 1;
            app.PlotFaultSetup_Button = uibutton(app.Panel, 'push');
            app.PlotFaultSetup_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotFaultSetup_ButtonPushed, true);
            app.PlotFaultSetup_Button.Text = '1) Plot fault setup';
            app.FigEditFieldLabel = uilabel(app.Panel);
            app.FigEditFieldLabel.Text = 'Fig #';
            app.FigNumFaultModel_EditField = uieditfield(app.Panel, 'numeric');
            app.FigNumFaultModel_EditField.Limits = [0 Inf];
            app.FigNumFaultModel_EditField.RoundFractionalValues = 'on';
            app.FigNumFaultModel_EditField.ValueDisplayFormat = '%d';
            app.FigNumFaultModel_EditField.Value = 2;
            app.LoadMeshAndModel_Button = uibutton(app.Panel, 'push');
            app.LoadMeshAndModel_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadMeshAndModel_ButtonPushed, true);
            app.LoadMeshAndModel_Button.Text = 'Load fault model';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Bound_ListBox = uilistbox(app.Panel_2);
            app.Bound_ListBox.Items = {'Boundary_Bottom'};
            app.Bound_ListBox.ItemsData = {'1', '2', '3', '4', '5'};
            app.Bound_ListBox.ValueChangedFcn = createCallbackFcn(app, @Bound_ListBoxValueChanged, true);
            app.Bound_ListBox.Value = '1';
            app.NameEditField_2Label = uilabel(app.Panel_2);
            app.NameEditField_2Label.Text = 'Name';
            app.BoundName_EditField = uieditfield(app.Panel_2, 'text');
            app.BoundName_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundName_EditFieldValueChanged, true);
            app.BoundName_EditField.Value = 'Boundary_Bottom';
            app.EastingtopcenterkmEditField_2Label = uilabel(app.Panel_2);
            app.EastingtopcenterkmEditField_2Label.Text = 'Easting (top center, km)';
            app.BoundEasting_EditField = uieditfield(app.Panel_2, 'numeric');
            app.BoundEasting_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundEasting_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundEasting_EditFieldValueChanged, true);
            app.BoundEasting_EditField.Value = -80;
            app.NorthingtopcenterkmEditField_2Label = uilabel(app.Panel_2);
            app.NorthingtopcenterkmEditField_2Label.Text = 'Northing(top center, km)';
            app.BoundNorthing_EditField = uieditfield(app.Panel_2, 'numeric');
            app.BoundNorthing_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundNorthing_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundNorthing_EditFieldValueChanged, true);
            app.DepthtopcenterkmEditField_2Label = uilabel(app.Panel_2);
            app.DepthtopcenterkmEditField_2Label.Text = 'Depth (top center, km)';
            app.BoundDepth_EditField = uieditfield(app.Panel_2, 'numeric');
            app.BoundDepth_EditField.Limits = [-Inf 0];
            app.BoundDepth_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundDepth_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundDepth_EditFieldValueChanged, true);
            app.BoundDepth_EditField.Value = -22;
            app.LengthkmEditField_2Label = uilabel(app.Panel_2);
            app.LengthkmEditField_2Label.Text = 'Length (km)';
            app.BoundLength_EditField = uieditfield(app.Panel_2, 'numeric');
            app.BoundLength_EditField.Limits = [0 Inf];
            app.BoundLength_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundLength_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundLength_EditFieldValueChanged, true);
            app.BoundLength_EditField.Value = 300;
            app.WidthkmEditField_2Label = uilabel(app.Panel_2);
            app.WidthkmEditField_2Label.Text = 'Width (km)';
            app.BoundWidth_EditField = uieditfield(app.Panel_2, 'numeric');
            app.BoundWidth_EditField.Limits = [0 Inf];
            app.BoundWidth_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundWidth_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundWidth_EditFieldValueChanged, true);
            app.BoundWidth_EditField.Value = 160;
            app.StrikedegEditField_2Label = uilabel(app.Panel_2);
            app.StrikedegEditField_2Label.Text = 'Strike (deg.)';
            app.BoundStrike_EditField = uieditfield(app.Panel_2, 'numeric');
            app.BoundStrike_EditField.Limits = [0 360];
            app.BoundStrike_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundStrike_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundStrike_EditFieldValueChanged, true);
            app.DipdegEditField_2Label = uilabel(app.Panel_2);
            app.DipdegEditField_2Label.Text = 'Dip (deg.)';
            app.BoundDip_EditField = uieditfield(app.Panel_2, 'numeric');
            app.BoundDip_EditField.Limits = [0 90];
            app.BoundDip_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundDip_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundDip_EditFieldValueChanged, true);
            app.DeleteBound_Button = uibutton(app.Panel_2, 'push');
            app.DeleteBound_Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteBound_ButtonPushed, true);
            app.DeleteBound_Button.Text = 'Delete';
            app.AddBound_Button = uibutton(app.Panel_2, 'push');
            app.AddBound_Button.ButtonPushedFcn = createCallbackFcn(app, @AddBound_ButtonPushed, true);
            app.AddBound_Button.Text = 'Add ';
            app.applyrateDropDownLabel = uilabel(app.Panel_2);
            app.applyrateDropDownLabel.Text = 'apply rate';
            app.BoundApplyRateAt_DropDown = uidropdown(app.Panel_2);
            app.BoundApplyRateAt_DropDown.Items = {'top/bottom edge', 'left/right edge'};
            app.BoundApplyRateAt_DropDown.ItemsData = {'1', '2'};
            app.BoundApplyRateAt_DropDown.ValueChangedFcn = createCallbackFcn(app, @BoundApplyRateAt_DropDownValueChanged, true);
            app.BoundApplyRateAt_DropDown.Value = '1';
            app.strikeratemmyrEditFieldLabel = uilabel(app.Panel_2);
            app.strikeratemmyrEditFieldLabel.Text = 'strike-rate (mm/yr)';
            app.BoundStrikeratemmyrEditField = uieditfield(app.Panel_2, 'text');
            app.BoundStrikeratemmyrEditField.ValueChangedFcn = createCallbackFcn(app, @BoundStrikeratemmyrEditFieldValueChanged, true);
            app.BoundStrikeratemmyrEditField.Value = 'NaN NaN';
            app.dipratemmyrEditFieldLabel = uilabel(app.Panel_2);
            app.dipratemmyrEditFieldLabel.Text = 'dip-rate (mm/yr)';
            app.BoundDipratemmyrEditField = uieditfield(app.Panel_2, 'text');
            app.BoundDipratemmyrEditField.ValueChangedFcn = createCallbackFcn(app, @BoundDipratemmyrEditFieldValueChanged, true);
            app.BoundDipratemmyrEditField.Value = 'NaN NaN';
            app.normalratemmyrEditFieldLabel = uilabel(app.Panel_2);
            app.normalratemmyrEditFieldLabel.Text = 'normal-rate (mm/yr)';
            app.BoundNormalratemmyrEditField = uieditfield(app.Panel_2, 'text');
            app.BoundNormalratemmyrEditField.ValueChangedFcn = createCallbackFcn(app, @BoundNormalratemmyrEditFieldValueChanged, true);
            app.BoundNormalratemmyrEditField.Value = 'NaN NaN';
            app.DipdegEditField_2Label_2 = uilabel(app.Panel_2);
            app.DipdegEditField_2Label_2.Text = 'rotate all boundaries by (deg)';
            app.BoundRotateAllBy_EditField = uieditfield(app.Panel_2, 'numeric');
            app.BoundRotateAllBy_EditField.Limits = [-180 180];
            app.BoundRotateAllBy_EditField.ValueDisplayFormat = '%3.1f';
            app.Triangulate_Button = uibutton(app.UIFigure, 'push');
            app.Triangulate_Button.ButtonPushedFcn = createCallbackFcn(app, @Triangulate_ButtonPushed, true);
            app.Triangulate_Button.Text = '2) Mesh';
            app.PlotMesh_Button = uibutton(app.UIFigure, 'push');
            app.PlotMesh_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotMesh_ButtonPushed, true);
            app.PlotMesh_Button.Text = 'Plot mesh';
            app.FigEditField_2Label = uilabel(app.UIFigure);
            app.FigEditField_2Label.Text = 'Fig #';
            app.FigNumFaultMesh_EditField = uieditfield(app.UIFigure, 'numeric');
            app.FigNumFaultMesh_EditField.Limits = [0 Inf];
            app.FigNumFaultMesh_EditField.RoundFractionalValues = 'on';
            app.FigNumFaultMesh_EditField.ValueDisplayFormat = '%d';
            app.FigNumFaultMesh_EditField.Value = 3;
            app.SaveMesh_Button = uibutton(app.UIFigure, 'push');
            app.SaveMesh_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveMesh_ButtonPushed, true);
            app.SaveMesh_Button.Text = '3) Save triangulation';
            app.Panel_3 = uipanel(app.UIFigure);
            app.ShifVertex_tButton = uibutton(app.Panel_3, 'push');
            app.ShifVertex_tButton.ButtonPushedFcn = createCallbackFcn(app, @ShifVertex_tButtonPushed, true);
            app.ShifVertex_tButton.Text = 'Shift';
            app.ofDropDown_2Label = uilabel(app.Panel_3);
            app.ofDropDown_2Label.Text = 'of';
            app.Move2_2ndFault_DropDown = uidropdown(app.Panel_3);
            app.Move2_2ndFault_DropDown.Items = {'Fault_1', 'Boundary_Bottom'};
            app.Move2_2ndFault_DropDown.ItemsData = {'1', '2'};
            app.Move2_2ndFault_DropDown.Value = '1';
            app.MoveLabel = uilabel(app.Panel_3);
            app.MoveLabel.Text = 'Move';
            app.Move2_2ndCorner_DropDown = uidropdown(app.Panel_3);
            app.Move2_2ndCorner_DropDown.Items = {'red square', 'green square', 'blue square', 'yellow square'};
            app.Move2_2ndCorner_DropDown.ItemsData = {'1', '2', '3', '4'};
            app.Move2_2ndCorner_DropDown.Value = '1';
            app.toLabel = uilabel(app.Panel_3);
            app.toLabel.Text = 'to';
            app.Move2_1stCorner_DropDown = uidropdown(app.Panel_3);
            app.Move2_1stCorner_DropDown.Items = {'red point', 'green point', 'blue point', 'yellow point'};
            app.Move2_1stCorner_DropDown.ItemsData = {'1', '2', '3', '4'};
            app.Move2_1stCorner_DropDown.Value = '1';
            app.ofDropDownLabel = uilabel(app.Panel_3);
            app.ofDropDownLabel.Text = 'of';
            app.Move2_1stFault_DropDown = uidropdown(app.Panel_3);
            app.Move2_1stFault_DropDown.Items = {'Fault_1', 'Boundary_Bottom'};
            app.Move2_1stFault_DropDown.ItemsData = {'1', '2'};
            app.Move2_1stFault_DropDown.Value = '1';
            app.ShiftingthisoneLabel = uilabel(app.Panel_3);
            app.ShiftingthisoneLabel.Text = '<--------- Shifting this one --------->';
            app.shiftvertexkmLabel = uilabel(app.Panel_3);
            app.shiftvertexkmLabel.Text = 'shift vertex (km)';
            app.FaultVertexPos_DropDown = uidropdown(app.Panel_3);
            app.FaultVertexPos_DropDown.Items = {'red point', 'green point', 'blue point', 'yellow point'};
            app.FaultVertexPos_DropDown.ItemsData = {'1', '2', '3', '4'};
            app.FaultVertexPos_DropDown.ValueChangedFcn = createCallbackFcn(app, @FaultVertexPos_DropDownValueChanged, true);
            app.FaultVertexPos_DropDown.Value = '1';
            app.instrikeEditFieldLabel = uilabel(app.Panel_3);
            app.instrikeEditFieldLabel.Text = 'in strike';
            app.FaultVertexPos_Strike_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FaultVertexPos_Strike_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultVertexPos_Strike_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultVertexPos_Strike_EditFieldValueChanged, true);
            app.indipEditFieldLabel = uilabel(app.Panel_3);
            app.indipEditFieldLabel.Text = 'in dip';
            app.FaultVertexPos_Dip_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FaultVertexPos_Dip_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultVertexPos_Dip_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultVertexPos_Dip_EditFieldValueChanged, true);
            app.outofplaneEditFieldLabel = uilabel(app.Panel_3);
            app.outofplaneEditFieldLabel.Text = 'out-of-plane';
            app.FaultVertexPos_Norm_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FaultVertexPos_Norm_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultVertexPos_Norm_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultVertexPos_Norm_EditFieldValueChanged, true);
            app.Move_KeepCenterFixed_CheckBox = uicheckbox(app.Panel_3);
            app.Move_KeepCenterFixed_CheckBox.Text = 'keep center fixed';
            app.ShowTrigStats_CheckBox = uicheckbox(app.Panel_3);
            app.ShowTrigStats_CheckBox.Enable = 'off';
            app.ShowTrigStats_CheckBox.Visible = 'off';
            app.ShowTrigStats_CheckBox.Text = 'show trig. stats.';
            app.IntendedtrigboundaryLabel = uilabel(app.UIFigure);
            app.IntendedtrigboundaryLabel.Text = 'leg size, boundary (km)';
            app.ElemSizeBound_EditField = uieditfield(app.UIFigure, 'numeric');
            app.ElemSizeBound_EditField.Limits = [0 Inf];
            app.ElemSizeBound_EditField.ValueDisplayFormat = '%3.2f';
            app.ElemSizeBound_EditField.Value = 5;
            app.ActualtrigboundaryLabel = uilabel(app.UIFigure);
            app.ActualtrigboundaryLabel.Text = 'trig # (bound.)';
            app.ElemNumBound_EditField = uieditfield(app.UIFigure, 'numeric');
            app.ElemNumBound_EditField.Limits = [0 Inf];
            app.ElemNumBound_EditField.RoundFractionalValues = 'on';
            app.ElemNumBound_EditField.ValueDisplayFormat = '%d';
            app.Lamp = uilamp(app.UIFigure);
            app.IntendedtrigfaultsLabel = uilabel(app.UIFigure);
            app.IntendedtrigfaultsLabel.Text = 'leg size, fault (km)';
            app.ElemSizeFault_EditField = uieditfield(app.UIFigure, 'numeric');
            app.ElemSizeFault_EditField.Limits = [0 Inf];
            app.ElemSizeFault_EditField.ValueDisplayFormat = '%3.2f';
            app.ElemSizeFault_EditField.Value = 1;
            app.ActualtrigfaultsLabel = uilabel(app.UIFigure);
            app.ActualtrigfaultsLabel.Text = 'trig # (faults)';
            app.ElemNumFault_EditField = uieditfield(app.UIFigure, 'numeric');
            app.ElemNumFault_EditField.Limits = [0 Inf];
            app.ElemNumFault_EditField.RoundFractionalValues = 'on';
            app.ElemNumFault_EditField.ValueDisplayFormat = '%d';
            app.ActualtrigfaultsLabel_3 = uilabel(app.UIFigure);
            app.ActualtrigfaultsLabel_3.Text = '~single-element EQ size';
            app.SingleElementEQsize_EditField = uieditfield(app.UIFigure, 'numeric');
            app.SingleElementEQsize_EditField.ValueDisplayFormat = '%4.2f';
            app.SingleElementEQsize_EditField.Editable = 'off';
            app.PlotCompFault_DropDown = uidropdown(app.UIFigure);
            app.PlotCompFault_DropDown.Items = {'Fault ID', 'useSlip', 'Rate', 'Direction'};
            app.PlotCompFault_DropDown.ItemsData = {'1', '2', '3', '4'};
            app.PlotCompFault_DropDown.Value = '1';
            app.PlotCompBound_DropDown = uidropdown(app.UIFigure);
            app.PlotCompBound_DropDown.Items = {'Boundary ID', 'strike slip-rate', 'dip slip-rate', 'normal slip-rate'};
            app.PlotCompBound_DropDown.ItemsData = {'1', '2', '3', '4'};
            app.PlotCompBound_DropDown.Value = '1';
            app.ShowMesh_CheckBox = uicheckbox(app.UIFigure);
            app.ShowMesh_CheckBox.Text = 'show mesh';
            app.ShowMesh_CheckBox.Value = true;
            app.AxisEqual_CheckBox = uicheckbox(app.UIFigure);
            app.AxisEqual_CheckBox.Text = 'axis equal';
            app.AxisEqual_CheckBox.Value = true;
            app.viewangleLabel = uilabel(app.UIFigure);
            app.viewangleLabel.Text = 'view angle';
            app.ViewAngle_EditField = uieditfield(app.UIFigure, 'text');
            app.ViewAngle_EditField.Value = '40 20';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A2_EnterFault_MCQsimV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end