classdef A3_FaultGeomFromSTL_MCQsimV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SavetriangulationButton         matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        viewangleEditField              matlab.ui.control.EditField
        viewangleEditFieldLabel         matlab.ui.control.Label
        FigEditField                    matlab.ui.control.NumericEditField
        FigEditFieldLabel               matlab.ui.control.Label
        PlotCompBound_DropDown          matlab.ui.control.DropDown
        PlotCompFault_DropDown          matlab.ui.control.DropDown
        PlotMeshButton                  matlab.ui.control.Button
        ShowMesh_CheckBox               matlab.ui.control.CheckBox
        AxisEqual_CheckBox              matlab.ui.control.CheckBox
        Panel_2                         matlab.ui.container.Panel
        FaultIDEditField                matlab.ui.control.NumericEditField
        FaultIDEditFieldLabel           matlab.ui.control.Label
        FaultStressDirection_EditField  matlab.ui.control.NumericEditField
        RakedegLabel_2                  matlab.ui.control.Label
        FaultStressRate_EditField       matlab.ui.control.NumericEditField
        SlipratemmyrLabel_2             matlab.ui.control.Label
        FaultSlipRake_EditField         matlab.ui.control.NumericEditField
        RakedegLabel                    matlab.ui.control.Label
        FaultSlipRate_EditField         matlab.ui.control.NumericEditField
        SlipratemmyrLabel               matlab.ui.control.Label
        UseregionalstressCheckBox       matlab.ui.control.CheckBox
        UsefaultsliprateCheckBox        matlab.ui.control.CheckBox
        SingleElementEQsize_EditField   matlab.ui.control.NumericEditField
        ActualtrigfaultsLabel           matlab.ui.control.Label
        faultelementEditField           matlab.ui.control.NumericEditField
        faultelementEditFieldLabel      matlab.ui.control.Label
        inmCheckBox                     matlab.ui.control.CheckBox
        inkmCheckBox                    matlab.ui.control.CheckBox
        Lamp                            matlab.ui.control.Lamp
        FaultnameEditField              matlab.ui.control.EditField
        FaultnameEditFieldLabel         matlab.ui.control.Label
        importedfaultsListBox           matlab.ui.control.ListBox
        importedfaultsListBoxLabel      matlab.ui.control.Label
        LoadImportSTLfilesButton        matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        boundaryelementEditField        matlab.ui.control.NumericEditField
        boundaryelementEditFieldLabel   matlab.ui.control.Label
        BoundElemSize_EditField         matlab.ui.control.NumericEditField
        DipdegEditField_2Label_2        matlab.ui.control.Label
        DeleteBound_Button              matlab.ui.control.Button
        AddBound_Button                 matlab.ui.control.Button
        BoundRotateAllBy_EditField      matlab.ui.control.NumericEditField
        DipdegEditField_2Label_3        matlab.ui.control.Label
        BoundNormalratemmyrEditField    matlab.ui.control.EditField
        normalratemmyrEditFieldLabel    matlab.ui.control.Label
        BoundDipratemmyrEditField       matlab.ui.control.EditField
        dipratemmyrEditFieldLabel       matlab.ui.control.Label
        BoundStrikeratemmyrEditField    matlab.ui.control.EditField
        strikeratemmyrEditFieldLabel    matlab.ui.control.Label
        BoundApplyRateAt_DropDown       matlab.ui.control.DropDown
        applyrateDropDownLabel          matlab.ui.control.Label
        BoundName_EditField             matlab.ui.control.EditField
        NameEditField_2Label            matlab.ui.control.Label
        BoundDip_EditField              matlab.ui.control.NumericEditField
        DipdegEditField_2Label          matlab.ui.control.Label
        BoundStrike_EditField           matlab.ui.control.NumericEditField
        StrikedegEditField_2Label       matlab.ui.control.Label
        BoundWidth_EditField            matlab.ui.control.NumericEditField
        WidthkmEditField_2Label         matlab.ui.control.Label
        BoundLength_EditField           matlab.ui.control.NumericEditField
        LengthkmEditField_2Label        matlab.ui.control.Label
        BoundDepth_EditField            matlab.ui.control.NumericEditField
        DepthtopcenterkmEditField_2Label  matlab.ui.control.Label
        BoundNorthing_EditField         matlab.ui.control.NumericEditField
        NorthingtopcenterkmEditField_2Label  matlab.ui.control.Label
        BoundEasting_EditField          matlab.ui.control.NumericEditField
        EastingtopcenterkmEditField_2Label  matlab.ui.control.Label
        Bound_ListBox                   matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        FaultNames     = { };
        BoundaryNames  = {'Boundary_Bottom'};
        BoundaryValues = [ -80,         0,      -22,    300,   160,      0, 0,   1,  NaN, NaN, NaN,      NaN, NaN, NaN];
        Fault      = struct;
        Bound      = struct;
        FaultsImported = 0;
    end
    
    methods (Access = private)
        %------------------------------------------------------------------
        %------------------------------------------------------------------
        function InCentPos  = GetInCenter(~, Vert_A,Vert_B, Vert_C)
            %https://www.geeksforgeeks.org/program-to-find-the-incenter-of-a-triangle/
            a = sqrt(sum((Vert_C-Vert_B).^2));
            b = sqrt(sum((Vert_A-Vert_C).^2));
            c = sqrt(sum((Vert_B-Vert_A).^2));

            InCentPos(1,1) = (a*Vert_A(1,1) +b*Vert_B(1,1) +c*Vert_C(1,1))/(a +b +c);
            InCentPos(2,1) = (a*Vert_A(2,1) +b*Vert_B(2,1) +c*Vert_C(2,1))/(a +b +c);
            InCentPos(3,1) = (a*Vert_A(3,1) +b*Vert_B(3,1) +c*Vert_C(3,1))/(a +b +c);
        end
        %------------------------------------------------------------------
        function MeshBound(app)
            %---------------------------------------------- 
            app.Bound              = struct;
            app.Bound.TriangleList = [];
            app.Bound.VertexList   = [];
            %--------------------------
            for SelBound = 1 : size(app.BoundaryValues,1)
                BoundCent_E = app.BoundaryValues(SelBound,1);
                BoundCent_N = app.BoundaryValues(SelBound,2);
                BoundCent_Z = app.BoundaryValues(SelBound,3);
                Bound_Lgth  = app.BoundaryValues(SelBound,4);
                Bound_Wdth  = app.BoundaryValues(SelBound,5);
                Bound_Stk   = app.BoundaryValues(SelBound,6)*pi/180;
                Bound_Dip   = app.BoundaryValues(SelBound,7)*pi/180;
                %--------------------------
                UL_stk = -Bound_Lgth/2;     UR_stk =  Bound_Lgth/2;     LL_stk = -Bound_Lgth/2;     LR_stk = Bound_Lgth/2;
                UL_dip = 0;                 UR_dip =  0;                LL_dip = -Bound_Wdth;       LR_dip =-Bound_Wdth;
                UL_nrm = 0;                 UR_nrm =  0;                LL_nrm = 0;                 LR_nrm =  0;   
                stkA   = [sin(Bound_Stk), cos(Bound_Stk), 0];
                nrmA   = [sin(Bound_Dip)*cos(Bound_Stk), -sin(Bound_Dip)*sin(Bound_Stk), cos(Bound_Dip)];
                dipA   = cross(nrmA,stkA);
                RotMat = [stkA;dipA;nrmA];
                RotMatT= RotMat';
                %-------------------- 
                l_left  = [LL_stk;LL_dip;LL_nrm];           l_right = [LR_stk;LR_dip;LR_nrm];
                u_right = [UR_stk;UR_dip;UR_nrm];           u_left  = [UL_stk;UL_dip;UL_nrm];
                %-------------------- 
                TopPoints  = [u_left'; u_right'];           BotPoints  = [l_left'; l_right'];
                %-------------------- 
                TopLength  = sum(sqrt(sum(diff(TopPoints(:,1:3)).^2,2)));
                BotLength  = sum(sqrt(sum(diff(BotPoints(:,1:3)).^2,2)));
                ElemNumS   = floor(((TopLength+BotLength)/2)/app.BoundElemSize_EditField.Value);
                %---------------------------------------------------------
                UsedSdeLgh = TopLength/ElemNumS;
                PntListTop = zeros(ElemNumS+1,3);
                PntListTop(1,:)   = TopPoints(1,:);
                PntListTop(end,:) = TopPoints(end,:);
                CurrLocn   = TopPoints(1,:);
                Counter    = 1;
                TrgtPos    = 2;
                UsedAmount = 0;
                %--------------------------
                while (Counter < ElemNumS)
                    TrgtLocn  = TopPoints(TrgtPos,:);
                    Vect2Next = TrgtLocn - CurrLocn;
                    VectLgth  = sqrt(sum(Vect2Next.^2));
                    if ((UsedSdeLgh-UsedAmount) <= (VectLgth))
                        CurrLocn = CurrLocn + ((UsedSdeLgh-UsedAmount)/VectLgth).*Vect2Next;
                        Counter  = Counter  +1;
                        PntListTop(Counter,:) = CurrLocn;
                        UsedAmount = 0;
                    else
                        CurrLocn   = TopPoints(TrgtPos,:);
                        TrgtPos    = TrgtPos +1;
                        UsedAmount = UsedAmount + VectLgth;
                    end
                end
                %--------------------------
                UsedSdeLgh = BotLength/ElemNumS;
                PntListBot = zeros(ElemNumS+1,3);
                PntListBot(1,:)   = BotPoints(1,:);
                PntListBot(end,:) = BotPoints(end,:);
                CurrLocn   = BotPoints(1,:);
                Counter    = 1;
                TrgtPos    = 2;
                UsedAmount = 0;
                %--------------------------
                while (Counter < ElemNumS)
                    TrgtLocn  = BotPoints(TrgtPos,:);
                    Vect2Next = TrgtLocn - CurrLocn;
                    VectLgth  = sqrt(sum(Vect2Next.^2));
                    if ((UsedSdeLgh-UsedAmount) <= (VectLgth))
                        CurrLocn = CurrLocn + ((UsedSdeLgh-UsedAmount)/VectLgth).*Vect2Next;
                        Counter  = Counter  +1;
                        PntListBot(Counter,:) = CurrLocn;
                        UsedAmount = 0;
                    else
                        CurrLocn   = BotPoints(TrgtPos,:);
                        TrgtPos    = TrgtPos +1;
                        UsedAmount = UsedAmount + VectLgth;
                    end
                end
                %---------------------------------------------------------
                VectLgths   = sqrt( sum((PntListBot - PntListTop).^2,2));
                ElemNumD    = floor(mean(VectLgths)/app.BoundElemSize_EditField.Value);
                
                IniPointMat = zeros(ElemNumS,ElemNumD,4);
                IDCount     = 0;
                %----------------------------------------------
                for i = 1 : (ElemNumS+1)
                    IDCount             = IDCount+1;
                    IniPointMat(i,1,1:3)= PntListTop(i,1:3);
                    IniPointMat(i,1,4)  = IDCount;
                    CurrLocn            = PntListTop(i,1:3);
                    EndLocn             = PntListBot(i,1:3);
    
                    NextVect   = EndLocn- CurrLocn;
                    VectLgth   = sqrt(sum(NextVect.^2));
                    UsedSdeLgh = VectLgth/ElemNumD;
                    Counter    = 1;
                    %--------------------------
                    while (Counter < ElemNumD)
                        IDCount   = IDCount+1;
                        Counter   = Counter+1;
                        Vect2Next = EndLocn - CurrLocn;
                        VectLgth  = sqrt(sum(Vect2Next.^2));
                        CurrLocn  = CurrLocn + (UsedSdeLgh/VectLgth).*Vect2Next;
                       
                        IniPointMat(i,Counter,1:3) = CurrLocn;
                        IniPointMat(i,Counter,4)   = IDCount;
                    end
                    %--------------------------
                    IDCount   = IDCount+1;
                    Counter   = Counter+1;
                    IniPointMat(i,Counter,1:3) = EndLocn;
                    IniPointMat(i,Counter,4)   = IDCount;
                end
                %----------------------------------------------
                VertexList = zeros(((ElemNumS+1)*(ElemNumD+1) + (ElemNumS*ElemNumD)), 3);
                TrigListe  = zeros(ElemNumS*ElemNumD*4, 10);
                VertCount  = 0;
                TrigCount  = 0;
                %----------------------------------------------
                for i = 1 : (ElemNumS+1)
                    for j = 1 : (ElemNumD+1)
                        VertCount = VertCount +1;
                        VertexList(VertCount,:) = squeeze(IniPointMat(i,j,1:3));
                    end
                end
                %----------------------------------------------
                for i = 1 : ElemNumS
                    for j = 1 : ElemNumD
                        %----------------------------------------------
                        StkRate = NaN;      DipRate = NaN;      NrmRate = NaN;
                        %----------------------------------------------
                        if (app.BoundaryValues(SelBound,8) == 1) %top/bottom 
                            if     (j == 1)          
                                if (~isnan(app.BoundaryValues(SelBound,9))),     StkRate = app.BoundaryValues(SelBound,9);      end     
                                if (~isnan(app.BoundaryValues(SelBound,10))),    DipRate = app.BoundaryValues(SelBound,10);     end 
                                if (~isnan(app.BoundaryValues(SelBound,11))),    NrmRate = app.BoundaryValues(SelBound,11);     end 
                            elseif (j == ElemNumD)  
                                if (~isnan(app.BoundaryValues(SelBound,12))),    StkRate = app.BoundaryValues(SelBound,12);     end     
                                if (~isnan(app.BoundaryValues(SelBound,13))),    DipRate = app.BoundaryValues(SelBound,13);     end 
                                if (~isnan(app.BoundaryValues(SelBound,14))),    NrmRate = app.BoundaryValues(SelBound,14);     end  
                            end
                        else
                            if     (i == 1)        
                                if (~isnan(app.BoundaryValues(SelBound,9))),     StkRate = app.BoundaryValues(SelBound,9);      end     
                                if (~isnan(app.BoundaryValues(SelBound,10))),    DipRate = app.BoundaryValues(SelBound,10);     end 
                                if (~isnan(app.BoundaryValues(SelBound,11))),    NrmRate = app.BoundaryValues(SelBound,11);     end 
                            elseif (i == ElemNumS)  
                                if (~isnan(app.BoundaryValues(SelBound,12))),    StkRate = app.BoundaryValues(SelBound,12);     end     
                                if (~isnan(app.BoundaryValues(SelBound,13))),    DipRate = app.BoundaryValues(SelBound,13);     end 
                                if (~isnan(app.BoundaryValues(SelBound,14))),    NrmRate = app.BoundaryValues(SelBound,14);     end              
                            end
                        end
                        %----------------------------------------------
                        VertCount = VertCount +1;
                        Vert_A = squeeze(IniPointMat(i,j,:));
                        Vert_B = squeeze(IniPointMat(i+1,j,:));
                        Vert_C = squeeze(IniPointMat(i,j+1,:));
                        Vert_D = squeeze(IniPointMat(i+1,j+1,:));
                        Vert_E = [(Vert_A(1:3,1)+Vert_B(1:3,1)+Vert_C(1:3,1)+Vert_D(1:3,1))./4; VertCount];
                        %-------------------------------
                        VertexList(VertCount,:) = Vert_E(1:3,1);
                        %-------------------------------
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_A(1:3,1), Vert_B(1:3,1), Vert_E(1:3,1));
                        TrigListe(TrigCount,:) = [Vert_A(4,1), Vert_B(4,1), Vert_E(4,1), SelBound, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1), StkRate, DipRate, NrmRate];
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_B(1:3,1), Vert_D(1:3,1), Vert_E(1:3,1));
                        TrigListe(TrigCount,:) = [Vert_B(4,1), Vert_D(4,1), Vert_E(4,1), SelBound, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1), StkRate, DipRate, NrmRate];
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_D(1:3,1), Vert_C(1:3,1), Vert_E(1:3,1));
                        TrigListe(TrigCount,:) = [Vert_D(4,1), Vert_C(4,1), Vert_E(4,1), SelBound, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1), StkRate, DipRate, NrmRate];
                        TrigCount  = TrigCount +1;      
                        InCentPos  = GetInCenter(app,Vert_C(1:3,1), Vert_A(1:3,1), Vert_E(1:3,1));
                        TrigListe(TrigCount,:) = [Vert_C(4,1), Vert_A(4,1), Vert_E(4,1), SelBound, InCentPos(1,1),InCentPos(2,1),InCentPos(3,1), StkRate, DipRate, NrmRate];
                    end
                end
                %----------------------------------------------
                %----------------------------------------------
                SelBounds = ~isnan(TrigListe(:,8));
                if (sum(SelBounds(:) > 0))
                    SelCentE       = TrigListe(SelBounds,5);         SelCentN  = TrigListe(SelBounds,6);         SelCentC  = TrigListe(SelBounds,8);
                    AllCentE       = TrigListe(:,5);                 AllCentN  = TrigListe(:,6);
                    F_stk          = scatteredInterpolant(SelCentE,SelCentN,SelCentC,'natural');
                    TrigListe(:,8) = F_stk(AllCentE,AllCentN);
                else
                    TrigListe(:,8) = 0;
                end
                %----------------------------------------------
                SelBounds = ~isnan(TrigListe(:,9));
                if (sum(SelBounds(:) > 0))
                    SelCentE       = TrigListe(SelBounds,5);         SelCentN  = TrigListe(SelBounds,6);         SelCentC  = TrigListe(SelBounds,9);
                    AllCentE       = TrigListe(:,5);                 AllCentN  = TrigListe(:,6);
                    F_stk          = scatteredInterpolant(SelCentE,SelCentN,SelCentC,'natural');
                    TrigListe(:,9) = F_stk(AllCentE,AllCentN);
                else
                    TrigListe(:,9) = 0;
                end
                %----------------------------------------------
                SelBounds = ~isnan(TrigListe(:,10));
                if (sum(SelBounds(:) > 0))
                    SelCentE       = TrigListe(SelBounds,5);         SelCentN  = TrigListe(SelBounds,6);         SelCentC  = TrigListe(SelBounds,10);
                    AllCentE       = TrigListe(:,5);                 AllCentN  = TrigListe(:,6);
                    F_stk          = scatteredInterpolant(SelCentE,SelCentN,SelCentC,'natural');
                    TrigListe(:,10)= F_stk(AllCentE,AllCentN);
                else
                    TrigListe(:,10) = 0;
                end
                %----------------------------------------------
                %----------------------------------------------
                VertexListNew = RotMatT*VertexList' +[BoundCent_E;BoundCent_N;BoundCent_Z];
                %----------------------------------------------
                for i = 1 : size(TrigListe,1)
                    Vert_A = VertexListNew(:,TrigListe(i,1));
                    Vert_B = VertexListNew(:,TrigListe(i,2));
                    Vert_C = VertexListNew(:,TrigListe(i,3));
                    InCentPos  = GetInCenter(app,Vert_A, Vert_B, Vert_C);
                    TrigListe(i,5) = InCentPos(1,1);
                    TrigListe(i,6) = InCentPos(2,1);
                    TrigListe(i,7) = InCentPos(3,1);
                end
                %----------------------------------------------
                %----------------------------------------------

                TrigListe(:,1:3)       = TrigListe(:,1:3) + size(app.Bound.VertexList,1);
                app.Bound.TriangleList = [app.Bound.TriangleList ; TrigListe];
                app.Bound.VertexList   = [app.Bound.VertexList; VertexListNew'];
                %----------------------------------------------
                %----------------------------------------------
            end
            %--------------------------
            RandListOrder    = [rand(size(app.Bound.TriangleList,1),1), cumsum(ones(size(app.Bound.TriangleList,1),1))];
            RandListOrder    = sortrows(RandListOrder);
            app.Bound.TriangleList = app.Bound.TriangleList(RandListOrder(:,2),:);
            %--------------------------
            app.boundaryelementEditField.Value = size(app.Bound.TriangleList,1);
            %----------------------------------------------
            RotAngle         = app.BoundRotateAllBy_EditField.Value;
            MeanENofBoundary = mean(app.Bound.VertexList(:,1:2));
            TempEN           = app.Bound.VertexList(:,1:2) - MeanENofBoundary;
            %----------------------------------------------
            app.Bound.VertexList(:,1) =  TempEN(:,1)*cosd(RotAngle) + TempEN(:,2)*sind(RotAngle) + MeanENofBoundary(1,1);
            app.Bound.VertexList(:,2) = -TempEN(:,1)*sind(RotAngle) + TempEN(:,2)*cosd(RotAngle) + MeanENofBoundary(1,2);
            %----------------------------------------------
            for i = 1 : size(app.Bound.TriangleList,1)
                Vert_A = app.Bound.VertexList(app.Bound.TriangleList(i,1),:)';
                Vert_B = app.Bound.VertexList(app.Bound.TriangleList(i,2),:)';
                Vert_C = app.Bound.VertexList(app.Bound.TriangleList(i,3),:)';
                InCentPos  = GetInCenter(app,Vert_A, Vert_B, Vert_C);
                app.Bound.TriangleList(i,5) = InCentPos(1,1);
                app.Bound.TriangleList(i,6) = InCentPos(2,1);
                app.Bound.TriangleList(i,7) = InCentPos(3,1);

                if (app.BoundaryValues(app.Bound.TriangleList(i,4),7) == 0)
                    CombStk     = (app.BoundaryValues(app.Bound.TriangleList(i,4),6) + RotAngle);
                    TempStkRate = app.Bound.TriangleList(i,8)*cosd(CombStk) + app.Bound.TriangleList(i,9)*sind(CombStk); 
                    TempDipRate =-app.Bound.TriangleList(i,8)*sind(CombStk) + app.Bound.TriangleList(i,9)*cosd(CombStk); 
                    app.Bound.TriangleList(i,8) = TempStkRate;
                    app.Bound.TriangleList(i,9) = TempDipRate;
                end
            end
            %----------------------------------------------    
        end
        %------------------------------------------------------------------
        %------------------------------------------------------------------
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImportSTLfilesButton
        function LoadImportSTLfilesButtonPushed(app, event)
            %---------------------------------------------
            IniVertNum = 10000000;
            %---------------------------------------------
            if (app.inmCheckBox.Value  == 1),       ScaleFact = 1000;
            else,                                   ScaleFact = 1;
            end
            %---------------------------------------------
            [LoadName,LoadPath] = uigetfile('*.stl','Load STL file','MultiSelect','on');
            if (~iscell(LoadName))
                if     (LoadName ~=0),      LoadName = cellstr(LoadName);
                elseif (LoadName ==0),      return;      
                end   
            end
            %---------------------------------------------
            app.FaultsImported  = 0;
            app.FaultNames      = {};
            app.Fault.VertexList  = [];
            app.Fault.TriangleList = [];
            app.Fault.Values    = [];
            app.Lamp.Color = 'yellow';  drawnow;
            %---------------------------------------------
            for i = 1 : size(LoadName,2)
                STLPath  = strcat(LoadPath,LoadName{1,i});
                fidasc   = fopen(STLPath ,'r');   
                app.FaultNames(i) = extractBefore(LoadName(1,i),'.stl');
                %--------------------
                VertCounter  = 0;
                NewVertices  = zeros(IniVertNum,4);
                NewTriangles = zeros(IniVertNum,11);
                %--------------------
                nextline = fgetl(fidasc);
                while 1
                    nextline      = fgetl(fidasc);    if (nextline == -1),      break;          end        
                    nextline      = fgetl(fidasc);    if (nextline == -1),      break;          end      
                    nextline      = fgetl(fidasc);    if (nextline == -1),      break;          end,        Vertex1 = sscanf(nextline,'%*s %e %e %e');
                    nextline      = fgetl(fidasc);    if (nextline == -1),      break;          end,        Vertex2 = sscanf(nextline,'%*s %e %e %e'); 
                    nextline      = fgetl(fidasc);    if (nextline == -1),      break;          end,        Vertex3 = sscanf(nextline,'%*s %e %e %e');  
                    nextline      = fgetl(fidasc);    if (nextline == -1),      break;          end
                    nextline      = fgetl(fidasc);    if (nextline == -1),      break;          end
                    VertCounter   = VertCounter +1;   NewVertices(VertCounter,1:3) = Vertex1'./ScaleFact;   
                    VertCounter   = VertCounter +1;   NewVertices(VertCounter,1:3) = Vertex2'./ScaleFact;
                    VertCounter   = VertCounter +1;   NewVertices(VertCounter,1:3) = Vertex3'./ScaleFact;
                end
                fclose(fidasc);
                %--------------------
                NewVertices = NewVertices(1:VertCounter,:);
                %---------------------------------------------
                [NewVertices,~,IC] = unique(NewVertices,'rows','stable');
                for j = 1 : VertCounter/3
                    Vert1 = IC((j-1)*3 +1);            Vert2 = IC((j-1)*3 +2);         Vert3 = IC((j-1)*3 +3);
                    %--------------------
                    InCentPos         = GetInCenter(app,NewVertices(Vert1,1:3)', NewVertices(Vert2,1:3)', NewVertices(Vert3,1:3)');
                    NewTriangles(j,:) = [Vert1, Vert2, Vert3, i, i, 1, 0, 0, InCentPos(1,1), InCentPos(2,1), InCentPos(3,1)];
                end
                %---------------------------------------------
                NewTriangles          = NewTriangles(1:VertCounter/3,:);
                NewTriangles(:,1:3)   = NewTriangles(:,1:3) + size(app.Fault.VertexList,1);
                app.Fault.VertexList    = [app.Fault.VertexList; NewVertices];
                app.Fault.TriangleList   = [app.Fault.TriangleList; NewTriangles];
                app.Fault.Values(i,:) = [i, 1 0 0 0 0];
                %---------------------------------------------
            end
            %---------------------------------------------
            PatchAreasFault = zeros(size(app.Fault.TriangleList,1),1);
            %---------------------------------------------
            for i = 1 : size(app.Fault.TriangleList,1)
                v1   = app.Fault.TriangleList(i,1);
                v2   = app.Fault.TriangleList(i,2);
                v3   = app.Fault.TriangleList(i,3);
                v1v2 = app.Fault.VertexList(v2,1:3)- app.Fault.VertexList(v1,1:3);
                v2v3 = app.Fault.VertexList(v3,1:3)- app.Fault.VertexList(v2,1:3);

                PatchAreasFault(i,1) = 0.5*sqrt(sum(cross(v1v2,v2v3).^2));
            end
            app.SingleElementEQsize_EditField.Value = log10(mean(PatchAreasFault)) +3.98; %HanksAndBakun2008
            %---------------------------------------------
            app.FaultnameEditField.Value        = app.FaultNames{1};
            app.importedfaultsListBox.Items     = app.FaultNames;
            app.importedfaultsListBox.ItemsData = 1 : size(app.FaultNames,2);
            app.importedfaultsListBox.Value     = 1;
            app.faultelementEditField.Value    = size(app.Fault.TriangleList,1);
            %---------------------------------------------
            app.Lamp.Color = 'green';       drawnow;
            app.FaultsImported = 1;
        end

        % Value changed function: inmCheckBox
        function inmCheckBoxValueChanged(app, event)
            app.inmCheckBox.Value  = 1;
            app.inkmCheckBox.Value = 0;
        end

        % Value changed function: inkmCheckBox
        function inkmCheckBoxValueChanged(app, event)
            app.inmCheckBox.Value  = 0;
            app.inkmCheckBox.Value = 1;
        end

        % Value changed function: BoundName_EditField
        function BoundName_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            app.BoundaryNames(SelBoundPos) = {app.BoundName_EditField.Value};
            app.Bound_ListBox.Items        = app.BoundaryNames;
            app.Bound_ListBox.ItemsData    = 1:size(app.BoundaryNames,2);
            app.Bound_ListBox.Value        = 1;               
        end

        % Value changed function: BoundEasting_EditField
        function BoundEasting_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end          
            app.BoundaryValues(SelBoundPos,1) = app.BoundEasting_EditField.Value;
        end

        % Value changed function: BoundNorthing_EditField
        function BoundNorthing_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end           
            app.BoundaryValues(SelBoundPos,2) = app.BoundNorthing_EditField.Value;
        end

        % Value changed function: BoundDepth_EditField
        function BoundDepth_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end           
            app.BoundaryValues(SelBoundPos,3) = app.BoundDepth_EditField.Value;   
        end

        % Value changed function: BoundLength_EditField
        function BoundLength_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end         
            app.BoundaryValues(SelBoundPos,4) = app.BoundLength_EditField.Value;  
        end

        % Value changed function: BoundWidth_EditField
        function BoundWidth_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end          
            app.BoundaryValues(SelBoundPos,5) = app.BoundWidth_EditField.Value;   
        end

        % Value changed function: BoundStrike_EditField
        function BoundStrike_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end          
            app.BoundaryValues(SelBoundPos,6) = app.BoundStrike_EditField.Value;  
        end

        % Value changed function: BoundDip_EditField
        function BoundDip_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end         
            app.BoundaryValues(SelBoundPos,7) = app.BoundDip_EditField.Value; 
        end

        % Value changed function: Bound_ListBox
        function Bound_ListBoxValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end

            app.BoundName_EditField.Value       = app.BoundaryNames{SelBoundPos};
            app.BoundEasting_EditField.Value    = app.BoundaryValues(SelBoundPos,1);
            app.BoundNorthing_EditField.Value   = app.BoundaryValues(SelBoundPos,2);
            app.BoundDepth_EditField.Value      = app.BoundaryValues(SelBoundPos,3);
            app.BoundLength_EditField.Value     = app.BoundaryValues(SelBoundPos,4);
            app.BoundWidth_EditField.Value      = app.BoundaryValues(SelBoundPos,5);
            app.BoundStrike_EditField.Value     = app.BoundaryValues(SelBoundPos,6);
            app.BoundDip_EditField.Value        = app.BoundaryValues(SelBoundPos,7);   
            app.BoundApplyRateAt_DropDown.Value = app.BoundApplyRateAt_DropDown.ItemsData(app.BoundaryValues(SelBoundPos,8));
            %-------------------------------------------------- 
            app.BoundStrikeratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(SelBoundPos, 9)),"  ",num2str(app.BoundaryValues(SelBoundPos,12)));
            app.BoundDipratemmyrEditField.Value    = strcat(num2str(app.BoundaryValues(SelBoundPos,10)),"  ",num2str(app.BoundaryValues(SelBoundPos,13)));
            app.BoundNormalratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(SelBoundPos,11)),"  ",num2str(app.BoundaryValues(SelBoundPos,14)));

        end

        % Button pushed function: AddBound_Button
        function AddBound_ButtonPushed(app, event)
            s = 'ABCDEFGHIJKLMNOPQRSTUVWXYZabcdefghijklmnopqrstuvwxyz0123456789';
            sLength      = 3;
            RandomName   = s(randi([1, 62],1,sLength));
            NewBndName   = strcat('Boundary_',RandomName);
            BoundaryInList                      = size(app.BoundaryValues,1);
            app.BoundaryNames(BoundaryInList+1) = {NewBndName};
            %---------------------------------------
            NewBoundary                         = [  rand(1), rand(1), -rand(1), (rand(1)*10+5), (rand(1)*10+5), (rand(1)*360), (rand(1)*90),   1, NaN, NaN, NaN,      NaN, NaN, NaN];
            app.BoundaryValues                  = [app.BoundaryValues; NewBoundary];
            %---------------------------------------
            app.BoundName_EditField.Value       = NewBndName;
            app.BoundEasting_EditField.Value    = app.BoundaryValues(end,1);
            app.BoundNorthing_EditField.Value   = app.BoundaryValues(end,2);
            app.BoundDepth_EditField.Value      = app.BoundaryValues(end,3);
            app.BoundLength_EditField.Value     = app.BoundaryValues(end,4);
            app.BoundWidth_EditField.Value      = app.BoundaryValues(end,5);
            app.BoundStrike_EditField.Value     = app.BoundaryValues(end,6);
            app.BoundDip_EditField.Value        = app.BoundaryValues(end,7);
            app.BoundApplyRateAt_DropDown.Value = app.BoundApplyRateAt_DropDown.ItemsData(app.BoundaryValues(end,8));
            app.BoundStrikeratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(end, 9)),"  ",num2str(app.BoundaryValues(end,12)));
            app.BoundDipratemmyrEditField.Value    = strcat(num2str(app.BoundaryValues(end,10)),"  ",num2str(app.BoundaryValues(end,13)));
            app.BoundNormalratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(end,11)),"  ",num2str(app.BoundaryValues(end,14)));
            %---------------------------------------
            app.Bound_ListBox.Items     = app.BoundaryNames;
            app.Bound_ListBox.ItemsData = 1:size(app.BoundaryNames,2);
            app.Bound_ListBox.Value     = size(app.BoundaryNames,2);
            %---------------------------------------     
        end

        % Button pushed function: DeleteBound_Button
        function DeleteBound_ButtonPushed(app, event)
            if (size(app.BoundaryValues,1) < 1),           return;                                     end
            SelBoundPos  = app.Bound_ListBox.Value;   
            if (~isa(SelBoundPos,'double')),        SelBoundPos = str2double(SelBoundPos);             end
            %---------------------------------------
            app.BoundaryNames(SelBoundPos)         = [];
            app.BoundaryValues(SelBoundPos,:)      = [];
            %---------------------------------------
            if (size(app.BoundaryValues,1) < 1)   
                app.BoundaryNames                   = {'No Boundary used'};              
                app.BoundName_EditField.Value       = 'No Boundary used';
                app.BoundNorthing_EditField.Value   = 0;
                app.BoundEasting_EditField.Value    = 0;
                app.BoundDepth_EditField.Value      = 0;
                app.BoundLength_EditField.Value     = 0;
                app.BoundWidth_EditField.Value      = 0;
                app.BoundStrike_EditField.Value     = 0;
                app.BoundDip_EditField.Value        = 0;   
                app.BoundStrikeratemmyrEditField.Value = 'NaN NaN';
                app.BoundDipratemmyrEditField.Value    = 'NaN NaN';
                app.BoundNormalratemmyrEditField.Value = 'NaN NaN';
            else
                app.BoundName_EditField.Value       = app.BoundaryNames{1};
                app.BoundNorthing_EditField.Value   = app.BoundaryValues(1,1);
                app.BoundEasting_EditField.Value    = app.BoundaryValues(1,2);
                app.BoundDepth_EditField.Value      = app.BoundaryValues(1,3);
                app.BoundLength_EditField.Value     = app.BoundaryValues(1,4);
                app.BoundWidth_EditField.Value      = app.BoundaryValues(1,5);
                app.BoundStrike_EditField.Value     = app.BoundaryValues(1,6);
                app.BoundDip_EditField.Value        = app.BoundaryValues(1,7);
                app.BoundApplyRateAt_DropDown.Value = app.BoundApplyRateAt_DropDown.ItemsData(app.BoundaryValues(1,8));
                app.BoundStrikeratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(end, 9)),"  ",num2str(app.BoundaryValues(end,12)));
                app.BoundDipratemmyrEditField.Value    = strcat(num2str(app.BoundaryValues(end,10)),"  ",num2str(app.BoundaryValues(end,13)));
                app.BoundNormalratemmyrEditField.Value = strcat(num2str(app.BoundaryValues(end,11)),"  ",num2str(app.BoundaryValues(end,14)));
            end
            %---------------------------------------
            app.Bound_ListBox.Items     = app.BoundaryNames;
            app.Bound_ListBox.ItemsData = 1:size(app.BoundaryNames,2);
            app.Bound_ListBox.Value     = size(app.BoundaryNames,2);
            %---------------------------------------  
        end

        % Value changed function: FaultnameEditField
        function FaultnameEditFieldValueChanged(app, event)
            SelFaultPos                 = app.importedfaultsListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end
            app.FaultNames{SelFaultPos} = app.FaultnameEditField.Value;
            app.importedfaultsListBox.Items     = app.FaultNames;
            app.importedfaultsListBox.ItemsData = 1:size(app.FaultNames,2);
            app.importedfaultsListBox.Value     = 1;
        end

        % Value changed function: FaultIDEditField
        function FaultIDEditFieldValueChanged(app, event)
            if (app.FaultsImported == 0),       return;         end
            SelFaultPos                 = app.importedfaultsListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end         
            app.Fault.Values(SelFaultPos,1) = app.FaultIDEditField.Value;
            %---------------------------------
            app.Fault.TriangleList(app.Fault.TriangleList(:,4) == SelFaultPos,5) = app.Fault.Values(SelFaultPos,1);
            %---------------------------------
        end

        % Value changed function: UsefaultsliprateCheckBox
        function UsefaultsliprateCheckBoxValueChanged(app, event)
            if (app.FaultsImported == 0),       return;         end
            SelFaultPos                 = app.importedfaultsListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end         
            app.UsefaultsliprateCheckBox.Value  = 1;
            app.UseregionalstressCheckBox.Value = 0;
            app.Fault.Values(SelFaultPos,2)     = 1; 
            %---------------------------------
            app.Fault.TriangleList(app.Fault.TriangleList(:,4) == SelFaultPos,6) = app.Fault.Values(SelFaultPos,2);
            %---------------------------------
        end

        % Value changed function: UseregionalstressCheckBox
        function UseregionalstressCheckBoxValueChanged(app, event)
            if (app.FaultsImported == 0),       return;         end
            SelFaultPos                 = app.importedfaultsListBox.Value;
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end         
            app.UsefaultsliprateCheckBox.Value  = 0;
            app.UseregionalstressCheckBox.Value = 1;
            app.Fault.Values(SelFaultPos,2)     = 0;  
            %---------------------------------
            app.Fault.TriangleList(app.Fault.TriangleList(:,4) == SelFaultPos,6) = app.Fault.Values(SelFaultPos,2);
            %---------------------------------
        end

        % Value changed function: FaultSlipRate_EditField
        function FaultSlipRate_EditFieldValueChanged(app, event)
            if (app.FaultsImported == 0),       return;         end
            SelFaultPos                 = app.importedfaultsListBox.Value;
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end 
            app.Fault.Values(SelFaultPos,3) = app.FaultSlipRate_EditField.Value;
            app.Fault.Values(SelFaultPos,2) = 1; 
            app.UsefaultsliprateCheckBox.Value  = 1;
            app.UseregionalstressCheckBox.Value = 0;
            %---------------------------------
            app.Fault.TriangleList(app.Fault.TriangleList(:,4) == SelFaultPos,7) = app.Fault.Values(SelFaultPos,3);
            %---------------------------------
        end

        % Value changed function: FaultSlipRake_EditField
        function FaultSlipRake_EditFieldValueChanged(app, event)
            if (app.FaultsImported == 0),       return;         end
            SelFaultPos                 = app.importedfaultsListBox.Value;
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end 
            app.Fault.Values(SelFaultPos,4) = app.FaultSlipRake_EditField.Value;
            app.Fault.Values(SelFaultPos,2) = 1; 
            app.UsefaultsliprateCheckBox.Value  = 1;
            app.UseregionalstressCheckBox.Value = 0;
            %---------------------------------
            app.Fault.TriangleList(app.Fault.TriangleList(:,4) == SelFaultPos,8) = app.Fault.Values(SelFaultPos,4);
            %---------------------------------
        end

        % Value changed function: FaultStressRate_EditField
        function FaultStressRate_EditFieldValueChanged(app, event)
            if (app.FaultsImported == 0),       return;         end
            SelFaultPos                 = app.importedfaultsListBox.Value;
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end 
            app.Fault.Values(SelFaultPos,5) = app.FaultStressRate_EditField.Value;
            app.Fault.Values(SelFaultPos,2) = 0; 
            app.UsefaultsliprateCheckBox.Value  = 0;
            app.UseregionalstressCheckBox.Value = 1;
            %---------------------------------
            app.Fault.TriangleList(app.Fault.TriangleList(:,4) == SelFaultPos,7) = app.Fault.Values(SelFaultPos,5);
            %---------------------------------
        end

        % Value changed function: FaultStressDirection_EditField
        function FaultStressDirection_EditFieldValueChanged(app, event)
            if (app.FaultsImported == 0),       return;         end
            SelFaultPos                 = app.importedfaultsListBox.Value;
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end 
            app.Fault.Values(SelFaultPos,6) = app.FaultStressDirection_EditField.Value;
            app.Fault.Values(SelFaultPos,2) = 0; 
            app.UsefaultsliprateCheckBox.Value  = 0;
            app.UseregionalstressCheckBox.Value = 1;
            %---------------------------------
            app.Fault.TriangleList(app.Fault.TriangleList(:,4) == SelFaultPos,8) = app.Fault.Values(SelFaultPos,6);
            %---------------------------------     
        end

        % Value changed function: importedfaultsListBox
        function importedfaultsListBoxValueChanged(app, event)
            SelFaultPos                    = app.importedfaultsListBox.Value;
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end
            %---------------------------------
            app.FaultnameEditField.Value          = app.FaultNames{SelFaultPos};
            app.FaultIDEditField.Value            = app.Fault.Values(SelFaultPos,1);
            %---------------------------------
            if (app.Fault.Values(SelFaultPos,2) == 1)
                app.UsefaultsliprateCheckBox.Value = 1;         app.UseregionalstressCheckBox.Value= 0;
            else
                app.UsefaultsliprateCheckBox.Value = 0;         app.UseregionalstressCheckBox.Value= 1;
            end   
            %---------------------------------
            app.FaultSlipRate_EditField.Value        = app.Fault.Values(SelFaultPos,3);
            app.FaultSlipRake_EditField.Value        = app.Fault.Values(SelFaultPos,4);
            app.FaultStressRate_EditField.Value      = app.Fault.Values(SelFaultPos,5);
            app.FaultStressDirection_EditField.Value = app.Fault.Values(SelFaultPos,6);
        end

        % Button pushed function: PlotMeshButton
        function PlotMeshButtonPushed(app, event)
            %-----------------------------------------------
            FigNum = app.FigEditField.Value;
            ViewA  = sscanf(app.viewangleEditField.Value,'%d');
            %-----------------------------------------------
            FltComp = str2double(app.PlotCompFault_DropDown.Value);
            BndComp = str2double(app.PlotCompBound_DropDown.Value);
            %-----------------------------------------------
            figure(FigNum); clf; hold on; axis equal; view(ViewA(1,1), ViewA(2,1));
            xlabel('easting');ylabel('northing'); zlabel('depth');
            AxEqual  = app.AxisEqual_CheckBox.Value;
            ShowMesh = app.ShowMesh_CheckBox.Value;
            %----------------------------------
            if (ShowMesh == 1), UsedEdge = 'black';
            else,               UsedEdge = 'none';
            end
            BndCol = 0;
            %-----------------------------------------------
            if (size(app.BoundaryValues,1) > 0)
                %-----------------------------------------------
                MeshBound(app);
                %-----------------------------------------------
                AllV1s          = zeros(size(app.Bound.TriangleList,1),3);
                AllV2s          = zeros(size(app.Bound.TriangleList,1),3);
                AllV3s          = zeros(size(app.Bound.TriangleList,1),3);
                BndCol          = zeros(size(app.Bound.TriangleList,1),1);
                Cntr            = 1;
                %-----------------------------------------------
                for j = 1 : size(app.Bound.TriangleList,1)
                    AllV1s(Cntr,1) = app.Bound.VertexList(app.Bound.TriangleList(j,1),1);
                    AllV1s(Cntr,2) = app.Bound.VertexList(app.Bound.TriangleList(j,2),1);
                    AllV1s(Cntr,3) = app.Bound.VertexList(app.Bound.TriangleList(j,3),1);
                
                    AllV2s(Cntr,1) = app.Bound.VertexList(app.Bound.TriangleList(j,1),2);
                    AllV2s(Cntr,2) = app.Bound.VertexList(app.Bound.TriangleList(j,2),2);
                    AllV2s(Cntr,3) = app.Bound.VertexList(app.Bound.TriangleList(j,3),2);
                
                    AllV3s(Cntr,1) = app.Bound.VertexList(app.Bound.TriangleList(j,1),3);
                    AllV3s(Cntr,2) = app.Bound.VertexList(app.Bound.TriangleList(j,2),3);
                    AllV3s(Cntr,3) = app.Bound.VertexList(app.Bound.TriangleList(j,3),3);

                    if     (BndComp == 1),     BndCol(Cntr,1) = app.Bound.TriangleList(j,4);
                    elseif (BndComp == 2),     BndCol(Cntr,1) = app.Bound.TriangleList(j,8);
                    elseif (BndComp == 3),     BndCol(Cntr,1) = app.Bound.TriangleList(j,9);
                    elseif (BndComp == 4),     BndCol(Cntr,1) = app.Bound.TriangleList(j,10);
                    end
                    Cntr = Cntr +1;
                end
                patch(AllV1s',AllV2s',AllV3s',BndCol','facealpha',0.7,'edgecolor',UsedEdge);
            end
            %-----------------------------------------------
            if (app.FaultsImported == 1)
                AllV1s          = zeros(size(app.Fault.TriangleList,1),3);
                AllV2s          = zeros(size(app.Fault.TriangleList,1),3);
                AllV3s          = zeros(size(app.Fault.TriangleList,1),3);
                FltCol          = zeros(size(app.Fault.TriangleList,1),1);
                Cntr            = 1;
                %-----------------------------------------------
                for j = 1 : size(app.Fault.TriangleList,1)
                    AllV1s(Cntr,1) = app.Fault.VertexList(app.Fault.TriangleList(j,1),1);
                    AllV1s(Cntr,2) = app.Fault.VertexList(app.Fault.TriangleList(j,2),1);
                    AllV1s(Cntr,3) = app.Fault.VertexList(app.Fault.TriangleList(j,3),1);
                
                    AllV2s(Cntr,1) = app.Fault.VertexList(app.Fault.TriangleList(j,1),2);
                    AllV2s(Cntr,2) = app.Fault.VertexList(app.Fault.TriangleList(j,2),2);
                    AllV2s(Cntr,3) = app.Fault.VertexList(app.Fault.TriangleList(j,3),2);
                
                    AllV3s(Cntr,1) = app.Fault.VertexList(app.Fault.TriangleList(j,1),3);
                    AllV3s(Cntr,2) = app.Fault.VertexList(app.Fault.TriangleList(j,2),3);
                    AllV3s(Cntr,3) = app.Fault.VertexList(app.Fault.TriangleList(j,3),3);
                    
                    if     (FltComp == 1),     FltCol(Cntr,1) = app.Fault.TriangleList(j,5);
                    elseif (FltComp == 2),     FltCol(Cntr,1) = app.Fault.TriangleList(j,6);
                    elseif (FltComp == 3),     FltCol(Cntr,1) = app.Fault.TriangleList(j,7);
                    elseif (FltComp == 4),     FltCol(Cntr,1) = app.Fault.TriangleList(j,8);
                    end
                    Cntr = Cntr +1;
                end
                %-----------------------------------------------
                patch(AllV1s',AllV2s',AllV3s',FltCol','facealpha',0.7,'edgecolor',UsedEdge);
            end
            %-----------------------------------------------
            if (min([BndCol;FltCol]) ~= max([BndCol;FltCol]))
                caxis([min([BndCol;FltCol])  max([BndCol;FltCol])]);
                colorbar;
            end
            %-----------------------------------------------
        end

        % Button pushed function: SavetriangulationButton
        function SavetriangulationButtonPushed(app, event)
            if (app.FaultsImported == 0),       return;         end
            %-----------------------  s
            SectionNum = size(app.Fault.Values,1);
            %-----------------------   
            [DataName,DataPath]  = uiputfile('*.*','Save Fault Model');
            if     (DataName ~=0),      DataName = strtok(DataName,'.');        CompletePath = strcat(DataPath,DataName); 
            elseif (DataName ==0),      return;         
            end
            %-----------------------
            Name4ascii  = strcat(CompletePath,'_FLT.txt');
            Name4binary = strcat(CompletePath,'_FLTtrig.dat');
            %----------------------------------------------------------------
            fidascii    = fopen(Name4ascii ,'w');
            if (fidascii == -1), errordlg('The entered filename has not been found.');    return;   end
            
            fprintf(fidascii,'Number_of_fault_sections:  %d\n',SectionNum);
            fprintf(fidascii,'Combined_number_of_trig_elements_and_vertices:  %d   %d\n', size(app.Fault.TriangleList,1), size(app.Fault.VertexList,1));
            fprintf(fidascii,'\n');   
            %--------------------------------------
            for i = 1 : SectionNum
                fprintf(fidascii,'Fault_ID_number:   %d\n',app.Fault.Values(i,1));
                fprintf(fidascii,'Fault_section_name: %s\n',app.FaultNames{1,i});
                fprintf(fidascii,'Easting_of_fault_top_center(km):     -9999.9\n');
                fprintf(fidascii,'Northing_of_fault_top_center(km):    -9999.9\n');
                fprintf(fidascii,'Depth_of_fault_top_center(km):       -9999.9\n');
                fprintf(fidascii,'Length_of_fault_section(km):         -9999.9\n');
                fprintf(fidascii,'Width_of_fault_section(km):          -9999.9\n');
                fprintf(fidascii,'Strike_of_fault_section(deg):        -9999.9\n');
                fprintf(fidascii,'Dip_of_fault_section(deg):           -9999.9\n');
                fprintf(fidascii,'UseSlipRate_or_RegStress_(1/0):      %d\n',    app.Fault.Values(i,2));
                fprintf(fidascii,'SlipRate_of_fault_section(mm/yr):    %10.4e\n',app.Fault.Values(i,3));
                fprintf(fidascii,'Rake_of_fault_section(deg):          %10.4e\n',app.Fault.Values(i,4));
                fprintf(fidascii,'StressRate_of_fault_section(MPa/yr): %10.4e\n',app.Fault.Values(i,5));
                fprintf(fidascii,'StressDir_of_fault_section(deg):     %10.4e\n',app.Fault.Values(i,6));
                fprintf(fidascii,'Shift_of_lowerleft_vertex_(E/N/Z)(km):    0.0    0.0    0.0\n');
                fprintf(fidascii,'Shift_of_lowerright_vertex_(E/N/Z)(km):   0.0    0.0    0.0\n');
                fprintf(fidascii,'Shift_of_upperright_vertex_(E/N/Z)(km):   0.0    0.0    0.0\n');
                fprintf(fidascii,'Shift_of_upperleft_vertex_(E/N/Z)(km):    0.0    0.0    0.0\n');
                
                FltTrigs        = app.Fault.TriangleList(app.Fault.TriangleList(:,4) == i,1:3);
                PatchesPerFault = size(FltTrigs,1);             VertsOfFaults   = unique(FltTrigs(:));              VertsPerFault   = size(VertsOfFaults,1);
                fprintf(fidascii,'Triangle_patch_number_of_section:   %d\n', PatchesPerFault);
                fprintf(fidascii,'Triangle_vertex_number_of_section:  %d\n',VertsPerFault);
                fprintf(fidascii,'\n');      
            end
            fclose(fidascii);
            %------------------------------------------
            fidbin    = fopen(Name4binary ,'wb'); 
            if (fidbin == -1), errordlg('The entered filename has not been found.');    return;   end
  
            fwrite(fidbin,size(app.Fault.TriangleList,1),'int32');
            fwrite(fidbin,size(app.Fault.VertexList,1), 'int32');

            fwrite(fidbin,app.Fault.TriangleList(:,1),   'int32');
            fwrite(fidbin,app.Fault.TriangleList(:,2),   'int32');
            fwrite(fidbin,app.Fault.TriangleList(:,3),   'int32');
            fwrite(fidbin,app.Fault.TriangleList(:,4),   'int32'); %fault section ID number
            fwrite(fidbin,app.Fault.TriangleList(:,5),   'int32'); %assigned fault ID number
            fwrite(fidbin,app.Fault.TriangleList(:,6),   'int32'); %SlipRateOrStressing
            fwrite(fidbin,app.Fault.TriangleList(:,7),   'single');%TheRate
            fwrite(fidbin,app.Fault.TriangleList(:,8),   'single');%TheRake/Direction
            fwrite(fidbin,app.Fault.TriangleList(:,9),   'single');
            fwrite(fidbin,app.Fault.TriangleList(:,10),  'single');    
            fwrite(fidbin,app.Fault.TriangleList(:,11),  'single');  

            fwrite(fidbin,app.Fault.VertexList(:,1), 'single');
            fwrite(fidbin,app.Fault.VertexList(:,2), 'single');    
            fwrite(fidbin,app.Fault.VertexList(:,3), 'single'); 
            fwrite(fidbin,app.Fault.VertexList(:,4), 'single'); 

            fclose(fidbin);    
            %------------------------------------------%------------------------------------------
            if (size(app.BoundaryValues,1) > 0)
                Name4ascii  = strcat(CompletePath,'_BND.txt');
                Name4binary = strcat(CompletePath,'_BNDtrig.dat');
                %----------------------------------------------------------------
                fidascii    = fopen(Name4ascii ,'w');
                if (fidascii == -1), errordlg('The entered filename has not been found.');    return;   end
                
                fprintf(fidascii,'Number_of_boundary_segments:  %d\n',size(app.BoundaryValues,1));
                fprintf(fidascii,'Combined_number_of_trig_patches_and_vertices:  %d   %d\n', size(app.Bound.TriangleList,1), size(app.Bound.VertexList,1));
                fprintf(fidascii,'\n');   
                %--------------------------------------
                for i = 1 : size(app.BoundaryValues,1)
                    fprintf(fidascii,'Fault_section_name: %s\n',                  app.BoundaryNames{1,i});
                    fprintf(fidascii,'Easting_of_fault_top_center(km):  %10.4e\n',app.BoundaryValues(i,1));
                    fprintf(fidascii,'Northing_of_fault_top_center(km): %10.4e\n',app.BoundaryValues(i,2));
                    fprintf(fidascii,'Depth_of_fault_top_center(km):    %10.4e\n',app.BoundaryValues(i,3));
                    fprintf(fidascii,'Length_of_fault_section(km):      %10.4e\n',app.BoundaryValues(i,4));
                    fprintf(fidascii,'Width_of_fault_section(km):       %10.4e\n',app.BoundaryValues(i,5));
                    fprintf(fidascii,'Strike_of_fault_section(deg):     %10.4e\n',app.BoundaryValues(i,6));
                    fprintf(fidascii,'Dip_of_fault_section(deg):        %10.4e\n',app.BoundaryValues(i,7));
                    fprintf(fidascii,'Slip-rate_in_strike(mm/yr):       %10.4e\n',app.BoundaryValues(i,8));
                    fprintf(fidascii,'Slip-rate_in_dip(mm/yr):          %10.4e\n',app.BoundaryValues(i,9));
                    fprintf(fidascii,'Slip-rate_in_normal(mm/yr):       %10.4e\n',app.BoundaryValues(i,10));                    

                    BndTrigs        = app.Bound.TriangleList(app.Bound.TriangleList(:,4) == i,1:3);
                    PatchesPerBound = size(BndTrigs,1);         VertsOfBound    = unique(BndTrigs(:));         VertsPerBound   = size(VertsOfBound,1);
                    fprintf(fidascii,'Triangle_patch_number_of_boundary:   %d\n',PatchesPerBound);
                    fprintf(fidascii,'Triangle_vertex_number_of_boundary:  %d\n',VertsPerBound);
                    fprintf(fidascii,'\n'); 
                end
                fclose(fidascii);
                %--------------------------------------
                fidbin    = fopen(Name4binary ,'wb'); 
                if (fidbin == -1), errordlg('The entered filename has not been found.');    return;         end

                fwrite(fidbin,size(app.Bound.TriangleList,1),'int32');
                fwrite(fidbin,size(app.Bound.VertexList,1),  'int32');

                fwrite(fidbin,app.Bound.TriangleList(:,1),   'int32');
                fwrite(fidbin,app.Bound.TriangleList(:,2),   'int32');
                fwrite(fidbin,app.Bound.TriangleList(:,3),   'int32');
                fwrite(fidbin,app.Bound.TriangleList(:,4),   'int32');    
                fwrite(fidbin,app.Bound.TriangleList(:,5),   'single');
                fwrite(fidbin,app.Bound.TriangleList(:,6),   'single');    
                fwrite(fidbin,app.Bound.TriangleList(:,7),   'single'); 

                fwrite(fidbin,app.Bound.VertexList(:,1),     'single');
                fwrite(fidbin,app.Bound.VertexList(:,2),     'single');    
                fwrite(fidbin,app.Bound.VertexList(:,3),     'single');  

                fwrite(fidbin,app.Bound.TriangleList(:,8),   'single');
                fwrite(fidbin,app.Bound.TriangleList(:,9),   'single');    
                fwrite(fidbin,app.Bound.TriangleList(:,10),  'single'); 
                %--------------------------------------
                fclose(fidbin);    
            end

        end

        % Value changed function: BoundApplyRateAt_DropDown
        function BoundApplyRateAt_DropDownValueChanged(app, event)
            value = str2double(app.BoundApplyRateAt_DropDown.Value);
            %------------------------------------        
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            %------------------------------------ 
            app.BoundaryValues(SelBoundPos,8) = value;
        end

        % Value changed function: BoundStrikeratemmyrEditField
        function BoundStrikeratemmyrEditFieldValueChanged(app, event)
            value = str2num(app.BoundStrikeratemmyrEditField.Value);
            if (isempty(value)),            return;                         end
            if (size(value,2) == 1),        value = [value, value];         end
            if (isnan(sum(value))),         value = [NaN, NaN];             end
            %------------------------------------        
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            %------------------------------------  
            app.BoundaryValues(SelBoundPos,9) = value(1,1);
            app.BoundaryValues(SelBoundPos,12)= value(1,2);
        end

        % Value changed function: BoundDipratemmyrEditField
        function BoundDipratemmyrEditFieldValueChanged(app, event)
            value = str2num(app.BoundDipratemmyrEditField.Value);
            if (isempty(value)),            return;                         end
            if (size(value,2) == 1),        value = [value, value];         end
            if (isnan(sum(value))),         value = [NaN, NaN];             end
            %------------------------------------        
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            %------------------------------------ 
            app.BoundaryValues(SelBoundPos,10)= value(1,1);
            app.BoundaryValues(SelBoundPos,13)= value(1,2);
        end

        % Value changed function: BoundNormalratemmyrEditField
        function BoundNormalratemmyrEditFieldValueChanged(app, event)
            value = str2num(app.BoundNormalratemmyrEditField.Value);
            if (isempty(value)),            return;                         end
            if (size(value,2) == 1),        value = [value, value];         end
            if (isnan(sum(value))),         value = [NaN, NaN];             end
            %------------------------------------        
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            %------------------------------------ 
            app.BoundaryValues(SelBoundPos,11)= value(1,1);
            app.BoundaryValues(SelBoundPos,14)= value(1,2);    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Bound_ListBox = uilistbox(app.Panel);
            app.Bound_ListBox.Items = {'Boundary_Bottom'};
            app.Bound_ListBox.ItemsData = {'1', '2', '3', '4', '5'};
            app.Bound_ListBox.ValueChangedFcn = createCallbackFcn(app, @Bound_ListBoxValueChanged, true);
            app.Bound_ListBox.Value = '1';
            app.EastingtopcenterkmEditField_2Label = uilabel(app.Panel);
            app.EastingtopcenterkmEditField_2Label.Text = 'Easting (top center, km)';
            app.BoundEasting_EditField = uieditfield(app.Panel, 'numeric');
            app.BoundEasting_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundEasting_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundEasting_EditFieldValueChanged, true);
            app.BoundEasting_EditField.Value = -80;
            app.NorthingtopcenterkmEditField_2Label = uilabel(app.Panel);
            app.NorthingtopcenterkmEditField_2Label.Text = 'Northing(top center, km)';
            app.BoundNorthing_EditField = uieditfield(app.Panel, 'numeric');
            app.BoundNorthing_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundNorthing_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundNorthing_EditFieldValueChanged, true);
            app.DepthtopcenterkmEditField_2Label = uilabel(app.Panel);
            app.DepthtopcenterkmEditField_2Label.Text = 'Depth (top center, km)';
            app.BoundDepth_EditField = uieditfield(app.Panel, 'numeric');
            app.BoundDepth_EditField.Limits = [-Inf 0];
            app.BoundDepth_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundDepth_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundDepth_EditFieldValueChanged, true);
            app.BoundDepth_EditField.Value = -22;
            app.LengthkmEditField_2Label = uilabel(app.Panel);
            app.LengthkmEditField_2Label.Text = 'Length (km)';
            app.BoundLength_EditField = uieditfield(app.Panel, 'numeric');
            app.BoundLength_EditField.Limits = [0 Inf];
            app.BoundLength_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundLength_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundLength_EditFieldValueChanged, true);
            app.BoundLength_EditField.Value = 300;
            app.WidthkmEditField_2Label = uilabel(app.Panel);
            app.WidthkmEditField_2Label.Text = 'Width (km)';
            app.BoundWidth_EditField = uieditfield(app.Panel, 'numeric');
            app.BoundWidth_EditField.Limits = [0 Inf];
            app.BoundWidth_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundWidth_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundWidth_EditFieldValueChanged, true);
            app.BoundWidth_EditField.Value = 160;
            app.StrikedegEditField_2Label = uilabel(app.Panel);
            app.StrikedegEditField_2Label.Text = 'Strike (deg.)';
            app.BoundStrike_EditField = uieditfield(app.Panel, 'numeric');
            app.BoundStrike_EditField.Limits = [0 360];
            app.BoundStrike_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundStrike_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundStrike_EditFieldValueChanged, true);
            app.DipdegEditField_2Label = uilabel(app.Panel);
            app.DipdegEditField_2Label.Text = 'Dip (deg.)';
            app.BoundDip_EditField = uieditfield(app.Panel, 'numeric');
            app.BoundDip_EditField.Limits = [0 90];
            app.BoundDip_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundDip_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundDip_EditFieldValueChanged, true);
            app.NameEditField_2Label = uilabel(app.Panel);
            app.NameEditField_2Label.Text = 'Name';
            app.BoundName_EditField = uieditfield(app.Panel, 'text');
            app.BoundName_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundName_EditFieldValueChanged, true);
            app.BoundName_EditField.Value = 'Boundary_Fault';
            app.applyrateDropDownLabel = uilabel(app.Panel);
            app.applyrateDropDownLabel.Text = 'apply rate';
            app.BoundApplyRateAt_DropDown = uidropdown(app.Panel);
            app.BoundApplyRateAt_DropDown.Items = {'top/bottom edge', 'left/right edge'};
            app.BoundApplyRateAt_DropDown.ItemsData = {'1', '2'};
            app.BoundApplyRateAt_DropDown.ValueChangedFcn = createCallbackFcn(app, @BoundApplyRateAt_DropDownValueChanged, true);
            app.BoundApplyRateAt_DropDown.Value = '1';
            app.strikeratemmyrEditFieldLabel = uilabel(app.Panel);
            app.strikeratemmyrEditFieldLabel.Text = 'strike-rate (mm/yr)';
            app.BoundStrikeratemmyrEditField = uieditfield(app.Panel, 'text');
            app.BoundStrikeratemmyrEditField.ValueChangedFcn = createCallbackFcn(app, @BoundStrikeratemmyrEditFieldValueChanged, true);
            app.BoundStrikeratemmyrEditField.Value = 'NaN NaN';
            app.dipratemmyrEditFieldLabel = uilabel(app.Panel);
            app.dipratemmyrEditFieldLabel.Text = 'dip-rate (mm/yr)';
            app.BoundDipratemmyrEditField = uieditfield(app.Panel, 'text');
            app.BoundDipratemmyrEditField.ValueChangedFcn = createCallbackFcn(app, @BoundDipratemmyrEditFieldValueChanged, true);
            app.BoundDipratemmyrEditField.Value = 'NaN NaN';
            app.normalratemmyrEditFieldLabel = uilabel(app.Panel);
            app.normalratemmyrEditFieldLabel.Text = 'normal-rate (mm/yr)';
            app.BoundNormalratemmyrEditField = uieditfield(app.Panel, 'text');
            app.BoundNormalratemmyrEditField.ValueChangedFcn = createCallbackFcn(app, @BoundNormalratemmyrEditFieldValueChanged, true);
            app.BoundNormalratemmyrEditField.Value = 'NaN NaN';
            app.DipdegEditField_2Label_3 = uilabel(app.Panel);
            app.DipdegEditField_2Label_3.Text = 'rotate all boundaries by (deg)';
            app.BoundRotateAllBy_EditField = uieditfield(app.Panel, 'numeric');
            app.BoundRotateAllBy_EditField.Limits = [-180 180];
            app.BoundRotateAllBy_EditField.ValueDisplayFormat = '%3.1f';
            app.AddBound_Button = uibutton(app.Panel, 'push');
            app.AddBound_Button.ButtonPushedFcn = createCallbackFcn(app, @AddBound_ButtonPushed, true);
            app.AddBound_Button.Text = 'Add';
            app.DeleteBound_Button = uibutton(app.Panel, 'push');
            app.DeleteBound_Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteBound_ButtonPushed, true);
            app.DeleteBound_Button.Text = 'Delete';
            app.DipdegEditField_2Label_2 = uilabel(app.Panel);
            app.DipdegEditField_2Label_2.Text = 'Element Size (km)';
            app.BoundElemSize_EditField = uieditfield(app.Panel, 'numeric');
            app.BoundElemSize_EditField.Limits = [0 Inf];
            app.BoundElemSize_EditField.ValueDisplayFormat = '%4.2f';
            app.BoundElemSize_EditField.Value = 4;
            app.boundaryelementEditFieldLabel = uilabel(app.Panel);
            app.boundaryelementEditFieldLabel.Text = 'boundary element #';
            app.boundaryelementEditField = uieditfield(app.Panel, 'numeric');
            app.boundaryelementEditField.Limits = [1 Inf];
            app.boundaryelementEditField.RoundFractionalValues = 'on';
            app.boundaryelementEditField.ValueDisplayFormat = '%d';
            app.boundaryelementEditField.Editable = 'off';
            app.boundaryelementEditField.Value = 1;
            app.Panel_2 = uipanel(app.UIFigure);
            app.LoadImportSTLfilesButton = uibutton(app.Panel_2, 'push');
            app.LoadImportSTLfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImportSTLfilesButtonPushed, true);
            app.LoadImportSTLfilesButton.Text = '1) Load/Import STL files';
            app.importedfaultsListBoxLabel = uilabel(app.Panel_2);
            app.importedfaultsListBoxLabel.Text = 'imported faults';
            app.importedfaultsListBox = uilistbox(app.Panel_2);
            app.importedfaultsListBox.Items = {};
            app.importedfaultsListBox.ValueChangedFcn = createCallbackFcn(app, @importedfaultsListBoxValueChanged, true);
            app.importedfaultsListBox.Value = {};
            app.FaultnameEditFieldLabel = uilabel(app.Panel_2);
            app.FaultnameEditFieldLabel.Text = 'Fault name';
            app.FaultnameEditField = uieditfield(app.Panel_2, 'text');
            app.FaultnameEditField.ValueChangedFcn = createCallbackFcn(app, @FaultnameEditFieldValueChanged, true);
            app.Lamp = uilamp(app.Panel_2);
            app.inkmCheckBox = uicheckbox(app.Panel_2);
            app.inkmCheckBox.ValueChangedFcn = createCallbackFcn(app, @inkmCheckBoxValueChanged, true);
            app.inkmCheckBox.Text = 'in km';
            app.inmCheckBox = uicheckbox(app.Panel_2);
            app.inmCheckBox.ValueChangedFcn = createCallbackFcn(app, @inmCheckBoxValueChanged, true);
            app.inmCheckBox.Text = 'in m';
            app.inmCheckBox.Value = true;
            app.faultelementEditFieldLabel = uilabel(app.Panel_2);
            app.faultelementEditFieldLabel.Text = 'fault element #';
            app.faultelementEditField = uieditfield(app.Panel_2, 'numeric');
            app.faultelementEditField.Limits = [1 Inf];
            app.faultelementEditField.RoundFractionalValues = 'on';
            app.faultelementEditField.ValueDisplayFormat = '%d';
            app.faultelementEditField.Editable = 'off';
            app.faultelementEditField.Value = 1;
            app.ActualtrigfaultsLabel = uilabel(app.Panel_2);
            app.ActualtrigfaultsLabel.Text = 'approx. single-element EQ size';
            app.SingleElementEQsize_EditField = uieditfield(app.Panel_2, 'numeric');
            app.SingleElementEQsize_EditField.ValueDisplayFormat = '%4.2f';
            app.SingleElementEQsize_EditField.Editable = 'off';
            app.UsefaultsliprateCheckBox = uicheckbox(app.Panel_2);
            app.UsefaultsliprateCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsefaultsliprateCheckBoxValueChanged, true);
            app.UsefaultsliprateCheckBox.Text = 'use fault slip rate';
            app.UsefaultsliprateCheckBox.Value = true;
            app.UseregionalstressCheckBox = uicheckbox(app.Panel_2);
            app.UseregionalstressCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseregionalstressCheckBoxValueChanged, true);
            app.UseregionalstressCheckBox.Text = 'or use regional stress';
            app.SlipratemmyrLabel = uilabel(app.Panel_2);
            app.SlipratemmyrLabel.Text = 'Slip-rate (mm/yr)';
            app.FaultSlipRate_EditField = uieditfield(app.Panel_2, 'numeric');
            app.FaultSlipRate_EditField.ValueDisplayFormat = '%5.2f';
            app.FaultSlipRate_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultSlipRate_EditFieldValueChanged, true);
            app.FaultSlipRate_EditField.Value = 10;
            app.RakedegLabel = uilabel(app.Panel_2);
            app.RakedegLabel.Text = 'Rake (deg.)';
            app.FaultSlipRake_EditField = uieditfield(app.Panel_2, 'numeric');
            app.FaultSlipRake_EditField.Limits = [-180 180];
            app.FaultSlipRake_EditField.ValueDisplayFormat = '%5.2f';
            app.FaultSlipRake_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultSlipRake_EditFieldValueChanged, true);
            app.SlipratemmyrLabel_2 = uilabel(app.Panel_2);
            app.SlipratemmyrLabel_2.Text = 'Stress-rate (MPa/yr)';
            app.FaultStressRate_EditField = uieditfield(app.Panel_2, 'numeric');
            app.FaultStressRate_EditField.ValueDisplayFormat = '%5.2f';
            app.FaultStressRate_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultStressRate_EditFieldValueChanged, true);
            app.FaultStressRate_EditField.Tooltip = {'COMPRESSION = NEGATIVE'};
            app.RakedegLabel_2 = uilabel(app.Panel_2);
            app.RakedegLabel_2.Text = 'Stress direction (deg.)';
            app.FaultStressDirection_EditField = uieditfield(app.Panel_2, 'numeric');
            app.FaultStressDirection_EditField.Limits = [0 180];
            app.FaultStressDirection_EditField.ValueDisplayFormat = '%5.2f';
            app.FaultStressDirection_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultStressDirection_EditFieldValueChanged, true);
            app.FaultIDEditFieldLabel = uilabel(app.Panel_2);
            app.FaultIDEditFieldLabel.Text = 'Fault ID';
            app.FaultIDEditField = uieditfield(app.Panel_2, 'numeric');
            app.FaultIDEditField.Limits = [1 Inf];
            app.FaultIDEditField.RoundFractionalValues = 'on';
            app.FaultIDEditField.ValueDisplayFormat = '%d';
            app.FaultIDEditField.ValueChangedFcn = createCallbackFcn(app, @FaultIDEditFieldValueChanged, true);
            app.FaultIDEditField.Value = 1;
            app.Panel_4 = uipanel(app.UIFigure);
            app.AxisEqual_CheckBox = uicheckbox(app.Panel_4);
            app.AxisEqual_CheckBox.Text = 'axis equal';
            app.AxisEqual_CheckBox.Value = true;
            app.ShowMesh_CheckBox = uicheckbox(app.Panel_4);
            app.ShowMesh_CheckBox.Text = 'show mesh';
            app.ShowMesh_CheckBox.Value = true;
            app.PlotMeshButton = uibutton(app.Panel_4, 'push');
            app.PlotMeshButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMeshButtonPushed, true);
            app.PlotMeshButton.Text = '2) Plot/Mesh';
            app.PlotCompFault_DropDown = uidropdown(app.Panel_4);
            app.PlotCompFault_DropDown.Items = {'Fault ID', 'useSlip', 'Rate', 'Direction'};
            app.PlotCompFault_DropDown.ItemsData = {'1', '2', '3', '4'};
            app.PlotCompFault_DropDown.Value = '1';
            app.PlotCompBound_DropDown = uidropdown(app.Panel_4);
            app.PlotCompBound_DropDown.Items = {'Boundary ID', 'strike slip-rate', 'dip slip-rate', 'normal slip-rate'};
            app.PlotCompBound_DropDown.ItemsData = {'1', '2', '3', '4'};
            app.PlotCompBound_DropDown.Value = '1';
            app.FigEditFieldLabel = uilabel(app.Panel_4);
            app.FigEditFieldLabel.Text = 'Fig #';
            app.FigEditField = uieditfield(app.Panel_4, 'numeric');
            app.FigEditField.Value = 2;
            app.viewangleEditFieldLabel = uilabel(app.Panel_4);
            app.viewangleEditFieldLabel.Text = 'view angle';
            app.viewangleEditField = uieditfield(app.Panel_4, 'text');
            app.viewangleEditField.Value = '40 20';
            app.SavetriangulationButton = uibutton(app.UIFigure, 'push');
            app.SavetriangulationButton.ButtonPushedFcn = createCallbackFcn(app, @SavetriangulationButtonPushed, true);
            app.SavetriangulationButton.Text = '3) Save triangulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A3_FaultGeomFromSTL_MCQsimV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end