classdef C_Generate_TXTandSHfiles_MCQsimV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RealizCheckBox             matlab.ui.control.CheckBox
        RandSeedEditField          matlab.ui.control.NumericEditField
        RandSeedEditFieldLabel     matlab.ui.control.Label
        Runname_EditField          matlab.ui.control.EditField
        RunnameLabel               matlab.ui.control.Label
        SaveshfilesButton          matlab.ui.control.Button
        shfileTextArea             matlab.ui.control.TextArea
        shfileTextAreaLabel        matlab.ui.control.Label
        SavetxtfilesButton         matlab.ui.control.Button
        RealizNum2_EditField       matlab.ui.control.NumericEditField
        toEditFieldLabel           matlab.ui.control.Label
        RealizNum1_EditField       matlab.ui.control.NumericEditField
        RealizationEditFieldLabel  matlab.ui.control.Label
        Inputname_EditField        matlab.ui.control.EditField
        InputnameEditFieldLabel    matlab.ui.control.Label
        txtfileTextArea            matlab.ui.control.TextArea
        txtfileTextAreaLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SavetxtfilesButton
        function SavetxtfilesButtonPushed(app, event)
            %--------------------------------------------
            ModelName  = app.Inputname_EditField.Value;
            RealizNum1 = app.RealizNum1_EditField.Value;
            RealizNum2 = app.RealizNum2_EditField.Value;
            RandSeedNum= app.RandSeedEditField.Value;
            RandSeedAdd= app.RealizCheckBox.Value;
            TextBlock  = app.txtfileTextArea.Value;
            %--------------------------------------------
            for i = RealizNum1 : RealizNum2 
                %--------------------------------------------
                SaveName = strcat(ModelName,'_',num2str(i),'.txt');
                fidasc   = fopen(SaveName,"w");
                if (fidasc == -1), errordlg('The entered filename has not been found.');    return;   end
                %--------------------------------------------
                for j = 1 : size(TextBlock,1)
                    if     (j == 3) 
                        String2Write = [strtok(TextBlock{j,1}),'                     ',ModelName];
                        fprintf(fidasc,'%s\n', String2Write);
                    elseif (j == 4)
                        String2Write = [strtok(TextBlock{j,1}),'            ',num2str(i)];
                        fprintf(fidasc,'%s\n', String2Write);
                    elseif (j == 7)
                        if (RandSeedAdd == 1),      RandSeedN = num2str( (RandSeedNum + i) );
                        else,                       RandSeedN = num2str( (RandSeedNum    ) );
                        end
                        String2Write = [strtok(TextBlock{j,1}),'                 ',RandSeedN];
                        fprintf(fidasc,'%s\n', String2Write);
                    elseif (j == 11)
                        String2Write = [strtok(TextBlock{j,1}),'            ',ModelName,'_',num2str(i),'_Khmat.dat'];
                        fprintf(fidasc,'%s\n', String2Write);
                    else
                        fprintf(fidasc,'%s\n', TextBlock{j,1});
                    end
                end
                fclose(fidasc);
            end
            %--------------------------------------------

        end

        % Button pushed function: SaveshfilesButton
        function SaveshfilesButtonPushed(app, event)
            %--------------------------------------------
            ModelName  = app.Inputname_EditField.Value;
            RunName    = app.Runname_EditField.Value;
            RealizNum1 = app.RealizNum1_EditField.Value;
            RealizNum2 = app.RealizNum2_EditField.Value;
            TextBlock  = app.shfileTextArea.Value;
            %--------------------------------------------
            for i = RealizNum1 : RealizNum2 
                %--------------------------------------------
                SaveName = strcat(ModelName,'_',num2str(i),'.sh');
                fidasc   = fopen(SaveName,"w");
                if (fidasc == -1), errordlg('The entered filename has not been found.');    return;   end
                %--------------------------------------------
                for j = 1 : size(TextBlock,1)
                    WriteName = strcat(ModelName,num2str(i));
                    if (j == 4) 
                        String2Write = [strtok(TextBlock{j,1},'='),'=',WriteName];
                        fprintf(fidasc,'%s\n', String2Write);
                    elseif (j == 5)
                        String2Write = [strtok(TextBlock{j,1},'='),'=',WriteName,'.out'];
                        fprintf(fidasc,'%s\n', String2Write);
                    elseif (j == 6)
                        String2Write = [strtok(TextBlock{j,1},'='),'=',WriteName,'.err'];
                        fprintf(fidasc,'%s\n', String2Write);
                    elseif (j == 14)
                        String2Write = [extractBefore(TextBlock{j,1},RunName),' ',RunName,' ',ModelName,'_',num2str(i),'.txt'];
                        fprintf(fidasc,'%s\n', String2Write);
                    else
                        fprintf(fidasc,'%s\n', TextBlock{j,1});
                    end
                end
                %--------------------------------------------
                fclose(fidasc);
                %--------------------------------------------
            end
            %--------------------------------------------
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.txtfileTextAreaLabel = uilabel(app.UIFigure);
            app.txtfileTextAreaLabel.Text = '*.txt file';
            app.txtfileTextArea = uitextarea(app.UIFigure);
            app.txtfileTextArea.Value = {'Run_Parameter_Information'; '--------------------------'; 'InputName:                          %s'; 'Realization_Number:           %d'; 'CatalogTypeNr:                 3'; 'PlotCatalog2Screen:            1'; 'RandSeedValue:                 %d'; 'MinElementNum4Catalog:         1'; 'ContinueCatalog:               0'; 'LoadPrevious_Kh_mat:           1'; 'Kh_mat_file_2_load:            %s_1_Khmat.dat'; '--------------------------'; 'StoreSTF4LargeEQs(0/1):        1'; 'MinMagnitude4STF:              7.0f'; 'UseRuptPropag(0/1):            0'; 'MinMagnitude4RupProp:          6.0f'; '--------------------------'; 'IntSeisLoadStep(days):         1.0f'; 'pow2forInterSeisSteps:         7'; '--------------------------'; 'Visco_AfterSlip(years):        3.0f'; 'Visco_DeepRelax(years):        20.0f'; '--------------------------'; 'HealingDuringCoseis:           0.05f'; 'OvershootFraction:             1.25f'; 'PreStressFraction:             0.85f'; 'MinCoSeisSlipRate(m/s):        1.0E-2'; '--------------------------'; 'EQrecordLength(years):         23000.0f'; '--------------------------'};
            app.InputnameEditFieldLabel = uilabel(app.UIFigure);
            app.InputnameEditFieldLabel.Text = 'Input name:';
            app.Inputname_EditField = uieditfield(app.UIFigure, 'text');
            app.Inputname_EditField.Value = 'ModelName';
            app.RealizationEditFieldLabel = uilabel(app.UIFigure);
            app.RealizationEditFieldLabel.Text = 'Realization #';
            app.RealizNum1_EditField = uieditfield(app.UIFigure, 'numeric');
            app.RealizNum1_EditField.Limits = [1 Inf];
            app.RealizNum1_EditField.RoundFractionalValues = 'on';
            app.RealizNum1_EditField.ValueDisplayFormat = '%d';
            app.RealizNum1_EditField.Value = 1;
            app.toEditFieldLabel = uilabel(app.UIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.RealizNum2_EditField = uieditfield(app.UIFigure, 'numeric');
            app.RealizNum2_EditField.Limits = [1 Inf];
            app.RealizNum2_EditField.RoundFractionalValues = 'on';
            app.RealizNum2_EditField.ValueDisplayFormat = '%d';
            app.RealizNum2_EditField.Value = 1;
            app.SavetxtfilesButton = uibutton(app.UIFigure, 'push');
            app.SavetxtfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SavetxtfilesButtonPushed, true);
            app.SavetxtfilesButton.Text = 'Save *.txt files';
            app.shfileTextAreaLabel = uilabel(app.UIFigure);
            app.shfileTextAreaLabel.Text = '*.sh file';
            app.shfileTextArea = uitextarea(app.UIFigure);
            app.shfileTextArea.Value = {'#!/bin/bash'; '#SBATCH --nodes=1'; '#SBATCH --partition=workq'; '#SBATCH --job-name=%s'; '#SBATCH --output=%s.out'; '#SBATCH --error=%s .err'; '#SBATCH --mail-type=ALL'; '#SBATCH --account=XXX'; '#SBATCH --time=20:00:00'; ''; 'export OMP_NUM_THREADS=1'; ''; 'srun --hint=nomultithread --ntasks=192  --ntasks-per-node=192 --ntasks-per-socket=96 --cpus-per-task=1 --ntasks-per-core=1 --mem-bind=v,local --cpu-bind=threads MCQsimV2p5 %s.txt'};
            app.SaveshfilesButton = uibutton(app.UIFigure, 'push');
            app.SaveshfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveshfilesButtonPushed, true);
            app.SaveshfilesButton.Text = 'Save *.sh files';
            app.RunnameLabel = uilabel(app.UIFigure);
            app.RunnameLabel.Text = 'Run name';
            app.Runname_EditField = uieditfield(app.UIFigure, 'text');
            app.Runname_EditField.Value = 'MCQsimV2p5';
            app.RandSeedEditFieldLabel = uilabel(app.UIFigure);
            app.RandSeedEditFieldLabel.Text = 'RandSeed#';
            app.RandSeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.RandSeedEditField.Value = 1000;
            app.RealizCheckBox = uicheckbox(app.UIFigure);
            app.RealizCheckBox.Text = '+ Realiz#';
            app.RealizCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = C_Generate_TXTandSHfiles_MCQsimV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end