classdef Z0_Load_srfb_MCQsimV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        evolutionCheckBox              matlab.ui.control.CheckBox
        PlotMaxValue_EditField         matlab.ui.control.NumericEditField
        maxvalueEditFieldLabel         matlab.ui.control.Label
        PlotMinValue_EditField         matlab.ui.control.NumericEditField
        minvalueEditFieldLabel         matlab.ui.control.Label
        foroverviewListBox             matlab.ui.control.ListBox
        onsectionEditField             matlab.ui.control.NumericEditField
        onsectionEditFieldLabel        matlab.ui.control.Label
        ViewAngle_EditField            matlab.ui.control.EditField
        ViewangleEditFieldLabel        matlab.ui.control.Label
        LoadprerunforgeometryButton    matlab.ui.control.Button
        ExporttosrffileButton          matlab.ui.control.Button
        selectedelementEditField       matlab.ui.control.NumericEditField
        selectedelementEditFieldLabel  matlab.ui.control.Label
        framespersecEditField          matlab.ui.control.NumericEditField
        framespersecEditFieldLabel     matlab.ui.control.Label
        dtofeventsEditField            matlab.ui.control.NumericEditField
        dtofeventsEditFieldLabel       matlab.ui.control.Label
        secframeEditField              matlab.ui.control.NumericEditField
        secframeEditFieldLabel         matlab.ui.control.Label
        saveasmovieCheckBox            matlab.ui.control.CheckBox
        ShowmomentrateButton           matlab.ui.control.Button
        ShowelementSTFButton           matlab.ui.control.Button
        SelectelementButton            matlab.ui.control.Button
        ShowoverviewButton             matlab.ui.control.Button
        plotcompListBox                matlab.ui.control.ListBox
        plotcompListBoxLabel           matlab.ui.control.Label
        FigEditField                   matlab.ui.control.NumericEditField
        FigEditFieldLabel              matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        LampLabel                      matlab.ui.control.Label
        ShowtimeevolutionButton        matlab.ui.control.Button
        LoadsourcemodelButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        Fault        = struct;
        STF          = struct;
        FaultLoaded  = 0;
        SrceLoaded   = 0;
        BlueWhiteRed = [0	0	0.500;0	0	0.523;0	0	0.547;0	0	0.570;0	0	0.594;0	0	0.617;0	0	0.640;0	0	0.664;0	0	0.687;0	0	0.710;0	0	0.734;0	0	0.757;0	0	0.781;0	0	0.804;0	0	0.827;0	0	0.851;0	0	0.874;0	0	0.897;0	0	0.921;0	0	0.944;0	0	0.968;0	0	0.991;0.0120	0.0120	1;0.0310	0.0310	1;0.0500	0.0500	1;0.0700	0.0700	1;0.0890	0.0890	1;0.108	0.108	1;0.128	0.128	1;0.147	0.147	1;0.166	0.166	1;0.185	0.185	1;0.205	0.205	1;0.224	0.224	1;0.243	0.243	1;0.263	0.263	1;0.282	0.282	1;0.301	0.301	1;0.321	0.321	1;0.340	0.340	1;0.359	0.359	1;0.379	0.379	1;0.398	0.398	1;0.417	0.417	1;0.436	0.436	1;0.456	0.456	1;0.475	0.475	1;0.494	0.494	1;0.514	0.514	1;0.533	0.533	1;0.552	0.552	1;0.572	0.572	1;0.591	0.591	1;0.610	0.610	1;0.630	0.630	1;0.649	0.649	1;0.668	0.668	1;0.687	0.687	1;0.707	0.707	1;0.726	0.726	1;0.745	0.745	1;0.765	0.765	1;0.784	0.784	1;0.825	0.800	0.975;0.975	0.800	0.825;1	0.784	0.784;1	0.765	0.765;1	0.745	0.745;1	0.726	0.726;1	0.707	0.707;1	0.687	0.687;1	0.668	0.668;1	0.649	0.649;1	0.630	0.630;1	0.610	0.610;1	0.591	0.591;1	0.572	0.572;1	0.552	0.552;1	0.533	0.533;1	0.514	0.514;1	0.494	0.494;1	0.475	0.475;1	0.456	0.456;1	0.436	0.436;1	0.417	0.417;1	0.398	0.398;1	0.379	0.379;1	0.359	0.359;1	0.340	0.340;1	0.321	0.321;1	0.301	0.301;1	0.282	0.282;1	0.263	0.263;1	0.243	0.243;1	0.224	0.224;1	0.205	0.205;1	0.185	0.185;1	0.166	0.166;1	0.147	0.147;1	0.128	0.128;1	0.108	0.108;1	0.0890	0.0890;1	0.0700	0.0700;1	0.0500	0.0500;1	0.0310	0.0310;1	0.0120	0.0120;0.991	0	0;0.968	0	0;0.944	0	0;0.921	0	0;0.897	0	0;0.874	0	0;0.851	0	0;0.827	0	0;0.804	0	0;0.781	0	0;0.757	0	0;0.734	0	0;0.710	0	0;0.687	0	0;0.664	0	0;0.640	0	0;0.617	0	0;0.594	0	0;0.570	0	0;0.547	0	0;0.523	0	0;0.500	0	0];
        Cols4Slip    = [1	1	1; 0.9450	0.9840	1;  0.8900	0.9670	1;0.8350	0.9500	1;0.7800	0.9340	1;0.7250	0.9170	1;0.6700	0.9010	1;0.6150	0.8840	1;0.5590	0.8680	1;0.5040	0.8510	1;0.4480	0.8340	1;0.3930	0.8180	1;0.3380	0.8020	1;0.2830	0.7850	1;0.2280	0.7690	1;0.1730	0.7520	1;0.1180	0.7350	1;0.06300	0.7190	1;0.008000	0.7020	1;0	0.7050	0.9530;0	0.7100	0.8980;0	0.7160	0.8430;0	0.7210	0.7880;0	0.7270	0.7330;0	0.7320	0.6770;0	0.7370	0.6220;0	0.7430	0.5670;0	0.7490	0.5120;0	0.7550	0.4560;0	0.7600	0.4010;0	0.7660	0.3460;0	0.7710	0.2910;0	0.7770	0.2360;0	0.7820	0.1810;0	0.7880	0.1260;0	0.7930	0.07100;0	0.7980	0.01600;0.02000	0.8080	0;0.04800	0.8190	0;0.07500	0.8300	0;0.1020	0.8410	0;0.1300	0.8520	0;0.1580	0.8630	0;0.1850	0.8740	0;0.2120	0.8850	0;0.2400	0.8960	0;0.2680	0.9070	0;0.2950	0.9180	0;0.3230	0.9290	0;0.3500	0.9400	0;0.3780	0.9510	0;0.4050	0.9630	0;0.4330	0.9730	0;0.4600	0.9840	0;0.4880	0.9950	0;0.5160	1	0;0.5440	1	0;0.5710	1	0;0.5980	1	0;0.6260	1	0;0.6540	1	0;0.6810	1	0;0.7090	1	0;0.7360	1	0;0.7640	1	0;0.7910	1	0;0.8190	1	0;0.8460	1	0;0.8740	1	0;0.9020	1	0;0.9290	1	0;0.9560	1	0;0.9840	1	0;1	0.9880	0;1	0.9600	0;1	0.9330	0;1	0.9050	0;1	0.8780	0;1	0.8500	0;1	0.8230	0;1	0.7950	0;1	0.7680	0;1	0.7400	0;1	0.7120	0;1	0.6850	0;1	0.6580	0;1	0.6300	0;1	0.6020	0;1	0.5750	0;1	0.5480	0;1	0.5200	0;1	0.4920	0;1	0.4640	0;1	0.4370	0;1	0.4090	0;1	0.3820	0;1	0.3540	0;1	0.3270	0;1	0.2990	0;1	0.2720	0;1	0.2440	0;1	0.2160	0;1	0.1890	0;1	0.1620	0;1	0.1340	0;1	0.1060	0;1	0.07900	0;1	0.05200	0;1	0.02400	0;0.9960	0	0;0.9680	0	0;0.9410	0	0;0.9130	0	0;0.8860	0	0;0.8580	0	0;0.8300	0	0;0.8030	0	0;0.7760	0	0;0.7480	0	0;0.7200	0	0;0.6930	0	0;0.6660	0	0;0.6380	0	0;0.6100	0	0;0.5830	0	0;0.5560	0	0;0.5280	0	0;0.5000	0	0];           

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadprerunforgeometryButton
        function LoadprerunforgeometryButtonPushed(app, event)
            %-------------------------------------
            [LoadName,LoadPath] = uigetfile('*PreRunData.dat','Load selected parameter');
            if     (LoadName ~=0),      ParaPath  = strcat(LoadPath,LoadName);
            elseif (LoadName ==0),      return;         
            end
            fidbin    = fopen(ParaPath ,'rb');      
            if (fidbin == -1), errordlg('The entered filename has not been found.');    return;   end
            app.Lamp.Color = 'yellow';
            %-------------------------------------
            FPatchNum  = fread(fidbin,1,'uint32');
            FVertNum   = fread(fidbin,1,'uint32');
            dummy      = fread(fidbin,1,'uint32');
            dummy      = fread(fidbin,1,'uint32');
            %-----------------------------------------   
            app.Fault  = struct;
            app.Fault.SizeTrigList       = FPatchNum;                app.Fault.SizeVertList    = FVertNum;
            %-----------------------
            app.Fault.TriangleList       = zeros(FPatchNum,5);       app.Fault.GlobalVertices  = zeros(FVertNum,4); 
            app.Fault.GlobCentPos        = zeros(FPatchNum,3);       app.Fault.Parameter       = zeros(FPatchNum,11);
            %-----------------------
            app.Fault.TriangleList(:,1)  = fread(fidbin,FPatchNum,'uint32');
            app.Fault.TriangleList(:,2)  = fread(fidbin,FPatchNum,'uint32');
            app.Fault.TriangleList(:,3)  = fread(fidbin,FPatchNum,'uint32');
            app.Fault.TriangleList(:,4)  = fread(fidbin,FPatchNum,'uint32');
            app.Fault.TriangleList(:,5)  = fread(fidbin,FPatchNum,'uint32');

            app.Fault.TriangleList(:,1)  = app.Fault.TriangleList(:,1) +1;
            app.Fault.TriangleList(:,2)  = app.Fault.TriangleList(:,2) +1;
            app.Fault.TriangleList(:,3)  = app.Fault.TriangleList(:,3) +1;
            app.Fault.TriangleList(:,4)  = app.Fault.TriangleList(:,4) +1;
            app.Fault.TriangleList(:,5)  = app.Fault.TriangleList(:,5) +1;
            
            app.Fault.GlobalVertices(:,1)= fread(fidbin,FVertNum,'single');
            app.Fault.GlobalVertices(:,2)= fread(fidbin,FVertNum,'single');
            app.Fault.GlobalVertices(:,3)= fread(fidbin,FVertNum,'single');
            app.Fault.GlobalVertices(:,4)= fread(fidbin,FVertNum,'single');
            
            app.Fault.GlobCentPos(:,1)   = fread(fidbin,FPatchNum,'single');
            app.Fault.GlobCentPos(:,2)   = fread(fidbin,FPatchNum,'single');
            app.Fault.GlobCentPos(:,3)   = fread(fidbin,FPatchNum,'single');
            %-------------------------------------      
            fclose(fidbin);  
            %-------------------------------------  
            app.FaultLoaded = 1;
            app.LoadprerunforgeometryButton.BackgroundColor    = [0.7569    0.8667    0.7765];
            app.Lamp.Color = 'green';
        end

        % Button pushed function: LoadsourcemodelButton
        function LoadsourcemodelButtonPushed(app, event)
            [LoadName,LoadPath] = uigetfile('*.srfb','Load SRF file');
            if     (LoadName ~=0),      ParaPath  = strcat(LoadPath,LoadName);
            elseif (LoadName ==0),      return;         
            end
            fidbin    = fopen(ParaPath ,'rb');      
            if (fidbin == -1), errordlg('The entered filename has not been found.');    return;   end
            app.Lamp.Color = 'yellow';
            %-------------------------------------  
            %if (isfield(app,'STF')),     app = rmfield(app,'STF');       end
            app.STF = struct;
            app.STF.ActivElemNum = fread(fidbin,1,'uint32');
            app.STF.DeltT        = fread(fidbin,1,'single');
            app.STF.Vs           = fread(fidbin,1,'single');
            app.STF.Density      = fread(fidbin,1,'single');
            %-------------------------------------  
            app.STF.ActivElem    = zeros(app.STF.ActivElemNum, 1);
            app.STF.RuptStart    = zeros(app.STF.ActivElemNum, 1); 
            app.STF.Stk_Slip     = zeros(app.STF.ActivElemNum, 1);
            app.STF.Stk_Dur      = zeros(app.STF.ActivElemNum, 1);
            app.STF.Dip_Slip     = zeros(app.STF.ActivElemNum, 1);
            app.STF.Dip_Dur      = zeros(app.STF.ActivElemNum, 1);
            app.STF.Max_Dur      = 0;
            app.STF.Min_StkSlip  =  Inf;        app.STF.Min_DipSlip  =  Inf;        app.STF.Min_CmbSlip  =  Inf;
            app.STF.Min_StkRate  =  Inf;        app.STF.Min_DipRate  =  Inf;        app.STF.Min_CmbRate  =  Inf;
            app.STF.Max_StkSlip  = -Inf;        app.STF.Max_DipSlip  = -Inf;        app.STF.Max_CmbSlip  = -Inf;
            app.STF.Max_StkRate  = -Inf;        app.STF.Max_DipRate  = -Inf;        app.STF.Max_CmbRate  = -Inf;
            %-----------------------------------------   
            for i = 1 : app.STF.ActivElemNum
                app.STF.ActivElem(i) = fread(fidbin,1,'uint32');    app.STF.ActivElem(i) = app.STF.ActivElem(i) +1;
                app.STF.RuptStart(i) = fread(fidbin,1,'uint32');    app.STF.RuptStart(i) = app.STF.RuptStart(i) +1;
                app.STF.Stk_Slip(i)  = fread(fidbin,1,'single'); 
                app.STF.Stk_Dur(i)   = fread(fidbin,1,'uint32'); 
                app.STF.Dip_Slip(i)  = fread(fidbin,1,'single'); 
                app.STF.Dip_Dur(i)   = fread(fidbin,1,'uint32'); 
                app.STF.CombSlip(i)  = sqrt(app.STF.Stk_Slip(i).^2 + app.STF.Dip_Slip(i).^2);
                %----------------------------------------- 
                app.STF.Min_StkSlip  = min( [app.STF.Min_StkSlip   app.STF.Stk_Slip(i)] );
                app.STF.Min_DipSlip  = min( [app.STF.Min_DipSlip   app.STF.Dip_Slip(i)] );
                app.STF.Min_CmbSlip  = min( [app.STF.Min_CmbSlip   app.STF.CombSlip(i)] );
                app.STF.Max_StkSlip  = max( [app.STF.Max_StkSlip   app.STF.Stk_Slip(i)] );
                app.STF.Max_DipSlip  = max( [app.STF.Max_DipSlip   app.STF.Dip_Slip(i)] );
                app.STF.Max_CmbSlip  = max( [app.STF.Max_CmbSlip   app.STF.CombSlip(i)] );
                app.STF.Max_Dur      = max( [app.STF.Max_Dur (app.STF.RuptStart(i)+app.STF.Stk_Dur(i)) (app.STF.RuptStart(i) +app.STF.Dip_Dur(i))] );
                %----------------------------------------- 
                app.STF.Stk_Stf(i).Vals   = fread(fidbin,app.STF.Stk_Dur(i),'single'); 
                app.STF.Dip_Stf(i).Vals   = fread(fidbin,app.STF.Stk_Dur(i),'single'); 
                app.STF.Stk_Stf(i).Vals   = app.STF.Stk_Stf(i).Vals./app.STF.DeltT;
                app.STF.Dip_Stf(i).Vals   = app.STF.Dip_Stf(i).Vals./app.STF.DeltT;
                %----------------------------------------- 
                CombRate = sqrt(app.STF.Stk_Stf(i).Vals.^2  +  app.STF.Dip_Stf(i).Vals.^2 );
                app.STF.Min_StkRate = min( [ app.STF.Min_StkRate  min(app.STF.Stk_Stf(i).Vals)] ) ;
                app.STF.Min_DipRate = min( [ app.STF.Min_DipRate  min(app.STF.Dip_Stf(i).Vals)] ) ;
                app.STF.Min_CmbRate = min( [ app.STF.Min_CmbRate  min(CombRate)] ) ;
                app.STF.Max_StkRate = max( [ app.STF.Max_StkRate  max(app.STF.Stk_Stf(i).Vals)] ) ;
                app.STF.Max_DipRate = max( [ app.STF.Max_DipRate  max(app.STF.Dip_Stf(i).Vals)] ) ;
                app.STF.Max_CmbRate = max( [ app.STF.Max_CmbRate  max(CombRate)] ) ;
            end


            %-------------------------------------      
            fclose(fidbin);  
            %-------------------------------------  
            disp(strcat("max. duration (i.e., total rupture time):_",num2str(app.STF.Max_Dur)));
            app.SrceLoaded = 1;
            app.LoadsourcemodelButton.BackgroundColor    = [0.7569    0.8667    0.7765];
            app.dtofeventsEditField.Value = app.STF.DeltT;
            app.Lamp.Color = 'green';
        end

        % Value changed function: plotcompListBox
        function plotcompListBoxValueChanged(app, event)
            value = app.plotcompListBox.Value;
            
            if     (value == 1),            app.PlotMinValue_EditField.Value = app.STF.Min_CmbSlip;         app.PlotMaxValue_EditField.Value = app.STF.Max_CmbSlip;
            elseif (value == 2),            app.PlotMinValue_EditField.Value = app.STF.Min_StkSlip;         app.PlotMaxValue_EditField.Value = app.STF.Max_StkSlip;
            elseif (value == 3),            app.PlotMinValue_EditField.Value = app.STF.Min_DipSlip;         app.PlotMaxValue_EditField.Value = app.STF.Max_DipSlip;
            elseif (value == 4),            app.PlotMinValue_EditField.Value = app.STF.Min_CmbRate;         app.PlotMaxValue_EditField.Value = app.STF.Max_CmbRate;
            elseif (value == 5),            app.PlotMinValue_EditField.Value = app.STF.Min_StkRate;         app.PlotMaxValue_EditField.Value = app.STF.Max_StkRate;
            elseif (value == 6),            app.PlotMinValue_EditField.Value = app.STF.Min_DipRate;         app.PlotMaxValue_EditField.Value = app.STF.Max_DipRate;
            end
        end

        % Button pushed function: ShowtimeevolutionButton
        function ShowtimeevolutionButtonPushed(app, event)
            if (app.FaultLoaded == 0),          return;         end
            if (app.SrceLoaded  == 0),          return;         end
            app.Lamp.Color = 'yellow';
            drawnow;
            %-----------------------------------------------
            FigNum = app.FigEditField.Value*10+1;
            ViewA  = app.ViewAngle_EditField.Value;      ViewA = sscanf(ViewA,'%f')';
            %-----------------------------------------------
            SelComp   = app.plotcompListBox.Value;
            %-----------------------------------------------
            deltaT      = app.dtofeventsEditField.Value;
            MakeMovie   = app.saveasmovieCheckBox.Value;
            SecPerFrame = app.secframeEditField.Value;
            FrameStep   = floor(SecPerFrame/deltaT);


            FrameBins = 0 : FrameStep : (app.STF.Max_Dur +FrameStep);
        
 %           FrameBins = 0 : 5: 1400;
            FramePerSec = app.framespersecEditField.Value;
            %-----------------------------------------------
            AllV1s          = zeros(app.Fault.SizeTrigList,3);
            AllV2s          = zeros(app.Fault.SizeTrigList,3);
            AllV3s          = zeros(app.Fault.SizeTrigList,3);
            Cntr            = 1;
            %-----------------------------------------------
            if (MakeMovie == 1)
                [DataName,DataPath]  = uiputfile({'*.*'},'JO!');
                if     (DataName ~=0),                      CompletePath         = strcat(DataPath,DataName); 
                elseif (DataName ==0),                      return;         
                end
                %---------------------%
            end
            FrameBinNum    = size(FrameBins,2);
            F(FrameBinNum) = struct('cdata',[],'colormap',[]);
            %-----------------------------------------------
            figure(FigNum); clf; axis equal; view(ViewA); axis tight;
            xlabel('Easting (km)');ylabel('Northing (km)'); zlabel('Depth (km)');
            set(gcf,'Color',[1 1 1]);
            set(gca,'XColor',[1 1 1],'YColor',[1 1 1],'ZColor',[1 1 1 ],'Color',[1 1 1]);
            %-----------------------------------------------
            colormap(app.Cols4Slip); colorbar;
            caxis([app.PlotMinValue_EditField.Value app.PlotMaxValue_EditField.Value]);
            %-----------------------------------------------
            for j = 1 : size(app.Fault.TriangleList,1)
                AllV1s(Cntr,1) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,1),1);
                AllV1s(Cntr,2) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,2),1);
                AllV1s(Cntr,3) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,3),1);
        
                AllV2s(Cntr,1) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,1),2);
                AllV2s(Cntr,2) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,2),2);
                AllV2s(Cntr,3) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,3),2);
        
                AllV3s(Cntr,1) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,1),3);
                AllV3s(Cntr,2) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,2),3);
                AllV3s(Cntr,3) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,3),3);
                Cntr           = Cntr +1;
            end
            %-----------------------------------------------
            if (SelComp < 4),   Factor = app.STF.DeltT;
            else,               Factor = 1;
            end
            for i = 1 : (size(FrameBins,2)-1)
                cla;
                PlottedComp = nan(app.Fault.SizeTrigList,1);
                MinStep     = FrameBins(1,i);
                MaxStep     = FrameBins(1,i+1);
                %-----------------------------------
                for j = 1 : app.STF.ActivElemNum
                    NextVal  = 0;
                    CurrElem = app.STF.ActivElem(j);
                    %-----------------------------------
                    if (SelComp < 4)
                        Pos1 = min([MinStep app.STF.RuptStart(j)]) - app.STF.RuptStart(j) +1;
                        Pos2 = min([MaxStep (app.STF.RuptStart(j)+app.STF.Stk_Dur(j)) (app.STF.RuptStart(j)+app.STF.Dip_Dur(j))] ) - app.STF.RuptStart(j);
                    else
                        Pos1 = max([MinStep app.STF.RuptStart(j)]) - app.STF.RuptStart(j) +1;
                        Pos2 = min([MaxStep (app.STF.RuptStart(j)+app.STF.Stk_Dur(j)) (app.STF.RuptStart(j)+app.STF.Dip_Dur(j))] ) - app.STF.RuptStart(j);
                    end
                    %-----------------------------------
                    if (Pos1 > 0) && (Pos1 <= Pos2)
                        if     (SelComp == 1)
                            NextVal = sum(sqrt(app.STF.Stk_Stf(j).Vals(Pos1:Pos2).^2  +  app.STF.Dip_Stf(j).Vals(Pos1:Pos2).^2  ) );
                        elseif (SelComp == 4)
                            NextVal = mean(sqrt(app.STF.Stk_Stf(j).Vals(Pos1:Pos2).^2  +  app.STF.Dip_Stf(j).Vals(Pos1:Pos2).^2  ) );
                        elseif (SelComp == 2)
                            NextVal = sum(app.STF.Stk_Stf(j).Vals(Pos1:Pos2));
                        elseif (SelComp == 5)
                            NextVal = mean(app.STF.Stk_Stf(j).Vals(Pos1:Pos2));
                        elseif (SelComp == 3)
                            NextVal = sum(app.STF.Dip_Stf(j).Vals(Pos1:Pos2));
                        elseif (SelComp == 6)
                            NextVal = mean(app.STF.Dip_Stf(j).Vals(Pos1:Pos2));
                        end
                    end
                    %-----------------------------------
                    if (NextVal ~= 0)
                        PlottedComp(CurrElem,1) = NextVal.*Factor;
                    end
                end
                %-----------------------------------
                patch(AllV1s',AllV2s',AllV3s',PlottedComp','FaceAlpha',1.0,'EdgeColor','black','EdgeAlpha',0.3);
                drawnow;
                pause(0.05);
           %     disp(i);
                %-----------------------------------
                if (MakeMovie == 1)
                    F(i) = getframe(gca);
                end
            end
            %-----------------------------------------------
            if (MakeMovie == 1)
                MovieFrames = F(1,1:end-1);
                %---------------------%
                myVideo           = VideoWriter(CompletePath,"MPEG-4");
                myVideo.FrameRate = FramePerSec;
                myVideo.Quality   = 95;    % Default 75
                %---------------------%
                open(myVideo);
                writeVideo(myVideo,MovieFrames);
                close(myVideo);
            end
            %-----------------------------------------------
            app.Lamp.Color = 'green';
        end

        % Button pushed function: ShowmomentrateButton
        function ShowmomentrateButtonPushed(app, event)
            if (app.SrceLoaded == 0),          return;         end
            FigNum = app.FigEditField.Value*10+2;
            WithTimeEvol = app.evolutionCheckBox.Value;
            %-------------------------------------  
            MRFvect = zeros(app.STF.Max_Dur,3);
            Timvect = (1:app.STF.Max_Dur)'.*app.dtofeventsEditField.Value;
            %------------------------------------- 
            for i = 1 : app.STF.ActivElemNum
                StartPos = app.STF.RuptStart(i);
                EndPos   = app.STF.RuptStart(i) + app.STF.Stk_Dur(i) -1;
                MRFvect(StartPos:EndPos,1) = MRFvect(StartPos:EndPos,1) + app.STF.Stk_Stf(i).Vals;
                EndPos   = app.STF.RuptStart(i) + app.STF.Dip_Dur(i) -1;
                MRFvect(StartPos:EndPos,2) = MRFvect(StartPos:EndPos,2) + app.STF.Dip_Stf(i).Vals;
            end
            MRFvect(:,3) = sqrt(MRFvect(:,1).^2 + MRFvect(:,2).^2);
            %-------------------------------------  
            if (WithTimeEvol == 0)
                figure(FigNum); clf; hold on;
                plot(Timvect',MRFvect(:,3),'o-');
            else
                deltaT      = app.dtofeventsEditField.Value;
                MakeMovie   = app.saveasmovieCheckBox.Value;
                SecPerFrame = app.secframeEditField.Value;
                FrameStep   = floor(SecPerFrame/deltaT);
                FrameBins   = 1 : FrameStep : (app.STF.Max_Dur +FrameStep);
        
     %           FrameBins = 0 : 5: 1400;
                FramePerSec = app.framespersecEditField.Value;
                %-------------------------------------  
                if (MakeMovie == 1)
                    [DataName,DataPath]  = uiputfile({'*.*'},'JO!');
                    if     (DataName ~=0),                      CompletePath         = strcat(DataPath,DataName); 
                    elseif (DataName ==0),                      return;         
                    end
                end
                FrameBinNum    = size(FrameBins,2);
                F(FrameBinNum) = struct('cdata',[],'colormap',[]);
                %-------------------------------------  
                figure(FigNum); clf; hold on; xlim([0 max(Timvect)]); ylim([0 max(MRFvect(:,3))]);
                %-------------------------------------  
                for i = 1 : (size(FrameBins,2)-1)
                    cla;
                    PlottedComp = nan(app.STF.Max_Dur,2);
                    Pos1    = 1;
                    Pos2    = min([size(Timvect,1)   FrameBins(1,i+1)]);
                    %-----------------------------------
                    PlottedComp(Pos1:Pos2,1) = Timvect(Pos1:Pos2,1);
                    PlottedComp(Pos1:Pos2,2) = MRFvect(Pos1:Pos2,3);
                    %-----------------------------------
                    plot(PlottedComp(:,1),PlottedComp(:,2),'o-');
                    drawnow;
                    pause(0.05);
                    %-----------------------------------
                    if (MakeMovie == 1)
                        F(i) = getframe(gca);
                    end
                end
                %-----------------------------------------------
                if (MakeMovie == 1)
                    MovieFrames = F(1,1:end-1);
                    %---------------------%
                    myVideo           = VideoWriter(CompletePath,"MPEG-4");
                    myVideo.FrameRate = FramePerSec;
                    myVideo.Quality   = 95;    % Default 75
                    %---------------------%
                    open(myVideo);
                    writeVideo(myVideo,MovieFrames);
                    close(myVideo);
                end
                %-----------------------------------------------
            end
        end

        % Button pushed function: ShowoverviewButton
        function ShowoverviewButtonPushed(app, event)
            if (app.FaultLoaded == 0),          return;         end
            if (app.SrceLoaded  == 0),          return;         end
            %-------------------------------------
            FigNum = app.FigEditField.Value*10+3;
            ViewA  = app.ViewAngle_EditField.Value;      ViewA = sscanf(ViewA,'%f')';
            %----------------------------------------     
            figure(FigNum); clf; hold on;            
            view(ViewA);
            xlabel('Easting (km)');ylabel('Northing (km)'); zlabel('Depth (km)');
            %-----------------------------------------------
            AllV1s          = zeros(app.Fault.SizeTrigList,3);
            AllV2s          = zeros(app.Fault.SizeTrigList,3);
            AllV3s          = zeros(app.Fault.SizeTrigList,3);
            Cntr            = 1;
            PlottedComp     = nan(app.Fault.SizeTrigList,1);
            SelComp         = app.foroverviewListBox.Value;
            %-----------------------------------------------
            for j = 1 : size(app.Fault.TriangleList,1)
                AllV1s(Cntr,1) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,1),1);
                AllV1s(Cntr,2) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,2),1);
                AllV1s(Cntr,3) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,3),1);
        
                AllV2s(Cntr,1) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,1),2);
                AllV2s(Cntr,2) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,2),2);
                AllV2s(Cntr,3) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,3),2);
        
                AllV3s(Cntr,1) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,1),3);
                AllV3s(Cntr,2) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,2),3);
                AllV3s(Cntr,3) = app.Fault.GlobalVertices(app.Fault.TriangleList(j,3),3);
                Cntr           = Cntr +1;
            end
            %----------------------------------------------- 
            for i = 1 : app.STF.ActivElemNum
                if     (SelComp == 1),  PlottedComp(app.STF.ActivElem(i),1) = app.STF.RuptStart(i)*app.dtofeventsEditField.Value;
                elseif (SelComp == 2),  PlottedComp(app.STF.ActivElem(i),1) = max([app.STF.Stk_Dur(i)  app.STF.Dip_Dur(i)])*app.dtofeventsEditField.Value;
                elseif (SelComp == 3),  PlottedComp(app.STF.ActivElem(i),1) = app.STF.CombSlip(i);
                end
            end
            %----------------------------------------------- 
            figure(FigNum); clf; hold on;
            patch(AllV1s',AllV2s',AllV3s',PlottedComp','EdgeColor','black');
            view(ViewA); axis equal;
        end

        % Button pushed function: SelectelementButton
        function SelectelementButtonPushed(app, event)
            if (app.FaultLoaded == 0),          return;         end
            if (app.SrceLoaded  == 0),          return;         end
            %--------------------------------------
            FigNum = app.FigEditField.Value*10+4;         
            ViewA  = app.ViewAngle_EditField.Value;      ViewA = sscanf(ViewA,'%f')';
            %-------------------------------------
            SelComp  = app.foroverviewListBox.Value;
            PlotThis = nan(size(app.Fault.TriangleList,1),1);
            for i = 1 : app.STF.ActivElemNum
                if     (SelComp == 1),  PlotThis(app.STF.ActivElem(i),1) = app.STF.RuptStart(i)*app.dtofeventsEditField.Value;
                elseif (SelComp == 2),  PlotThis(app.STF.ActivElem(i),1) = max([app.STF.Stk_Dur(i)  app.STF.Dip_Dur(i)])*app.dtofeventsEditField.Value;
                elseif (SelComp == 3),  PlotThis(app.STF.ActivElem(i),1) = app.STF.CombSlip(i);
                end
            end
            %-------------------------------------
            SelSect   = app.onsectionEditField.Value;
            SelSecPos = (app.Fault.TriangleList(:,5) == SelSect);
            SelectPtch= app.Fault.TriangleList(SelSecPos,:); 
            PlotThis  = PlotThis(SelSecPos,:);
            SelPtchNum= size(SelectPtch,1);
            %-----------------------------------------------
            AllV1s      = zeros(SelPtchNum,3);
            AllV2s      = zeros(SelPtchNum,3);
            AllV3s      = zeros(SelPtchNum,3);
            %-----------------------------------------------
            for j = 1 : SelPtchNum
                AllV1s(j,1) = app.Fault.GlobalVertices(SelectPtch(j,1),1);
                AllV1s(j,2) = app.Fault.GlobalVertices(SelectPtch(j,2),1);
                AllV1s(j,3) = app.Fault.GlobalVertices(SelectPtch(j,3),1);
            
                AllV2s(j,1) = app.Fault.GlobalVertices(SelectPtch(j,1),2);
                AllV2s(j,2) = app.Fault.GlobalVertices(SelectPtch(j,2),2);
                AllV2s(j,3) = app.Fault.GlobalVertices(SelectPtch(j,3),2);
            
                AllV3s(j,1) = app.Fault.GlobalVertices(SelectPtch(j,1),3);
                AllV3s(j,2) = app.Fault.GlobalVertices(SelectPtch(j,2),3);
                AllV3s(j,3) = app.Fault.GlobalVertices(SelectPtch(j,3),3);
            end
            %----------------------------------------------- 
            
            size(PlotThis)
            size(AllV3s)
            %----------------------------------------------- 
            figure(FigNum); clf; hold on;   view(ViewA);
            patch(AllV1s',AllV2s',AllV3s',PlotThis,'EdgeColor','black');
            drawnow;
            axis equal;
            %-----------------------------           
            datacursormode on;
            dcm_obj = datacursormode(gcf);
    
            waitforbuttonpress;
            f = getCursorInfo(dcm_obj);
            PointCoord    = f.Position;
            %-----------------------------  
            PointDistance = sqrt((app.Fault.GlobCentPos(:,1) - PointCoord(1,1)).^2 +(app.Fault.GlobCentPos(:,2) - PointCoord(1,2)).^2 +(app.Fault.GlobCentPos(:,3) - PointCoord(1,3)).^2);
            MinDistPos    = find(PointDistance == min(PointDistance(:)),1,'first');
            %-----------------------------  
            app.selectedelementEditField.Value = MinDistPos;
            %-----------------------------  
            plot3(app.Fault.GlobCentPos(MinDistPos,1), app.Fault.GlobCentPos(MinDistPos,2), app.Fault.GlobCentPos(MinDistPos,3), 'ro','MarkerFaceColor','red'); 
        end

        % Button pushed function: ShowelementSTFButton
        function ShowelementSTFButtonPushed(app, event)
            if (app.SrceLoaded == 0),          return;         end
            %-----------------------------  
            FigNum = app.FigEditField.Value*10+5;
            %-----------------------------  
            SelComp = app.plotcompListBox.Value;
            %-----------------------------  
            SelElem = app.selectedelementEditField.Value;
            SelPos  = nan;
            MRFvect = zeros(app.STF.Max_Dur,3);
            Timvect = (1:app.STF.Max_Dur).*app.dtofeventsEditField.Value;
            %-----------------------------  
            for i = 1 : app.STF.ActivElemNum
                if (app.STF.ActivElem(i) == SelElem),   SelPos = i;     break;      end 
            end
            %-----------------------------  
            if (isnan(SelPos)),                 return;         end
            %-----------------------------  
            StartPos = app.STF.RuptStart(SelPos);
            EndPos   = app.STF.RuptStart(SelPos) + app.STF.Stk_Dur(SelPos) -1;
            MRFvect(StartPos:EndPos,2) = app.STF.Stk_Stf(SelPos).Vals;
            EndPos   = app.STF.RuptStart(SelPos) + app.STF.Dip_Dur(SelPos) -1;
            MRFvect(StartPos:EndPos,3) = app.STF.Dip_Stf(SelPos).Vals;

            MRFvect(:,1) = sqrt(MRFvect(:,2).^2 + MRFvect(:,3).^2);
            if (SelComp < 4)
                MRFvect = cumsum(MRFvect);
            else
                SelComp = SelComp -3;
            end
            %-------------------------------------  
            figure(FigNum); clf; hold on;
            plot(Timvect,MRFvect(:,SelComp),'o-');
        end

        % Button pushed function: ExporttosrffileButton
        function ExporttosrffileButtonPushed(app, event)
            if (app.SrceLoaded ~= 1),   return;         end
            [SaveName,SavePath] = uiputfile('*.srf','Save SRF file');
            if     (SaveName ~=0),      SavePath = strcat(SavePath,SaveName);
            elseif (SaveName ==0),      return;         
            end
            %------------------------------------- 
            fid = fopen(SavePath ,'w');      
            if (fid == -1), errordlg('The entered filename has not been found.');    return;   end
            %------------------------------------- 

            fprintf(fid,"2.0\n");
            fprintf(fid,"POINTS   %d\n", app.STF.ActivElemNum);
            %------------------------------------- 
            for i = 1 : app.STF.ActivElemNum
                NextElem = app.STF.ActivElem(i);
                Lon      = app.Fault.GlobCentPos(NextElem,1);
                Lat      = app.Fault.GlobCentPos(NextElem,2);
                Dep      = app.Fault.GlobCentPos(NextElem,3);
                V1       = app.Fault.TriangleList(NextElem,1);
                V2       = app.Fault.TriangleList(NextElem,2);
                V3       = app.Fault.TriangleList(NextElem,3);
                Vert1    = app.Fault.GlobalVertices(V1,1:3)';
                Vert2    = app.Fault.GlobalVertices(V2,1:3)';
                Vert3    = app.Fault.GlobalVertices(V3,1:3)';
                %https://www.mathworks.com/matlabcentral/answers/271797-surface-area-of-a-3d-surface
                Area     = 1E-6 *1/2*norm(cross((Vert2-Vert1),(Vert3-Vert1)));   
                %------------------------------------- 
                Vnorm = cross(Vert2-Vert1,Vert3-Vert1);
                Vnorm = Vnorm/norm(Vnorm);
                eY      = [0 1 0]';
                eZ      = [0 0 1]';
                Vstrike = cross(eZ,Vnorm);
                if (norm(Vstrike)== 0),     Vstrike = eY*Vnorm(3);           end
                Vstrike = Vstrike/norm(Vstrike);
                %------------------------------------- 
                Stk = atan2d(Vstrike(1,1),Vstrike(2,1));
                if (Stk > 0),       Stk = 360 - Stk;
                else,               Stk = -Stk;
                end
                Dip = acosd(Vnorm(3,1));
                if (Dip > 90),      Dip = 180 - Dip;    Stk = Stk + 180;
                elseif (Dip < 0),   Dip = -Dip;         Stk = Stk + 180;
                end
                if (Stk > 360),     Stk = Stk -360;     end
                %------------------------------------- 
                Tinit = app.STF.RuptStart(i);
                dT    = app.STF.DeltT;
                Vs    = app.STF.Vs;
                Den   = app.STF.Density;
                Slip1 = sqrt(app.STF.Stk_Slip(i)^2 + app.STF.Dip_Slip(i)^2);
                NT1   = (app.STF.Stk_Dur(i) + app.STF.Dip_Dur(i))/2;
 %               Slip2 = 0;
 %               NT2   = 0;
 %               Slip3 = 0;
 %               NT3   = 0;
                SlpRte= sqrt(app.STF.Stk_Stf(i).Vals.^2 + app.STF.Dip_Stf(i).Vals.^2);
                Rake  = atan2d(app.STF.Dip_Slip(i), app.STF.Stk_Slip(i));

                %------------------------------------- 
                fprintf(fid, "%3.4f   %3.4f   %3.2f  %3.0f   %2.0f   %e   %3.4f   %3.4f   %3.4f   %4.0f\n", Lon, Lat, Dep, Stk, Dip, Area, Tinit, dT, Vs, Den);
                fprintf(fid, "%3.0f   %2.4f   %4.0f   0.0   0   0.0   0\n", Rake, Slip1, NT1);
                fprintf(fid,"%2.4f  ",SlpRte);
                fprintf(fid,"\n");
                %------------------------------------- 

            end
            %------------------------------------- 
            fclose(fid);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadsourcemodelButton = uibutton(app.UIFigure, 'push');
            app.LoadsourcemodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsourcemodelButtonPushed, true);
            app.LoadsourcemodelButton.Text = 'Load source model';
            app.ShowtimeevolutionButton = uibutton(app.UIFigure, 'push');
            app.ShowtimeevolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowtimeevolutionButtonPushed, true);
            app.ShowtimeevolutionButton.Text = 'Show time evolution';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.FigEditFieldLabel = uilabel(app.UIFigure);
            app.FigEditFieldLabel.Text = 'Fig #';
            app.FigEditField = uieditfield(app.UIFigure, 'numeric');
            app.FigEditField.Limits = [1 Inf];
            app.FigEditField.RoundFractionalValues = 'on';
            app.FigEditField.ValueDisplayFormat = '%d';
            app.FigEditField.Value = 1;
            app.plotcompListBoxLabel = uilabel(app.UIFigure);
            app.plotcompListBoxLabel.Text = 'plot comp.';
            app.plotcompListBox = uilistbox(app.UIFigure);
            app.plotcompListBox.Items = {'total slip', 'strike slip', 'dip slip', 'total slip-rate', 'strike slip-rate', 'dip slip-rate'};
            app.plotcompListBox.ItemsData = [1 2 3 4 5 6];
            app.plotcompListBox.ValueChangedFcn = createCallbackFcn(app, @plotcompListBoxValueChanged, true);
            app.plotcompListBox.Value = 1;
            app.ShowoverviewButton = uibutton(app.UIFigure, 'push');
            app.ShowoverviewButton.ButtonPushedFcn = createCallbackFcn(app, @ShowoverviewButtonPushed, true);
            app.ShowoverviewButton.Text = 'Show overview';
            app.SelectelementButton = uibutton(app.UIFigure, 'push');
            app.SelectelementButton.ButtonPushedFcn = createCallbackFcn(app, @SelectelementButtonPushed, true);
            app.SelectelementButton.Text = 'Select element';
            app.ShowelementSTFButton = uibutton(app.UIFigure, 'push');
            app.ShowelementSTFButton.ButtonPushedFcn = createCallbackFcn(app, @ShowelementSTFButtonPushed, true);
            app.ShowelementSTFButton.Text = 'Show element STF';
            app.ShowmomentrateButton = uibutton(app.UIFigure, 'push');
            app.ShowmomentrateButton.ButtonPushedFcn = createCallbackFcn(app, @ShowmomentrateButtonPushed, true);
            app.ShowmomentrateButton.Text = 'Show moment rate';
            app.saveasmovieCheckBox = uicheckbox(app.UIFigure);
            app.saveasmovieCheckBox.Text = 'save as movie';
            app.secframeEditFieldLabel = uilabel(app.UIFigure);
            app.secframeEditFieldLabel.Text = 'sec/frame';
            app.secframeEditField = uieditfield(app.UIFigure, 'numeric');
            app.secframeEditField.Value = 0.5;
            app.dtofeventsEditFieldLabel = uilabel(app.UIFigure);
            app.dtofeventsEditFieldLabel.Text = 'dt of event(s)';
            app.dtofeventsEditField = uieditfield(app.UIFigure, 'numeric');
            app.dtofeventsEditField.Limits = [0 Inf];
            app.dtofeventsEditField.ValueDisplayFormat = '%1.4f';
            app.dtofeventsEditField.Editable = 'off';
            app.framespersecEditFieldLabel = uilabel(app.UIFigure);
            app.framespersecEditFieldLabel.Text = 'frames per sec';
            app.framespersecEditField = uieditfield(app.UIFigure, 'numeric');
            app.framespersecEditField.Value = 10;
            app.selectedelementEditFieldLabel = uilabel(app.UIFigure);
            app.selectedelementEditFieldLabel.Text = 'selected element';
            app.selectedelementEditField = uieditfield(app.UIFigure, 'numeric');
            app.selectedelementEditField.Limits = [1 Inf];
            app.selectedelementEditField.RoundFractionalValues = 'on';
            app.selectedelementEditField.ValueDisplayFormat = '%d';
            app.selectedelementEditField.Value = 1;
            app.ExporttosrffileButton = uibutton(app.UIFigure, 'push');
            app.ExporttosrffileButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttosrffileButtonPushed, true);
            app.ExporttosrffileButton.Text = 'Export to *.srf file';
            app.LoadprerunforgeometryButton = uibutton(app.UIFigure, 'push');
            app.LoadprerunforgeometryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadprerunforgeometryButtonPushed, true);
            app.LoadprerunforgeometryButton.Text = 'Load pre-run for geometry';
            app.ViewangleEditFieldLabel = uilabel(app.UIFigure);
            app.ViewangleEditFieldLabel.Text = 'View angle';
            app.ViewAngle_EditField = uieditfield(app.UIFigure, 'text');
            app.ViewAngle_EditField.Value = '40 20';
            app.onsectionEditFieldLabel = uilabel(app.UIFigure);
            app.onsectionEditFieldLabel.Text = 'on section';
            app.onsectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.onsectionEditField.Limits = [1 Inf];
            app.onsectionEditField.RoundFractionalValues = 'on';
            app.onsectionEditField.ValueDisplayFormat = '%d';
            app.onsectionEditField.Value = 1;
            app.foroverviewListBox = uilistbox(app.UIFigure);
            app.foroverviewListBox.Items = {'start time', 'duration', 'total slip'};
            app.foroverviewListBox.ItemsData = [1 2 3];
            app.foroverviewListBox.Value = 1;
            app.minvalueEditFieldLabel = uilabel(app.UIFigure);
            app.minvalueEditFieldLabel.Text = 'min. value';
            app.PlotMinValue_EditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotMinValue_EditField.ValueDisplayFormat = '%5.2f';
            app.maxvalueEditFieldLabel = uilabel(app.UIFigure);
            app.maxvalueEditFieldLabel.Text = 'max. value';
            app.PlotMaxValue_EditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotMaxValue_EditField.ValueDisplayFormat = '%5.2f';
            app.evolutionCheckBox = uicheckbox(app.UIFigure);
            app.evolutionCheckBox.Text = 'evolution';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Z0_Load_srfb_MCQsimV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end