classdef Z3_Output_MCQsimV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Select_onSectn_EditField        matlab.ui.control.NumericEditField
        onfaultLabel                    matlab.ui.control.Label
        picksallpatcheswhereatleastonevertexisat10mdepthLabel  matlab.ui.control.Label
        SelectAlllSurfPatches_Button    matlab.ui.control.Button
        InterEvent_PatchOrAll_DropDown  matlab.ui.control.DropDown
        forLabel_2                      matlab.ui.control.Label
        InterEvent_Yscale_DropDown      matlab.ui.control.DropDown
        scaleDropDownLabel_3            matlab.ui.control.Label
        InterEvent_Cvalue_DropDown      matlab.ui.control.DropDown
        colorLabel                      matlab.ui.control.Label
        InterEvent_Yvalue_DropDown      matlab.ui.control.DropDown
        yaxisLabel                      matlab.ui.control.Label
        InterEvent_Xvalue_DropDown      matlab.ui.control.DropDown
        xaxisDropDownLabel_2            matlab.ui.control.Label
        InterEvent_Xscale_DropDown      matlab.ui.control.DropDown
        scaleDropDownLabel_2            matlab.ui.control.Label
        InterEventFigNum_EditField      matlab.ui.control.NumericEditField
        FigEditField_2Label_2           matlab.ui.control.Label
        InterEvent_PlotStuff_Button     matlab.ui.control.Button
        InterEventMaxMag_EditField      matlab.ui.control.NumericEditField
        maxMagnLabel                    matlab.ui.control.Label
        InterEventMinMag_EditField      matlab.ui.control.NumericEditField
        minMagnLabel                    matlab.ui.control.Label
        SelectHypoCentDistrib_Button    matlab.ui.control.Button
        SelectPlotParaAlongSurf_Button  matlab.ui.control.Button
        SelectPlotParVsTime_Button      matlab.ui.control.Button
        SelectHighlightPatch_Button     matlab.ui.control.Button
        SelectPatchID_edit              matlab.ui.control.NumericEditField
        PatchLabel                      matlab.ui.control.Label
        SelectSinglePatch_Button        matlab.ui.control.Button
        fpsEditFieldLabel_2             matlab.ui.control.Label
        secframeEditFieldLabel_2        matlab.ui.control.Label
        PlotColorMap_DropDown           matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        PlotViewAngle_EditField         matlab.ui.control.EditField
        viewangleEditFieldLabel         matlab.ui.control.Label
        PlotFigNum_EditField            matlab.ui.control.NumericEditField
        FigEditFieldLabel               matlab.ui.control.Label
        PlotEQsequencePanel             matlab.ui.container.Panel
        Sequence_EndTEditField          matlab.ui.control.NumericEditField
        EndtimeEditFieldLabel           matlab.ui.control.Label
        Sequence_StartT_EditField       matlab.ui.control.NumericEditField
        StarttimeEditFieldLabel         matlab.ui.control.Label
        SequencePlot_Button             matlab.ui.control.Button
        PlotindividualeventparameterPanel  matlab.ui.container.Panel
        FillContour_CheckBox            matlab.ui.control.CheckBox
        PlotContourValue_EditField      matlab.ui.control.NumericEditField
        contourLabel                    matlab.ui.control.Label
        PlotMaxValue_EditField          matlab.ui.control.NumericEditField
        maxvalueEditFieldLabel          matlab.ui.control.Label
        PlotMinValue_EditField          matlab.ui.control.NumericEditField
        minvalueEditFieldLabel          matlab.ui.control.Label
        PlotComp2Plot_DropDown          matlab.ui.control.DropDown
        toplotDropDownLabel             matlab.ui.control.Label
        PlotOnFault_Button              matlab.ui.control.Button
        PlotMRF_Button                  matlab.ui.control.Button
        LoadandparsecatalogPanel        matlab.ui.container.Panel
        matLabel                        matlab.ui.control.Label
        SaveMRFButton                   matlab.ui.control.Button
        SaveNameEditField               matlab.ui.control.EditField
        SaveNameEditFieldLabel          matlab.ui.control.Label
        SaveSelectedButton              matlab.ui.control.Button
        LoadSeisWidth4PRvsFR_EditField  matlab.ui.control.NumericEditField
        minpatchslipLabel_2             matlab.ui.control.Label
        LoadMinPatchSlip_EditField      matlab.ui.control.NumericEditField
        minpatchslipLabel               matlab.ui.control.Label
        LoadMinMag4Table_EditField      matlab.ui.control.NumericEditField
        EQsintablewithMagnitudeLabel    matlab.ui.control.Label
        LoadCat_Lamp                    matlab.ui.control.Lamp
        CatalogimportedLampLabel        matlab.ui.control.Label
        LoadCatRemoveEQ_EditField       matlab.ui.control.NumericEditField
        RemoveEQswithlessthanEditFieldLabel  matlab.ui.control.Label
        LoadCatalog_Button              matlab.ui.control.Button
        ImportedparsedEQcatalogPanel    matlab.ui.container.Panel
        wseismomentCheckBox             matlab.ui.control.CheckBox
        PlotHockeyStickButton           matlab.ui.control.Button
        MaxMag4ForScaleRel_EditField    matlab.ui.control.NumericEditField
        binningminvalueLabel_2          matlab.ui.control.Label
        MinMag4ForScaleRel_EditField    matlab.ui.control.NumericEditField
        binLabel_2                      matlab.ui.control.Label
        PlotBinNum_EditField            matlab.ui.control.NumericEditField
        binLabel                        matlab.ui.control.Label
        PlotBinMaxValue_EditField       matlab.ui.control.NumericEditField
        maxvalueLabel                   matlab.ui.control.Label
        PlotBinMinValue_EditField       matlab.ui.control.NumericEditField
        binningminvalueLabel            matlab.ui.control.Label
        Scaling_PatchOrAll_DropDown     matlab.ui.control.DropDown
        forLabel                        matlab.ui.control.Label
        ScalingYvalue_DropDown          matlab.ui.control.DropDown
        yaxisDropDownLabel              matlab.ui.control.Label
        ScalingXvalue_DropDown          matlab.ui.control.DropDown
        xaxisDropDownLabel              matlab.ui.control.Label
        ScalingYscale_DropDown          matlab.ui.control.DropDown
        scaleDropDown_2Label            matlab.ui.control.Label
        ScalingXscale_DropDown          matlab.ui.control.DropDown
        scaleDropDownLabel              matlab.ui.control.Label
        Scaling_Symbol_DropDown         matlab.ui.control.DropDown
        symbolDropDownLabel             matlab.ui.control.Label
        FigNumScaling_EditField         matlab.ui.control.NumericEditField
        FigEditField_2Label             matlab.ui.control.Label
        PlotScaling_Button              matlab.ui.control.Button
        EQtable_UITable                 matlab.ui.control.Table
        PlotTimeEvolsecPerFrame_EditField  matlab.ui.control.NumericEditField
        secframeEditFieldLabel          matlab.ui.control.Label
        PlotMoviFps_EditField           matlab.ui.control.NumericEditField
        fpsEditFieldLabel               matlab.ui.control.Label
        PlotSaveMovie_CheckBox          matlab.ui.control.CheckBox
        PlotShowTimeEvol_CheckBox       matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        BlueWhiteRed = [0.0784    0.1686    0.5490;  0         0    1.0000;      0.0333    0.0333    1.0000; 0.0667    0.0667    1.0000;  0.1000    0.1000    1.0000; 0.1333    0.1333    1.0000;...
                        0.1667    0.1667    1.0000;  0.2000    0.2000    1.0000; 0.2333    0.2333    1.0000; 0.2667    0.2667    1.0000;  0.3000    0.3000    1.0000; 0.3333    0.3333    1.0000;...
                        0.3667    0.3667    1.0000;  0.4000    0.4000    1.0000; 0.4333    0.4333    1.0000; 0.4667    0.4667    1.0000;  0.5000    0.5000    1.0000; 0.5333    0.5333    1.0000;...
                        0.5667    0.5667    1.0000;  0.6000    0.6000    1.0000; 0.6333    0.6333    1.0000; 0.6667    0.6667    1.0000;  0.7000    0.7000    1.0000; 0.7333    0.7333    1.0000;...
                        0.7667    0.7667    1.0000;  0.8000    0.8000    1.0000; 0.8333    0.8333    1.0000; 0.8667    0.8667    1.0000;  0.9000    0.9000    1.0000; 0.9333    0.9333    1.0000;...
                        0.9667    0.9667    1.0000;  1.0000    1.0000    1.0000; 1.0000    0.9677    0.9677; 1.0000    0.9355    0.9355;  1.0000    0.9032    0.9032; 1.0000    0.8710    0.8710;...
                        1.0000    0.8387    0.8387;  1.0000    0.8065    0.8065; 1.0000    0.7742    0.7742; 1.0000    0.7419    0.7419;  1.0000    0.7097    0.7097; 1.0000    0.6774    0.6774;...
                        1.0000    0.6452    0.6452;  1.0000    0.6129    0.6129; 1.0000    0.5806    0.5806; 1.0000    0.5484    0.5484;  1.0000    0.5161    0.5161; 1.0000    0.4839    0.4839;...
                        1.0000    0.4516    0.4516;  1.0000    0.4194    0.4194; 1.0000    0.3871    0.3871; 1.0000    0.3548    0.3548;  1.0000    0.3226    0.3226; 1.0000    0.2903    0.2903;...
                        1.0000    0.2581    0.2581;  1.0000    0.2258    0.2258; 1.0000    0.1935    0.1935; 1.0000    0.1613    0.1613;  1.0000    0.1290    0.1290; 1.0000    0.0968    0.0968;...
                        1.0000    0.0645    0.0645;  1.0000    0.0323    0.0323; 1.0000         0         0; 0.8471    0.1608         0];
    
        Cols4Slip    = [1.0000    1.0000    1.0000;  0.8890    0.9670    1.0000; 0.7780    0.9330    1.0000; 0.6670    0.9000    1.0000;  0.5560    0.8670    1.0000; 0.4440    0.8330    1.0000;...
                        0.3330    0.8000    1.0000;  0.2220    0.7670    1.0000; 0.1110    0.7330    1.0000
                        0         0.7000    1.0000;  0         0.7110    0.8890; 0         0.7220    0.7780; 0         0.7330    0.6670;  0         0.7440    0.5560; 0         0.7560    0.4440;...
                        0         0.7670    0.3330;  0         0.7780    0.2220; 0         0.7890    0.1110; 0         0.8000         0;  0.0560    0.8220         0; 0.1110    0.8440    0;...
                        0.1670    0.8670         0;  0.2220    0.8890         0; 0.2780    0.9110         0; 0.3330    0.9330         0;  0.3890    0.9560         0; 0.4440    0.9780    0;...
                        0.5000    1.0000         0;  0.5560    1.0000         0; 0.6110    1.0000         0; 0.6670    1.0000         0;  0.7220    1.0000         0; 0.7780    1.0000    0;...
                        0.8330    1.0000         0;  0.8890    1.0000         0; 0.9440    1.0000         0; 1.0000    1.0000         0;  1.0000    0.9440         0; 1.0000    0.8890    0;...
                        1.0000    0.8330         0;  1.0000    0.7780         0; 1.0000    0.7220         0; 1.0000    0.6670         0;  1.0000    0.6110         0; 1.0000    0.5560    0;...
                        1.0000    0.5000         0;  1.0000    0.4440         0; 1.0000    0.3890         0; 1.0000    0.3330         0;  1.0000    0.2780         0; 1.0000    0.2220    0;...
                        1.0000    0.1670         0;  1.0000    0.1110         0; 1.0000    0.0560         0; 1.0000         0         0;  0.9440         0         0; 0.8890         0    0;...
                        0.8330         0         0;  0.7780         0         0; 0.7220         0         0; 0.6670         0         0;  0.6110         0         0; 0.5560         0    0;...
                        0.5000         0         0];
         
        
        MRF         = struct;
        ClnCatalog  = struct;
        FaultGeom   = struct;
        ShearMod    = 0;
        DeltaT      = 0;
        CatImported = 0;
        EQcount     = 0;
        selectedRow = 1;
        selectedCol = 1;
    
        ExtraParamter = []; % Description
        SelPatchList  = [];
    end
    
    methods (Access = private)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function LoadCatGroupTime_CheckBoxValueChanged(app, event)
            app.LoadCatGroupTime_CheckBox.Value = 1;
            app.LoadCatGroupDist_CheckBox.Value = 0;          
        end

        % Callback function
        function LoadCatGroupDist_CheckBoxValueChanged(app, event)
            app.LoadCatGroupTime_CheckBox.Value = 0;
            app.LoadCatGroupDist_CheckBox.Value = 1;
        end

        % Button pushed function: LoadCatalog_Button
        function LoadCatalog_ButtonPushed(app, event)
            app.LoadCat_Lamp.Color = 'red';         drawnow;   
            app.CatImported        = 0;
            MinPatchNum = app.LoadCatRemoveEQ_EditField.Value;
            MinMag4Tabl = app.LoadMinMag4Table_EditField.Value;
            MinPatchSlp = app.LoadMinPatchSlip_EditField.Value;
            SeisWidth   = app.LoadSeisWidth4PRvsFR_EditField.Value;
            %---------------------------------------------------------------------
            [LoadName,LoadPath] = uigetfile('*Catalog.dat','Load EQ catalog');
            if     (LoadName ~=0) ,     CtlgPath  = strcat(LoadPath,LoadName);
            elseif (LoadName ==0),      return;         
            end
            %---------------------------------------------------------------------
            fidbinCat    = fopen(CtlgPath,'rb');
            app.LoadCat_Lamp.Color = 'yellow';      drawnow;
            %---------------------------------------------------------------------
            EQnumRaw       = fread(fidbinCat,1,'uint32')
           % EQnumRaw  = 2400
            CatType        = fread(fidbinCat,1,'uint32');
            app.ShearMod   = fread(fidbinCat,1,'single');
            app.DeltaT     = fread(fidbinCat,1,'single');
            if (CatType < 3), disp('the catalog has the wrong type; only catalog 3 or higher can be read with this');  return;
            end
            PatchNum       = fread(fidbinCat,1,'uint32');
            VertNum        = fread(fidbinCat,1,'uint32');
            %-----------------------------------------
            if (app.DeltaT > 1000),         app.DeltaT = 1;             end  %if it is LARGE (is measured in seconds) => then don't use it!        
            %-----------------------------------------   
            app.ClnCatalog                   = struct;
            app.MRF                          = struct;
            app.FaultGeom                    = struct;
            app.FaultGeom.SizeTrigList       = PatchNum;
            app.FaultGeom.SizeVertList       = VertNum;
            
            %-----------------------
            app.FaultGeom.TriangleList       = zeros(PatchNum,5);
            app.FaultGeom.GlobalVertices     = zeros(VertNum,4); 
            app.FaultGeom.GlobTrigCentr      = zeros(PatchNum,4);
            app.FaultGeom.TriangleAreas      = zeros(PatchNum,1);
            app.FaultGeom.IsSurfPatch        = zeros(PatchNum,1); 
            %-----------------------
            app.FaultGeom.TriangleList(:,1)  = fread(fidbinCat,PatchNum,'uint32');
            app.FaultGeom.TriangleList(:,2)  = fread(fidbinCat,PatchNum,'uint32');
            app.FaultGeom.TriangleList(:,3)  = fread(fidbinCat,PatchNum,'uint32');
            app.FaultGeom.TriangleList(:,4)  = fread(fidbinCat,PatchNum,'uint32');
            app.FaultGeom.TriangleList(:,5)  = fread(fidbinCat,PatchNum,'uint32');
            app.FaultGeom.GlobalVertices(:,1)= fread(fidbinCat,VertNum,'single');
            app.FaultGeom.GlobalVertices(:,2)= fread(fidbinCat,VertNum,'single');
            app.FaultGeom.GlobalVertices(:,3)= fread(fidbinCat,VertNum,'single');
            app.FaultGeom.GlobalVertices(:,4)= fread(fidbinCat,VertNum,'single'); %is the element's height
 
            app.FaultGeom.GlobTrigCentr(:,1) = fread(fidbinCat,PatchNum,'single');
            app.FaultGeom.GlobTrigCentr(:,2) = fread(fidbinCat,PatchNum,'single');
            app.FaultGeom.GlobTrigCentr(:,3) = fread(fidbinCat,PatchNum,'single');
            
            app.FaultGeom.TriangleList(:,1)  = app.FaultGeom.TriangleList(:,1) +1;
            app.FaultGeom.TriangleList(:,2)  = app.FaultGeom.TriangleList(:,2) +1;
            app.FaultGeom.TriangleList(:,3)  = app.FaultGeom.TriangleList(:,3) +1;
            app.FaultGeom.TriangleList(:,4)  = app.FaultGeom.TriangleList(:,4) +1;
            app.FaultGeom.TriangleList(:,5)  = app.FaultGeom.TriangleList(:,5) +1;
            %----------------------------------------- %want to determine the local trig center next      
            for ii = 1 : PatchNum
                Vert1 = app.FaultGeom.TriangleList(ii,1);
                Vert2 = app.FaultGeom.TriangleList(ii,2);
                Vert3 = app.FaultGeom.TriangleList(ii,3);
                app.FaultGeom.GlobTrigCentr(ii,4)  = (app.FaultGeom.GlobalVertices(Vert1,4) +app.FaultGeom.GlobalVertices(Vert2,4) +app.FaultGeom.GlobalVertices(Vert3,4))/3;
            end
            %---------------------------------------------------------------------
            MeanLegLength = 0;
            for i = 1 : size(app.FaultGeom.TriangleList,1)
                v1    = app.FaultGeom.TriangleList(i,1);
                v2    = app.FaultGeom.TriangleList(i,2);
                v3    = app.FaultGeom.TriangleList(i,3);
                %---------------------------
                %https://www.mathworks.com/matlabcentral/answers/271797-surface-area-of-a-3d-surface
                app.FaultGeom.TriangleAreas(i,1) = 1E-6 *1/2*norm(cross((app.FaultGeom.GlobalVertices(v2,1:3)-app.FaultGeom.GlobalVertices(v1,1:3)),(app.FaultGeom.GlobalVertices(v3,1:3)-app.FaultGeom.GlobalVertices(v1,1:3))));   
    
                Leg12   = sqrt((app.FaultGeom.GlobalVertices(v1,1)-app.FaultGeom.GlobalVertices(v2,1))^2 +(app.FaultGeom.GlobalVertices(v1,2)-app.FaultGeom.GlobalVertices(v2,2))^2 +(app.FaultGeom.GlobalVertices(v1,3)-app.FaultGeom.GlobalVertices(v2,3))^2 ); 
                Leg13   = sqrt((app.FaultGeom.GlobalVertices(v1,1)-app.FaultGeom.GlobalVertices(v3,1))^2 +(app.FaultGeom.GlobalVertices(v1,2)-app.FaultGeom.GlobalVertices(v3,2))^2 +(app.FaultGeom.GlobalVertices(v1,3)-app.FaultGeom.GlobalVertices(v3,3))^2 ); 
                Leg23   = sqrt((app.FaultGeom.GlobalVertices(v2,1)-app.FaultGeom.GlobalVertices(v3,1))^2 +(app.FaultGeom.GlobalVertices(v2,2)-app.FaultGeom.GlobalVertices(v3,2))^2 +(app.FaultGeom.GlobalVertices(v2,3)-app.FaultGeom.GlobalVertices(v3,3))^2 ); 
                MeanLegLength = MeanLegLength + mean([Leg12,Leg13,Leg23]);
                %--------------------------- necessary for distance check if it is going to be used
                tester = 0;
                if (abs(app.FaultGeom.GlobalVertices(v1,3)) <= mean([Leg12,Leg13,Leg23])/2),         tester = tester +1;         end
                if (abs(app.FaultGeom.GlobalVertices(v2,3)) <= mean([Leg12,Leg13,Leg23])/2),         tester = tester +1;         end
                if (abs(app.FaultGeom.GlobalVertices(v3,3)) <= mean([Leg12,Leg13,Leg23])/2),         tester = tester +1;         end
            
                if (tester > 1)
                    app.FaultGeom.IsSurfPatch(i,1) = 1;
                end
            end


            app.ClnCatalog.RMS                = sqrt(sum((app.FaultGeom.GlobTrigCentr(:,4)-mean(app.FaultGeom.GlobTrigCentr(:,4))).^2)./app.FaultGeom.SizeTrigList);
            %------------------------------------------
            EQ_ID           = zeros(EQnumRaw*10,1);
            EQ_Time         = zeros(EQnumRaw*10,1);
            EQ_Magnitude    = zeros(EQnumRaw*10,1);
            EQ_RuptArea     = zeros(EQnumRaw*10,1);
            EQ_ActPtchN     = zeros(EQnumRaw*10,1);
            EQ_MeanStrsDrop = zeros(EQnumRaw*10,1);
            EQ_MeanSlpH     = zeros(EQnumRaw*10,1);
            EQ_MeanSlpV     = zeros(EQnumRaw*10,1);
            EQ_SlipDur      = zeros(EQnumRaw*10,1);
            %---------------------------------------------------------------------
            disp(strcat('Number of "raw"/unparsed earthquakes:',num2str(EQnumRaw)));
            EQcount       = 0;
            EQinTablcount = 0;
            for i = 1 : EQnumRaw
                Time    = fread(fidbinCat,1,      'double');
                Magn    = fread(fidbinCat,1,      'single');
                HypoLoc = fread(fidbinCat,3,      'single');
                MomCentr= fread(fidbinCat,3,      'single');
                RuptArea= fread(fidbinCat,1,      'single');
                MeanSlip= fread(fidbinCat,1,      'single');
                MeanDtau= fread(fidbinCat,1,      'single');
             
                MRFNum  = fread(fidbinCat,1,      'uint32');
                MRFval  = fread(fidbinCat,MRFNum, 'single');
                %---------------------------------------
                PtNum   = fread(fidbinCat,1,      'uint32');
                PtchID  = fread(fidbinCat,PtNum,  'uint32');
                PtchID  = PtchID +1; %this is again b/c of C vs. matlab notation
                t0      = fread(fidbinCat,PtNum,  'uint32');
                delt_t  = fread(fidbinCat,PtNum,  'single');
                slip_h  = fread(fidbinCat,PtNum,  'single');
                slip_v  = fread(fidbinCat,PtNum,  'single');
                StabTyp = fread(fidbinCat,PtNum,  'uint32');
                
                blah   = sqrt(slip_h.^2 +slip_v.^2);
                blahfin= find (blah > MinPatchSlp);

                PtchID = PtchID(blahfin);
                t0     = t0(blahfin);
                delt_t = delt_t(blahfin);
                slip_h = slip_h(blahfin);
                slip_v = slip_v(blahfin);
                StabTyp= StabTyp(blahfin);
             
                PtNum  = size(PtchID,1);
                %---------------------------------------%---------------------------------------
                if (PtNum >= MinPatchNum)
                    
                    EQcount = EQcount +1;
                    
                    app.MRF(EQcount).Num           = MRFNum;
                    app.MRF(EQcount).Val           = MRFval;
                   
                    RuptArea   = 0;
                    for kk = 1 : PtNum
                        NextPtchID = PtchID(kk,1);
                        PatchPos   = find(PtchID == NextPtchID,1,'first');
                        RuptArea   = RuptArea + app.FaultGeom.TriangleAreas(PatchPos,1);
                    end  
                    app.ClnCatalog(EQcount).EQtime    = Time;
                    app.ClnCatalog(EQcount).Magn      = Magn;
                    app.ClnCatalog(EQcount).RuptArea  = RuptArea;
                    app.ClnCatalog(EQcount).PtchID    = PtchID';
                    app.ClnCatalog(EQcount).T0        = t0'.*app.DeltaT;
                    app.ClnCatalog(EQcount).Delt_t    = delt_t';
                    app.ClnCatalog(EQcount).Slip_h    = slip_h';
                    app.ClnCatalog(EQcount).Slip_v    = slip_v';
                    app.ClnCatalog(EQcount).StabT     = StabTyp';

                    RuptWidth = (max(app.FaultGeom.GlobTrigCentr(PtchID,3)) - min(app.FaultGeom.GlobTrigCentr(PtchID,3)))/1000;
                    if (RuptWidth <= SeisWidth),        app.ClnCatalog(EQcount).RuptTyp= 1; %partial rupture
                    else,                               app.ClnCatalog(EQcount).RuptTyp= 2; %full rupture
                    end
                    %------------------%------------------
                    if (Magn >= MinMag4Tabl)
                        EQinTablcount                  = EQinTablcount+1;
                        EQ_ID(EQinTablcount)           = EQcount;
                        EQ_Time(EQinTablcount)         = Time;
                        EQ_Magnitude(EQinTablcount)    = Magn;
                        EQ_RuptArea(EQinTablcount)     = RuptArea;
                        EQ_ActPtchN(EQinTablcount)     = PtNum;
                        EQ_MeanStrsDrop(EQinTablcount) = mean(delt_t);
                        EQ_MeanSlpH(EQinTablcount)     = mean(slip_h);
                        EQ_MeanSlpV(EQinTablcount)     = mean(slip_v);
                        EQ_SlipDur(EQinTablcount)      = (max(t0(:)) - min(t0(:))).*app.DeltaT;
                    end
                    %------------------%------------------
                end
            end
            %------------------%------------%------------------%------------
            EQNums          = EQ_ID(1:EQinTablcount);
            EQ_Time         = EQ_Time(1:EQinTablcount,1);
            EQ_Magnitude    = EQ_Magnitude(1:EQinTablcount,1);
            EQ_RuptArea     = EQ_RuptArea(1:EQinTablcount,1);
            EQ_ActPtchN     = EQ_ActPtchN(1:EQinTablcount,1);
            EQ_MeanStrsDrop = EQ_MeanStrsDrop(1:EQinTablcount,1);
            EQ_MeanSlpH     = EQ_MeanSlpH(1:EQinTablcount,1);
            EQ_MeanSlpV     = EQ_MeanSlpV(1:EQinTablcount,1);
            EQ_SlipDur      = EQ_SlipDur(1:EQinTablcount,1);
            %------------------%------------%------------------%------------
            %------------------%------------%------------------%------------
            fclose(fidbinCat);
            
            app.EQtable_UITable.Data = table(EQNums,EQ_Time, EQ_Magnitude, EQ_RuptArea, EQ_ActPtchN, EQ_MeanStrsDrop, EQ_MeanSlpH, EQ_MeanSlpV, EQ_SlipDur);

            %------------------%------------%------------------%------------
            MinVal = +Inf;              MaxVal = -Inf;
            MinTime= +Inf;              MaxTime= -Inf;
            app.PlotComp2Plot_DropDown.Value = num2str(1);
            for i = 1 : EQcount
                CombVal = sqrt(app.ClnCatalog(i).Slip_h.^2 +app.ClnCatalog(i).Slip_v.^2);    
                MinVal  = min([MinVal; CombVal(:)]);
                MaxVal  = max([MaxVal; CombVal(:)]);
                MinTime = min([MinTime, app.ClnCatalog(i).EQtime]);
                MaxTime = max([MaxTime, app.ClnCatalog(i).EQtime]);
            end
            app.PlotMinValue_EditField.Value = MinVal;
            app.PlotMaxValue_EditField.Value = MaxVal;
            app.Sequence_StartT_EditField.Value = MinTime;
            app.Sequence_EndTEditField.Value    = MaxTime;
            %------------------%------------%------------------%------------
            app.CatImported = 1;
            app.EQcount     = EQcount;
            app.LoadCat_Lamp.Color = 'green';     
            app.SaveNameEditField.Value = '';

            disp(strcat('Imported Events: ',num2str(EQcount)));
        end

        % Button pushed function: PlotScaling_Button
        function PlotScaling_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end       
 
            FigNum    = app.FigNumScaling_EditField.Value;
            
            PatchOrAll= str2double(app.Scaling_PatchOrAll_DropDown.Value);
            
            SelX      = str2double(app.ScalingXvalue_DropDown.Value);
            StringX   = app.ScalingXvalue_DropDown.Items{SelX};
            SelY      = str2double(app.ScalingYvalue_DropDown.Value);
            StringY   = app.ScalingYvalue_DropDown.Items{SelY};
            SelSymb   = str2double(app.Scaling_Symbol_DropDown.Value);
            SelSymbs  = app.Scaling_Symbol_DropDown.Items{SelSymb};      
            SclX      = str2double(app.ScalingXscale_DropDown.Value);
            SclXs     = app.ScalingXscale_DropDown.Items{SclX};
            SclY      = str2double(app.ScalingYscale_DropDown.Value);
            SclYs     = app.ScalingYscale_DropDown.Items{SclY};
            
            MinTime   = app.Sequence_StartT_EditField.Value;
            MaxTime   = app.Sequence_EndTEditField.Value;

            MinMag    = app.MinMag4ForScaleRel_EditField.Value;
            MaxMag    = app.MaxMag4ForScaleRel_EditField.Value;
            
            MinBinVal = app.PlotBinMinValue_EditField.Value;
            MaxBinVal = app.PlotBinMaxValue_EditField.Value;
            BinNum    = app.PlotBinNum_EditField.Value;
            %---------------------------------------------------            
            aa = zeros(size(app.ClnCatalog(:),1),1);
            bb = zeros(size(app.ClnCatalog(:),1),1);
            cc = zeros(size(app.ClnCatalog(:),1),1);
            dd = zeros(size(app.ClnCatalog(:),1),1);
            ee = zeros(size(app.ClnCatalog(:),1),1);
            ff = zeros(size(app.ClnCatalog(:),1),1);
            gg = zeros(size(app.ClnCatalog(:),1),1);
            ii = zeros(size(app.ClnCatalog(:),1),1);
            jj = zeros(size(app.ClnCatalog(:),1),1);
            %------------------
            for iii = 1 : size(app.ClnCatalog(:),1)
                aa(iii) = app.ClnCatalog(iii).EQtime;
                bb(iii) = app.ClnCatalog(iii).Magn;
                cc(iii) = app.ClnCatalog(iii).RuptArea;
                jj(iii) = app.ClnCatalog(iii).RuptTyp;
                dd(iii) = size(unique(app.ClnCatalog(iii).PtchID(:)),1);
                
                BLAH    = app.ClnCatalog(iii).Delt_t(app.ClnCatalog(iii).Delt_t > 0.0);
                ee(iii) = nanmean(BLAH);
                ff(iii) = mean(app.ClnCatalog(iii).Slip_h(:));
                gg(iii) = mean(app.ClnCatalog(iii).Slip_v(:));
                ii(iii) = (max(app.ClnCatalog(iii).T0(:)) - min(app.ClnCatalog(iii).T0(:))).*app.DeltaT;
            end
            hh   = sqrt(ff.^2 + gg.^2);
            %---------------------------------------------------
            data = [aa, bb, cc, dd, ee, ff, gg, hh, ii, jj]; 
            %---------------------------------------------------
            if (PatchOrAll == 2)
                SelPatch  = app.SelectPatchID_edit.Value;
                IsIn      = zeros(size(data,1),1);
                
                for (i = 1 : size(data,1))
                    IsIn(i,1) = sum(app.ClnCatalog(i).PtchID == SelPatch);
                end 
                data = data(logical(IsIn),:);
            elseif (PatchOrAll == 3)
                data = data(data(:,10) == 1,:);
            elseif (PatchOrAll == 4)
                data = data(data(:,10) == 2,:);
            end
            %---------------------%---------------------
            data = data(data(:,1)>=MinTime,:);
            data = data(data(:,1)<=MaxTime,:);

            data = data(data(:,2)>=MinMag,:);
            data = data(data(:,2)<=MaxMag,:);
            %---------------------%---------------------
            figure(FigNum); hold on;
            if (SelY < 10)   
                SelXvals1 = data(data(:,10) == 1,:);
                SelXvals2 = data(data(:,10) == 2,:);

                SelYvals1 = data(data(:,10) == 1,:);
                SelYvals2 = data(data(:,10) == 2,:);

                SelXvals1  = SelXvals1(:,SelX);           SelYvals1  = SelYvals1(:,SelY);
                SelXvals2  = SelXvals2(:,SelX);           SelYvals2  = SelYvals2(:,SelY);    
                
                plot(SelXvals1, SelYvals1, SelSymbs,'color','b'); 
                plot(SelXvals2, SelYvals2, SelSymbs,'color','r'); 
            elseif  (SelY == 10)   
                %---------------------%-------
                if (SelX == 1),     SelData = diff(data(:,SelX));
                else,               SelData = data(:,SelX);
                end
                %---------------------%-------
                if (BinNum > 1),    BinValues = linspace(MinBinVal,MaxBinVal,BinNum);
                else,               BinValues = linspace(min(SelData),max(SelData),round(sqrt(size(SelData,1))));
                end
                %---------------------%-------
                [SelYvals,SelXvals] = histcounts(SelData,BinValues);     
                SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                SelXvals            = SelXvals(1:end-1);
                SelYvals = SelYvals./sum(SelYvals); 
                bar(SelXvals, SelYvals,'FaceAlpha',0.4, 'FaceColor','r'); 
            elseif  (SelY == 11)
                %---------------------%-------
                if (SelX == 1),     SelData = diff(data(:,SelX));
                else,               SelData = data(:,SelX);
                end
                %---------------------%-------
                if (BinNum > 1),    BinValues = linspace(MinBinVal,MaxBinVal,BinNum);
                else,               BinValues = linspace(min(SelData),max(SelData),round(sqrt(size(SelData,1))));
                end
                %---------------------%-------
                [SelYvals,SelXvals] = histcounts(SelData,BinValues);        
                SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                SelXvals            = SelXvals(1:end-1);
                %SelYvals = SelYvals./sum(SelYvals); 
                SelYvals = fliplr(cumsum(fliplr(SelYvals)));
                plot(SelXvals, SelYvals, SelSymbs,'color','k'); 
            end
          
            set(gca,'XScale',SclXs,'YScale',SclYs);
            grid on;
            %---------------------
            xlabel(strcat(StringX,',',SclXs));   ylabel(strcat(StringY,',',SclYs));      
        end

        % Cell selection callback: EQtable_UITable
        function EQtable_UITableCellSelection(app, event)
            app.selectedRow = event.Indices(1);
            app.selectedCol = event.Indices(2);
        end

        % Value changed function: PlotComp2Plot_DropDown
        function PlotComp2Plot_DropDownValueChanged(app, event)
            value   = str2double(app.PlotComp2Plot_DropDown.Value);
            MinVal  = +Inf;
            MaxVal  = -Inf;
            MinTime = app.Sequence_StartT_EditField.Value;
            MaxTime = app.Sequence_EndTEditField.Value;
            %-------------------------------------
            if (value >= 7)
                CombVal = zeros(1,app.FaultGeom.SizeTrigList);
                app.PlotShowTimeEvol_CheckBox.Value = 0;   
            end
            %-------------------------------------
            for i = 1 : app.EQcount
                if     (value == 1),    CombVal = sqrt(app.ClnCatalog(i).Slip_h.^2 +app.ClnCatalog(i).Slip_v.^2);  
                elseif (value == 2),    CombVal = app.ClnCatalog(i).Slip_h; 
                elseif (value == 3),    CombVal = app.ClnCatalog(i).Slip_v; 
                elseif (value == 4),    CombVal = app.ClnCatalog(i).Delt_t; 
                elseif (value == 5),    CombVal = app.ClnCatalog(i).T0; 
                elseif (value == 6),    CombVal = app.ClnCatalog(i).StabT;   
                elseif (value == 7)
                    NextTime = app.ClnCatalog(i).EQtime;
                    if ((NextTime >= MinTime) && (NextTime <= MaxTime))
                        CombVal(1,app.ClnCatalog(i).PtchID) = CombVal(1,app.ClnCatalog(i).PtchID) + sqrt(app.ClnCatalog(i).Slip_h.^2 +app.ClnCatalog(i).Slip_v.^2);
                    end
                elseif (value == 8),    CombVal = app.FaultGeom.GlobalVertices(:,4);
                end
                MinVal  = min([MinVal; CombVal(:)]);
                MaxVal  = max([MaxVal; CombVal(:)]);
            end
            app.PlotMinValue_EditField.Value = MinVal;
            app.PlotMaxValue_EditField.Value = MaxVal;
    
        end

        % Button pushed function: PlotMRF_Button
        function PlotMRF_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end       
            SelRow   = app.selectedRow;    
            SelEQnum = table2array(app.EQtable_UITable.Data(SelRow,1));
            SelTime  = table2array(app.EQtable_UITable.Data(SelRow,2));
            SelMagn  = table2array(app.EQtable_UITable.Data(SelRow,3));
            FigNum   = app.PlotFigNum_EditField.Value;
            %----------------------------------------     
            Yval     = app.MRF(SelEQnum).Val';
            Xval     = (1 : app.MRF(SelEQnum).Num).*app.DeltaT;
            %---------------------------------------- 
            figure(FigNum);         clf;          
            plot(Xval,Yval,'.-k');
            xlabel('Time (sec)');       ylabel('Moment relase (Nm)');    
            title(strcat('Time: ',num2str(SelTime), 'and Magnitude:',num2str(SelMagn)));
        end

        % Button pushed function: PlotOnFault_Button
        function PlotOnFault_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end           
            %---------------------------------------- 
            FigNum    = app.PlotFigNum_EditField.Value; 
            ViewA     = app.PlotViewAngle_EditField.Value;          ViewA  = sscanf(ViewA,'%f')';
            %----------------------------------------            
            SelCol    = str2double(app.PlotColorMap_DropDown.Value);
            SelCols   = app.PlotColorMap_DropDown.Items{SelCol};
            MinVal    = app.PlotMinValue_EditField.Value;
            MaxVal    = app.PlotMaxValue_EditField.Value;
            MinTime = app.Sequence_StartT_EditField.Value;
            MaxTime = app.Sequence_EndTEditField.Value;
            SelRow    = app.selectedRow;    
            ThisEvent = table2array(app.EQtable_UITable.Data(SelRow,1));
            %----------------------------------------         
            Comp2Plot = str2double(app.PlotComp2Plot_DropDown.Value);
            ShowEvol  = app.PlotShowTimeEvol_CheckBox.Value;
            DeltT     = app.PlotTimeEvolsecPerFrame_EditField.Value;
            FrmPs     = app.PlotMoviFps_EditField.Value;
            SaveMovie = app.PlotSaveMovie_CheckBox.Value;
            %----------------------------------------                
            if (ShowEvol == 1)
                FrameBins = 0 : DeltT : (max(app.ClnCatalog(ThisEvent).T0)+DeltT);
                if (SaveMovie == 1)
                    [DataName,DataPath]  = uiputfile({'*.*'},'JO!');
                    if     (DataName ~=0),                      CompletePath         = strcat(DataPath,DataName); 
                    elseif (DataName ==0),                      return;         
                    end
                    %---------------------%
                    FileName       = strcat(CompletePath,'.avi');
                    %---------------------%
                end
                FrameBinNum    = size(FrameBins,2);
                F(FrameBinNum) = struct('cdata',[],'colormap',[]);
            end
            
            figure(FigNum); clf; hold on; axis equal; view(ViewA); axis tight; 
            xlabel('Easting (km)');ylabel('Northing (km)'); zlabel('Depth (km)');
            set(gca,'color',[0.3 0.3 0.3]);
            %-----------------------------------------------
            AllV1s          = zeros(app.FaultGeom.SizeTrigList,3);
            AllV2s          = zeros(app.FaultGeom.SizeTrigList,3);
            AllV3s          = zeros(app.FaultGeom.SizeTrigList,3);
            PlottedComp     = zeros(1,app.FaultGeom.SizeTrigList);
            SelCompWtime    = nan(app.FaultGeom.SizeTrigList,1);
            Patchtime       = nan(app.FaultGeom.SizeTrigList,1);
   
            EventPatches    = app.ClnCatalog(ThisEvent).PtchID;
            if     (Comp2Plot == 1),        PlotComp = sqrt(app.ClnCatalog(ThisEvent).Slip_h.^2 +app.ClnCatalog(ThisEvent).Slip_v.^2);
            elseif (Comp2Plot == 2),        PlotComp =      app.ClnCatalog(ThisEvent).Slip_h;
            elseif (Comp2Plot == 3),        PlotComp =      app.ClnCatalog(ThisEvent).Slip_v;
            elseif (Comp2Plot == 4),        PlotComp =      app.ClnCatalog(ThisEvent).Delt_t;
            elseif (Comp2Plot == 5),        PlotComp =      app.ClnCatalog(ThisEvent).T0;     
            elseif (Comp2Plot == 6),        PlotComp =      app.ClnCatalog(ThisEvent).StabT;              
            elseif (Comp2Plot == 7)
                PlottedComp  = zeros(1,app.FaultGeom.SizeTrigList);
                for i = 1 : app.EQcount
                    NextTime = app.ClnCatalog(i).EQtime;
                    if ((NextTime >= MinTime) && (NextTime <= MaxTime))
                        PlottedComp(1,app.ClnCatalog(i).PtchID) = PlottedComp(1,app.ClnCatalog(i).PtchID) + sqrt(app.ClnCatalog(i).Slip_h.^2 +app.ClnCatalog(i).Slip_v.^2);
                    end
                end
            elseif (Comp2Plot == 8)
                PlottedComp =      app.FaultGeom.GlobTrigCentr(:,4);
            end
        %    MinValue  = min(PlottedComp(:))
            MeanValue = mean(PlottedComp(:))
        %    MaxValue  = max(PlottedComp(:))
            
            
            %-----------------------------------------------
            for j = 1 : app.FaultGeom.SizeTrigList
                AllV1s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),1);
                AllV1s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),1);
                AllV1s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),1);
            
                AllV2s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),2);
                AllV2s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),2);
                AllV2s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),2);
            
                AllV3s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),3);
                AllV3s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),3);
                AllV3s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),3);
                %-----------------------------
                if (Comp2Plot < 7)
                    PosInQke    = find(EventPatches == j);
                    if ~isempty(PosInQke),        
                        PlottedComp(1,j) = PlotComp(PosInQke);         
                        Patchtime(j,1) = app.ClnCatalog(ThisEvent).T0(PosInQke);           
                    end
                end
            end
            %-----------------------------------------------
            if (SelCol < 5),        colormap(SelCols);
            elseif (SelCol == 5),   colormap(app.BlueWhiteRed);
            elseif (SelCol == 6),   colormap(app.Cols4Slip);
            end
            %-----------------------------
            if (MinVal < MaxVal),   caxis([MinVal MaxVal]);        colorbar;            end
            %-----------------------------
            if (ShowEvol == 0)
                if (SelCol <= 6)
                    patch(AllV1s',AllV2s',AllV3s',PlottedComp','FaceAlpha',0.7,'EdgeColor','none'); 
                else
                    uniqueSections = unique(app.FaultGeom.TriangleList(:,4));
                    
                    for i = 1: size(uniqueSections,1)

                        nextSection    = uniqueSections(i,1);
                        nextLocalCoord = app.FaultGeom.GlobTrigCentr(  app.FaultGeom.TriangleList(:,4) == nextSection,:);

                        nextPlotComp   =                  PlottedComp(1,app.FaultGeom.TriangleList(:,4) == nextSection);

                        [xi, yi] = meshgrid(linspace(min(nextLocalCoord(:,2)),max(nextLocalCoord(:,2))),linspace(min(nextLocalCoord(:,3)),max(nextLocalCoord(:,3))));
                        zi       = griddata(nextLocalCoord(:,2),nextLocalCoord(:,3),nextPlotComp, xi,yi);
                        %-----------------------------
                        %ContLines= MinVal:app.PlotContourValue_EditField.Value:MaxVal;
                        ContLines = linspace(MinVal,MaxVal,app.PlotContourValue_EditField.Value);
                        %-----------------------------
                        if (i > 1)
                            figure(FigNum+(i-1)); clf; hold on; axis equal; axis tight;
                        end
                        xlabel('Distance along fault (km)'); ylabel('Depth (km)'); view(0,90);
                        %-----------------------------
                        if (app.FillContour_CheckBox.Value == 1)
                            contourf(xi,yi,zi,ContLines);
                        else
                            contour(xi,yi,zi,ContLines,'k');
                        end
                    end  
                end
            else
                %---------------------%%---------------------%
                if (SaveMovie == 1)
                    pos  = get(gca,'position');
                    marg = 30;
                    rect = [-marg, -marg, pos(3)+2*marg, pos(4)+2*marg];
                end
                %---------------------%%---------------------%
                for i = 1 : size(FrameBins,2)
                    NextVal = FrameBins(1,i);
                    CurrSel               = find(Patchtime <= NextVal);
                    SelCompWtime(CurrSel) = PlottedComp(CurrSel); 
                    patch(AllV1s',AllV2s',AllV3s',SelCompWtime','EdgeColor','none'); 
                    drawnow;
                    pause(0.05);
                    if (SaveMovie == 1)
                        F(i) = getframe(gca);
                    end
                end
                %---------------------%%---------------------%
                if (SaveMovie == 1)
                    MovieFrames = F(1,1:end-1);
                    %---------------------%
                    myVideo           = VideoWriter(FileName,"MPEG-4");
                    myVideo.FrameRate = FrmPs;
                    myVideo.Quality   = 90;    % Default 75
                    %---------------------%
                    open(myVideo);
                    writeVideo(myVideo,MovieFrames);
                    close(myVideo);
                end
                %---------------------%%---------------------%
            end
            disp('done');
%set(gca,'color',[0.7 0.7 0.7])
%set(gcf,'color',[0.7 0.7 0.7])

        end

        % Button pushed function: SequencePlot_Button
        function SequencePlot_ButtonPushed(app, event)
            
            FigNum    = app.PlotFigNum_EditField.Value; 
            ViewA     = app.PlotViewAngle_EditField.Value;          ViewA  = sscanf(ViewA,'%f')';
            SelCol    = str2double(app.PlotColorMap_DropDown.Value);
            SelCols   = app.PlotColorMap_DropDown.Items{SelCol};
            if (SelCol < 5),        UsedCol = colormap(SelCols);
            elseif (SelCol == 5),   UsedCol = colormap(app.BlueWhiteRed);
            elseif (SelCol == 6),   UsedCol = colormap(app.Cols4Slip);
            end
            %----------------------------------------    
            Comp2Plot = str2double(app.PlotComp2Plot_DropDown.Value);
            
            MinVal    = app.PlotMinValue_EditField.Value;
            MaxVal    = app.PlotMaxValue_EditField.Value;   
            MinTime   = app.Sequence_StartT_EditField.Value;
            MaxTime   = app.Sequence_EndTEditField.Value;
            DeltT     = app.PlotTimeEvolsecPerFrame_EditField.Value;
            FrmPs     = app.PlotMoviFps_EditField.Value;
            SaveMovie = app.PlotSaveMovie_CheckBox.Value;
            %----------------------------------------         
            FrameBins   = MinTime : DeltT : MaxTime;
            FrameBinNum = size(FrameBins,2);
            
            if (SaveMovie == 1)
                [DataName,DataPath]  = uiputfile({'*.*'},'JO!');
                if     (DataName ~=0),                      CompletePath         = strcat(DataPath,DataName); 
                elseif (DataName ==0),                      return;         
                end
                %---------------------%
                FileName       = strcat(CompletePath,'.avi');
                %---------------------%
                
                F(FrameBinNum) = struct('cdata',[],'colormap',[]);
            end
            %-----------------------------------------------
            AllV1s          = zeros(app.FaultGeom.SizeTrigList,3);
            AllV2s          = zeros(app.FaultGeom.SizeTrigList,3);
            AllV3s          = zeros(app.FaultGeom.SizeTrigList,3);
            for j = 1 : app.FaultGeom.SizeTrigList
                AllV1s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),1);
                AllV1s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),1);
                AllV1s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),1);
            
                AllV2s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),2);
                AllV2s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),2);
                AllV2s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),2);
            
                AllV3s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),3);
                AllV3s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),3);
                AllV3s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),3);
            end
            %-----------------------------------------------
            figure(FigNum); 

            StartPos = 1;
            for i =  2 : FrameBinNum
                %-----------------------------------------
                clf; hold on; axis equal;  view(ViewA); axis tight;
                xlabel('Easting (km)');ylabel('Northing (km)'); zlabel('Depth (km)'); title(strcat('Time: ',num2str(FrameBins(1,i))));
                colormap(UsedCol);
                caxis([MinVal MaxVal]);        
                colorbar;
                %this needs improvement in terms of correct cutting to the "frame"
                %-----------------------------------------
                TheseEvents  = [];
                MinTime      = FrameBins(1,i-1);             MaxTime = FrameBins(1,i);
                for j = StartPos : size(app.ClnCatalog,2)
                    if ((app.ClnCatalog(j).EQtime >= MinTime) && (app.ClnCatalog(j).EQtime <= MaxTime))
                        TheseEvents = [TheseEvents; j];   %#ok<AGROW>
                    end
                    if (app.ClnCatalog(j).EQtime > MaxTime),        StartPos = j;      break;          end
                end
                %-----------------------------------------
                PlottedComp  = zeros(app.FaultGeom.SizeTrigList,1);
                if ~isempty(TheseEvents)
                    for j = 1 : size(TheseEvents,1)
                        ThisEvent       = TheseEvents(j,1);
                        EventPatches    = app.ClnCatalog(ThisEvent).PtchID;
                        if     (Comp2Plot == 1),        PlotComp = sqrt(app.ClnCatalog(ThisEvent).Slip_h.^2 +app.ClnCatalog(ThisEvent).Slip_v.^2);
                        elseif (Comp2Plot == 2),        PlotComp =      app.ClnCatalog(ThisEvent).Slip_h;
                        elseif (Comp2Plot == 3),        PlotComp =      app.ClnCatalog(ThisEvent).Slip_v;
                        elseif (Comp2Plot == 4),        PlotComp =      app.ClnCatalog(ThisEvent).Delt_t;
                        elseif (Comp2Plot == 5),        PlotComp =      app.ClnCatalog(ThisEvent).T0;    
                        elseif (Comp2Plot == 6),        PlotComp =      app.ClnCatalog(ThisEvent).StabT;    
                        end
                        
                        for k = 1 : app.FaultGeom.SizeTrigList
                            PosInQke    = find(EventPatches == k,1,'first');
                            if ~isempty(PosInQke)       
                                if (Comp2Plot < 6)
                                    PlottedComp(k,1) = PlottedComp(k,1) + PlotComp(PosInQke);         
                                else
                                    PlottedComp(k,1) = max([PlottedComp(k,1), PlotComp(PosInQke)]);     
                                end
                            end
                        end
                    end
                end
                patch(AllV1s',AllV2s',AllV3s',zeros(size(AllV2s,1),1)','facealpha',0); 
                hold on;
                Sel = find(PlottedComp ~= 0);
                patch(AllV1s(Sel,:)',AllV2s(Sel,:)',AllV3s(Sel,:)',PlottedComp(Sel)','facealpha',1); 
                drawnow;
                pause(0.05);
                %-----------------------------------------
                if (SaveMovie == 1)
                    F(i) = getframe(gca);
                end
            end
            %-----------------------------------------------
            if (SaveMovie == 1)
                MovieFrames = F(1,2:end);
                %---------------------%
                myVideo           = VideoWriter(FileName);
                myVideo.FrameRate = FrmPs;
                myVideo.Quality   = 75;    % Default 75
                %---------------------%
                open(myVideo);
                writeVideo(myVideo,MovieFrames);
                close(myVideo);
            end 
        end

        % Button pushed function: SelectSinglePatch_Button
        function SelectSinglePatch_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end           
            
            SelSectn  = app.Select_onSectn_EditField.Value;
            %---------------------------------------- 
            FigNum    = app.PlotFigNum_EditField.Value; 
            ViewA     = app.PlotViewAngle_EditField.Value;          ViewA  = sscanf(ViewA,'%f')';
            %-----------------------------------------------
            SelSecPos = (app.FaultGeom.TriangleList(:,4) == SelSectn);
            SelectPtch= app.FaultGeom.TriangleList(SelSecPos,:); 
            SelPtchNum= size(SelectPtch,1);
            
            AllV1s    = zeros(SelPtchNum,3);
            AllV2s    = zeros(SelPtchNum,3);
            AllV3s    = zeros(SelPtchNum,3);
            %-----------------------------------------------
            for j = 1 : SelPtchNum
                AllV1s(j,1) = app.FaultGeom.GlobalVertices(SelectPtch(j,1),1);
                AllV1s(j,2) = app.FaultGeom.GlobalVertices(SelectPtch(j,2),1);
                AllV1s(j,3) = app.FaultGeom.GlobalVertices(SelectPtch(j,3),1);
            
                AllV2s(j,1) = app.FaultGeom.GlobalVertices(SelectPtch(j,1),2);
                AllV2s(j,2) = app.FaultGeom.GlobalVertices(SelectPtch(j,2),2);
                AllV2s(j,3) = app.FaultGeom.GlobalVertices(SelectPtch(j,3),2);
            
                AllV3s(j,1) = app.FaultGeom.GlobalVertices(SelectPtch(j,1),3);
                AllV3s(j,2) = app.FaultGeom.GlobalVertices(SelectPtch(j,2),3);
                AllV3s(j,3) = app.FaultGeom.GlobalVertices(SelectPtch(j,3),3);
            end
            %-----------------------------
            figure(FigNum); clf; hold on;   view(ViewA);
            patch(AllV1s',AllV2s',AllV3s',[0.8 0.8 0.8]); 
            drawnow;
            axis equal;
            %-----------------------------           
            datacursormode on;
            dcm_obj = datacursormode(gcf);
    
            waitforbuttonpress;
            f = getCursorInfo(dcm_obj);
            PointCoord    = f.Position;
            
            PointDistance = sqrt((app.FaultGeom.GlobTrigCentr(:,1) - PointCoord(1,1)).^2 +(app.FaultGeom.GlobTrigCentr(:,2) - PointCoord(1,2)).^2 +(app.FaultGeom.GlobTrigCentr(:,3) - PointCoord(1,3)).^2);
            MinDistPos    = find(PointDistance == min(PointDistance(:)),1,'first');
            
            app.SelectPatchID_edit.Value = MinDistPos;
            app.SelPatchList             = MinDistPos;
            plot3(app.FaultGeom.GlobTrigCentr(MinDistPos,1), app.FaultGeom.GlobTrigCentr(MinDistPos,2), app.FaultGeom.GlobTrigCentr(MinDistPos,3), 'ro','MarkerFaceColor','red'); 
 
        end

        % Button pushed function: SelectAlllSurfPatches_Button
        function SelectAlllSurfPatches_ButtonPushed(app, event)
            app.SelPatchList = [];    
            ShallowVerts     = find(app.FaultGeom.GlobalVertices(:,3) >= -10.0);
            %----------------------------
            for i = 1 : size(ShallowVerts,1)
                UsingVert1 = find(app.FaultGeom.TriangleList(:,1) == ShallowVerts(i,1));
                UsingVert2 = find(app.FaultGeom.TriangleList(:,2) == ShallowVerts(i,1));
                UsingVert3 = find(app.FaultGeom.TriangleList(:,3) == ShallowVerts(i,1));
                app.SelPatchList = [app.SelPatchList; UsingVert1; UsingVert2; UsingVert3];
            end
            app.SelPatchList = unique(app.SelPatchList);
            %----------------------------
            SelectHighlightPatch_ButtonPushed(app, event);
            %----------------------------
            app.SelectPatchID_edit.Value = 0;
        end

        % Button pushed function: SelectHighlightPatch_Button
        function SelectHighlightPatch_ButtonPushed(app, event)
            app.PlotShowTimeEvol_CheckBox.Value = 0;
            if isempty(app.SelPatchList),                                               return;             end
            if (max(app.SelPatchList(:)) > size(app.FaultGeom.TriangleList,1)),         return;             end
            %----------------------------
            drawnow;
            PlotOnFault_ButtonPushed(app, event);
            %----------------------------
            Vx       = zeros(size(app.SelPatchList,1),4);
            Vy       = zeros(size(app.SelPatchList,1),4);
            Vz       = zeros(size(app.SelPatchList,1),4);
            %----------------------------
            for i = 1 : size(app.SelPatchList,1)
                Vx(i,1)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),1),1);
                Vx(i,2)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),2),1);
                Vx(i,3)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),3),1);
                Vx(i,4)  = Vx(i,1);
                Vy(i,1)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),1),2);
                Vy(i,2)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),2),2);
                Vy(i,3)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),3),2);
                Vy(i,4)  = Vy(i,1);
                Vz(i,1)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),1),3);
                Vz(i,2)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),2),3);
                Vz(i,3)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),3),3);
                Vz(i,4)  = Vz(i,1);
            end
            plot3(Vx',Vy',Vz','-r','LineWidth',2);           
        end

        % Button pushed function: SelectPlotParVsTime_Button
        function SelectPlotParVsTime_ButtonPushed(app, event)
            %to show step-plot (time-vs-slip etc..)
            if (app.CatImported == 0),      return;             end           
            
            Comp2Plot = str2double(app.PlotComp2Plot_DropDown.Value);
            FigNum    = app.PlotFigNum_EditField.Value*10+Comp2Plot;
            MinTime   = app.Sequence_StartT_EditField.Value;
            MaxTime   = app.Sequence_EndTEditField.Value;
           
            figure(FigNum);    hold on;
            
            
            counter   = 0;
            
            
            Xvalues   = zeros(20000,2);
            Yvalues   = zeros(20000,2);
            ExtractCat= [];
            for k = 1 : size(app.SelPatchList,1)
                CurrentCat= zeros(10000,2);
                CatCounter= 0;
                SelPatch  = app.SelPatchList(k,1);
                %----------------------------------------
                for i = 1 : size(app.ClnCatalog,2)
                    if ((app.ClnCatalog(i).EQtime >= MinTime) && (app.ClnCatalog(i).EQtime <= MaxTime) && (app.ClnCatalog(i).Magn >= app.InterEventMinMag_EditField.Value) && (app.ClnCatalog(i).Magn <= app.InterEventMaxMag_EditField.Value))
                    
                        FindPatchInEQ = find(app.ClnCatalog(i).PtchID == SelPatch,1,'first');  
                        
                        if (~isempty(FindPatchInEQ))
                            CatCounter               = CatCounter+1;
                            CurrentCat(CatCounter,1) = app.ClnCatalog(i).EQtime;
                                
                            if     (Comp2Plot == 1),        CurrentCat(CatCounter,2) = sqrt(app.ClnCatalog(i).Slip_h(1,FindPatchInEQ).^2 +app.ClnCatalog(i).Slip_v(1,FindPatchInEQ).^2);
                            elseif (Comp2Plot == 2),        CurrentCat(CatCounter,2) =      app.ClnCatalog(i).Slip_h(1,FindPatchInEQ);
                            elseif (Comp2Plot == 3),        CurrentCat(CatCounter,2) =      app.ClnCatalog(i).Slip_v(1,FindPatchInEQ);
                            elseif (Comp2Plot == 4),        CurrentCat(CatCounter,2) =      app.ClnCatalog(i).Delt_t(1,FindPatchInEQ);
                            elseif (Comp2Plot == 5),        CurrentCat(CatCounter,2) =      app.ClnCatalog(i).T0(1,FindPatchInEQ);    
                            elseif (Comp2Plot == 6),        CurrentCat(CatCounter,2) =      app.ClnCatalog(i).StabT(1,FindPatchInEQ);    
                            else,                           return;
                            end     
                        end
                    end
                end
                CurrentCat = CurrentCat(1:CatCounter,:);               
                %----------------------------------------
                if (~isempty(CurrentCat))
                    counter = counter +1;
                    if (Comp2Plot < 5),             CurrentCat(:,2) = cumsum(CurrentCat(:,2));                  end
                    Xvalues(counter,1) = CurrentCat(1,1);               Xvalues(counter,2) = CurrentCat(1,1);
                    Yvalues(counter,1) = 0;                             Yvalues(counter,2) = CurrentCat(1,2);
                    

                    for i = 2 : 1 :size(CurrentCat,1)
                        counter = counter +1;
                        Xvalues(counter,1) = CurrentCat(i-1,1);         Xvalues(counter,2) = CurrentCat(i,1);
                        Yvalues(counter,1) = CurrentCat(i-1,2);         Yvalues(counter,2) = CurrentCat(i-1,2);
                        counter = counter +1;
                        Xvalues(counter,1) = CurrentCat(i,1);           Xvalues(counter,2) = CurrentCat(i,1);
                        Yvalues(counter,1) = CurrentCat(i-1,2);         Yvalues(counter,2) = CurrentCat(i,2); 
                    end
                    ExtractCat = [ExtractCat; CurrentCat];
                end
            end
            Xvalues = Xvalues(1:counter,:);
            Yvalues = Yvalues(1:counter,:);
            plot(Xvalues',Yvalues','-','color',[0 0 0 0.1],'linewidth',2);
          %  plot(ExtractCat(:,1),ExtractCat(:,2),'ok');  
            %----------------------------------------
            xlabel('Time (years)');ylabel('Selected parameter');
            
            %----------------------------------------
%                 ParameterCV = ExtractCat(:,2);
%                 TimeCV      = diff(ExtractCat(:,1));
%                 ParameterCV = std(ParameterCV)/mean(ParameterCV);
%                 TimeCV      = std(TimeCV)/mean(TimeCV);
%                 WriteString = ['The inter-event time CV is: ',num2str(TimeCV)];
%                 disp(WriteString);
%                 WriteString = ['The CV of selected parameter is: ',num2str(ParameterCV)];
%                 disp(WriteString);
            %----------------------------------------
            
            
        end

        % Button pushed function: SelectPlotParaAlongSurf_Button
        function SelectPlotParaAlongSurf_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end           
          
            Comp2Plot = str2double(app.PlotComp2Plot_DropDown.Value);
            FigNum    = app.PlotFigNum_EditField.Value*10+Comp2Plot;

            MinTime   = app.Sequence_StartT_EditField.Value;
            MaxTime   = app.Sequence_EndTEditField.Value;
            
            SrfPtchPos= find(app.FaultGeom.IsSurfPatch == 1);
            SrfXpos   = app.FaultGeom.GlobTrigCentr(SrfPtchPos,1);
            SrfYpos   = app.FaultGeom.GlobTrigCentr(SrfPtchPos,2);

            figure(FigNum); hold on;
            for (i = 1 : size(SrfPtchPos,1))
                plot3(SrfXpos(i,1),SrfYpos(i,1),0,'ok');    
            end
            drawnow;
            EQcolors = rand(size(app.ClnCatalog,2),3);
            %------------------------------------------------
            for (i = 1 : size(SrfPtchPos,1))
                NextPtch = SrfPtchPos(i,1);
                SrfParam = 0;
                for (j = 1 : size(app.ClnCatalog,2))
                    if ((app.ClnCatalog(j).EQtime >= MinTime) && (app.ClnCatalog(j).EQtime <= MaxTime))
                        EQruptPtch = find(app.ClnCatalog(j).PtchID == NextPtch,1,'first');
                        
                        if ~isnan(EQruptPtch)
                            if     (Comp2Plot == 1),        SrfParam = SrfParam + sqrt(app.ClnCatalog(j).Slip_h(1,EQruptPtch).^2 +app.ClnCatalog(j).Slip_v(1,EQruptPtch).^2);
                            elseif (Comp2Plot == 2),        SrfParam = SrfParam +      app.ClnCatalog(j).Slip_h(1,EQruptPtch);
                            elseif (Comp2Plot == 3),        SrfParam = SrfParam +      app.ClnCatalog(j).Slip_v(1,EQruptPtch);
                            elseif (Comp2Plot == 4),        SrfParam = SrfParam +      app.ClnCatalog(j).Delt_t(1,EQruptPtch);
                            elseif (Comp2Plot == 5),        return;    
                            elseif (Comp2Plot == 6),        return;    
                            else,                           return;
                            end    

                            plot3(SrfXpos(i,1),SrfYpos(i,1),SrfParam,'o','Color',EQcolors(j,:),'MarkerFaceColor',EQcolors(j,:));
                            
                        end
                    end
                end
            end           
        end

        % Button pushed function: SelectHypoCentDistrib_Button
        function SelectHypoCentDistrib_ButtonPushed(app, event)

            FigNum      = app.PlotFigNum_EditField.Value*10+9;
            ViewA       = app.PlotViewAngle_EditField.Value;            ViewA  = sscanf(ViewA,'%f')';
            SelCol      = str2double(app.PlotColorMap_DropDown.Value);
            SelCols     = app.PlotColorMap_DropDown.Items{SelCol};
            %------------------------------------------------        
            MinTime     = app.Sequence_StartT_EditField.Value;
            MaxTime     = app.Sequence_EndTEditField.Value;
            
            AllV1s      = zeros(app.FaultGeom.SizeTrigList,3);
            AllV2s      = zeros(app.FaultGeom.SizeTrigList,3);
            AllV3s      = zeros(app.FaultGeom.SizeTrigList,3);
            PlottedComp = zeros(app.FaultGeom.SizeTrigList,1);
            %------------------------------------------------    
            for j = 1 : app.FaultGeom.SizeTrigList
                AllV1s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),1);
                AllV1s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),1);
                AllV1s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),1);
            
                AllV2s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),2);
                AllV2s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),2);
                AllV2s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),2);
            
                AllV3s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),3);
                AllV3s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),3);
                AllV3s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),3);
            end
            %------------------------------------------------    
            for (j = 1 : size(app.ClnCatalog,2))
                if ((app.ClnCatalog(j).EQtime >= MinTime) && (app.ClnCatalog(j).EQtime <= MaxTime))
                  %  HypoPtchID = app.ClnCatalog(j).PtchID(app.ClnCatalog(j).T0(:) == min(app.ClnCatalog(j).T0(:)));
                    HypoPtchID = app.ClnCatalog(j).PtchID(app.ClnCatalog(j).T0(:) == 0);
                    PlottedComp(HypoPtchID) = PlottedComp(HypoPtchID) +1;
                end
            end
            %------------------------------------------------
            if (SelCol < 5),        colormap(SelCols);
            elseif (SelCol == 5),   colormap(app.BlueWhiteRed);
            elseif (SelCol == 6),   colormap(app.Cols4Slip);
            end  
            min(PlottedComp(:))
            max(PlottedComp(:))
            
            
            figure(FigNum); clf; hold on; axis equal; axis tight; view(ViewA);
            xlabel('Easting (km)');ylabel('Northing (km)'); zlabel('Depth (km)');
            
            patch(AllV1s',AllV2s',AllV3s',PlottedComp'); 
            
        end

        % Button pushed function: InterEvent_PlotStuff_Button
        function InterEvent_PlotStuff_ButtonPushed(app, event)
            %--------------------------------------------------- 
            FigNum     = app.InterEventFigNum_EditField.Value;
            PatchOrAll = str2double(app.Scaling_PatchOrAll_DropDown.Value);
            SelPatch   = app.SelectPatchID_edit.Value;
            SelSymb    = str2double(app.Scaling_Symbol_DropDown.Value);
            SelSymbs   = app.Scaling_Symbol_DropDown.Items{SelSymb};      
            %--------------------------------------------------- 
            Xscale  = str2double(app.InterEvent_Xscale_DropDown.Value);
            SclXs   = app.InterEvent_Xscale_DropDown.Items{Xscale};
            Yscale  = str2double(app.InterEvent_Yscale_DropDown.Value);
            SclYs   = app.InterEvent_Yscale_DropDown.Items{Yscale};
            Xvalue  = str2double(app.InterEvent_Xvalue_DropDown.Value);
            Xstring = app.InterEvent_Xvalue_DropDown.Items{Xvalue}; 
            Yvalue  = str2double(app.InterEvent_Yvalue_DropDown.Value);
            Ystring = app.InterEvent_Yvalue_DropDown.Items{Yvalue}; 
            Cvalue  = str2double(app.InterEvent_Cvalue_DropDown.Value);
            Cstring = app.InterEvent_Cvalue_DropDown.Items{Cvalue};             
            %--------------------------------------------------- 
            MinMagn   = app.InterEventMinMag_EditField.Value;
            MaxMagn   = app.InterEventMaxMag_EditField.Value;         
            MinTime   = app.Sequence_StartT_EditField.Value;
            MaxTime   = app.Sequence_EndTEditField.Value;
            
            MinBinVal = app.PlotBinMinValue_EditField.Value;
            MaxBinVal = app.PlotBinMaxValue_EditField.Value;
            BinNum    = app.PlotBinNum_EditField.Value;
            %---------------------------------------------------            
            aa = zeros(size(app.ClnCatalog(:),1),1);
            bb = zeros(size(app.ClnCatalog(:),1),1);
            cc = zeros(size(app.ClnCatalog(:),1),1);
            %------------------
            irun = 0;
            for iii = 1 : size(app.ClnCatalog(:),1)
                
                if ((app.ClnCatalog(iii).Magn >= MinMagn) &&(app.ClnCatalog(iii).Magn <= MaxMagn) &&(app.ClnCatalog(iii).EQtime >= MinTime)&& (app.ClnCatalog(iii).EQtime <= MaxTime))
                    %--------------------------------
                    if (PatchOrAll == 1),       LetsDoThis = 1;
                    else,                       LetsDoThis = sum(app.ClnCatalog(iii).PtchID == SelPatch);
                    end
                    %--------------------------------
                    if (LetsDoThis > 0)
                        irun = irun +1;
                        aa(irun) = app.ClnCatalog(iii).EQtime;
                        bb(irun) = app.ClnCatalog(iii).Magn;
                        %------------------ 
                        CentPos  = app.FaultGeom.GlobTrigCentr(app.ClnCatalog(iii).PtchID(:),:);
                        SlipAmnt = sqrt(app.ClnCatalog(iii).Slip_h(:).^2 +app.ClnCatalog(iii).Slip_v(:).^2);
                        ScledPos = CentPos.*SlipAmnt;
                        %--------------------------------
                        cc(irun,1:3) = sum(ScledPos)/sum(SlipAmnt(:));
                    end
                    %--------------------------------
                end  
            end
            aa = [0; diff(aa(1:irun))];%.*31557000]; %time
            bb = [0; diff(bb(1:irun))]; %magnitude
            cc = sqrt((cc(2:irun,1)-cc(1:irun-1,1)).^2 + (cc(2:irun,2)-cc(1:irun-1,2)).^2 +(cc(2:irun,3)-cc(1:irun-1,3)).^2)./1000; %distance
            cc = [0; cc];
            %---------------------------------------------------
            data = [aa, cc, bb]; 
            %---------------------------------------------------
            %---------------------------------------------------
            figure(FigNum); clf;   
            if (Xvalue == Yvalue)
                if (BinNum > 1),    BinValues = linspace(MinBinVal,MaxBinVal,BinNum);
                else,               BinValues = linspace(min(data(:,Xvalue)),max(data(:,Xvalue)),round(sqrt(size(data(:,Xvalue),1))));
                end
                
                [SelYvals,SelXvals] = histcounts(data(:,Xvalue),BinValues);     
                SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                SelXvals            = SelXvals(1:end-1);
                SelYvals = SelYvals./sum(SelYvals); 
                
                %SelYvals = fliplr(cumsum(fliplr(SelYvals)));
                
                plot(SelXvals, SelYvals, SelSymbs,'color','k'); 
                
                xlabel(strcat(Xstring,',',SclXs));   ylabel('frequency');      
            else
                SelXvals  = data(:,Xvalue);           SelYvals  = data(:,Yvalue);
               % plot(SelXvals, SelYvals, SelSymbs,'color','k'); 
                
                scatter(SelXvals,SelYvals,12,data(:,Cvalue),'filled');
                xlabel(strcat(Xstring,',',SclXs));   ylabel(strcat(Ystring,',',SclYs));      
            end
            set(gca,'XScale',SclXs,'YScale',SclYs);
            grid on;
            %--------------------- 
            
        end

        % Value changed function: SelectPatchID_edit
        function SelectPatchID_editValueChanged(app, event)
            if ((app.SelectPatchID_edit.Value > 0) && (app.SelectPatchID_edit.Value <= size(app.FaultGeom.TriangleList,1)))
                app.SelPatchList = app.SelectPatchID_edit.Value;                
            else
                app.SelPatchList = 0;
                app.SelectPatchID_edit.Value = 0;
            end
        end

        % Button pushed function: PlotHockeyStickButton
        function PlotHockeyStickButtonPushed(app, event)
            

            FigNum = app.FigNumScaling_EditField.Value*10+7;
            GridOn = 1;
            %-------------------------------
            MinMag = app.MinMag4ForScaleRel_EditField.Value; 
            MaxMag = app.MaxMag4ForScaleRel_EditField.Value;
            MinTim = app.Sequence_StartT_EditField.Value;
            MaxTim = app.Sequence_EndTEditField.Value;
            %-------------------------------
            Catalog = zeros(size(app.ClnCatalog(:),1),3);
            %------------------
            for iii = 1 : size(app.ClnCatalog(:),1)
                Catalog(iii,1) = app.ClnCatalog(iii).EQtime;
                Catalog(iii,2) = app.ClnCatalog(iii).Magn;
                Catalog(iii,3) = 10^(app.ClnCatalog(iii).Magn*1.5 +9.05);
            end
            %-------------------------------
            Catalog = Catalog(Catalog(:,1) >= MinTim,:);
            Catalog = Catalog(Catalog(:,1) <= MaxTim,:);
            Catalog = Catalog(Catalog(:,2) >= MinMag,:);
            Catalog = Catalog(Catalog(:,2) <= MaxMag,:);
            %-------------------------------
            Xvalues    = Catalog(:,1);
            Yvalues    = 1:size(Catalog,1);
            if (app.wseismomentCheckBox.Value == 1)
                Yvalues = cumsum(Catalog(:,3));
            end
            %-------------------------------
            PntSize = zeros(size(Catalog(:,2)));
            for i = 1 : size(Catalog(:,2),1)
                if     ((Catalog(i,2) >= 3)&&(Catalog(i,2) < 3.5)),    PntSize(i) = 10; 
                elseif ((Catalog(i,2) >= 3.5)&&(Catalog(i,2) < 4)),    PntSize(i) = 20; 
                elseif ((Catalog(i,2) >= 4)&&(Catalog(i,2) < 4.5)),    PntSize(i) = 75; 
                elseif ((Catalog(i,2) >= 4.5)&&(Catalog(i,2) < 5)),    PntSize(i) = 300; 
                elseif ((Catalog(i,2) >= 5)&&(Catalog(i,2) < 5.5)),    PntSize(i) = 500; 
                elseif ((Catalog(i,2) >= 5.5)&&(Catalog(i,2) < 6)),    PntSize(i) = 700; 
                elseif ((Catalog(i,2) >= 6)&&(Catalog(i,2) < 6.5)),    PntSize(i) = 1000; 
                elseif ((Catalog(i,2) >= 6.5)&&(Catalog(i,2) < 7)),    PntSize(i) = 2000; 
                elseif ((Catalog(i,2) >= 7)&&(Catalog(i,2) < 7.5)),    PntSize(i) = 5000; 
                elseif ((Catalog(i,2) >= 7.5)&&(Catalog(i,2) < 8)),    PntSize(i) = 10000; 
                end
            end
            %-------------------------------
            figure(FigNum); clf; hold on;   if (GridOn == 1),     grid on;        end
            plot(Xvalues,Yvalues,'-','color',[0 0.45 0.74]);
            scatter(Xvalues,Yvalues,PntSize,[0 0.45 0.74]);
            %-------------------------------
         %   xlim([6620 6700]);
        end

        % Button pushed function: SaveSelectedButton
        function SaveSelectedButtonPushed(app, event)
            SaveName  = app.SaveNameEditField.Value;
            SaveName  = strcat(SaveName,'.mat');
            Comp2Plot = str2double(app.PlotComp2Plot_DropDown.Value);
            SelRow    = app.selectedRow;    
            ThisEvent = table2array(app.EQtable_UITable.Data(SelRow,1));
            %-------------------------------
            EventPatches    = app.ClnCatalog(ThisEvent).PtchID;
            %-------------------------------
            if     (Comp2Plot == 1)      
                CombSlip = zeros(1,app.FaultGeom.SizeTrigList);
                PlotComp = sqrt(app.ClnCatalog(ThisEvent).Slip_h.^2 +app.ClnCatalog(ThisEvent).Slip_v.^2);
                for j = 1 : app.FaultGeom.SizeTrigList
                    PosInQke    = find(EventPatches == j);
                    if ~isempty(PosInQke),        CombSlip (1,j) = PlotComp(PosInQke);                  end
                end
                save(SaveName,'CombSlip','-mat');
            elseif (Comp2Plot == 2)       
                HorzSlip = zeros(1,app.FaultGeom.SizeTrigList);
                PlotComp = app.ClnCatalog(ThisEvent).Slip_h;
                for j = 1 : app.FaultGeom.SizeTrigList
                    PosInQke    = find(EventPatches == j);
                    if ~isempty(PosInQke),          HorzSlip (1,j) = PlotComp(PosInQke);                 end
                end
                save(SaveName,'HorzSlip','-mat');
            elseif (Comp2Plot == 3)      
                VertSlip = zeros(1,app.FaultGeom.SizeTrigList);
                PlotComp = app.ClnCatalog(ThisEvent).Slip_v;
                for j = 1 : app.FaultGeom.SizeTrigList
                    PosInQke    = find(EventPatches == j);
                    if ~isempty(PosInQke),          VertSlip (1,j) = PlotComp(PosInQke);                 end
                end
                save(SaveName,'VertSlip','-mat');
            elseif (Comp2Plot == 4)
                DeltStrss = zeros(1,app.FaultGeom.SizeTrigList);
                PlotComp  = app.ClnCatalog(ThisEvent).Delt_t;
                for j = 1 : app.FaultGeom.SizeTrigList
                    PosInQke    = find(EventPatches == j);
                    if ~isempty(PosInQke),          DeltStrss(1,j) = PlotComp(PosInQke);                 end
                end
                save(SaveName,'DeltStrss','-mat');
            elseif (Comp2Plot == 5)       
                RuptStart = zeros(1,app.FaultGeom.SizeTrigList);
                PlotComp  = app.ClnCatalog(ThisEvent).T0;
                for j = 1 : app.FaultGeom.SizeTrigList
                    PosInQke    = find(EventPatches == j);
                    if ~isempty(PosInQke),          RuptStart(1,j) = PlotComp(PosInQke);                 end
                end
                save(SaveName,'RuptStart','-mat');             
            end
            %-------------------------------
        end

        % Button pushed function: SaveMRFButton
        function SaveMRFButtonPushed(app, event)
            SaveName  = app.SaveNameEditField.Value;
            SaveName  = strcat(SaveName,'.mat');
            SelRow    = app.selectedRow;    
            ThisEvent = table2array(app.EQtable_UITable.Data(SelRow,1));
            %----------------------------------------     
            MomRateF  = [app.MRF(ThisEvent).Val';   (1 : app.MRF(ThisEvent).Num).*app.DeltaT];
            %---------------------------------------- 
            save(SaveName,'MomRateF','-mat');    
            %-------------------------------
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PlotShowTimeEvol_CheckBox = uicheckbox(app.UIFigure);
            app.PlotShowTimeEvol_CheckBox.Text = 'show time evol.';
            app.PlotShowTimeEvol_CheckBox.Value = true;
            app.PlotSaveMovie_CheckBox = uicheckbox(app.UIFigure);
            app.PlotSaveMovie_CheckBox.Text = 'save movie';
            app.fpsEditFieldLabel = uilabel(app.UIFigure);
            app.fpsEditFieldLabel.Tooltip = {'FramePerSec/YearPerFrame; first for single-event; second for EQ sequence'};
            app.fpsEditFieldLabel.Text = 'fps';
            app.PlotMoviFps_EditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotMoviFps_EditField.Limits = [1 Inf];
            app.PlotMoviFps_EditField.RoundFractionalValues = 'on';
            app.PlotMoviFps_EditField.ValueDisplayFormat = '%d';
            app.PlotMoviFps_EditField.Value = 10;
            app.secframeEditFieldLabel = uilabel(app.UIFigure);
            app.secframeEditFieldLabel.Text = 'sec/frame';
            app.PlotTimeEvolsecPerFrame_EditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotTimeEvolsecPerFrame_EditField.Limits = [0.1 Inf];
            app.PlotTimeEvolsecPerFrame_EditField.ValueDisplayFormat = '%2.1f';
            app.PlotTimeEvolsecPerFrame_EditField.Value = 2;
            app.ImportedparsedEQcatalogPanel = uipanel(app.UIFigure);
            app.ImportedparsedEQcatalogPanel.Title = 'Imported/parsed EQ catalog';
            app.EQtable_UITable = uitable(app.ImportedparsedEQcatalogPanel);
            app.EQtable_UITable.ColumnName = {'EQ#'; 'Time (years)'; 'Magnitude'; 'Rupture Area (km^2)'; 'Slipping patches (#)'; 'Stress drop (MPa)'; 'Horz. slip (m)'; 'Vert. slip (m)'; 'Rupture duration (s)'};
            app.EQtable_UITable.RowName = {};
            app.EQtable_UITable.ColumnSortable = true;
            app.EQtable_UITable.CellSelectionCallback = createCallbackFcn(app, @EQtable_UITableCellSelection, true);
            app.PlotScaling_Button = uibutton(app.ImportedparsedEQcatalogPanel, 'push');
            app.PlotScaling_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotScaling_ButtonPushed, true);
            app.PlotScaling_Button.Text = 'Plot scaling relationship';
            app.FigEditField_2Label = uilabel(app.ImportedparsedEQcatalogPanel);
            app.FigEditField_2Label.Text = 'Fig #';
            app.FigNumScaling_EditField = uieditfield(app.ImportedparsedEQcatalogPanel, 'numeric');
            app.FigNumScaling_EditField.Limits = [1 Inf];
            app.FigNumScaling_EditField.RoundFractionalValues = 'on';
            app.FigNumScaling_EditField.ValueDisplayFormat = '%d';
            app.FigNumScaling_EditField.Value = 3;
            app.symbolDropDownLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.symbolDropDownLabel.Text = 'symbol';
            app.Scaling_Symbol_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.Scaling_Symbol_DropDown.Items = {'-', '--', '-.', '.-', '.', 'o-', 'o'};
            app.Scaling_Symbol_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.Scaling_Symbol_DropDown.Value = '1';
            app.scaleDropDownLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.scaleDropDownLabel.Text = 'scale';
            app.ScalingXscale_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.ScalingXscale_DropDown.Items = {'linear', 'log'};
            app.ScalingXscale_DropDown.ItemsData = {'1', '2'};
            app.ScalingXscale_DropDown.Value = '1';
            app.scaleDropDown_2Label = uilabel(app.ImportedparsedEQcatalogPanel);
            app.scaleDropDown_2Label.Text = 'scale';
            app.ScalingYscale_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.ScalingYscale_DropDown.Items = {'linear', 'log'};
            app.ScalingYscale_DropDown.ItemsData = {'1', '2'};
            app.ScalingYscale_DropDown.Value = '1';
            app.xaxisDropDownLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.xaxisDropDownLabel.Text = 'x-axis';
            app.ScalingXvalue_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.ScalingXvalue_DropDown.Items = {'time', 'magnitude', 'rupture area', 'slipping patches', 'stress drop', 'horz. slip', 'vert. slip', 'comb. slip', 'rupt. duration'};
            app.ScalingXvalue_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ScalingXvalue_DropDown.Value = '1';
            app.yaxisDropDownLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.yaxisDropDownLabel.Text = 'y-axis';
            app.ScalingYvalue_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.ScalingYvalue_DropDown.Items = {'time', 'magnitude', 'rupture area', 'slipping patches', 'stress drop', 'horz. slip', 'vert. slip', 'comb. slip', 'rupt. duration', 'frequency', 'frequency (cum)'};
            app.ScalingYvalue_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.ScalingYvalue_DropDown.Value = '2';
            app.forLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.forLabel.Text = 'for';
            app.Scaling_PatchOrAll_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.Scaling_PatchOrAll_DropDown.Items = {'all events', 'selected patch(es)', 'PR events', 'FR events'};
            app.Scaling_PatchOrAll_DropDown.ItemsData = {'1', '2', '3', '4'};
            app.Scaling_PatchOrAll_DropDown.Value = '1';
            app.binningminvalueLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.binningminvalueLabel.Text = 'min. value';
            app.PlotBinMinValue_EditField = uieditfield(app.ImportedparsedEQcatalogPanel, 'numeric');
            app.PlotBinMinValue_EditField.ValueDisplayFormat = '%5.2f';
            app.maxvalueLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.maxvalueLabel.Text = 'max. value';
            app.PlotBinMaxValue_EditField = uieditfield(app.ImportedparsedEQcatalogPanel, 'numeric');
            app.PlotBinMaxValue_EditField.ValueDisplayFormat = '%5.2f';
            app.PlotBinMaxValue_EditField.Value = 1;
            app.binLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.binLabel.Text = 'bin #';
            app.PlotBinNum_EditField = uieditfield(app.ImportedparsedEQcatalogPanel, 'numeric');
            app.PlotBinNum_EditField.Limits = [1 Inf];
            app.PlotBinNum_EditField.ValueDisplayFormat = '%d';
            app.PlotBinNum_EditField.Value = 1;
            app.binLabel_2 = uilabel(app.ImportedparsedEQcatalogPanel);
            app.binLabel_2.Text = 'with magnitude between';
            app.MinMag4ForScaleRel_EditField = uieditfield(app.ImportedparsedEQcatalogPanel, 'numeric');
            app.MinMag4ForScaleRel_EditField.Limits = [1 Inf];
            app.MinMag4ForScaleRel_EditField.ValueDisplayFormat = '%5.2f';
            app.MinMag4ForScaleRel_EditField.Value = 1;
            app.binningminvalueLabel_2 = uilabel(app.ImportedparsedEQcatalogPanel);
            app.binningminvalueLabel_2.Text = 'and';
            app.MaxMag4ForScaleRel_EditField = uieditfield(app.ImportedparsedEQcatalogPanel, 'numeric');
            app.MaxMag4ForScaleRel_EditField.ValueDisplayFormat = '%5.2f';
            app.MaxMag4ForScaleRel_EditField.Value = 9;
            app.PlotHockeyStickButton = uibutton(app.ImportedparsedEQcatalogPanel, 'push');
            app.PlotHockeyStickButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHockeyStickButtonPushed, true);
            app.PlotHockeyStickButton.Text = 'Plot HockeyStick';
            app.wseismomentCheckBox = uicheckbox(app.ImportedparsedEQcatalogPanel);
            app.wseismomentCheckBox.Text = 'w/ seis. moment';
            app.LoadandparsecatalogPanel = uipanel(app.UIFigure);
            app.LoadandparsecatalogPanel.Title = 'Load and parse catalog';
            app.LoadCatalog_Button = uibutton(app.LoadandparsecatalogPanel, 'push');
            app.LoadCatalog_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadCatalog_ButtonPushed, true);
            app.LoadCatalog_Button.Text = 'Load Geometry/Catalog';
            app.RemoveEQswithlessthanEditFieldLabel = uilabel(app.LoadandparsecatalogPanel);
            app.RemoveEQswithlessthanEditFieldLabel.Text = 'Remove EQs with less than';
            app.LoadCatRemoveEQ_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.LoadCatRemoveEQ_EditField.Limits = [1 Inf];
            app.LoadCatRemoveEQ_EditField.RoundFractionalValues = 'on';
            app.LoadCatRemoveEQ_EditField.ValueDisplayFormat = '%d';
            app.LoadCatRemoveEQ_EditField.Value = 1;
            app.CatalogimportedLampLabel = uilabel(app.LoadandparsecatalogPanel);
            app.CatalogimportedLampLabel.Text = 'Catalog imported';
            app.LoadCat_Lamp = uilamp(app.LoadandparsecatalogPanel);
            app.EQsintablewithMagnitudeLabel = uilabel(app.LoadandparsecatalogPanel);
            app.EQsintablewithMagnitudeLabel.Text = 'EQs 4 table must be >';
            app.LoadMinMag4Table_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.LoadMinMag4Table_EditField.ValueDisplayFormat = '%5.2f';
            app.LoadMinMag4Table_EditField.Value = 6;
            app.minpatchslipLabel = uilabel(app.LoadandparsecatalogPanel);
            app.minpatchslipLabel.Text = 'min. patch slip';
            app.LoadMinPatchSlip_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.LoadMinPatchSlip_EditField.ValueDisplayFormat = '%5.3f';
            app.minpatchslipLabel_2 = uilabel(app.LoadandparsecatalogPanel);
            app.minpatchslipLabel_2.Text = 'seis. width (km)';
            app.LoadSeisWidth4PRvsFR_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.LoadSeisWidth4PRvsFR_EditField.ValueDisplayFormat = '%5.2f';
            app.LoadSeisWidth4PRvsFR_EditField.Value = 20;
            app.SaveSelectedButton = uibutton(app.LoadandparsecatalogPanel, 'push');
            app.SaveSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSelectedButtonPushed, true);
            app.SaveSelectedButton.Text = 'Save Selected';
            app.SaveNameEditFieldLabel = uilabel(app.LoadandparsecatalogPanel);
            app.SaveNameEditFieldLabel.Text = 'SaveName';
            app.SaveNameEditField = uieditfield(app.LoadandparsecatalogPanel, 'text');
            app.SaveMRFButton = uibutton(app.LoadandparsecatalogPanel, 'push');
            app.SaveMRFButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMRFButtonPushed, true);
            app.SaveMRFButton.Text = 'Save MRF';
            app.matLabel = uilabel(app.LoadandparsecatalogPanel);
            app.matLabel.Text = '.mat';
            app.PlotindividualeventparameterPanel = uipanel(app.UIFigure);
            app.PlotindividualeventparameterPanel.Title = 'Plot individual-event parameter';
            app.PlotMRF_Button = uibutton(app.PlotindividualeventparameterPanel, 'push');
            app.PlotMRF_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotMRF_ButtonPushed, true);
            app.PlotMRF_Button.Text = 'Plot MRF';
            app.PlotOnFault_Button = uibutton(app.PlotindividualeventparameterPanel, 'push');
            app.PlotOnFault_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotOnFault_ButtonPushed, true);
            app.PlotOnFault_Button.Text = 'Plot "on fault"';
            app.toplotDropDownLabel = uilabel(app.PlotindividualeventparameterPanel);
            app.toplotDropDownLabel.Text = 'to plot:';
            app.PlotComp2Plot_DropDown = uidropdown(app.PlotindividualeventparameterPanel);
            app.PlotComp2Plot_DropDown.Items = {'comb. slip', 'horz. slip', 'vert. slip', 'stress drop', 'rupture time', 'stability type', 'accum. coseis slip', 'roughness'};
            app.PlotComp2Plot_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.PlotComp2Plot_DropDown.ValueChangedFcn = createCallbackFcn(app, @PlotComp2Plot_DropDownValueChanged, true);
            app.PlotComp2Plot_DropDown.Value = '1';
            app.minvalueEditFieldLabel = uilabel(app.PlotindividualeventparameterPanel);
            app.minvalueEditFieldLabel.Text = 'min. value';
            app.PlotMinValue_EditField = uieditfield(app.PlotindividualeventparameterPanel, 'numeric');
            app.PlotMinValue_EditField.ValueDisplayFormat = '%5.2f';
            app.maxvalueEditFieldLabel = uilabel(app.PlotindividualeventparameterPanel);
            app.maxvalueEditFieldLabel.Text = 'max. value';
            app.PlotMaxValue_EditField = uieditfield(app.PlotindividualeventparameterPanel, 'numeric');
            app.PlotMaxValue_EditField.ValueDisplayFormat = '%5.2f';
            app.contourLabel = uilabel(app.PlotindividualeventparameterPanel);
            app.contourLabel.Text = 'contour #';
            app.PlotContourValue_EditField = uieditfield(app.PlotindividualeventparameterPanel, 'numeric');
            app.PlotContourValue_EditField.RoundFractionalValues = 'on';
            app.PlotContourValue_EditField.ValueDisplayFormat = '%d';
            app.FillContour_CheckBox = uicheckbox(app.PlotindividualeventparameterPanel);
            app.FillContour_CheckBox.Text = 'fill';
            app.FillContour_CheckBox.Value = true;
            app.PlotEQsequencePanel = uipanel(app.UIFigure);
            app.PlotEQsequencePanel.Title = 'Plot EQ sequence';
            app.SequencePlot_Button = uibutton(app.PlotEQsequencePanel, 'push');
            app.SequencePlot_Button.ButtonPushedFcn = createCallbackFcn(app, @SequencePlot_ButtonPushed, true);
            app.SequencePlot_Button.Text = 'Plot EQ sequence';
            app.StarttimeEditFieldLabel = uilabel(app.PlotEQsequencePanel);
            app.StarttimeEditFieldLabel.Text = 'Start time';
            app.Sequence_StartT_EditField = uieditfield(app.PlotEQsequencePanel, 'numeric');
            app.Sequence_StartT_EditField.Limits = [0 Inf];
            app.Sequence_StartT_EditField.ValueDisplayFormat = '%5.3f';
            app.EndtimeEditFieldLabel = uilabel(app.PlotEQsequencePanel);
            app.EndtimeEditFieldLabel.Text = 'End time';
            app.Sequence_EndTEditField = uieditfield(app.PlotEQsequencePanel, 'numeric');
            app.Sequence_EndTEditField.Limits = [0 Inf];
            app.Sequence_EndTEditField.ValueDisplayFormat = '%5.3f';
            app.FigEditFieldLabel = uilabel(app.UIFigure);
            app.FigEditFieldLabel.Text = 'Fig #';
            app.PlotFigNum_EditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotFigNum_EditField.Limits = [1 Inf];
            app.PlotFigNum_EditField.RoundFractionalValues = 'on';
            app.PlotFigNum_EditField.ValueDisplayFormat = '%d';
            app.PlotFigNum_EditField.Value = 2;
            app.viewangleEditFieldLabel = uilabel(app.UIFigure);
            app.viewangleEditFieldLabel.Text = 'view angle';
            app.PlotViewAngle_EditField = uieditfield(app.UIFigure, 'text');
            app.PlotViewAngle_EditField.Value = '70 20';
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Text = 'Color map';
            app.PlotColorMap_DropDown = uidropdown(app.UIFigure);
            app.PlotColorMap_DropDown.Items = {'parula', 'jet', 'hot', 'gray', 'blue-white-red', 'slip', 'blah'};
            app.PlotColorMap_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.PlotColorMap_DropDown.Value = '1';
            app.secframeEditFieldLabel_2 = uilabel(app.UIFigure);
            app.secframeEditFieldLabel_2.Text = 'years/frame';
            app.fpsEditFieldLabel_2 = uilabel(app.UIFigure);
            app.fpsEditFieldLabel_2.Tooltip = {'FramePerSec/YearPerFrame; first for single-event; second for EQ sequence'};
            app.fpsEditFieldLabel_2.Text = 'fps';
            app.SelectSinglePatch_Button = uibutton(app.UIFigure, 'push');
            app.SelectSinglePatch_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectSinglePatch_ButtonPushed, true);
            app.SelectSinglePatch_Button.Text = 'Select single patch';
            app.PatchLabel = uilabel(app.UIFigure);
            app.PatchLabel.Text = 'Patch';
            app.SelectPatchID_edit = uieditfield(app.UIFigure, 'numeric');
            app.SelectPatchID_edit.Limits = [0 Inf];
            app.SelectPatchID_edit.RoundFractionalValues = 'on';
            app.SelectPatchID_edit.ValueDisplayFormat = '%d';
            app.SelectPatchID_edit.ValueChangedFcn = createCallbackFcn(app, @SelectPatchID_editValueChanged, true);
            app.SelectPatchID_edit.Value = 1;
            app.SelectHighlightPatch_Button = uibutton(app.UIFigure, 'push');
            app.SelectHighlightPatch_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectHighlightPatch_ButtonPushed, true);
            app.SelectHighlightPatch_Button.Text = 'Highlight patch(es)';
            app.SelectPlotParVsTime_Button = uibutton(app.UIFigure, 'push');
            app.SelectPlotParVsTime_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectPlotParVsTime_ButtonPushed, true);
            app.SelectPlotParVsTime_Button.Text = 'Plot parameter vs. time (and get CVs, histograms)';
            app.SelectPlotParaAlongSurf_Button = uibutton(app.UIFigure, 'push');
            app.SelectPlotParaAlongSurf_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectPlotParaAlongSurf_ButtonPushed, true);
            app.SelectPlotParaAlongSurf_Button.Text = 'Plot parameter along surface';
            app.SelectHypoCentDistrib_Button = uibutton(app.UIFigure, 'push');
            app.SelectHypoCentDistrib_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectHypoCentDistrib_ButtonPushed, true);
            app.SelectHypoCentDistrib_Button.Enable = 'off';
            app.SelectHypoCentDistrib_Button.Visible = 'off';
            app.SelectHypoCentDistrib_Button.Text = 'Plot Hypocenter distrib';
            app.minMagnLabel = uilabel(app.UIFigure);
            app.minMagnLabel.Enable = 'off';
            app.minMagnLabel.Visible = 'off';
            app.minMagnLabel.Text = 'min. Magn.';
            app.InterEventMinMag_EditField = uieditfield(app.UIFigure, 'numeric');
            app.InterEventMinMag_EditField.ValueDisplayFormat = '%5.2f';
            app.InterEventMinMag_EditField.Enable = 'off';
            app.InterEventMinMag_EditField.Visible = 'off';
            app.InterEventMinMag_EditField.Value = 3;
            app.maxMagnLabel = uilabel(app.UIFigure);
            app.maxMagnLabel.Enable = 'off';
            app.maxMagnLabel.Visible = 'off';
            app.maxMagnLabel.Text = 'max. Magn.';
            app.InterEventMaxMag_EditField = uieditfield(app.UIFigure, 'numeric');
            app.InterEventMaxMag_EditField.ValueDisplayFormat = '%5.2f';
            app.InterEventMaxMag_EditField.Enable = 'off';
            app.InterEventMaxMag_EditField.Visible = 'off';
            app.InterEventMaxMag_EditField.Value = 8;
            app.InterEvent_PlotStuff_Button = uibutton(app.UIFigure, 'push');
            app.InterEvent_PlotStuff_Button.ButtonPushedFcn = createCallbackFcn(app, @InterEvent_PlotStuff_ButtonPushed, true);
            app.InterEvent_PlotStuff_Button.Enable = 'off';
            app.InterEvent_PlotStuff_Button.Visible = 'off';
            app.InterEvent_PlotStuff_Button.Text = 'Plot inter-event stuff';
            app.FigEditField_2Label_2 = uilabel(app.UIFigure);
            app.FigEditField_2Label_2.Enable = 'off';
            app.FigEditField_2Label_2.Visible = 'off';
            app.FigEditField_2Label_2.Text = 'Fig #';
            app.InterEventFigNum_EditField = uieditfield(app.UIFigure, 'numeric');
            app.InterEventFigNum_EditField.Limits = [1 Inf];
            app.InterEventFigNum_EditField.RoundFractionalValues = 'on';
            app.InterEventFigNum_EditField.ValueDisplayFormat = '%d';
            app.InterEventFigNum_EditField.Enable = 'off';
            app.InterEventFigNum_EditField.Visible = 'off';
            app.InterEventFigNum_EditField.Value = 5;
            app.scaleDropDownLabel_2 = uilabel(app.UIFigure);
            app.scaleDropDownLabel_2.Enable = 'off';
            app.scaleDropDownLabel_2.Visible = 'off';
            app.scaleDropDownLabel_2.Text = 'scale';
            app.InterEvent_Xscale_DropDown = uidropdown(app.UIFigure);
            app.InterEvent_Xscale_DropDown.Items = {'linear', 'log'};
            app.InterEvent_Xscale_DropDown.ItemsData = {'1', '2'};
            app.InterEvent_Xscale_DropDown.Enable = 'off';
            app.InterEvent_Xscale_DropDown.Visible = 'off';
            app.InterEvent_Xscale_DropDown.Value = '1';
            app.xaxisDropDownLabel_2 = uilabel(app.UIFigure);
            app.xaxisDropDownLabel_2.Enable = 'off';
            app.xaxisDropDownLabel_2.Visible = 'off';
            app.xaxisDropDownLabel_2.Text = 'x-axis';
            app.InterEvent_Xvalue_DropDown = uidropdown(app.UIFigure);
            app.InterEvent_Xvalue_DropDown.Items = {'time difference', 'centroid distance', 'magnitude'};
            app.InterEvent_Xvalue_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.InterEvent_Xvalue_DropDown.Enable = 'off';
            app.InterEvent_Xvalue_DropDown.Visible = 'off';
            app.InterEvent_Xvalue_DropDown.Value = '1';
            app.yaxisLabel = uilabel(app.UIFigure);
            app.yaxisLabel.Enable = 'off';
            app.yaxisLabel.Visible = 'off';
            app.yaxisLabel.Text = 'y-axis';
            app.InterEvent_Yvalue_DropDown = uidropdown(app.UIFigure);
            app.InterEvent_Yvalue_DropDown.Items = {'time difference', 'centroid distance', 'magnitude'};
            app.InterEvent_Yvalue_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.InterEvent_Yvalue_DropDown.Enable = 'off';
            app.InterEvent_Yvalue_DropDown.Visible = 'off';
            app.InterEvent_Yvalue_DropDown.Value = '1';
            app.colorLabel = uilabel(app.UIFigure);
            app.colorLabel.Enable = 'off';
            app.colorLabel.Visible = 'off';
            app.colorLabel.Text = 'color';
            app.InterEvent_Cvalue_DropDown = uidropdown(app.UIFigure);
            app.InterEvent_Cvalue_DropDown.Items = {'time difference', 'centroid distance', 'magnitude'};
            app.InterEvent_Cvalue_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.InterEvent_Cvalue_DropDown.Enable = 'off';
            app.InterEvent_Cvalue_DropDown.Visible = 'off';
            app.InterEvent_Cvalue_DropDown.Value = '1';
            app.scaleDropDownLabel_3 = uilabel(app.UIFigure);
            app.scaleDropDownLabel_3.Enable = 'off';
            app.scaleDropDownLabel_3.Visible = 'off';
            app.scaleDropDownLabel_3.Text = 'scale';
            app.InterEvent_Yscale_DropDown = uidropdown(app.UIFigure);
            app.InterEvent_Yscale_DropDown.Items = {'linear', 'log'};
            app.InterEvent_Yscale_DropDown.ItemsData = {'1', '2'};
            app.InterEvent_Yscale_DropDown.Enable = 'off';
            app.InterEvent_Yscale_DropDown.Visible = 'off';
            app.InterEvent_Yscale_DropDown.Value = '1';
            app.forLabel_2 = uilabel(app.UIFigure);
            app.forLabel_2.Enable = 'off';
            app.forLabel_2.Visible = 'off';
            app.forLabel_2.Text = 'for';
            app.InterEvent_PatchOrAll_DropDown = uidropdown(app.UIFigure);
            app.InterEvent_PatchOrAll_DropDown.Items = {'all events', 'selected patch(es)'};
            app.InterEvent_PatchOrAll_DropDown.ItemsData = {'1', '2'};
            app.InterEvent_PatchOrAll_DropDown.Enable = 'off';
            app.InterEvent_PatchOrAll_DropDown.Visible = 'off';
            app.InterEvent_PatchOrAll_DropDown.Value = '1';
            app.SelectAlllSurfPatches_Button = uibutton(app.UIFigure, 'push');
            app.SelectAlllSurfPatches_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectAlllSurfPatches_ButtonPushed, true);
            app.SelectAlllSurfPatches_Button.Text = 'Select all surface patches';
            app.picksallpatcheswhereatleastonevertexisat10mdepthLabel = uilabel(app.UIFigure);
            app.picksallpatcheswhereatleastonevertexisat10mdepthLabel.Text = '(picks all patches where at least one vertex is at < 10m depth)';
            app.onfaultLabel = uilabel(app.UIFigure);
            app.onfaultLabel.Text = 'on fault';
            app.Select_onSectn_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Select_onSectn_EditField.Limits = [1 Inf];
            app.Select_onSectn_EditField.RoundFractionalValues = 'on';
            app.Select_onSectn_EditField.ValueDisplayFormat = '%d';
            app.Select_onSectn_EditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Z3_Output_MCQsimV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end